classdef PTtoolbox_DEVELOP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        PTtoolbox                      matlab.ui.Figure
        PTtoolbox_Logo                 matlab.ui.control.Image
        PTtoolbox_Label                matlab.ui.control.Label
        TabGroup                       matlab.ui.container.TabGroup
        GrtBi_Tab                      matlab.ui.container.Tab
        GrtBi_Input_Tab                matlab.ui.container.Panel
        GrtBi_Input_TabGrid            matlab.ui.container.GridLayout
        GrtBi_Garnet_Label             matlab.ui.control.Label
        GrtBi_Biotite_Label            matlab.ui.control.Label
        FeEditFieldLabel_5             matlab.ui.control.Label
        GrtBi_grt_Fe_EditField         matlab.ui.control.NumericEditField
        MgLabel_5                      matlab.ui.control.Label
        GrtBi_grt_Mg_EditField         matlab.ui.control.NumericEditField
        CaLabel_4                      matlab.ui.control.Label
        GrtBi_grt_Ca_EditField         matlab.ui.control.NumericEditField
        MnLabel_3                      matlab.ui.control.Label
        GrtBi_grt_Mn_EditField         matlab.ui.control.NumericEditField
        AlLabel_3                      matlab.ui.control.Label
        GrtBi_bi_Al_EditField          matlab.ui.control.NumericEditField
        FeLabel                        matlab.ui.control.Label
        GrtBi_bi_Fe_EditField          matlab.ui.control.NumericEditField
        TiLabel                        matlab.ui.control.Label
        GrtBi_bi_Ti_EditField          matlab.ui.control.NumericEditField
        PkbarLabel_3                   matlab.ui.control.Label
        GrtBi_P_EditField              matlab.ui.control.NumericEditField
        MgLabel_6                      matlab.ui.control.Label
        GrtBi_bi_Mg_EditField          matlab.ui.control.NumericEditField
        MnLabel_4                      matlab.ui.control.Label
        GrtBi_bi_Mn_EditField          matlab.ui.control.NumericEditField
        GrtBi_Results_Tab              matlab.ui.container.Panel
        GrtBi_Results_TabGrid          matlab.ui.container.GridLayout
        GrtBi_T76color_EditField       matlab.ui.control.EditField
        GrtBi_T76_CheckBox             matlab.ui.control.CheckBox
        T76Label                       matlab.ui.control.Label
        GrtBi_T76_EditField            matlab.ui.control.NumericEditField
        XMgLabel_5                     matlab.ui.control.Label
        GrtBi_bi_XMg_EditField         matlab.ui.control.NumericEditField
        XFeLabel_5                     matlab.ui.control.Label
        GrtBi_bi_XFe_EditField         matlab.ui.control.NumericEditField
        XMgLabel_6                     matlab.ui.control.Label
        GrtBi_grt_XMg_EditField        matlab.ui.control.NumericEditField
        XFeLabel_6                     matlab.ui.control.Label
        GrtBi_grt_XFe_EditField        matlab.ui.control.NumericEditField
        XCaLabel_4                     matlab.ui.control.Label
        GrtBi_grt_XCa_EditField        matlab.ui.control.NumericEditField
        XMnLabel_3                     matlab.ui.control.Label
        GrtBi_grt_XMn_EditField        matlab.ui.control.NumericEditField
        XMnLabel_4                     matlab.ui.control.Label
        GrtBi_bi_XMn_EditField         matlab.ui.control.NumericEditField
        lnKdEditFieldLabel_3           matlab.ui.control.Label
        GrtBi_lnKd_EditField           matlab.ui.control.NumericEditField
        GrtBi_GarnetResult_Label       matlab.ui.control.Label
        GrtBi_BiotiteResult_Label      matlab.ui.control.Label
        GA77Label                      matlab.ui.control.Label
        GrtBi_GA77_EditField           matlab.ui.control.NumericEditField
        HL77Label                      matlab.ui.control.Label
        GrtBi_HL77_EditField           matlab.ui.control.NumericEditField
        FS78Label                      matlab.ui.control.Label
        GrtBi_FS78_EditField           matlab.ui.control.NumericEditField
        HS82Label                      matlab.ui.control.Label
        GrtBi_HS82_EditField           matlab.ui.control.NumericEditField
        PL83aLabel                     matlab.ui.control.Label
        GrtBi_PL83a_EditField          matlab.ui.control.NumericEditField
        GS84Label                      matlab.ui.control.Label
        GrtBi_GS84_EditField           matlab.ui.control.NumericEditField
        D91Label                       matlab.ui.control.Label
        GrtBi_D91_EditField            matlab.ui.control.NumericEditField
        B92aLabel                      matlab.ui.control.Label
        GrtBi_B92a_EditField           matlab.ui.control.NumericEditField
        B92bLabel                      matlab.ui.control.Label
        GrtBi_B92b_EditField           matlab.ui.control.NumericEditField
        XTiLabel                       matlab.ui.control.Label
        GrtBi_bi_XTi_EditField         matlab.ui.control.NumericEditField
        XAlLabel_2                     matlab.ui.control.Label
        GrtBi_bi_XAl_EditField         matlab.ui.control.NumericEditField
        GrtBi_GA77_CheckBox            matlab.ui.control.CheckBox
        GrtBi_HL77_CheckBox            matlab.ui.control.CheckBox
        GrtBi_FS78_CheckBox            matlab.ui.control.CheckBox
        GrtBi_HS82_CheckBox            matlab.ui.control.CheckBox
        GrtBi_PL83a_CheckBox           matlab.ui.control.CheckBox
        GrtBi_GS84_CheckBox            matlab.ui.control.CheckBox
        GrtBi_D91_CheckBox             matlab.ui.control.CheckBox
        GrtBi_B92a_CheckBox            matlab.ui.control.CheckBox
        GrtBi_B92b_CheckBox            matlab.ui.control.CheckBox
        GrtBi_GA77color_EditField      matlab.ui.control.EditField
        GrtBi_HL77color_EditField      matlab.ui.control.EditField
        GrtBi_FS78color_EditField      matlab.ui.control.EditField
        GrtBi_HS82color_EditField      matlab.ui.control.EditField
        GrtBi_PL83acolor_EditField     matlab.ui.control.EditField
        GrtBi_GS84color_EditField      matlab.ui.control.EditField
        GrtBi_D91color_EditField       matlab.ui.control.EditField
        GrtBi_B92acolor_EditField      matlab.ui.control.EditField
        GrtBi_B92bcolor_EditField      matlab.ui.control.EditField
        IM85aLabel                     matlab.ui.control.Label
        GrtBi_IM85a_EditField          matlab.ui.control.NumericEditField
        IM85bLabel                     matlab.ui.control.Label
        GrtBi_IM85b_EditField          matlab.ui.control.NumericEditField
        GrtBi_IM85a_CheckBox           matlab.ui.control.CheckBox
        GrtBi_IM85b_CheckBox           matlab.ui.control.CheckBox
        GrtBi_P85_CheckBox             matlab.ui.control.CheckBox
        GrtBi_IM85acolor_EditField     matlab.ui.control.EditField
        GrtBi_IM85bcolor_EditField     matlab.ui.control.EditField
        GrtBi_P85color_EditField       matlab.ui.control.EditField
        P85Label_2                     matlab.ui.control.Label
        GrtBi_P85_EditField            matlab.ui.control.NumericEditField
        GrtBi_Thermometers_Label       matlab.ui.control.Label
        PL83bLabel                     matlab.ui.control.Label
        GrtBi_PL83b_EditField          matlab.ui.control.NumericEditField
        GrtBi_PL83b_CheckBox           matlab.ui.control.CheckBox
        GrtBi_PL83bcolor_EditField     matlab.ui.control.EditField
        GrtBi_Plot_Tab                 matlab.ui.container.Panel
        GrtBi_Plot_TabGrid             matlab.ui.container.GridLayout
        GrtBi_P_max_Label              matlab.ui.control.Label
        GrtBi_P_max                    matlab.ui.control.Spinner
        GrtBi_P_min_Label              matlab.ui.control.Label
        GrtBi_T_max_Label              matlab.ui.control.Label
        GrtBi_P_min                    matlab.ui.control.Spinner
        GrtBi_T_max                    matlab.ui.control.Spinner
        GrtBi_GridCheckBox             matlab.ui.control.CheckBox
        GrtBi_LineWidth_Label          matlab.ui.control.Label
        GrtBi_LineWidth_Spinner        matlab.ui.control.Spinner
        GrtBi_T_min_Label              matlab.ui.control.Label
        GrtBi_T_min                    matlab.ui.control.Spinner
        GrtBi_UIAxes                   matlab.ui.control.UIAxes
        GrtBi_DisplayAll_CheckBox      matlab.ui.control.CheckBox
        GrtBi_ColorAll_EditField       matlab.ui.control.EditField
        GrtBi_Colour_Label             matlab.ui.control.Label
        GrtChl_Tab                     matlab.ui.container.Tab
        GrtChl_Input_Tab               matlab.ui.container.Panel
        GrtChl_Input_TabGrid           matlab.ui.container.GridLayout
        GrtChl_Garnet_Label            matlab.ui.control.Label
        GrtChl_Chlorite_Label          matlab.ui.control.Label
        FeEditFieldLabel_8             matlab.ui.control.Label
        GrtChl_grt_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_11                     matlab.ui.control.Label
        GrtChl_grt_Mg_EditField        matlab.ui.control.NumericEditField
        CaLabel_7                      matlab.ui.control.Label
        GrtChl_grt_Ca_EditField        matlab.ui.control.NumericEditField
        MnLabel_7                      matlab.ui.control.Label
        GrtChl_grt_Mn_EditField        matlab.ui.control.NumericEditField
        FeLabel_4                      matlab.ui.control.Label
        GrtChl_chl_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_12                     matlab.ui.control.Label
        GrtChl_chl_Mg_EditField        matlab.ui.control.NumericEditField
        PkbarLabel_5                   matlab.ui.control.Label
        GrtChl_P_EditField             matlab.ui.control.NumericEditField
        GrtChl_Results_Tab             matlab.ui.container.Panel
        GrtChl_Results_TabGrid         matlab.ui.container.GridLayout
        GrtChl_DH86color_EditField     matlab.ui.control.EditField
        GrtChl_DH86_CheckBox           matlab.ui.control.CheckBox
        DH86Label                      matlab.ui.control.Label
        GrtChl_DH86_EditField          matlab.ui.control.NumericEditField
        XMgLabel_11                    matlab.ui.control.Label
        GrtChl_chl_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_11                    matlab.ui.control.Label
        GrtChl_chl_XFe_EditField       matlab.ui.control.NumericEditField
        XMgLabel_12                    matlab.ui.control.Label
        GrtChl_grt_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_12                    matlab.ui.control.Label
        GrtChl_grt_XFe_EditField       matlab.ui.control.NumericEditField
        XCaLabel_7                     matlab.ui.control.Label
        GrtChl_grt_XCa_EditField       matlab.ui.control.NumericEditField
        XMnLabel_7                     matlab.ui.control.Label
        GrtChl_grt_XMn_EditField       matlab.ui.control.NumericEditField
        lnKdEditFieldLabel_6           matlab.ui.control.Label
        GrtChl_lnKd_EditField          matlab.ui.control.NumericEditField
        GrtChl_GarnetResult_Label      matlab.ui.control.Label
        GrtChl_ChloriteResult_Label    matlab.ui.control.Label
        G90Label                       matlab.ui.control.Label
        GrtChl_G90_EditField           matlab.ui.control.NumericEditField
        P91Label                       matlab.ui.control.Label
        GrtChl_P91_EditField           matlab.ui.control.NumericEditField
        GrtChl_G90_CheckBox            matlab.ui.control.CheckBox
        GrtChl_P91_CheckBox            matlab.ui.control.CheckBox
        GrtChl_G90color_EditField      matlab.ui.control.EditField
        GrtChl_P91color_EditField      matlab.ui.control.EditField
        GrtChl_Thermometers_Label      matlab.ui.control.Label
        GrtChl_Plot_Tab                matlab.ui.container.Panel
        GrtChl_Plot_TabGrid            matlab.ui.container.GridLayout
        GrtChl_P_max_Label             matlab.ui.control.Label
        GrtChl_P_max                   matlab.ui.control.Spinner
        GrtChl_P_min_Label             matlab.ui.control.Label
        GrtChl_T_max_Label             matlab.ui.control.Label
        GrtChl_P_min                   matlab.ui.control.Spinner
        GrtChl_T_max                   matlab.ui.control.Spinner
        GrtChl_GridCheckBox            matlab.ui.control.CheckBox
        GrtChl_LineWidth_Label         matlab.ui.control.Label
        GrtChl_LineWidth_Spinner       matlab.ui.control.Spinner
        GrtChl_T_min_Label             matlab.ui.control.Label
        GrtChl_T_min                   matlab.ui.control.Spinner
        GrtChl_UIAxes                  matlab.ui.control.UIAxes
        GrtChl_DisplayAll_CheckBox     matlab.ui.control.CheckBox
        GrtChl_ColorAll_EditField      matlab.ui.control.EditField
        GrtChl_Colour_Label            matlab.ui.control.Label
        GrtCpx_Tab                     matlab.ui.container.Tab
        GrtCpx_Input_Tab               matlab.ui.container.Panel
        GrtCpx_Input_TabGrid           matlab.ui.container.GridLayout
        GrtCpx_Garnet_Label            matlab.ui.control.Label
        GrtCpx_Cpx_Label               matlab.ui.control.Label
        FeEditFieldLabel_3             matlab.ui.control.Label
        GrtCpx_grt_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_3                      matlab.ui.control.Label
        GrtCpx_grt_Mg_EditField        matlab.ui.control.NumericEditField
        CaLabel_2                      matlab.ui.control.Label
        GrtCpx_grt_Ca_EditField        matlab.ui.control.NumericEditField
        MnLabel_2                      matlab.ui.control.Label
        GrtCpx_grt_Mn_EditField        matlab.ui.control.NumericEditField
        FeEditFieldLabel_4             matlab.ui.control.Label
        GrtCpx_cpx_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_4                      matlab.ui.control.Label
        GrtCpx_cpx_Mg_EditField        matlab.ui.control.NumericEditField
        AlLabel_2                      matlab.ui.control.Label
        GrtCpx_cpx_Al_EditField        matlab.ui.control.NumericEditField
        PkbarLabel_2                   matlab.ui.control.Label
        GrtCpx_P_EditField             matlab.ui.control.NumericEditField
        CaLabel_3                      matlab.ui.control.Label
        GrtCpx_cpx_Ca_EditField        matlab.ui.control.NumericEditField
        NaLabel                        matlab.ui.control.Label
        GrtCpx_cpx_Na_EditField        matlab.ui.control.NumericEditField
        GrtCpx_Results_Tab             matlab.ui.container.Panel
        GrtCpx_Results_TabGrid         matlab.ui.container.GridLayout
        GrtCpx_MH72color_EditField     matlab.ui.control.EditField
        GrtCpx_MH72_CheckBox           matlab.ui.control.CheckBox
        MH72Label                      matlab.ui.control.Label
        GrtCpx_MH72_EditField          matlab.ui.control.NumericEditField
        XMgLabel_3                     matlab.ui.control.Label
        GrtCpx_cpx_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_3                     matlab.ui.control.Label
        GrtCpx_cpx_XFe_EditField       matlab.ui.control.NumericEditField
        XMgLabel_4                     matlab.ui.control.Label
        GrtCpx_grt_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_4                     matlab.ui.control.Label
        GrtCpx_grt_XFe_EditField       matlab.ui.control.NumericEditField
        XCaLabel_2                     matlab.ui.control.Label
        GrtCpx_grt_XCa_EditField       matlab.ui.control.NumericEditField
        XMnLabel_2                     matlab.ui.control.Label
        GrtCpx_grt_XMn_EditField       matlab.ui.control.NumericEditField
        XCaLabel_3                     matlab.ui.control.Label
        GrtCpx_cpx_XCa_EditField       matlab.ui.control.NumericEditField
        lnKdEditFieldLabel_2           matlab.ui.control.Label
        GrtCpx_lnKd_EditField          matlab.ui.control.NumericEditField
        GrtCpx_GarnetResult_Label      matlab.ui.control.Label
        GrtCpx_CpxResult_Label         matlab.ui.control.Label
        RG74Label                      matlab.ui.control.Label
        GrtCpx_RG74_EditField          matlab.ui.control.NumericEditField
        MG78EditFieldLabel_4           matlab.ui.control.Label
        GrtCpx_MG78_EditField          matlab.ui.control.NumericEditField
        EG79Label                      matlab.ui.control.Label
        GrtCpx_EG79_EditField          matlab.ui.control.NumericEditField
        S79Label                       matlab.ui.control.Label
        GrtCpx_S79_EditField           matlab.ui.control.NumericEditField
        D80Label                       matlab.ui.control.Label
        GrtCpx_D80_EditField           matlab.ui.control.NumericEditField
        P85Label                       matlab.ui.control.Label
        GrtCpx_P85_EditField           matlab.ui.control.NumericEditField
        K88Label                       matlab.ui.control.Label
        GrtCpx_K88_EditField           matlab.ui.control.NumericEditField
        A94Label                       matlab.ui.control.Label
        GrtCpx_A94_EditField           matlab.ui.control.NumericEditField
        R00Label                       matlab.ui.control.Label
        GrtCpx_R00_EditField           matlab.ui.control.NumericEditField
        N09Label                       matlab.ui.control.Label
        GrtCpx_N09_EditField           matlab.ui.control.NumericEditField
        GrtCpx_RG74_CheckBox           matlab.ui.control.CheckBox
        GrtCpx_MG78_CheckBox           matlab.ui.control.CheckBox
        GrtCpx_EG79_CheckBox           matlab.ui.control.CheckBox
        GrtCpx_S79_CheckBox            matlab.ui.control.CheckBox
        GrtCpx_D80_CheckBox            matlab.ui.control.CheckBox
        GrtCpx_P85_CheckBox            matlab.ui.control.CheckBox
        GrtCpx_K88_CheckBox            matlab.ui.control.CheckBox
        GrtCpx_A94_CheckBox            matlab.ui.control.CheckBox
        GrtCpx_R00_CheckBox            matlab.ui.control.CheckBox
        GrtCpx_N09_CheckBox            matlab.ui.control.CheckBox
        GrtCpx_RG74color_EditField     matlab.ui.control.EditField
        GrtCpx_MG78color_EditField     matlab.ui.control.EditField
        GrtCpx_EG79color_EditField     matlab.ui.control.EditField
        GrtCpx_S79color_EditField      matlab.ui.control.EditField
        GrtCpx_D80color_EditField      matlab.ui.control.EditField
        GrtCpx_P85color_EditField      matlab.ui.control.EditField
        GrtCpx_K88color_EditField      matlab.ui.control.EditField
        GrtCpx_A94color_EditField      matlab.ui.control.EditField
        GrtCpx_R00color_EditField      matlab.ui.control.EditField
        GrtCpx_N09color_EditField      matlab.ui.control.EditField
        GrtCpx_Thermometers_Label      matlab.ui.control.Label
        GrtCpx_Plot_Tab                matlab.ui.container.Panel
        GrtCpx_Plot_TabGrid            matlab.ui.container.GridLayout
        GrtCpx_P_max_Label             matlab.ui.control.Label
        GrtCpx_P_max                   matlab.ui.control.Spinner
        GrtCpx_P_min_Label             matlab.ui.control.Label
        GrtCpx_T_max_Label             matlab.ui.control.Label
        GrtCpx_P_min                   matlab.ui.control.Spinner
        GrtCpx_T_max                   matlab.ui.control.Spinner
        GrtCpx_GridCheckBox            matlab.ui.control.CheckBox
        GrtCpx_LineWidth_Label         matlab.ui.control.Label
        GrtCpx_LineWidth_Spinner       matlab.ui.control.Spinner
        GrtCpx_T_min_Label             matlab.ui.control.Label
        GrtCpx_T_min                   matlab.ui.control.Spinner
        GrtCpx_UIAxes                  matlab.ui.control.UIAxes
        GrtCpx_DisplayAll_CheckBox     matlab.ui.control.CheckBox
        GrtCpx_ColorAll_EditField      matlab.ui.control.EditField
        GrtCpx_Colour_Label            matlab.ui.control.Label
        GrtCrd_Tab                     matlab.ui.container.Tab
        GrtCrd_Input_Tab               matlab.ui.container.Panel
        GrtCrd_Input_TabGrid           matlab.ui.container.GridLayout
        GrtCrd_Garnet_Label            matlab.ui.control.Label
        GrtCrd_Cordierite_Label        matlab.ui.control.Label
        FeEditFieldLabel_6             matlab.ui.control.Label
        GrtCrd_grt_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_7                      matlab.ui.control.Label
        GrtCrd_grt_Mg_EditField        matlab.ui.control.NumericEditField
        CaLabel_5                      matlab.ui.control.Label
        GrtCrd_grt_Ca_EditField        matlab.ui.control.NumericEditField
        MnLabel_5                      matlab.ui.control.Label
        GrtCrd_grt_Mn_EditField        matlab.ui.control.NumericEditField
        FeLabel_2                      matlab.ui.control.Label
        GrtCrd_crd_Fe_EditField        matlab.ui.control.NumericEditField
        PkbarLabel_4                   matlab.ui.control.Label
        GrtCrd_P_EditField             matlab.ui.control.NumericEditField
        MgLabel_8                      matlab.ui.control.Label
        GrtCrd_crd_Mg_EditField        matlab.ui.control.NumericEditField
        GrtCrd_Results_Tab             matlab.ui.container.Panel
        GrtCrd_Results_TabGrid         matlab.ui.container.GridLayout
        GrtCrd_C71color_EditField      matlab.ui.control.EditField
        GrtCrd_C71_CheckBox            matlab.ui.control.CheckBox
        C71Label                       matlab.ui.control.Label
        GrtCrd_C71_EditField           matlab.ui.control.NumericEditField
        XMgLabel_7                     matlab.ui.control.Label
        GrtCrd_crd_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_7                     matlab.ui.control.Label
        GrtCrd_crd_XFe_EditField       matlab.ui.control.NumericEditField
        XMgLabel_8                     matlab.ui.control.Label
        GrtCrd_grt_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_8                     matlab.ui.control.Label
        GrtCrd_grt_XFe_EditField       matlab.ui.control.NumericEditField
        XCaLabel_5                     matlab.ui.control.Label
        GrtCrd_grt_XCa_EditField       matlab.ui.control.NumericEditField
        XMnLabel_5                     matlab.ui.control.Label
        GrtCrd_grt_XMn_EditField       matlab.ui.control.NumericEditField
        lnKdEditFieldLabel_4           matlab.ui.control.Label
        GrtCrd_lnKd_EditField          matlab.ui.control.NumericEditField
        GrtCrd_GarnetResult_Label      matlab.ui.control.Label
        GrtCrd_CordieriteResult_Label  matlab.ui.control.Label
        T76Label_2                     matlab.ui.control.Label
        GrtCrd_T76_EditField           matlab.ui.control.NumericEditField
        HL77Label_2                    matlab.ui.control.Label
        GrtCrd_HL77_EditField          matlab.ui.control.NumericEditField
        P85Label_3                     matlab.ui.control.Label
        GrtCrd_P85_EditField           matlab.ui.control.NumericEditField
        B88Label                       matlab.ui.control.Label
        GrtCrd_B88_EditField           matlab.ui.control.NumericEditField
        B93Label                       matlab.ui.control.Label
        GrtCrd_B93_EditField           matlab.ui.control.NumericEditField
        GrtCrd_T76_CheckBox            matlab.ui.control.CheckBox
        GrtCrd_HL77_CheckBox           matlab.ui.control.CheckBox
        GrtCrd_P85_CheckBox            matlab.ui.control.CheckBox
        GrtCrd_B88_CheckBox            matlab.ui.control.CheckBox
        GrtCrd_B93_CheckBox            matlab.ui.control.CheckBox
        GrtCrd_T76color_EditField      matlab.ui.control.EditField
        GrtCrd_HL77color_EditField     matlab.ui.control.EditField
        GrtCrd_P85color_EditField      matlab.ui.control.EditField
        GrtCrd_B88color_EditField      matlab.ui.control.EditField
        GrtCrd_B93color_EditField      matlab.ui.control.EditField
        GrtCrd_Thermometers_Label      matlab.ui.control.Label
        GrtCrd_Plot_Tab                matlab.ui.container.Panel
        GrtCrd_Plot_TabGrid            matlab.ui.container.GridLayout
        GrtCrd_P_max_Label             matlab.ui.control.Label
        GrtCrd_P_max                   matlab.ui.control.Spinner
        GrtCrd_P_min_Label             matlab.ui.control.Label
        GrtCrd_T_max_Label             matlab.ui.control.Label
        GrtCrd_P_min                   matlab.ui.control.Spinner
        GrtCrd_T_max                   matlab.ui.control.Spinner
        GrtCrd_GridCheckBox            matlab.ui.control.CheckBox
        GrtCrd_LineWidth_Label         matlab.ui.control.Label
        GrtCrd_LineWidth_Spinner       matlab.ui.control.Spinner
        GrtCrd_T_min_Label             matlab.ui.control.Label
        GrtCrd_T_min                   matlab.ui.control.Spinner
        GrtCrd_UIAxes                  matlab.ui.control.UIAxes
        GrtCrd_DisplayAll_CheckBox     matlab.ui.control.CheckBox
        GrtCrd_ColorAll_EditField      matlab.ui.control.EditField
        GrtCrd_Colour_Label            matlab.ui.control.Label
        GrtHbl_Tab                     matlab.ui.container.Tab
        GrtHbl_Input_Tab               matlab.ui.container.Panel
        GrtHbl_Input_TabGrid           matlab.ui.container.GridLayout
        GrtHbl_Garnet_Label            matlab.ui.control.Label
        GrtHbl_Hornblende_Label        matlab.ui.control.Label
        FeEditFieldLabel_7             matlab.ui.control.Label
        GrtHbl_grt_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_9                      matlab.ui.control.Label
        GrtHbl_grt_Mg_EditField        matlab.ui.control.NumericEditField
        CaLabel_6                      matlab.ui.control.Label
        GrtHbl_grt_Ca_EditField        matlab.ui.control.NumericEditField
        MnLabel_6                      matlab.ui.control.Label
        GrtHbl_grt_Mn_EditField        matlab.ui.control.NumericEditField
        FeLabel_3                      matlab.ui.control.Label
        GrtHbl_hbl_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_10                     matlab.ui.control.Label
        GrtHbl_hbl_Mg_EditField        matlab.ui.control.NumericEditField
        GrtHbl_Results_Tab             matlab.ui.container.Panel
        GrtHbl_Results_TabGrid         matlab.ui.container.GridLayout
        GrtHbl_GP84color_EditField     matlab.ui.control.EditField
        GrtHbl_GP84_CheckBox           matlab.ui.control.CheckBox
        GP84Label                      matlab.ui.control.Label
        GrtHbl_GP84_EditField          matlab.ui.control.NumericEditField
        XMgLabel_9                     matlab.ui.control.Label
        GrtHbl_hbl_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_9                     matlab.ui.control.Label
        GrtHbl_hbl_XFe_EditField       matlab.ui.control.NumericEditField
        XMgLabel_10                    matlab.ui.control.Label
        GrtHbl_grt_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_10                    matlab.ui.control.Label
        GrtHbl_grt_XFe_EditField       matlab.ui.control.NumericEditField
        XCaLabel_6                     matlab.ui.control.Label
        GrtHbl_grt_XCa_EditField       matlab.ui.control.NumericEditField
        XMnLabel_6                     matlab.ui.control.Label
        GrtHbl_grt_XMn_EditField       matlab.ui.control.NumericEditField
        lnKdEditFieldLabel_5           matlab.ui.control.Label
        GrtHbl_lnKd_EditField          matlab.ui.control.NumericEditField
        GrtHbl_GarnetResult_Label      matlab.ui.control.Label
        GrtHbl_HornblendeResult_Label  matlab.ui.control.Label
        Per85Label                     matlab.ui.control.Label
        GrtHbl_Per85_EditField         matlab.ui.control.NumericEditField
        Pow85Label                     matlab.ui.control.Label
        GrtHbl_Pow85_EditField         matlab.ui.control.NumericEditField
        R00Label_2                     matlab.ui.control.Label
        GrtHbl_R00_EditField           matlab.ui.control.NumericEditField
        GrtHbl_Per85_CheckBox          matlab.ui.control.CheckBox
        GrtHbl_Pow85_CheckBox          matlab.ui.control.CheckBox
        GrtHbl_R00_CheckBox            matlab.ui.control.CheckBox
        GrtHbl_Per85color_EditField    matlab.ui.control.EditField
        GrtHbl_Pow85color_EditField    matlab.ui.control.EditField
        GrtHbl_R00color_EditField      matlab.ui.control.EditField
        GrtHbl_Thermometers_Label      matlab.ui.control.Label
        GrtHbl_Plot_Tab                matlab.ui.container.Panel
        GrtHbl_Plot_TabGrid            matlab.ui.container.GridLayout
        GrtHbl_P_max_Label             matlab.ui.control.Label
        GrtHbl_P_max                   matlab.ui.control.Spinner
        GrtHbl_P_min_Label             matlab.ui.control.Label
        GrtHbl_T_max_Label             matlab.ui.control.Label
        GrtHbl_P_min                   matlab.ui.control.Spinner
        GrtHbl_T_max                   matlab.ui.control.Spinner
        GrtHbl_GridCheckBox            matlab.ui.control.CheckBox
        GrtHbl_LineWidth_Label         matlab.ui.control.Label
        GrtHbl_LineWidth_Spinner       matlab.ui.control.Spinner
        GrtHbl_T_min_Label             matlab.ui.control.Label
        GrtHbl_T_min                   matlab.ui.control.Spinner
        GrtHbl_UIAxes                  matlab.ui.control.UIAxes
        GrtHbl_DisplayAll_CheckBox     matlab.ui.control.CheckBox
        GrtHbl_ColorAll_EditField      matlab.ui.control.EditField
        GrtHbl_Colour_Label            matlab.ui.control.Label
        GrtIlm_Tab                     matlab.ui.container.Tab
        GrtIlm_Input_Tab               matlab.ui.container.Panel
        GrtIlm_Input_TabGrid           matlab.ui.container.GridLayout
        GrtIlm_Garnet_Label            matlab.ui.control.Label
        GrtIlm_Ilmenite_Label          matlab.ui.control.Label
        FeEditFieldLabel_11            matlab.ui.control.Label
        GrtIlm_grt_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_15                     matlab.ui.control.Label
        GrtIlm_grt_Mg_EditField        matlab.ui.control.NumericEditField
        CaLabel_9                      matlab.ui.control.Label
        GrtIlm_grt_Ca_EditField        matlab.ui.control.NumericEditField
        MnLabel_9                      matlab.ui.control.Label
        GrtIlm_grt_Mn_EditField        matlab.ui.control.NumericEditField
        FeLabel_5                      matlab.ui.control.Label
        GrtIlm_ilm_Fe_EditField        matlab.ui.control.NumericEditField
        MnLabel_10                     matlab.ui.control.Label
        GrtIlm_ilm_Mn_EditField        matlab.ui.control.NumericEditField
        GrtIlm_Results_Tab             matlab.ui.container.Panel
        GrtIlm_Results_TabGrid         matlab.ui.container.GridLayout
        GrtIlm_P87color_EditField      matlab.ui.control.EditField
        GrtIlm_P87_CheckBox            matlab.ui.control.CheckBox
        P87Label                       matlab.ui.control.Label
        GrtIlm_P87_EditField           matlab.ui.control.NumericEditField
        XMnLabel_10                    matlab.ui.control.Label
        GrtIlm_ilm_XMn_EditField       matlab.ui.control.NumericEditField
        XFeLabel_15                    matlab.ui.control.Label
        GrtIlm_ilm_XFe_EditField       matlab.ui.control.NumericEditField
        XMgLabel_16                    matlab.ui.control.Label
        GrtIlm_grt_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_16                    matlab.ui.control.Label
        GrtIlm_grt_XFe_EditField       matlab.ui.control.NumericEditField
        XCaLabel_9                     matlab.ui.control.Label
        GrtIlm_grt_XCa_EditField       matlab.ui.control.NumericEditField
        XMnLabel_9                     matlab.ui.control.Label
        GrtIlm_grt_XMn_EditField       matlab.ui.control.NumericEditField
        lnKdEditFieldLabel_8           matlab.ui.control.Label
        GrtIlm_lnKd_EditField          matlab.ui.control.NumericEditField
        GrtIlm_GarnetResult_Label      matlab.ui.control.Label
        GrtIlm_IlmeniteResult_Label    matlab.ui.control.Label
        P91Label_2                     matlab.ui.control.Label
        GrtIlm_P91_EditField           matlab.ui.control.NumericEditField
        M10Label                       matlab.ui.control.Label
        GrtIlm_M10_EditField           matlab.ui.control.NumericEditField
        GrtIlm_P91_CheckBox            matlab.ui.control.CheckBox
        GrtIlm_M10_CheckBox            matlab.ui.control.CheckBox
        GrtIlm_P91color_EditField      matlab.ui.control.EditField
        GrtIlm_M10color_EditField      matlab.ui.control.EditField
        GrtIlm_Thermometers_Label      matlab.ui.control.Label
        GrtIlm_Plot_Tab                matlab.ui.container.Panel
        GrtIlm_Plot_TabGrid            matlab.ui.container.GridLayout
        GrtIlm_P_max_Label             matlab.ui.control.Label
        GrtIlm_P_max                   matlab.ui.control.Spinner
        GrtIlm_P_min_Label             matlab.ui.control.Label
        GrtIlm_T_max_Label             matlab.ui.control.Label
        GrtIlm_P_min                   matlab.ui.control.Spinner
        GrtIlm_T_max                   matlab.ui.control.Spinner
        GrtIlm_GridCheckBox            matlab.ui.control.CheckBox
        GrtIlm_LineWidth_Label         matlab.ui.control.Label
        GrtIlm_LineWidth_Spinner       matlab.ui.control.Spinner
        GrtIlm_T_min_Label             matlab.ui.control.Label
        GrtIlm_T_min                   matlab.ui.control.Spinner
        GrtIlm_UIAxes                  matlab.ui.control.UIAxes
        GrtIlm_DisplayAll_CheckBox     matlab.ui.control.CheckBox
        GrtIlm_ColorAll_EditField      matlab.ui.control.EditField
        GrtIlm_Colour_Label            matlab.ui.control.Label
        GrtPh_Tab                      matlab.ui.container.Tab
        GrtPh_Input_Tab                matlab.ui.container.Panel
        GrtPh_Input_TabGrid            matlab.ui.container.GridLayout
        GrtPh_Garnet_Label             matlab.ui.control.Label
        GrtPh_Phengite_Label           matlab.ui.control.Label
        FeEditFieldLabel_9             matlab.ui.control.Label
        GrtPh_grt_Fe_EditField         matlab.ui.control.NumericEditField
        MgLabel_13                     matlab.ui.control.Label
        GrtPh_grt_Mg_EditField         matlab.ui.control.NumericEditField
        CaLabel_8                      matlab.ui.control.Label
        GrtPh_grt_Ca_EditField         matlab.ui.control.NumericEditField
        MnLabel_8                      matlab.ui.control.Label
        GrtPh_grt_Mn_EditField         matlab.ui.control.NumericEditField
        FeEditFieldLabel_10            matlab.ui.control.Label
        GrtPh_ph_Fe_EditField          matlab.ui.control.NumericEditField
        MgLabel_14                     matlab.ui.control.Label
        GrtPh_ph_Mg_EditField          matlab.ui.control.NumericEditField
        AlLabel_4                      matlab.ui.control.Label
        GrtPh_ph_Al_EditField          matlab.ui.control.NumericEditField
        PkbarLabel_6                   matlab.ui.control.Label
        GrtPh_P_EditField              matlab.ui.control.NumericEditField
        GrtPh_Results_Tab              matlab.ui.container.Panel
        GrtPh_Results_TabGrid          matlab.ui.container.GridLayout
        GrtPh_W02acolor_EditField      matlab.ui.control.EditField
        GrtPh_HF88color_EditField      matlab.ui.control.EditField
        GrtPh_W02a_CheckBox            matlab.ui.control.CheckBox
        GrtPh_HF88_CheckBox            matlab.ui.control.CheckBox
        GrtPh_GH82acolor_EditField     matlab.ui.control.EditField
        GrtPh_KR78color_EditField      matlab.ui.control.EditField
        GrtPh_GH82a_CheckBox           matlab.ui.control.CheckBox
        GrtPh_KR78_CheckBox            matlab.ui.control.CheckBox
        W02aLabel                      matlab.ui.control.Label
        GrtPh_W02a_EditField           matlab.ui.control.NumericEditField
        HF88Label                      matlab.ui.control.Label
        GrtPh_HF88_EditField           matlab.ui.control.NumericEditField
        GH82aLabel                     matlab.ui.control.Label
        GrtPh_GH82a_EditField          matlab.ui.control.NumericEditField
        KR78Label                      matlab.ui.control.Label
        GrtPh_KR78_EditField           matlab.ui.control.NumericEditField
        XMgLabel_13                    matlab.ui.control.Label
        GrtPh_ph_XMg_EditField         matlab.ui.control.NumericEditField
        XFeLabel_13                    matlab.ui.control.Label
        GrtPh_ph_XFe_EditField         matlab.ui.control.NumericEditField
        XMgLabel_14                    matlab.ui.control.Label
        GrtPh_grt_XMg_EditField        matlab.ui.control.NumericEditField
        XFeLabel_14                    matlab.ui.control.Label
        GrtPh_grt_XFe_EditField        matlab.ui.control.NumericEditField
        XCaLabel_8                     matlab.ui.control.Label
        GrtPh_grt_XCa_EditField        matlab.ui.control.NumericEditField
        XMnLabel_8                     matlab.ui.control.Label
        GrtPh_grt_XMn_EditField        matlab.ui.control.NumericEditField
        XAlLabel_3                     matlab.ui.control.Label
        GrtPh_ph_XAl_EditField         matlab.ui.control.NumericEditField
        lnKdEditFieldLabel_7           matlab.ui.control.Label
        GrtPh_lnKd_EditField           matlab.ui.control.NumericEditField
        GH82bLabel                     matlab.ui.control.Label
        GrtPh_GH82b_EditField          matlab.ui.control.NumericEditField
        GH82cLabel                     matlab.ui.control.Label
        GrtPh_GH82c_EditField          matlab.ui.control.NumericEditField
        W02bLabel                      matlab.ui.control.Label
        GrtPh_W02b_EditField           matlab.ui.control.NumericEditField
        GrtPh_GH82b_CheckBox           matlab.ui.control.CheckBox
        GrtPh_GH82c_CheckBox           matlab.ui.control.CheckBox
        GrtPh_W02b_CheckBox            matlab.ui.control.CheckBox
        GrtPh_GH82bcolor_EditField     matlab.ui.control.EditField
        GrtPh_GH82ccolor_EditField     matlab.ui.control.EditField
        GrtPh_W02bcolor_EditField      matlab.ui.control.EditField
        GrtPh_GarnetResult_Label       matlab.ui.control.Label
        GrtPh_PhengiteResult_Label     matlab.ui.control.Label
        GrtPh_Thermometers_Label       matlab.ui.control.Label
        GrtPh_Plot_Tab                 matlab.ui.container.Panel
        GrtPh_Plot_TabGrid             matlab.ui.container.GridLayout
        GrtPh_P_max_Label              matlab.ui.control.Label
        GrtPh_P_max                    matlab.ui.control.Spinner
        GrtPh_P_min_Label              matlab.ui.control.Label
        GrtPh_T_max_Label              matlab.ui.control.Label
        GrtPh_P_min                    matlab.ui.control.Spinner
        GrtPh_T_max                    matlab.ui.control.Spinner
        GrtPh_GridCheckBox             matlab.ui.control.CheckBox
        GrtPh_LineWidth_Label          matlab.ui.control.Label
        GrtPh_LineWidth_Spinner        matlab.ui.control.Spinner
        GrtPh_T_min_Label              matlab.ui.control.Label
        GrtPh_T_min                    matlab.ui.control.Spinner
        GrtPh_UIAxes                   matlab.ui.control.UIAxes
        GrtPh_DisplayAll_CheckBox      matlab.ui.control.CheckBox
        GrtPh_ColorAll_EditField       matlab.ui.control.EditField
        GrtPh_Colour_Label             matlab.ui.control.Label
        GrtOl_Tab                      matlab.ui.container.Tab
        GrtOpx_Tab                     matlab.ui.container.Tab
        GrtOpx_Input_Tab               matlab.ui.container.Panel
        GrtOpx_Input_TabGrid           matlab.ui.container.GridLayout
        GrtOpx_Garnet_Label            matlab.ui.control.Label
        GrtOpx_Opx_Label               matlab.ui.control.Label
        FeEditFieldLabel               matlab.ui.control.Label
        GrtOpx_grt_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel                        matlab.ui.control.Label
        GrtOpx_grt_Mg_EditField        matlab.ui.control.NumericEditField
        CaLabel                        matlab.ui.control.Label
        GrtOpx_grt_Ca_EditField        matlab.ui.control.NumericEditField
        MnLabel                        matlab.ui.control.Label
        GrtOpx_grt_Mn_EditField        matlab.ui.control.NumericEditField
        FeEditFieldLabel_2             matlab.ui.control.Label
        GrtOpx_opx_Fe_EditField        matlab.ui.control.NumericEditField
        MgLabel_2                      matlab.ui.control.Label
        GrtOpx_opx_Mg_EditField        matlab.ui.control.NumericEditField
        AlLabel                        matlab.ui.control.Label
        GrtOpx_opx_Al_EditField        matlab.ui.control.NumericEditField
        PkbarLabel                     matlab.ui.control.Label
        GrtOpx_P_EditField             matlab.ui.control.NumericEditField
        GrtOpx_Plot_Tab                matlab.ui.container.Panel
        GrtOpx_Plot_TabGrid            matlab.ui.container.GridLayout
        GrtOpx_P_max_Label             matlab.ui.control.Label
        GrtOpx_P_max                   matlab.ui.control.Spinner
        GrtOpx_P_min_Label             matlab.ui.control.Label
        GrtOpx_T_max_Label             matlab.ui.control.Label
        GrtOpx_P_min                   matlab.ui.control.Spinner
        GrtOpx_T_max                   matlab.ui.control.Spinner
        GrtOpx_GridCheckBox            matlab.ui.control.CheckBox
        GrtOpx_LineWidth_Label         matlab.ui.control.Label
        GrtOpx_LineWidth_Spinner       matlab.ui.control.Spinner
        GrtOpx_T_min_Label             matlab.ui.control.Label
        GrtOpx_T_min                   matlab.ui.control.Spinner
        GrtOpx_UIAxes                  matlab.ui.control.UIAxes
        GrtOpx_DisplayAll_CheckBox     matlab.ui.control.CheckBox
        GrtOpx_ColorAll_EditField      matlab.ui.control.EditField
        GrtOpx_Colour_Label            matlab.ui.control.Label
        GrtOpx_Results_Tab             matlab.ui.container.Panel
        GrtOpx_Results_TabGrid         matlab.ui.container.GridLayout
        GrtOpx_PL90color_EditField     matlab.ui.control.EditField
        GrtOpx_PL90_CheckBox           matlab.ui.control.CheckBox
        GrtOpx_AP89color_EditField     matlab.ui.control.EditField
        GrtOpx_AP89_CheckBox           matlab.ui.control.CheckBox
        GrtOpx_LG84color_EditField     matlab.ui.control.EditField
        GrtOpx_SB84color_EditField     matlab.ui.control.EditField
        GrtOpx_LG84_CheckBox           matlab.ui.control.CheckBox
        GrtOpx_SB84_CheckBox           matlab.ui.control.CheckBox
        GrtOpx_D80color_EditField      matlab.ui.control.EditField
        GrtOpx_MG78color_EditField     matlab.ui.control.EditField
        GrtOpx_D80_CheckBox            matlab.ui.control.CheckBox
        GrtOpx_MG78_CheckBox           matlab.ui.control.CheckBox
        PL90EditFieldLabel             matlab.ui.control.Label
        GrtOpx_PL90_EditField          matlab.ui.control.NumericEditField
        AP89EditFieldLabel             matlab.ui.control.Label
        GrtOpx_AP89_EditField          matlab.ui.control.NumericEditField
        LG84EditFieldLabel             matlab.ui.control.Label
        GrtOpx_LG84_EditField          matlab.ui.control.NumericEditField
        SB84EditFieldLabel             matlab.ui.control.Label
        GrtOpx_SB84_EditField          matlab.ui.control.NumericEditField
        D80EditFieldLabel              matlab.ui.control.Label
        GrtOpx_D80_EditField           matlab.ui.control.NumericEditField
        MG78EditFieldLabel             matlab.ui.control.Label
        GrtOpx_MG78_EditField          matlab.ui.control.NumericEditField
        XMgLabel_2                     matlab.ui.control.Label
        GrtOpx_opx_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel_2                     matlab.ui.control.Label
        GrtOpx_opx_XFe_EditField       matlab.ui.control.NumericEditField
        XMgLabel                       matlab.ui.control.Label
        GrtOpx_grt_XMg_EditField       matlab.ui.control.NumericEditField
        XFeLabel                       matlab.ui.control.Label
        GrtOpx_grt_XFe_EditField       matlab.ui.control.NumericEditField
        XCaLabel                       matlab.ui.control.Label
        GrtOpx_grt_XCa_EditField       matlab.ui.control.NumericEditField
        XMnLabel                       matlab.ui.control.Label
        GrtOpx_grt_XMn_EditField       matlab.ui.control.NumericEditField
        XAlLabel                       matlab.ui.control.Label
        GrtOpx_opx_XAl_EditField       matlab.ui.control.NumericEditField
        lnKdEditFieldLabel             matlab.ui.control.Label
        GrtOpx_lnKd_EditField          matlab.ui.control.NumericEditField
        R83EditFieldLabel              matlab.ui.control.Label
        GrtOpx_R83_EditField           matlab.ui.control.NumericEditField
        H84EditFieldLabel              matlab.ui.control.Label
        GrtOpx_H84_EditField           matlab.ui.control.NumericEditField
        P85EditFieldLabel              matlab.ui.control.Label
        GrtOpx_P85_EditField           matlab.ui.control.NumericEditField
        LG88EditFieldLabel             matlab.ui.control.Label
        GrtOpx_LG88_EditField          matlab.ui.control.NumericEditField
        B91EditFieldLabel              matlab.ui.control.Label
        GrtOpx_B91_EditField           matlab.ui.control.NumericEditField
        GrtOpx_R83_CheckBox            matlab.ui.control.CheckBox
        GrtOpx_H84_CheckBox            matlab.ui.control.CheckBox
        GrtOpx_B91_CheckBox            matlab.ui.control.CheckBox
        GrtOpx_P85_CheckBox            matlab.ui.control.CheckBox
        GrtOpx_LG88_CheckBox           matlab.ui.control.CheckBox
        GrtOpx_R83color_EditField      matlab.ui.control.EditField
        GrtOpx_H84color_EditField      matlab.ui.control.EditField
        GrtOpx_P85color_EditField      matlab.ui.control.EditField
        GrtOpx_LG88color_EditField     matlab.ui.control.EditField
        GrtOpx_B91color_EditField      matlab.ui.control.EditField
        GrtOpx_GarnetResult_Label      matlab.ui.control.Label
        GrtOpx_OpxResult_Label         matlab.ui.control.Label
        GrtOpx_Thermometers_Label      matlab.ui.control.Label
        TiBi_Tab                       matlab.ui.container.Tab
        TiBi_Input_Tab                 matlab.ui.container.Panel
        TiBi_Input_TabGrid             matlab.ui.container.GridLayout
        TiBi_Biotite_Label             matlab.ui.control.Label
        AlLabel_5                      matlab.ui.control.Label
        TiBi_Al_EditField              matlab.ui.control.NumericEditField
        FeLabel_6                      matlab.ui.control.Label
        TiBi_Fe_EditField              matlab.ui.control.NumericEditField
        TiLabel_2                      matlab.ui.control.Label
        TiBi_Ti_EditField              matlab.ui.control.NumericEditField
        PkbarLabel_7                   matlab.ui.control.Label
        TiBi_P_EditField               matlab.ui.control.NumericEditField
        MgLabel_17                     matlab.ui.control.Label
        TiBi_Mg_EditField              matlab.ui.control.NumericEditField
        TiBi_Results_Tab               matlab.ui.container.Panel
        TiBi_Results_TabGrid           matlab.ui.container.GridLayout
        TiBi_HG02color_EditField       matlab.ui.control.EditField
        TiBi_HG02_CheckBox             matlab.ui.control.CheckBox
        HG02Label                      matlab.ui.control.Label
        TiBi_HG02_EditField            matlab.ui.control.NumericEditField
        XMgLabel_17                    matlab.ui.control.Label
        TiBi_XMg_EditField             matlab.ui.control.NumericEditField
        XFeLabel_17                    matlab.ui.control.Label
        TiBi_XFe_EditField             matlab.ui.control.NumericEditField
        MgLabel_18                     matlab.ui.control.Label
        TiBi_MgN_EditField             matlab.ui.control.NumericEditField
        TiBi_BiotiteResult_Label       matlab.ui.control.Label
        H05Label                       matlab.ui.control.Label
        TiBi_H05_EditField             matlab.ui.control.NumericEditField
        WC15Label                      matlab.ui.control.Label
        TiBi_WC15_EditField            matlab.ui.control.NumericEditField
        XTiLabel_2                     matlab.ui.control.Label
        TiBi_XTi_EditField             matlab.ui.control.NumericEditField
        XAlLabel_4                     matlab.ui.control.Label
        TiBi_XAl_EditField             matlab.ui.control.NumericEditField
        TiBi_H05_CheckBox              matlab.ui.control.CheckBox
        TiBi_WC15_CheckBox             matlab.ui.control.CheckBox
        TiBi_H05color_EditField        matlab.ui.control.EditField
        TiBi_WC15color_EditField       matlab.ui.control.EditField
        TiBi_Thermometers_Label        matlab.ui.control.Label
        TiBi_Plot_Tab                  matlab.ui.container.Panel
        TiBi_Plot_TabGrid              matlab.ui.container.GridLayout
        TiBi_P_max_Label               matlab.ui.control.Label
        TiBi_P_max                     matlab.ui.control.Spinner
        TiBi_P_min_Label               matlab.ui.control.Label
        TiBi_T_max_Label               matlab.ui.control.Label
        TiBi_P_min                     matlab.ui.control.Spinner
        TiBi_T_max                     matlab.ui.control.Spinner
        TiBi_GridCheckBox              matlab.ui.control.CheckBox
        TiBi_LineWidth_Label           matlab.ui.control.Label
        TiBi_LineWidth_Spinner         matlab.ui.control.Spinner
        TiBi_T_min_Label               matlab.ui.control.Label
        TiBi_T_min                     matlab.ui.control.Spinner
        TiBi_UIAxes                    matlab.ui.control.UIAxes
        TiBi_DisplayAll_CheckBox       matlab.ui.control.CheckBox
        TiBi_ColorAll_EditField        matlab.ui.control.EditField
        TiBi_Colour_Label              matlab.ui.control.Label
        TiQz_Tab                       matlab.ui.container.Tab
        TiQz_Input_Tab                 matlab.ui.container.Panel
        TiQz_Input_TabGrid             matlab.ui.container.GridLayout
        TiQz_Quartz_Label              matlab.ui.control.Label
        aTiO2Label_2                   matlab.ui.control.Label
        TiQz_aTiO2_EditField           matlab.ui.control.NumericEditField
        TippmLabel_2                   matlab.ui.control.Label
        TiQz_Ti_EditField              matlab.ui.control.NumericEditField
        PkbarLabel_8                   matlab.ui.control.Label
        TiQz_P_EditField               matlab.ui.control.NumericEditField
        TiQz_Results_Tab               matlab.ui.container.Panel
        TiQz_Results_TabGrid           matlab.ui.container.GridLayout
        TiQz_WW06color_EditField       matlab.ui.control.EditField
        TiQz_WW06_CheckBox             matlab.ui.control.CheckBox
        WW06Label                      matlab.ui.control.Label
        TiQz_WW06_EditField            matlab.ui.control.NumericEditField
        TiQz_QuartzResult_Label        matlab.ui.control.Label
        KO08Label                      matlab.ui.control.Label
        TiQz_KO08_EditField            matlab.ui.control.NumericEditField
        TiQz_KO08_CheckBox             matlab.ui.control.CheckBox
        TiQz_KO08color_EditField       matlab.ui.control.EditField
        TiQz_Thermometers_Label        matlab.ui.control.Label
        TiQz_T10_CheckBox              matlab.ui.control.CheckBox
        TiQz_T10color_EditField        matlab.ui.control.EditField
        T10Label                       matlab.ui.control.Label
        TiQz_T10_EditField             matlab.ui.control.NumericEditField
        TiQz_HA12_CheckBox             matlab.ui.control.CheckBox
        TiQz_HA12color_EditField       matlab.ui.control.EditField
        HA12Label                      matlab.ui.control.Label
        TiQz_HA12_EditField            matlab.ui.control.NumericEditField
        TiQz_Plot_Tab                  matlab.ui.container.Panel
        TiQz_Plot_TabGrid              matlab.ui.container.GridLayout
        TiQz_P_max_Label               matlab.ui.control.Label
        TiQz_P_max                     matlab.ui.control.Spinner
        TiQz_P_min_Label               matlab.ui.control.Label
        TiQz_T_max_Label               matlab.ui.control.Label
        TiQz_P_min                     matlab.ui.control.Spinner
        TiQz_T_max                     matlab.ui.control.Spinner
        TiQz_GridCheckBox              matlab.ui.control.CheckBox
        TiQz_LineWidth_Label           matlab.ui.control.Label
        TiQz_LineWidth_Spinner         matlab.ui.control.Spinner
        TiQz_T_min_Label               matlab.ui.control.Label
        TiQz_T_min                     matlab.ui.control.Spinner
        TiQz_UIAxes                    matlab.ui.control.UIAxes
        TiQz_DisplayAll_CheckBox       matlab.ui.control.CheckBox
        TiQz_ColorAll_EditField        matlab.ui.control.EditField
        TiQz_Colour_Label              matlab.ui.control.Label
        TiZrn_Tab                      matlab.ui.container.Tab
        TiZrn_Input_Tab                matlab.ui.container.Panel
        TiZrn_Input_TabGrid            matlab.ui.container.GridLayout
        TiZrn_Zircon_Label             matlab.ui.control.Label
        aSiO2Label                     matlab.ui.control.Label
        TiZrn_aSiO2_EditField          matlab.ui.control.NumericEditField
        aTiO2Label                     matlab.ui.control.Label
        TiZrn_aTiO2_EditField          matlab.ui.control.NumericEditField
        TippmLabel                     matlab.ui.control.Label
        TiZrn_Ti_EditField             matlab.ui.control.NumericEditField
        TiZrn_Results_Tab              matlab.ui.container.Panel
        TiZrn_Results_TabGrid          matlab.ui.container.GridLayout
        TiZrn_W06color_EditField       matlab.ui.control.EditField
        TiZrn_W06_CheckBox             matlab.ui.control.CheckBox
        W06Label                       matlab.ui.control.Label
        TiZrn_W06_EditField            matlab.ui.control.NumericEditField
        TiZrn_ZirconResult_Label       matlab.ui.control.Label
        FW07Label                      matlab.ui.control.Label
        TiZrn_FW07_EditField           matlab.ui.control.NumericEditField
        TiZrn_FW07_CheckBox            matlab.ui.control.CheckBox
        TiZrn_FW07color_EditField      matlab.ui.control.EditField
        TiZrn_Thermometers_Label       matlab.ui.control.Label
        TiZrn_Plot_Tab                 matlab.ui.container.Panel
        TiZrn_Plot_TabGrid             matlab.ui.container.GridLayout
        TiZrn_P_max_Label              matlab.ui.control.Label
        TiZrn_P_max                    matlab.ui.control.Spinner
        TiZrn_P_min_Label              matlab.ui.control.Label
        TiZrn_T_max_Label              matlab.ui.control.Label
        TiZrn_P_min                    matlab.ui.control.Spinner
        TiZrn_T_max                    matlab.ui.control.Spinner
        TiZrn_GridCheckBox             matlab.ui.control.CheckBox
        TiZrn_LineWidth_Label          matlab.ui.control.Label
        TiZrn_LineWidth_Spinner        matlab.ui.control.Spinner
        TiZrn_T_min_Label              matlab.ui.control.Label
        TiZrn_T_min                    matlab.ui.control.Spinner
        TiZrn_UIAxes                   matlab.ui.control.UIAxes
        TiZrn_DisplayAll_CheckBox      matlab.ui.control.CheckBox
        TiZrn_ColorAll_EditField       matlab.ui.control.EditField
        TiZrn_Colour_Label             matlab.ui.control.Label
        ZrRu_Tab                       matlab.ui.container.Tab
        ZrRu_Input_Tab                 matlab.ui.container.Panel
        ZrRu_Input_TabGrid             matlab.ui.container.GridLayout
        ZrRu_Rutile_Label              matlab.ui.control.Label
        aSiO2Label_2                   matlab.ui.control.Label
        ZrRu_aSiO2_EditField           matlab.ui.control.NumericEditField
        ZrppmLabel                     matlab.ui.control.Label
        ZrRu_Zr_EditField              matlab.ui.control.NumericEditField
        PkbarLabel_9                   matlab.ui.control.Label
        ZrRu_P_EditField               matlab.ui.control.NumericEditField
        ZrRu_Results_Tab               matlab.ui.container.Panel
        ZrRu_Results_TabGrid           matlab.ui.container.GridLayout
        ZrRu_Z04color_EditField        matlab.ui.control.EditField
        ZrRu_Z04_CheckBox              matlab.ui.control.CheckBox
        Z04Label                       matlab.ui.control.Label
        ZrRu_Z04_EditField             matlab.ui.control.NumericEditField
        ZrRu_RutileResult_Label        matlab.ui.control.Label
        FW07Label_2                    matlab.ui.control.Label
        ZrRu_FW07_EditField            matlab.ui.control.NumericEditField
        ZrRu_FW07_CheckBox             matlab.ui.control.CheckBox
        ZrRu_FW07color_EditField       matlab.ui.control.EditField
        ZrRu_Thermometers_Label        matlab.ui.control.Label
        ZrRu_T07a_CheckBox             matlab.ui.control.CheckBox
        ZrRu_T07acolor_EditField       matlab.ui.control.EditField
        T07aLabel                      matlab.ui.control.Label
        ZrRu_T07a_EditField            matlab.ui.control.NumericEditField
        ZrRu_T07b_CheckBox             matlab.ui.control.CheckBox
        ZrRu_T07bcolor_EditField       matlab.ui.control.EditField
        T07bLabel                      matlab.ui.control.Label
        ZrRu_T07b_EditField            matlab.ui.control.NumericEditField
        ZrRu_T07c_CheckBox             matlab.ui.control.CheckBox
        ZrRu_T07ccolor_EditField       matlab.ui.control.EditField
        T07cLabel                      matlab.ui.control.Label
        ZrRu_T07c_EditField            matlab.ui.control.NumericEditField
        ZrRu_W06_CheckBox              matlab.ui.control.CheckBox
        ZrRu_W06color_EditField        matlab.ui.control.EditField
        W06Label_2                     matlab.ui.control.Label
        ZrRu_W06_EditField             matlab.ui.control.NumericEditField
        ZrRu_Plot_Tab                  matlab.ui.container.Panel
        ZrRu_Plot_TabGrid              matlab.ui.container.GridLayout
        ZrRu_P_max_Label               matlab.ui.control.Label
        ZrRu_P_max                     matlab.ui.control.Spinner
        ZrRu_P_min_Label               matlab.ui.control.Label
        ZrRu_T_max_Label               matlab.ui.control.Label
        ZrRu_P_min                     matlab.ui.control.Spinner
        ZrRu_T_max                     matlab.ui.control.Spinner
        ZrRu_GridCheckBox              matlab.ui.control.CheckBox
        ZrRu_LineWidth_Label           matlab.ui.control.Label
        ZrRu_LineWidth_Spinner         matlab.ui.control.Spinner
        ZrRu_T_min_Label               matlab.ui.control.Label
        ZrRu_T_min                     matlab.ui.control.Spinner
        ZrRu_UIAxes                    matlab.ui.control.UIAxes
        ZrRu_DisplayAll_CheckBox       matlab.ui.control.CheckBox
        ZrRu_ColorAll_EditField        matlab.ui.control.EditField
        ZrRu_Colour_Label              matlab.ui.control.Label
        ZrTtn_Tab                      matlab.ui.container.Tab
        ZrTtn_Input_Tab                matlab.ui.container.Panel
        ZrTtn_Input_TabGrid            matlab.ui.container.GridLayout
        ZrTtn_Titanite_Label           matlab.ui.control.Label
        aSiO2Label_3                   matlab.ui.control.Label
        ZrTtn_aSiO2_EditField          matlab.ui.control.NumericEditField
        ZrppmLabel_2                   matlab.ui.control.Label
        ZrTtn_Zr_EditField             matlab.ui.control.NumericEditField
        PkbarLabel_10                  matlab.ui.control.Label
        ZrTtn_P_EditField              matlab.ui.control.NumericEditField
        aTiO2Label_3                   matlab.ui.control.Label
        ZrTtn_aTiO2_EditField          matlab.ui.control.NumericEditField
        ZrTtn_Results_Tab              matlab.ui.container.Panel
        ZrTtn_Results_TabGrid          matlab.ui.container.GridLayout
        ZrTtn_H08color_EditField       matlab.ui.control.EditField
        ZrTtn_H08_CheckBox             matlab.ui.control.CheckBox
        H08Label                       matlab.ui.control.Label
        ZrTtn_H08_EditField            matlab.ui.control.NumericEditField
        ZrTtn_TitaniteResult_Label     matlab.ui.control.Label
        ZrTtn_Thermometers_Label       matlab.ui.control.Label
        ZrTtn_Plot_Tab                 matlab.ui.container.Panel
        ZrTtn_Plot_TabGrid             matlab.ui.container.GridLayout
        ZrTtn_P_max_Label              matlab.ui.control.Label
        ZrTtn_P_max                    matlab.ui.control.Spinner
        ZrTtn_P_min_Label              matlab.ui.control.Label
        ZrTtn_T_max_Label              matlab.ui.control.Label
        ZrTtn_P_min                    matlab.ui.control.Spinner
        ZrTtn_T_max                    matlab.ui.control.Spinner
        ZrTtn_GridCheckBox             matlab.ui.control.CheckBox
        ZrTtn_LineWidth_Label          matlab.ui.control.Label
        ZrTtn_LineWidth_Spinner        matlab.ui.control.Spinner
        ZrTtn_T_min_Label              matlab.ui.control.Label
        ZrTtn_T_min                    matlab.ui.control.Spinner
        ZrTtn_UIAxes                   matlab.ui.control.UIAxes
        ZrTtn_DisplayAll_CheckBox      matlab.ui.control.CheckBox
        ZrTtn_ColorAll_EditField       matlab.ui.control.EditField
        ZrTtn_Colour_Label             matlab.ui.control.Label
        EX_Tab                         matlab.ui.container.Tab
        EX_TabGrid                     matlab.ui.container.GridLayout
        EX_Garnet_Label                matlab.ui.control.Label
        EX_Biotite_Label               matlab.ui.control.Label
        EX_UIAxes                      matlab.ui.control.UIAxes
        XFeSliderLabel                 matlab.ui.control.Label
        EX_grt_XFe_Slider              matlab.ui.control.Slider
        XMgSliderLabel                 matlab.ui.control.Label
        EX_grt_XMg_Slider              matlab.ui.control.Slider
        XFeSlider_2Label               matlab.ui.control.Label
        EX_bi_XFe_Slider               matlab.ui.control.Slider
        XMgSlider_2Label               matlab.ui.control.Label
        EX_bi_XMg_Slider               matlab.ui.control.Slider
        TCLabel                        matlab.ui.control.Label
        EX_T_EditField                 matlab.ui.control.NumericEditField
        PkbarSliderLabel               matlab.ui.control.Label
        EX_P_Slider                    matlab.ui.control.Slider
        lnKdEditField_2Label           matlab.ui.control.Label
        EX_lnKd_EditField              matlab.ui.control.NumericEditField
        TestInput_Button               matlab.ui.control.Button
        Clear_Button                   matlab.ui.control.Button
        Export_Button                  matlab.ui.control.Button
        PTtoolbox_Version_Label        matlab.ui.control.Label
    end

    % PTtoolbox Developer Build
    % Developed with MATLAB App Designer
    % Joshua Laughton // jlaught2@uwo.ca
    
    properties (Access = private)        
    end
    
    methods (Access = private)
        
        function GrtBi_calculate(app)
            
            % Grt-Bi Input  
            
            GrtBi_grt_Fe = app.GrtBi_grt_Fe_EditField.Value;
            GrtBi_grt_Mg = app.GrtBi_grt_Mg_EditField.Value;
            GrtBi_grt_Ca = app.GrtBi_grt_Ca_EditField.Value;
            GrtBi_grt_Mn = app.GrtBi_grt_Mn_EditField.Value;
            GrtBi_bi_Ti = app.GrtBi_bi_Ti_EditField.Value;
            GrtBi_bi_Al = app.GrtBi_bi_Al_EditField.Value; 
            GrtBi_bi_Fe = app.GrtBi_bi_Fe_EditField.Value;
            GrtBi_bi_Mg = app.GrtBi_bi_Mg_EditField.Value;
            GrtBi_bi_Mn = app.GrtBi_bi_Mn_EditField.Value;
            GrtBi_P = app.GrtBi_P_EditField.Value;
            
            if GrtBi_grt_Fe || GrtBi_grt_Mg || GrtBi_grt_Ca || GrtBi_grt_Mn > 0                  
                GrtBi_grt_XFe = GrtBi_grt_Fe / (GrtBi_grt_Fe + GrtBi_grt_Mg + GrtBi_grt_Ca + GrtBi_grt_Mn);
                GrtBi_grt_XMg = GrtBi_grt_Mg / (GrtBi_grt_Fe + GrtBi_grt_Mg + GrtBi_grt_Ca + GrtBi_grt_Mn);
                GrtBi_grt_XCa = GrtBi_grt_Ca / (GrtBi_grt_Fe + GrtBi_grt_Mg + GrtBi_grt_Ca + GrtBi_grt_Mn);
                GrtBi_grt_XMn = GrtBi_grt_Mn / (GrtBi_grt_Fe + GrtBi_grt_Mg + GrtBi_grt_Ca + GrtBi_grt_Mn);
                app.GrtBi_grt_XFe_EditField.Value = GrtBi_grt_XFe;
                app.GrtBi_grt_XMg_EditField.Value = GrtBi_grt_XMg;
                app.GrtBi_grt_XCa_EditField.Value = GrtBi_grt_XCa;
                app.GrtBi_grt_XMn_EditField.Value = GrtBi_grt_XMn;
            end
                
            if GrtBi_bi_Ti || GrtBi_bi_Al || GrtBi_bi_Fe || GrtBi_bi_Mg || GrtBi_bi_Mn > 0
                GrtBi_bi_XTi = GrtBi_bi_Ti / (GrtBi_bi_Ti + GrtBi_bi_Al + GrtBi_bi_Fe + GrtBi_bi_Mg + GrtBi_bi_Mn);
                GrtBi_bi_XAl = GrtBi_bi_Al / (GrtBi_bi_Ti + GrtBi_bi_Al + GrtBi_bi_Fe + GrtBi_bi_Mg + GrtBi_bi_Mn);
                GrtBi_bi_XFe = GrtBi_bi_Fe / (GrtBi_bi_Ti + GrtBi_bi_Al + GrtBi_bi_Fe + GrtBi_bi_Mg + GrtBi_bi_Mn);
                GrtBi_bi_XMg = GrtBi_bi_Mg / (GrtBi_bi_Ti + GrtBi_bi_Al + GrtBi_bi_Fe + GrtBi_bi_Mg + GrtBi_bi_Mn);
                GrtBi_bi_XMn = GrtBi_bi_Mn / (GrtBi_bi_Ti + GrtBi_bi_Al + GrtBi_bi_Fe + GrtBi_bi_Mg + GrtBi_bi_Mn);
                app.GrtBi_bi_XTi_EditField.Value = GrtBi_bi_XTi;
                app.GrtBi_bi_XAl_EditField.Value = GrtBi_bi_XAl;
                app.GrtBi_bi_XFe_EditField.Value = GrtBi_bi_XFe;
                app.GrtBi_bi_XMg_EditField.Value = GrtBi_bi_XMg;
                app.GrtBi_bi_XMn_EditField.Value = GrtBi_bi_XMn;
            end
            
            % Grt-Bi Plot
                       
            plot(app.GrtBi_UIAxes,0,0);
            hold(app.GrtBi_UIAxes,'on');
            app.GrtBi_UIAxes.XLim = [app.GrtBi_T_min.Value app.GrtBi_T_max.Value];
            app.GrtBi_UIAxes.YLim = [app.GrtBi_P_min.Value app.GrtBi_P_max.Value];
            app.GrtBi_UIAxes.XGrid = app.GrtBi_GridCheckBox.Value;
            app.GrtBi_UIAxes.YGrid = app.GrtBi_GridCheckBox.Value;
            GrtBi_P_plot = [app.GrtBi_P_min.Value app.GrtBi_P_max.Value];
            
            % Grt-Bi Thermometers 
            
            if GrtBi_grt_Fe && GrtBi_grt_Mg && GrtBi_bi_Fe && GrtBi_bi_Mg > 0
                
                GrtBi_lnKd = log((GrtBi_grt_Fe/GrtBi_grt_Mg)/(GrtBi_bi_Fe/GrtBi_bi_Mg)); 
                app.GrtBi_lnKd_EditField.Value = GrtBi_lnKd;
                                
                % Thompson (1976) 
                GrtBi_T_T76 = (2739.3+0.0234*GrtBi_P*1000)/(GrtBi_lnKd+1.56)-273.15;
                app.GrtBi_T76_EditField.Value = GrtBi_T_T76;
                GrtBi_T_T76_plot = (2739.3+0.0234*GrtBi_P_plot*1000)/(GrtBi_lnKd+1.56)-273.15;
                if app.GrtBi_T76_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_T76_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_T76color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end

                % Goldman & Albee (1977)
                GrtBi_T_GA77 = sqrt((-1.4315e6)/(-GrtBi_lnKd-0.522))-273.15;
                app.GrtBi_GA77_EditField.Value = GrtBi_T_GA77;
                if app.GrtBi_GA77_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,[GrtBi_T_GA77,GrtBi_T_GA77],GrtBi_P_plot,...
                        'Color',app.GrtBi_GA77color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Holdaway & Lee (1977) 
                GrtBi_T_HL77 = (3095+0.0124*GrtBi_P*1000)/(GrtBi_lnKd+1.978)-273.15;
                app.GrtBi_HL77_EditField.Value = GrtBi_T_HL77;
                GrtBi_T_HL77_plot = (3095+0.0124*GrtBi_P_plot*1000)/(GrtBi_lnKd+1.978)-273.15;
                if app.GrtBi_HL77_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_HL77_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_HL77color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Ferry & Spear (1978)
                GrtBi_T_FS78 = (-12454-0.057*GrtBi_P*1000)/((5.961*-GrtBi_lnKd)-4.662)-273.15;
                app.GrtBi_FS78_EditField.Value = GrtBi_T_FS78;
                GrtBi_T_FS78_plot = (-12454-0.057*GrtBi_P_plot*1000)/((5.961*-GrtBi_lnKd)-4.662)-273.15;
                if app.GrtBi_FS78_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_FS78_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_FS78color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Hodges & Spear (1982)
                GrtBi_T_HS82 = (12454+0.057*GrtBi_P*1000+9900*(GrtBi_grt_XCa^2+GrtBi_grt_XFe*GrtBi_grt_XCa+...
                    GrtBi_grt_XCa*GrtBi_grt_XMn+GrtBi_grt_XMg*GrtBi_grt_XCa))/(4.5*(GrtBi_grt_XCa^2+...
                    GrtBi_grt_XFe*GrtBi_grt_XCa+GrtBi_grt_XCa*GrtBi_grt_XMn+GrtBi_grt_XMg*GrtBi_grt_XCa)-...
                    5.961*(-GrtBi_lnKd)+4.662)-273.15;
                app.GrtBi_HS82_EditField.Value = GrtBi_T_HS82;
                GrtBi_T_HS82_plot = (12454+0.057*GrtBi_P_plot*1000+9900*(GrtBi_grt_XCa^2+GrtBi_grt_XFe*GrtBi_grt_XCa+...
                    GrtBi_grt_XCa*GrtBi_grt_XMn+GrtBi_grt_XMg*GrtBi_grt_XCa))/(4.5*(GrtBi_grt_XCa^2+...
                    GrtBi_grt_XFe*GrtBi_grt_XCa+GrtBi_grt_XCa*GrtBi_grt_XMn+GrtBi_grt_XMg*GrtBi_grt_XCa)-...
                    5.961*(-GrtBi_lnKd)+4.662)-273.15;
                if app.GrtBi_HS82_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_HS82_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_HS82color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Perchuk & Lavrent'eva (1983) 
                GrtBi_T_PL83a = (7843.7-0.0577*(GrtBi_P*1000-6000))/(1.987*GrtBi_lnKd+5.699)-273.15;
                app.GrtBi_PL83a_EditField.Value = GrtBi_T_PL83a;
                GrtBi_T_PL83a_plot = (7843.7-0.0577*(GrtBi_P_plot*1000-6000))/(1.987*GrtBi_lnKd+5.699)-273.15;
                if app.GrtBi_PL83a_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_PL83a_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_PL83acolor_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                GrtBi_T_PL83b = (7843.7-0.0246*(GrtBi_P*1000-6000))/(1.987*GrtBi_lnKd+5.699)-273.15;
                app.GrtBi_PL83b_EditField.Value = GrtBi_T_PL83b;
                GrtBi_T_PL83b_plot = (7843.7-0.0246*(GrtBi_P_plot*1000-6000))/(1.987*GrtBi_lnKd+5.699)-273.15;
                if app.GrtBi_PL83b_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_PL83b_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_PL83bcolor_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Ganguly & Saxena (1984)
                GrtBi_T_GS84 = (1175+GrtBi_P*1000*0.00945+(((GrtBi_grt_XFe*2500+GrtBi_grt_XMg*200)/...
                    (GrtBi_grt_XFe+GrtBi_grt_XMg))*(GrtBi_grt_XFe-GrtBi_grt_XMg)+3000*GrtBi_grt_XCa+...
                    3000*GrtBi_grt_XMn)/1.987)/(0.782+GrtBi_lnKd)-273.15;
                app.GrtBi_GS84_EditField.Value = GrtBi_T_GS84;
                GrtBi_T_GS84_plot = (1175+GrtBi_P_plot*1000*0.00945+(((GrtBi_grt_XFe*2500+GrtBi_grt_XMg*200)/...
                    (GrtBi_grt_XFe+GrtBi_grt_XMg))*(GrtBi_grt_XFe-GrtBi_grt_XMg)+3000*GrtBi_grt_XCa+...
                    3000*GrtBi_grt_XMn)/1.987)/(0.782+GrtBi_lnKd)-273.15;
                if app.GrtBi_GS84_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_GS84_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_GS84color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end                               
                
                % Indares & Martignole (1985)
                GrtBi_T_IM85a = (12454+0.057*GrtBi_P*1000+3*(-464*GrtBi_bi_XAl-6767*GrtBi_bi_XTi)+9900*GrtBi_grt_XCa)/...
                    (4.662-5.9616*-GrtBi_lnKd+4.5*GrtBi_grt_XCa)-273.15;
                app.GrtBi_IM85a_EditField.Value = GrtBi_T_IM85a;
                GrtBi_T_IM85a_plot = (12454+0.057*GrtBi_P_plot*1000+3*(-464*GrtBi_bi_XAl-6767*GrtBi_bi_XTi)+9900*GrtBi_grt_XCa)/...
                    (4.662-5.9616*-GrtBi_lnKd+4.5*GrtBi_grt_XCa)-273.15;
                if app.GrtBi_IM85a_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_IM85a_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_IM85acolor_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                GrtBi_T_IM85b = (12454+0.057*GrtBi_P*1000+3*(-1590*GrtBi_bi_XAl-7451*GrtBi_bi_XTi)-3*(-3000*(GrtBi_grt_XCa+GrtBi_grt_XMn)))/...
                    (4.662-5.9616*-GrtBi_lnKd)-273.15;
                app.GrtBi_IM85b_EditField.Value = GrtBi_T_IM85b;
                GrtBi_T_IM85b_plot = (12454+0.057*GrtBi_P_plot*1000+3*(-1590*GrtBi_bi_XAl-7451*GrtBi_bi_XTi)-3*(-3000*(GrtBi_grt_XCa+GrtBi_grt_XMn)))/...
                    (4.662-5.9616*-GrtBi_lnKd)-273.15;
                if app.GrtBi_IM85b_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_IM85b_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_IM85bcolor_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Perchuk et al. (1985)
                GrtBi_T_P85 = (3720+2871*GrtBi_grt_XCa+0.038*GrtBi_P*1000)/(GrtBi_lnKd+0.625*GrtBi_grt_XCa+2.868)-273.15;
                app.GrtBi_P85_EditField.Value = GrtBi_T_P85;
                GrtBi_T_P85_plot = (3720+2871*GrtBi_grt_XCa+0.038*GrtBi_P_plot*1000)/(GrtBi_lnKd+0.625*GrtBi_grt_XCa+2.868)-273.15;
                if app.GrtBi_P85_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_P85_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_P85color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Dasgupta et al. (1991) 
                GrtBi_T_D91 = (4301+3000*GrtBi_grt_XCa+1300*GrtBi_grt_XMn-495*(GrtBi_grt_XMg-GrtBi_grt_XFe)-3595*GrtBi_bi_XAl-4423*...
                    GrtBi_bi_XTi+1073*(GrtBi_bi_XMg-GrtBi_bi_XFe)+0.0246*GrtBi_P*1000)/(1.85-1.987*-GrtBi_lnKd)-273.15;
                app.GrtBi_D91_EditField.Value = GrtBi_T_D91;
                GrtBi_T_D91_plot = (4301+3000*GrtBi_grt_XCa+1300*GrtBi_grt_XMn-495*(GrtBi_grt_XMg-GrtBi_grt_XFe)-3595*GrtBi_bi_XAl-4423*...
                    GrtBi_bi_XTi+1073*(GrtBi_bi_XMg-GrtBi_bi_XFe)+0.0246*GrtBi_P_plot*1000)/(1.85-1.987*-GrtBi_lnKd)-273.15;
                if app.GrtBi_D91_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_D91_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_D91color_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
                % Bhattacharya et al. (1992)
                GrtBi_T_B92a = (20286+0.0193*GrtBi_P*1000-(2080*GrtBi_grt_XMg^2-6350*GrtBi_grt_XFe^2-13807*...
                    GrtBi_grt_XCa*(1-GrtBi_grt_XMn)+8540*GrtBi_grt_XFe*GrtBi_grt_XMg*(1-GrtBi_grt_XMn)+...
                    4215*GrtBi_grt_XCa*(GrtBi_grt_XMg-GrtBi_grt_XFe))+4441*(2*GrtBi_bi_XMg-1))/(13.138+8.3143*...
                    GrtBi_lnKd+6.276*GrtBi_grt_XCa*(1-GrtBi_grt_XMn))-273.15;
                app.GrtBi_B92a_EditField.Value = GrtBi_T_B92a;
                GrtBi_T_B92a_plot = (20286+0.0193*GrtBi_P_plot*1000-(2080*GrtBi_grt_XMg^2-6350*GrtBi_grt_XFe^2-13807*...
                    GrtBi_grt_XCa*(1-GrtBi_grt_XMn)+8540*GrtBi_grt_XFe*GrtBi_grt_XMg*(1-GrtBi_grt_XMn)+...
                    4215*GrtBi_grt_XCa*(GrtBi_grt_XMg-GrtBi_grt_XFe))+4441*(2*GrtBi_bi_XMg-1))/(13.138+8.3143*...
                    GrtBi_lnKd+6.276*GrtBi_grt_XCa*(1-GrtBi_grt_XMn))-273.15;
                if app.GrtBi_B92a_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_B92a_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_B92acolor_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                GrtBi_T_B92b = (13538+0.0193*GrtBi_P*1000-(837*GrtBi_grt_XMg^2-10460*GrtBi_grt_XFe^2-13807*...
                    GrtBi_grt_XCa*(1-GrtBi_grt_XMn)+19246*GrtBi_grt_XFe*GrtBi_grt_XMg*(1-GrtBi_grt_XMn)+...
                    5649*GrtBi_grt_XCa*(GrtBi_grt_XMg-GrtBi_grt_XFe))+7972*(2*GrtBi_bi_XMg-1))/(6.778+8.3143*...
                    GrtBi_lnKd+6.276*GrtBi_grt_XCa*(1-GrtBi_grt_XMn))-273.15;
                app.GrtBi_B92b_EditField.Value = GrtBi_T_B92b;
                GrtBi_T_B92b_plot = (13538+0.0193*GrtBi_P_plot*1000-(837*GrtBi_grt_XMg^2-10460*GrtBi_grt_XFe^2-13807*...
                    GrtBi_grt_XCa*(1-GrtBi_grt_XMn)+19246*GrtBi_grt_XFe*GrtBi_grt_XMg*(1-GrtBi_grt_XMn)+...
                    5649*GrtBi_grt_XCa*(GrtBi_grt_XMg-GrtBi_grt_XFe))+7972*(2*GrtBi_bi_XMg-1))/(6.778+8.3143*...
                    GrtBi_lnKd+6.276*GrtBi_grt_XCa*(1-GrtBi_grt_XMn))-273.15;
                if app.GrtBi_B92b_CheckBox.Value == 1
                    plot(app.GrtBi_UIAxes,GrtBi_T_B92b_plot,GrtBi_P_plot,...
                        'Color',app.GrtBi_B92bcolor_EditField.Value,...
                        "LineWidth",app.GrtBi_LineWidth_Spinner.Value);
                end
                
            end
            
            hold(app.GrtBi_UIAxes,'off');
            
        end
        
        function GrtChl_calculate(app)
            
            % Grt-Chl Input  
            
            GrtChl_grt_Fe = app.GrtChl_grt_Fe_EditField.Value;
            GrtChl_grt_Mg = app.GrtChl_grt_Mg_EditField.Value;
            GrtChl_grt_Ca = app.GrtChl_grt_Ca_EditField.Value;
            GrtChl_grt_Mn = app.GrtChl_grt_Mn_EditField.Value; 
            GrtChl_chl_Fe = app.GrtChl_chl_Fe_EditField.Value;
            GrtChl_chl_Mg = app.GrtChl_chl_Mg_EditField.Value;
            GrtChl_P = app.GrtChl_P_EditField.Value;
            
            if GrtChl_grt_Fe || GrtChl_grt_Mg || GrtChl_grt_Ca || GrtChl_grt_Mn > 0                  
                GrtChl_grt_XFe = GrtChl_grt_Fe / (GrtChl_grt_Fe + GrtChl_grt_Mg + GrtChl_grt_Ca + GrtChl_grt_Mn);
                GrtChl_grt_XMg = GrtChl_grt_Mg / (GrtChl_grt_Fe + GrtChl_grt_Mg + GrtChl_grt_Ca + GrtChl_grt_Mn);
                GrtChl_grt_XCa = GrtChl_grt_Ca / (GrtChl_grt_Fe + GrtChl_grt_Mg + GrtChl_grt_Ca + GrtChl_grt_Mn);
                GrtChl_grt_XMn = GrtChl_grt_Mn / (GrtChl_grt_Fe + GrtChl_grt_Mg + GrtChl_grt_Ca + GrtChl_grt_Mn);
                app.GrtChl_grt_XFe_EditField.Value = GrtChl_grt_XFe;
                app.GrtChl_grt_XMg_EditField.Value = GrtChl_grt_XMg;
                app.GrtChl_grt_XCa_EditField.Value = GrtChl_grt_XCa;
                app.GrtChl_grt_XMn_EditField.Value = GrtChl_grt_XMn;
            end
                
            if GrtChl_chl_Fe || GrtChl_chl_Mg > 0
                GrtChl_chl_XFe = GrtChl_chl_Fe / (GrtChl_chl_Fe + GrtChl_chl_Mg);
                GrtChl_chl_XMg = GrtChl_chl_Mg / (GrtChl_chl_Fe + GrtChl_chl_Mg);
                app.GrtChl_chl_XFe_EditField.Value = GrtChl_chl_XFe;
                app.GrtChl_chl_XMg_EditField.Value = GrtChl_chl_XMg;
            end
            
            % Grt-Chl Plot
                       
            plot(app.GrtChl_UIAxes,0,0);
            hold(app.GrtChl_UIAxes,'on');
            app.GrtChl_UIAxes.XLim = [app.GrtChl_T_min.Value app.GrtChl_T_max.Value];
            app.GrtChl_UIAxes.YLim = [app.GrtChl_P_min.Value app.GrtChl_P_max.Value];
            app.GrtChl_UIAxes.XGrid = app.GrtChl_GridCheckBox.Value;
            app.GrtChl_UIAxes.YGrid = app.GrtChl_GridCheckBox.Value;
            GrtChl_P_plot = [app.GrtChl_P_min.Value app.GrtChl_P_max.Value];
            
            % Grt-Chl Thermometers 
            
            if GrtChl_grt_Fe && GrtChl_grt_Mg && GrtChl_chl_Fe && GrtChl_chl_Mg > 0
                
                GrtChl_lnKd = log((GrtChl_grt_Fe/GrtChl_grt_Mg)/(GrtChl_chl_Fe/GrtChl_chl_Mg)); 
                app.GrtChl_lnKd_EditField.Value = GrtChl_lnKd;
                
                % Dickenson & Hewitt (1986)
                GrtChl_T_DH86 = ((-51906-(0.438*GrtChl_P*1000))/(-7.541+(1.987*(15*-GrtChl_lnKd))))-273.15;
                app.GrtChl_DH86_EditField.Value = GrtChl_T_DH86;
                GrtChl_T_DH86_plot = ((-51906-(0.438*GrtChl_P_plot*1000))/(-7.541+(1.987*(15*-GrtChl_lnKd))))-273.15;
                if app.GrtChl_DH86_CheckBox.Value == 1
                    plot(app.GrtChl_UIAxes,GrtChl_T_DH86_plot,GrtChl_P_plot,...
                        'Color',app.GrtChl_DH86color_EditField.Value,...
                        "LineWidth",app.GrtChl_LineWidth_Spinner.Value);
                end
                
                % Grambling (1990)
                GrtChl_T_G90 = ((-24156-(0.05*GrtChl_P*1000)-(4607*-GrtChl_lnKd))/-19.02)-273.15;
                app.GrtChl_G90_EditField.Value = GrtChl_T_G90;
                GrtChl_T_G90_plot = ((-24156-(0.05*GrtChl_P_plot*1000)-(4607*-GrtChl_lnKd))/-19.02)-273.15;
                if app.GrtChl_G90_CheckBox.Value == 1
                    plot(app.GrtChl_UIAxes,GrtChl_T_G90_plot,GrtChl_P_plot,...
                        'Color',app.GrtChl_G90color_EditField.Value,...
                        "LineWidth",app.GrtChl_LineWidth_Spinner.Value);
                end
                
                % Perchuk (1991)
                GrtChl_T_P91 = 3973/(GrtChl_lnKd+2.773)-273.15;
                app.GrtChl_P91_EditField.Value = GrtChl_T_P91;
                if app.GrtChl_P91_CheckBox.Value == 1
                    plot(app.GrtChl_UIAxes,[GrtChl_T_P91,GrtChl_T_P91],GrtChl_P_plot,...
                        'Color',app.GrtChl_P91color_EditField.Value,...
                        "LineWidth",app.GrtChl_LineWidth_Spinner.Value);
                end                                                             
     
            end
            
            hold(app.GrtChl_UIAxes,'off');
            
        end
                           
        function GrtCpx_calculate(app)
            
            % Grt-Cpx Input  
            
            GrtCpx_grt_Fe = app.GrtCpx_grt_Fe_EditField.Value;
            GrtCpx_grt_Mg = app.GrtCpx_grt_Mg_EditField.Value;
            GrtCpx_grt_Ca = app.GrtCpx_grt_Ca_EditField.Value;
            GrtCpx_grt_Mn = app.GrtCpx_grt_Mn_EditField.Value;
            GrtCpx_cpx_Al = app.GrtCpx_cpx_Al_EditField.Value; 
            GrtCpx_cpx_Fe = app.GrtCpx_cpx_Fe_EditField.Value;
            GrtCpx_cpx_Mg = app.GrtCpx_cpx_Mg_EditField.Value;
            GrtCpx_cpx_Ca = app.GrtCpx_cpx_Ca_EditField.Value;
            GrtCpx_cpx_Na = app.GrtCpx_cpx_Na_EditField.Value;
            GrtCpx_P = app.GrtCpx_P_EditField.Value;
            
            if GrtCpx_grt_Fe || GrtCpx_grt_Mg || GrtCpx_grt_Ca || GrtCpx_grt_Mn > 0                  
                GrtCpx_grt_XFe = GrtCpx_grt_Fe / (GrtCpx_grt_Fe + GrtCpx_grt_Mg + GrtCpx_grt_Ca + GrtCpx_grt_Mn);
                GrtCpx_grt_XMg = GrtCpx_grt_Mg / (GrtCpx_grt_Fe + GrtCpx_grt_Mg + GrtCpx_grt_Ca + GrtCpx_grt_Mn);
                GrtCpx_grt_XCa = GrtCpx_grt_Ca / (GrtCpx_grt_Fe + GrtCpx_grt_Mg + GrtCpx_grt_Ca + GrtCpx_grt_Mn);
                GrtCpx_grt_XMn = GrtCpx_grt_Mn / (GrtCpx_grt_Fe + GrtCpx_grt_Mg + GrtCpx_grt_Ca + GrtCpx_grt_Mn);
                app.GrtCpx_grt_XFe_EditField.Value = GrtCpx_grt_XFe;
                app.GrtCpx_grt_XMg_EditField.Value = GrtCpx_grt_XMg;
                app.GrtCpx_grt_XCa_EditField.Value = GrtCpx_grt_XCa;
                app.GrtCpx_grt_XMn_EditField.Value = GrtCpx_grt_XMn;
            end
                
            if GrtCpx_cpx_Fe || GrtCpx_cpx_Mg || GrtCpx_cpx_Ca > 0
                GrtCpx_cpx_XFe = GrtCpx_cpx_Fe / (GrtCpx_cpx_Fe + GrtCpx_cpx_Mg + GrtCpx_cpx_Ca);
                GrtCpx_cpx_XMg = GrtCpx_cpx_Mg / (GrtCpx_cpx_Fe + GrtCpx_cpx_Mg + GrtCpx_cpx_Ca);
                GrtCpx_cpx_XCa = GrtCpx_cpx_Ca / (GrtCpx_cpx_Fe + GrtCpx_cpx_Mg + GrtCpx_cpx_Ca);
                app.GrtCpx_cpx_XFe_EditField.Value = GrtCpx_cpx_XFe;
                app.GrtCpx_cpx_XMg_EditField.Value = GrtCpx_cpx_XMg;
                app.GrtCpx_cpx_XCa_EditField.Value = GrtCpx_cpx_XCa;
            end
            
            % Grt-Cpx Plot
                       
            plot(app.GrtCpx_UIAxes,0,0);
            hold(app.GrtCpx_UIAxes,'on');
            app.GrtCpx_UIAxes.XLim = [app.GrtCpx_T_min.Value app.GrtCpx_T_max.Value];
            app.GrtCpx_UIAxes.YLim = [app.GrtCpx_P_min.Value app.GrtCpx_P_max.Value];
            app.GrtCpx_UIAxes.XGrid = app.GrtCpx_GridCheckBox.Value;
            app.GrtCpx_UIAxes.YGrid = app.GrtCpx_GridCheckBox.Value;
            GrtCpx_P_plot = [app.GrtCpx_P_min.Value app.GrtCpx_P_max.Value];
            
            % Grt-Cpx Thermometers 
            
            if GrtCpx_grt_Fe && GrtCpx_grt_Mg && GrtCpx_cpx_Fe && GrtCpx_cpx_Mg > 0
                
                GrtCpx_lnKd = log((GrtCpx_grt_Fe/GrtCpx_grt_Mg)/(GrtCpx_cpx_Fe/GrtCpx_cpx_Mg)); 
                app.GrtCpx_lnKd_EditField.Value = GrtCpx_lnKd;
                
                % Mysen & Heier (1972)
                GrtCpx_T_MH72 = 2475/(GrtCpx_lnKd+0.781)-273.15;
                app.GrtCpx_MH72_EditField.Value = GrtCpx_T_MH72;
                if app.GrtCpx_MH72_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,[GrtCpx_T_MH72,GrtCpx_T_MH72],GrtCpx_P_plot,...
                        'Color',app.GrtCpx_MH72color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end                
                
                % Raheim & Green (1974)
                GrtCpx_T_RG74 = (3686+28.35*GrtCpx_P)/(GrtCpx_lnKd+2.33)-273.15;
                app.GrtCpx_RG74_EditField.Value = GrtCpx_T_RG74;
                GrtCpx_T_RG74_plot = (3686+28.35*GrtCpx_P_plot)/(GrtCpx_lnKd+2.33)-273.15;
                if app.GrtCpx_RG74_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_RG74_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_RG74color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Mori & Green (1978)
                GrtCpx_T_MG78 = 2800/(GrtCpx_lnKd+1.19)-273.15;
                app.GrtCpx_MG78_EditField.Value = GrtCpx_T_MG78;
                if app.GrtCpx_MG78_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,[GrtCpx_T_MG78,GrtCpx_T_MG78],GrtCpx_P_plot,...
                        'Color',app.GrtCpx_MG78color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Ellis & Green (1979)
                GrtCpx_T_EG79 = (3104*GrtCpx_grt_XCa+3030+10.86*GrtCpx_P)/(GrtCpx_lnKd+1.9034)-273.15;
                app.GrtCpx_EG79_EditField.Value = GrtCpx_T_EG79;
                GrtCpx_T_EG79_plot = (3104*GrtCpx_grt_XCa+3030+10.86*GrtCpx_P_plot)/(GrtCpx_lnKd+1.9034)-273.15;
                if app.GrtCpx_EG79_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_EG79_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_EG79color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Saxena (1979)
                GrtCpx_cpx_XFe_S79 = GrtCpx_cpx_Fe/(GrtCpx_cpx_Fe+GrtCpx_cpx_Mg+GrtCpx_cpx_Ca+(GrtCpx_cpx_Al-GrtCpx_cpx_Na)/2);
                GrtCpx_cpx_XMg_S79 = GrtCpx_cpx_Mg/(GrtCpx_cpx_Fe+GrtCpx_cpx_Mg+GrtCpx_cpx_Ca+(GrtCpx_cpx_Al-GrtCpx_cpx_Na)/2);
                GrtCpx_cpx_XCa_S79 = GrtCpx_cpx_Ca/(GrtCpx_cpx_Fe+GrtCpx_cpx_Mg+GrtCpx_cpx_Ca+(GrtCpx_cpx_Al-GrtCpx_cpx_Na)/2);
                GrtCpx_cpx_XAl_S79 = 1-GrtCpx_cpx_XFe_S79-GrtCpx_cpx_XMg_S79-GrtCpx_cpx_XCa_S79;                
                GrtCpx_Q1_S79 = 2710*(GrtCpx_grt_XFe-GrtCpx_grt_XMg)+3150*GrtCpx_grt_XCa+2600*GrtCpx_grt_XMn;
                GrtCpx_Q2_S79 = -6594*(GrtCpx_cpx_XFe_S79*(GrtCpx_cpx_XFe_S79-2*GrtCpx_cpx_XMg_S79))...
                    -12762*(GrtCpx_cpx_XFe_S79-GrtCpx_cpx_XMg_S79*(1-GrtCpx_cpx_XFe_S79))...
                    -11281*(GrtCpx_cpx_XCa_S79*(1-GrtCpx_cpx_XAl_S79)-2*GrtCpx_cpx_XMg_S79*GrtCpx_cpx_XCa_S79)...
                    +6137*(GrtCpx_cpx_XCa_S79*(2*GrtCpx_cpx_XMg_S79+GrtCpx_cpx_XAl_S79))...
                    +35791*(GrtCpx_cpx_XAl_S79*(1-2*GrtCpx_cpx_XMg_S79))...
                    +25409*GrtCpx_cpx_XCa_S79^2-55137*(GrtCpx_cpx_XCa_S79*(GrtCpx_cpx_XMg_S79-GrtCpx_cpx_XFe_S79))...
                    -11338*(GrtCpx_cpx_XAl_S79*(GrtCpx_cpx_XFe_S79-GrtCpx_cpx_XMg_S79));                
                GrtCpx_T_S79 = (8288+0.0276*GrtCpx_P*1000+GrtCpx_Q1_S79-GrtCpx_Q2_S79)/(1.987*GrtCpx_lnKd+2.4083)-273.15;
                app.GrtCpx_S79_EditField.Value = GrtCpx_T_S79;
                GrtCpx_T_S79_plot = (8288+0.0276*GrtCpx_P_plot*1000+GrtCpx_Q1_S79-GrtCpx_Q2_S79)/(1.987*GrtCpx_lnKd+2.4083)-273.15;
                if app.GrtCpx_S79_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_S79_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_S79color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Dahl (1980)
                GrtCpx_T_D80 = (2324+0.022*GrtCpx_P*1000+1509*GrtCpx_grt_XFe*GrtCpx_grt_XMg+2810*GrtCpx_grt_XCa+2855*GrtCpx_grt_XMn)/(1.987*GrtCpx_lnKd)-273.15;
                app.GrtCpx_D80_EditField.Value = GrtCpx_T_D80;
                GrtCpx_T_D80_plot = (2324+0.022*GrtCpx_P_plot*1000+1509*GrtCpx_grt_XFe*GrtCpx_grt_XMg+2810*GrtCpx_grt_XCa+2855*GrtCpx_grt_XMn)/(1.987*GrtCpx_lnKd)-273.15;
                if app.GrtCpx_D80_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_D80_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_D80color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Powell (1985)
                GrtCpx_T_P85 = (2790+10*GrtCpx_P+3140*GrtCpx_grt_XCa)/(GrtCpx_lnKd+1.735)-273.15;
                app.GrtCpx_P85_EditField.Value = GrtCpx_T_P85;
                GrtCpx_T_P85_plot = (2790+10*GrtCpx_P_plot+3140*GrtCpx_grt_XCa)/(GrtCpx_lnKd+1.735)-273.15;
                if app.GrtCpx_P85_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_P85_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_P85color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Krogh (1988)
                GrtCpx_T_K88 = (-6173*GrtCpx_grt_XCa^2+10*GrtCpx_P+6731*GrtCpx_grt_XCa+1879)/(GrtCpx_lnKd+1.393)-273.15;
                app.GrtCpx_K88_EditField.Value = GrtCpx_T_K88;
                GrtCpx_T_K88_plot = (-6173*GrtCpx_grt_XCa^2+10*GrtCpx_P_plot+6731*GrtCpx_grt_XCa+1879)/(GrtCpx_lnKd+1.393)-273.15;
                if app.GrtCpx_K88_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_K88_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_K88color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Ai (1994)
                GrtCpx_grt_XMg2 = GrtCpx_grt_Mg/(GrtCpx_grt_Fe+GrtCpx_grt_Mg);
                GrtCpx_T_A94 = (-1629*GrtCpx_grt_XCa^2+3648.55*GrtCpx_grt_XCa-6.59*GrtCpx_grt_XMg2*100+1987.98+17.66*GrtCpx_P)/(GrtCpx_lnKd+1.076)-273.15;
                app.GrtCpx_A94_EditField.Value = GrtCpx_T_A94;
                GrtCpx_T_A94_plot = (-1629*GrtCpx_grt_XCa^2+3648.55*GrtCpx_grt_XCa-6.59*GrtCpx_grt_XMg2*100+1987.98+17.66*GrtCpx_P_plot)/(GrtCpx_lnKd+1.076)-273.15;
                if app.GrtCpx_A94_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_A94_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_A94color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Ravna (2000)
                GrtCpx_T_R00 = ((1939.9+3270*GrtCpx_grt_XCa-1396*GrtCpx_grt_XCa^2+...
                    3319*GrtCpx_grt_XMn-3535*GrtCpx_grt_XMn^2+1105*GrtCpx_grt_XMg2-3561*...
                    GrtCpx_grt_XMg2^2+2324*GrtCpx_grt_XMg2^3+169.4*GrtCpx_P/10)/(GrtCpx_lnKd+1.223))-273.15;
                app.GrtCpx_R00_EditField.Value = GrtCpx_T_R00;
                GrtCpx_T_R00_plot = ((1939.9+3270*GrtCpx_grt_XCa-1396*GrtCpx_grt_XCa^2+...
                    3319*GrtCpx_grt_XMn-3535*GrtCpx_grt_XMn^2+1105*GrtCpx_grt_XMg2-3561*...
                    GrtCpx_grt_XMg2^2+2324*GrtCpx_grt_XMg2^3+169.4*GrtCpx_P_plot/10)/(GrtCpx_lnKd+1.223))-273.15;
                if app.GrtCpx_R00_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_R00_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_R00color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
                % Nakamura (2009)
                GrtCpx_A_N09 = 0.5*GrtCpx_grt_XCa*(GrtCpx_grt_XMg-GrtCpx_grt_XFe-GrtCpx_grt_XMn);
                GrtCpx_B_N09 = 0.5*GrtCpx_grt_XCa*(GrtCpx_grt_XMg-GrtCpx_grt_XFe+GrtCpx_grt_XMn);
                GrtCpx_C_N09 = 0.5*(GrtCpx_grt_XCa+GrtCpx_grt_XMn)*(GrtCpx_grt_XMg-GrtCpx_grt_XFe);               
                GrtCpx_cpx_XMg_N09 = GrtCpx_cpx_Mg/(GrtCpx_cpx_Al+GrtCpx_cpx_Fe+GrtCpx_cpx_Mg);
                GrtCpx_cpx_XFe_N09 = GrtCpx_cpx_Fe/(GrtCpx_cpx_Al+GrtCpx_cpx_Fe+GrtCpx_cpx_Mg);                
                GrtCpx_T_N09 = (2784+14.52*GrtCpx_P+(2601+1.44*GrtCpx_P)*(2*GrtCpx_grt_XCa*GrtCpx_grt_XMg-GrtCpx_A_N09)+...
                    (1183+6.98*GrtCpx_P)*(GrtCpx_grt_XCa^2-GrtCpx_A_N09)-105*(2*GrtCpx_grt_XCa*GrtCpx_grt_XFe+GrtCpx_B_N09)+...
                    (814.6+3.61*GrtCpx_P)*(GrtCpx_grt_XCa^2+GrtCpx_B_N09)-(254.6+8.42*GrtCpx_P)*...
                    (2*GrtCpx_grt_XMg*GrtCpx_grt_XFe-GrtCpx_grt_XFe^2+GrtCpx_C_N09)-83.6*...
                    (GrtCpx_grt_XMg^2-2*GrtCpx_grt_XMg*GrtCpx_grt_XFe+GrtCpx_C_N09)+1388*GrtCpx_grt_XMn-462*...
                    (GrtCpx_cpx_XMg_N09-GrtCpx_cpx_XFe_N09))/(GrtCpx_lnKd+1.431+0.695*(2*GrtCpx_grt_XCa*GrtCpx_grt_XMg+GrtCpx_grt_XCa^2-2*GrtCpx_A_N09)+...
                    0.203*(GrtCpx_grt_XCa^2-2*GrtCpx_grt_XCa*GrtCpx_grt_XFe)+0.922*GrtCpx_grt_XMn)-273.15;       
                app.GrtCpx_N09_EditField.Value = GrtCpx_T_N09;
                GrtCpx_T_N09_plot = (2784+14.52*GrtCpx_P_plot+(2601+1.44*GrtCpx_P_plot)*(2*GrtCpx_grt_XCa*GrtCpx_grt_XMg-GrtCpx_A_N09)+...
                    (1183+6.98*GrtCpx_P_plot)*(GrtCpx_grt_XCa^2-GrtCpx_A_N09)-105*(2*GrtCpx_grt_XCa*GrtCpx_grt_XFe+GrtCpx_B_N09)+...
                    (814.6+3.61*GrtCpx_P_plot)*(GrtCpx_grt_XCa^2+GrtCpx_B_N09)-(254.6+8.42*GrtCpx_P_plot)*...
                    (2*GrtCpx_grt_XMg*GrtCpx_grt_XFe-GrtCpx_grt_XFe^2+GrtCpx_C_N09)-83.6*...
                    (GrtCpx_grt_XMg^2-2*GrtCpx_grt_XMg*GrtCpx_grt_XFe+GrtCpx_C_N09)+1388*GrtCpx_grt_XMn-462*...
                    (GrtCpx_cpx_XMg_N09-GrtCpx_cpx_XFe_N09))/(GrtCpx_lnKd+1.431+0.695*(2*GrtCpx_grt_XCa*GrtCpx_grt_XMg+GrtCpx_grt_XCa^2-2*GrtCpx_A_N09)+...
                    0.203*(GrtCpx_grt_XCa^2-2*GrtCpx_grt_XCa*GrtCpx_grt_XFe)+0.922*GrtCpx_grt_XMn)-273.15;
                if app.GrtCpx_N09_CheckBox.Value == 1
                    plot(app.GrtCpx_UIAxes,GrtCpx_T_N09_plot,GrtCpx_P_plot,...
                        'Color',app.GrtCpx_N09color_EditField.Value,...
                        "LineWidth",app.GrtCpx_LineWidth_Spinner.Value);
                end
                
            end
            
            hold(app.GrtCpx_UIAxes,'off');
            
        end         
        

        function GrtCrd_calculate(app)
            
            % Grt-Crd Input  
            
            GrtCrd_grt_Fe = app.GrtCrd_grt_Fe_EditField.Value;
            GrtCrd_grt_Mg = app.GrtCrd_grt_Mg_EditField.Value;
            GrtCrd_grt_Ca = app.GrtCrd_grt_Ca_EditField.Value;
            GrtCrd_grt_Mn = app.GrtCrd_grt_Mn_EditField.Value; 
            GrtCrd_crd_Fe = app.GrtCrd_crd_Fe_EditField.Value;
            GrtCrd_crd_Mg = app.GrtCrd_crd_Mg_EditField.Value;
            GrtCrd_P = app.GrtCrd_P_EditField.Value;
            
            if GrtCrd_grt_Fe || GrtCrd_grt_Mg || GrtCrd_grt_Ca || GrtCrd_grt_Mn > 0                  
                GrtCrd_grt_XFe = GrtCrd_grt_Fe / (GrtCrd_grt_Fe + GrtCrd_grt_Mg + GrtCrd_grt_Ca + GrtCrd_grt_Mn);
                GrtCrd_grt_XMg = GrtCrd_grt_Mg / (GrtCrd_grt_Fe + GrtCrd_grt_Mg + GrtCrd_grt_Ca + GrtCrd_grt_Mn);
                GrtCrd_grt_XCa = GrtCrd_grt_Ca / (GrtCrd_grt_Fe + GrtCrd_grt_Mg + GrtCrd_grt_Ca + GrtCrd_grt_Mn);
                GrtCrd_grt_XMn = GrtCrd_grt_Mn / (GrtCrd_grt_Fe + GrtCrd_grt_Mg + GrtCrd_grt_Ca + GrtCrd_grt_Mn);
                app.GrtCrd_grt_XFe_EditField.Value = GrtCrd_grt_XFe;
                app.GrtCrd_grt_XMg_EditField.Value = GrtCrd_grt_XMg;
                app.GrtCrd_grt_XCa_EditField.Value = GrtCrd_grt_XCa;
                app.GrtCrd_grt_XMn_EditField.Value = GrtCrd_grt_XMn;
            end
                
            if GrtCrd_crd_Fe || GrtCrd_crd_Mg > 0
                GrtCrd_crd_XFe = GrtCrd_crd_Fe / (GrtCrd_crd_Fe + GrtCrd_crd_Mg);
                GrtCrd_crd_XMg = GrtCrd_crd_Mg / (GrtCrd_crd_Fe + GrtCrd_crd_Mg);
                app.GrtCrd_crd_XFe_EditField.Value = GrtCrd_crd_XFe;
                app.GrtCrd_crd_XMg_EditField.Value = GrtCrd_crd_XMg;
            end
            
            % Grt-Crd Plot
                       
            plot(app.GrtCrd_UIAxes,0,0);
            hold(app.GrtCrd_UIAxes,'on');
            app.GrtCrd_UIAxes.XLim = [app.GrtCrd_T_min.Value app.GrtCrd_T_max.Value];
            app.GrtCrd_UIAxes.YLim = [app.GrtCrd_P_min.Value app.GrtCrd_P_max.Value];
            app.GrtCrd_UIAxes.XGrid = app.GrtCrd_GridCheckBox.Value;
            app.GrtCrd_UIAxes.YGrid = app.GrtCrd_GridCheckBox.Value;
            GrtCrd_P_plot = [app.GrtCrd_P_min.Value app.GrtCrd_P_max.Value];
            
            % Grt-Crd Thermometers 
            
            if GrtCrd_grt_Fe && GrtCrd_grt_Mg && GrtCrd_crd_Fe && GrtCrd_crd_Mg > 0
                
                GrtCrd_lnKd = log((GrtCrd_grt_Fe/GrtCrd_grt_Mg)/(GrtCrd_crd_Fe/GrtCrd_crd_Mg)); 
                app.GrtCrd_lnKd_EditField.Value = GrtCrd_lnKd;
                
                % Currie (1971)
                GrtCrd_T_C71 = 4515/(6.37-GrtCrd_lnKd)-273.15;
                app.GrtCrd_C71_EditField.Value = GrtCrd_T_C71;
                if app.GrtCrd_C71_CheckBox.Value == 1
                    plot(app.GrtCrd_UIAxes,[GrtCrd_T_C71,GrtCrd_T_C71],GrtCrd_P_plot,...
                        'Color',app.GrtCrd_C71color_EditField.Value,...
                        "LineWidth",app.GrtCrd_LineWidth_Spinner.Value);
                end
                
                % Thompson (1976)
                GrtCrd_T_T76 = (2725+0.0155*GrtCrd_P*1000)/(GrtCrd_lnKd+0.896)-273.15;
                app.GrtCrd_T76_EditField.Value = GrtCrd_T_T76;
                GrtCrd_T_T76_plot = (2725+0.0155*GrtCrd_P_plot*1000)/(GrtCrd_lnKd+0.896)-273.15;
                if app.GrtCrd_T76_CheckBox.Value == 1
                    plot(app.GrtCrd_UIAxes,GrtCrd_T_T76_plot,GrtCrd_P_plot,...
                        'Color',app.GrtCrd_T76color_EditField.Value,...
                        "LineWidth",app.GrtCrd_LineWidth_Spinner.Value);
                end
                
                % Holdaway & Lee (1977)
                GrtCrd_T_HL77 = (3095+0.0153*GrtCrd_P*1000)/(GrtCrd_lnKd+1.354)-273.15;
                app.GrtCrd_HL77_EditField.Value = GrtCrd_T_HL77;
                GrtCrd_T_HL77_plot = (3095+0.0153*GrtCrd_P_plot*1000)/(GrtCrd_lnKd+1.354)-273.15;
                if app.GrtCrd_HL77_CheckBox.Value == 1
                    plot(app.GrtCrd_UIAxes,GrtCrd_T_HL77_plot,GrtCrd_P_plot,...
                        'Color',app.GrtCrd_HL77color_EditField.Value,...
                        "LineWidth",app.GrtCrd_LineWidth_Spinner.Value);
                end
                
                % Perchuk et al. (1985)
                GrtCrd_T_P85 = (3087+0.018*GrtCrd_P*1000)/(GrtCrd_lnKd+1.342)-273.15;
                app.GrtCrd_P85_EditField.Value = GrtCrd_T_P85;
                GrtCrd_T_P85_plot = (3087+0.018*GrtCrd_P_plot*1000)/(GrtCrd_lnKd+1.342)-273.15;
                if app.GrtCrd_P85_CheckBox.Value == 1
                    plot(app.GrtCrd_UIAxes,GrtCrd_T_P85_plot,GrtCrd_P_plot,...
                        'Color',app.GrtCrd_P85color_EditField.Value,...
                        "LineWidth",app.GrtCrd_LineWidth_Spinner.Value);
                end
                
                % Bhattacharya et al. (1988)
                GrtCrd_T_B88 = (1814+0.0152*GrtCrd_P*1000+1122*(GrtCrd_crd_XMg-GrtCrd_crd_XFe)-1258*...
                    (GrtCrd_grt_XMg-GrtCrd_grt_XFe)+1510*(GrtCrd_grt_XCa+GrtCrd_grt_XMn))/(1.028+GrtCrd_lnKd)-273.15;
                app.GrtCrd_B88_EditField.Value = GrtCrd_T_B88;
                GrtCrd_T_B88_plot = (1814+0.0152*GrtCrd_P_plot*1000+1122*(GrtCrd_crd_XMg-GrtCrd_crd_XFe)-1258*...
                    (GrtCrd_grt_XMg-GrtCrd_grt_XFe)+1510*(GrtCrd_grt_XCa+GrtCrd_grt_XMn))/(1.028+GrtCrd_lnKd)-273.15;
                if app.GrtCrd_B88_CheckBox.Value == 1
                    plot(app.GrtCrd_UIAxes,GrtCrd_T_B88_plot,GrtCrd_P_plot,...
                        'Color',app.GrtCrd_B88color_EditField.Value,...
                        "LineWidth",app.GrtCrd_LineWidth_Spinner.Value);
                end
                
                % Bhattacharya (1993)
                GrtCrd_T_B93 = (1928.61+0.0152*GrtCrd_P*1000+1311*(GrtCrd_grt_XFe-GrtCrd_grt_XMg)+1573*...
                    (GrtCrd_grt_XCa+GrtCrd_grt_XMn)+991*(GrtCrd_crd_XMg-GrtCrd_crd_XFe))/(1+1.014*GrtCrd_lnKd)-273.15;
                app.GrtCrd_B93_EditField.Value = GrtCrd_T_B93;
                GrtCrd_T_B93_plot = (1928.61+0.0152*GrtCrd_P_plot*1000+1311*(GrtCrd_grt_XFe-GrtCrd_grt_XMg)+1573*...
                    (GrtCrd_grt_XCa+GrtCrd_grt_XMn)+991*(GrtCrd_crd_XMg-GrtCrd_crd_XFe))/(1+1.014*GrtCrd_lnKd)-273.15;
                if app.GrtCrd_B93_CheckBox.Value == 1
                    plot(app.GrtCrd_UIAxes,GrtCrd_T_B93_plot,GrtCrd_P_plot,...
                        'Color',app.GrtCrd_B93color_EditField.Value,...
                        "LineWidth",app.GrtCrd_LineWidth_Spinner.Value);
                end
           
            end
            
            hold(app.GrtCrd_UIAxes,'off');
            
        end
        
        function GrtHbl_calculate(app)
            
            % Grt-Hbl Input  
            
            GrtHbl_grt_Fe = app.GrtHbl_grt_Fe_EditField.Value;
            GrtHbl_grt_Mg = app.GrtHbl_grt_Mg_EditField.Value;
            GrtHbl_grt_Ca = app.GrtHbl_grt_Ca_EditField.Value;
            GrtHbl_grt_Mn = app.GrtHbl_grt_Mn_EditField.Value; 
            GrtHbl_hbl_Fe = app.GrtHbl_hbl_Fe_EditField.Value;
            GrtHbl_hbl_Mg = app.GrtHbl_hbl_Mg_EditField.Value;
            
            if GrtHbl_grt_Fe || GrtHbl_grt_Mg || GrtHbl_grt_Ca || GrtHbl_grt_Mn > 0                  
                GrtHbl_grt_XFe = GrtHbl_grt_Fe / (GrtHbl_grt_Fe + GrtHbl_grt_Mg + GrtHbl_grt_Ca + GrtHbl_grt_Mn);
                GrtHbl_grt_XMg = GrtHbl_grt_Mg / (GrtHbl_grt_Fe + GrtHbl_grt_Mg + GrtHbl_grt_Ca + GrtHbl_grt_Mn);
                GrtHbl_grt_XCa = GrtHbl_grt_Ca / (GrtHbl_grt_Fe + GrtHbl_grt_Mg + GrtHbl_grt_Ca + GrtHbl_grt_Mn);
                GrtHbl_grt_XMn = GrtHbl_grt_Mn / (GrtHbl_grt_Fe + GrtHbl_grt_Mg + GrtHbl_grt_Ca + GrtHbl_grt_Mn);
                app.GrtHbl_grt_XFe_EditField.Value = GrtHbl_grt_XFe;
                app.GrtHbl_grt_XMg_EditField.Value = GrtHbl_grt_XMg;
                app.GrtHbl_grt_XCa_EditField.Value = GrtHbl_grt_XCa;
                app.GrtHbl_grt_XMn_EditField.Value = GrtHbl_grt_XMn;
            end
                
            if GrtHbl_hbl_Fe || GrtHbl_hbl_Mg > 0
                GrtHbl_hbl_XFe = GrtHbl_hbl_Fe / (GrtHbl_hbl_Fe + GrtHbl_hbl_Mg);
                GrtHbl_hbl_XMg = GrtHbl_hbl_Mg / (GrtHbl_hbl_Fe + GrtHbl_hbl_Mg);
                app.GrtHbl_hbl_XFe_EditField.Value = GrtHbl_hbl_XFe;
                app.GrtHbl_hbl_XMg_EditField.Value = GrtHbl_hbl_XMg;
            end
            
            % Grt-Hbl Plot
                       
            plot(app.GrtHbl_UIAxes,0,0);
            hold(app.GrtHbl_UIAxes,'on');
            app.GrtHbl_UIAxes.XLim = [app.GrtHbl_T_min.Value app.GrtHbl_T_max.Value];
            app.GrtHbl_UIAxes.YLim = [app.GrtHbl_P_min.Value app.GrtHbl_P_max.Value];
            app.GrtHbl_UIAxes.XGrid = app.GrtHbl_GridCheckBox.Value;
            app.GrtHbl_UIAxes.YGrid = app.GrtHbl_GridCheckBox.Value;
            GrtHbl_P_plot = [app.GrtHbl_P_min.Value app.GrtHbl_P_max.Value];
            
            % Grt-Hbl Thermometers 
            
            if GrtHbl_grt_Fe && GrtHbl_grt_Mg && GrtHbl_hbl_Fe && GrtHbl_hbl_Mg > 0
                
                GrtHbl_lnKd = log((GrtHbl_grt_Fe/GrtHbl_grt_Mg)/(GrtHbl_hbl_Fe/GrtHbl_hbl_Mg)); 
                app.GrtHbl_lnKd_EditField.Value = GrtHbl_lnKd;
                
                % Graham & Powell (1984)
                GrtHbl_T_GP84 = (2880+3280*GrtHbl_grt_XCa)/(GrtHbl_lnKd+2.426)-273.15;
                app.GrtHbl_GP84_EditField.Value = GrtHbl_T_GP84;
                if app.GrtHbl_GP84_CheckBox.Value == 1
                    plot(app.GrtHbl_UIAxes,[GrtHbl_T_GP84,GrtHbl_T_GP84],GrtHbl_P_plot,...
                        'Color',app.GrtHbl_GP84color_EditField.Value,...
                        "LineWidth",app.GrtHbl_LineWidth_Spinner.Value);
                end
                
                % Perchuk et al. (1985)
                GrtHbl_T_Per85 = 3330/(GrtHbl_lnKd+2.333)-273.15;
                app.GrtHbl_Per85_EditField.Value = GrtHbl_T_Per85;
                if app.GrtHbl_Per85_CheckBox.Value == 1
                    plot(app.GrtHbl_UIAxes,[GrtHbl_T_Per85,GrtHbl_T_Per85],GrtHbl_P_plot,...
                        'Color',app.GrtHbl_Per85color_EditField.Value,...
                        "LineWidth",app.GrtHbl_LineWidth_Spinner.Value);
                end
                
                % Powell (1985)
                GrtHbl_T_Pow85 = (2580+3340*GrtHbl_grt_XCa)/(GrtHbl_lnKd+2.2)-273.15;
                app.GrtHbl_Pow85_EditField.Value = GrtHbl_T_Pow85;
                if app.GrtHbl_Pow85_CheckBox.Value == 1
                    plot(app.GrtHbl_UIAxes,[GrtHbl_T_Pow85,GrtHbl_T_Pow85],GrtHbl_P_plot,...
                        'Color',app.GrtHbl_Pow85color_EditField.Value,...
                        "LineWidth",app.GrtHbl_LineWidth_Spinner.Value);
                end
                
                % Ravna (2000)
                GrtHbl_T_R00 = (1504+1784*(GrtHbl_grt_XCa+GrtHbl_grt_XMn))/(GrtHbl_lnKd+0.72)-273.15;
                app.GrtHbl_R00_EditField.Value = GrtHbl_T_R00;
                if app.GrtHbl_R00_CheckBox.Value == 1
                    plot(app.GrtHbl_UIAxes,[GrtHbl_T_R00,GrtHbl_T_R00],GrtHbl_P_plot,...
                        'Color',app.GrtHbl_R00color_EditField.Value,...
                        "LineWidth",app.GrtHbl_LineWidth_Spinner.Value);
                end                               
     
            end
            
            hold(app.GrtHbl_UIAxes,'off');
            
        end
       
        function GrtIlm_calculate(app)
            
            % Grt-Ilm Input  
            
            GrtIlm_grt_Fe = app.GrtIlm_grt_Fe_EditField.Value;
            GrtIlm_grt_Mg = app.GrtIlm_grt_Mg_EditField.Value;
            GrtIlm_grt_Ca = app.GrtIlm_grt_Ca_EditField.Value;
            GrtIlm_grt_Mn = app.GrtIlm_grt_Mn_EditField.Value; 
            GrtIlm_ilm_Fe = app.GrtIlm_ilm_Fe_EditField.Value;
            GrtIlm_ilm_Mn = app.GrtIlm_ilm_Mn_EditField.Value;
            
            if GrtIlm_grt_Fe || GrtIlm_grt_Mg || GrtIlm_grt_Ca || GrtIlm_grt_Mn > 0                  
                GrtIlm_grt_XFe = GrtIlm_grt_Fe / (GrtIlm_grt_Fe + GrtIlm_grt_Mg + GrtIlm_grt_Ca + GrtIlm_grt_Mn);
                GrtIlm_grt_XMg = GrtIlm_grt_Mg / (GrtIlm_grt_Fe + GrtIlm_grt_Mg + GrtIlm_grt_Ca + GrtIlm_grt_Mn);
                GrtIlm_grt_XCa = GrtIlm_grt_Ca / (GrtIlm_grt_Fe + GrtIlm_grt_Mg + GrtIlm_grt_Ca + GrtIlm_grt_Mn);
                GrtIlm_grt_XMn = GrtIlm_grt_Mn / (GrtIlm_grt_Fe + GrtIlm_grt_Mg + GrtIlm_grt_Ca + GrtIlm_grt_Mn);
                app.GrtIlm_grt_XFe_EditField.Value = GrtIlm_grt_XFe;
                app.GrtIlm_grt_XMg_EditField.Value = GrtIlm_grt_XMg;
                app.GrtIlm_grt_XCa_EditField.Value = GrtIlm_grt_XCa;
                app.GrtIlm_grt_XMn_EditField.Value = GrtIlm_grt_XMn;
            end
                
            if GrtIlm_ilm_Fe || GrtIlm_ilm_Mn > 0
                GrtIlm_ilm_XFe = GrtIlm_ilm_Fe / (GrtIlm_ilm_Fe + GrtIlm_ilm_Mn);
                GrtIlm_ilm_XMn = GrtIlm_ilm_Mn / (GrtIlm_ilm_Fe + GrtIlm_ilm_Mn);
                app.GrtIlm_ilm_XFe_EditField.Value = GrtIlm_ilm_XFe;
                app.GrtIlm_ilm_XMn_EditField.Value = GrtIlm_ilm_XMn;
            end
            
            % Grt-Ilm Plot
                       
            plot(app.GrtIlm_UIAxes,0,0);
            hold(app.GrtIlm_UIAxes,'on');
            app.GrtIlm_UIAxes.XLim = [app.GrtIlm_T_min.Value app.GrtIlm_T_max.Value];
            app.GrtIlm_UIAxes.YLim = [app.GrtIlm_P_min.Value app.GrtIlm_P_max.Value];
            app.GrtIlm_UIAxes.XGrid = app.GrtIlm_GridCheckBox.Value;
            app.GrtIlm_UIAxes.YGrid = app.GrtIlm_GridCheckBox.Value;
            GrtIlm_P_plot = [app.GrtIlm_P_min.Value app.GrtIlm_P_max.Value];
            
            % Grt-Ilm Thermometers 
            
            if GrtIlm_grt_Fe && GrtIlm_grt_Mn && GrtIlm_ilm_Fe && GrtIlm_ilm_Mn > 0
                
                GrtIlm_lnKd = log((GrtIlm_grt_Mn/GrtIlm_grt_Fe)/(GrtIlm_ilm_Mn/GrtIlm_ilm_Fe)); 
                app.GrtIlm_lnKd_EditField.Value = GrtIlm_lnKd;                
                     
                % Pownceby et al. (1987)
                GrtIlm_T_P87 = ((-4089+420*(2*GrtIlm_ilm_XMn-1)-77*(2*GrtIlm_grt_XMn-1))/...
                    (-1.987*GrtIlm_lnKd-1.44))-273.15;
                app.GrtIlm_P87_EditField.Value = GrtIlm_T_P87;
                if app.GrtIlm_P87_CheckBox.Value == 1
                    plot(app.GrtIlm_UIAxes,[GrtIlm_T_P87,GrtIlm_T_P87],GrtIlm_P_plot,...
                        'Color',app.GrtIlm_P87color_EditField.Value,...
                        "LineWidth",app.GrtIlm_LineWidth_Spinner.Value);
                end 
                
                % Pownceby et al. (1991)
                GrtIlm_T_P91 = ((14918-2200*(2*GrtIlm_ilm_XMn-1)+620*(GrtIlm_grt_XMn-GrtIlm_grt_XFe)-...
                    972*GrtIlm_grt_XCa)/(8.314*GrtIlm_lnKd+4.38))-273.15;
                app.GrtIlm_P91_EditField.Value = GrtIlm_T_P91;
                if app.GrtIlm_P91_CheckBox.Value == 1
                    plot(app.GrtIlm_UIAxes,[GrtIlm_T_P91,GrtIlm_T_P91],GrtIlm_P_plot,...
                        'Color',app.GrtIlm_P91color_EditField.Value,...
                        "LineWidth",app.GrtIlm_LineWidth_Spinner.Value);
                end 
                
                % Martin et al. (2010)
                GrtIlm_T_M10 = ((14642-2200*(2*GrtIlm_ilm_XMn-1)+539*(GrtIlm_grt_XMn-GrtIlm_grt_XFe)+...
                    12083*GrtIlm_grt_XMg)/(8.314*GrtIlm_lnKd+7.67*GrtIlm_grt_XMg+4.203))-273.15;
                app.GrtIlm_M10_EditField.Value = GrtIlm_T_M10;
                if app.GrtIlm_M10_CheckBox.Value == 1
                    plot(app.GrtIlm_UIAxes,[GrtIlm_T_M10,GrtIlm_T_M10],GrtIlm_P_plot,...
                        'Color',app.GrtIlm_M10color_EditField.Value,...
                        "LineWidth",app.GrtIlm_LineWidth_Spinner.Value);
                end                                                            
     
            end
            
            hold(app.GrtIlm_UIAxes,'off');
            
        end        
        
        function GrtPh_calculate(app)
            
            % Grt-Ph Input  
            
            GrtPh_grt_Fe = app.GrtPh_grt_Fe_EditField.Value;
            GrtPh_grt_Mg = app.GrtPh_grt_Mg_EditField.Value;
            GrtPh_grt_Ca = app.GrtPh_grt_Ca_EditField.Value;
            GrtPh_grt_Mn = app.GrtPh_grt_Mn_EditField.Value;
            GrtPh_ph_Al = app.GrtPh_ph_Al_EditField.Value;
            GrtPh_ph_Fe = app.GrtPh_ph_Fe_EditField.Value;
            GrtPh_ph_Mg = app.GrtPh_ph_Mg_EditField.Value;
            GrtPh_P = app.GrtPh_P_EditField.Value;
            
            if GrtPh_grt_Fe || GrtPh_grt_Mg || GrtPh_grt_Ca || GrtPh_grt_Mn > 0                  
                GrtPh_grt_XFe = GrtPh_grt_Fe / (GrtPh_grt_Fe + GrtPh_grt_Mg + GrtPh_grt_Ca + GrtPh_grt_Mn);
                GrtPh_grt_XMg = GrtPh_grt_Mg / (GrtPh_grt_Fe + GrtPh_grt_Mg + GrtPh_grt_Ca + GrtPh_grt_Mn);
                GrtPh_grt_XCa = GrtPh_grt_Ca / (GrtPh_grt_Fe + GrtPh_grt_Mg + GrtPh_grt_Ca + GrtPh_grt_Mn);
                GrtPh_grt_XMn = GrtPh_grt_Mn / (GrtPh_grt_Fe + GrtPh_grt_Mg + GrtPh_grt_Ca + GrtPh_grt_Mn);
                app.GrtPh_grt_XFe_EditField.Value = GrtPh_grt_XFe;
                app.GrtPh_grt_XMg_EditField.Value = GrtPh_grt_XMg;
                app.GrtPh_grt_XCa_EditField.Value = GrtPh_grt_XCa;
                app.GrtPh_grt_XMn_EditField.Value = GrtPh_grt_XMn;
            end
                
            if GrtPh_ph_Al || GrtPh_ph_Fe || GrtPh_ph_Mg > 0
                GrtPh_ph_XAl = GrtPh_ph_Al / (GrtPh_ph_Al + GrtPh_ph_Fe + GrtPh_ph_Mg);
                GrtPh_ph_XFe = GrtPh_ph_Fe / (GrtPh_ph_Al + GrtPh_ph_Fe + GrtPh_ph_Mg);
                GrtPh_ph_XMg = GrtPh_ph_Mg / (GrtPh_ph_Al + GrtPh_ph_Fe + GrtPh_ph_Mg);
                app.GrtPh_ph_XAl_EditField.Value = GrtPh_ph_XAl;
                app.GrtPh_ph_XFe_EditField.Value = GrtPh_ph_XFe;
                app.GrtPh_ph_XMg_EditField.Value = GrtPh_ph_XMg;
            end
            
            % Grt-Ph Plot
                       
            plot(app.GrtPh_UIAxes,0,0);
            hold(app.GrtPh_UIAxes,'on');
            app.GrtPh_UIAxes.XLim = [app.GrtPh_T_min.Value app.GrtPh_T_max.Value];
            app.GrtPh_UIAxes.YLim = [app.GrtPh_P_min.Value app.GrtPh_P_max.Value];
            app.GrtPh_UIAxes.XGrid = app.GrtPh_GridCheckBox.Value;
            app.GrtPh_UIAxes.YGrid = app.GrtPh_GridCheckBox.Value;
            GrtPh_P_plot = [app.GrtPh_P_min.Value app.GrtPh_P_max.Value];
            
            % Grt-Ph Thermometers 
            
            if GrtPh_grt_Fe && GrtPh_grt_Mg && GrtPh_ph_Fe && GrtPh_ph_Mg > 0
                
                GrtPh_lnKd = log((GrtPh_grt_Fe/GrtPh_grt_Mg)/(GrtPh_ph_Fe/GrtPh_ph_Mg)); 
                app.GrtPh_lnKd_EditField.Value = GrtPh_lnKd;                
                
                % Krogh & Raheim (1978)
                GrtPh_T_KR78 = ((3685+77.1*GrtPh_P)/(GrtPh_lnKd+3.52))-273.15;
                app.GrtPh_KR78_EditField.Value = GrtPh_T_KR78;
                GrtPh_T_KR78_plot = ((3685+77.1*GrtPh_P_plot)/(GrtPh_lnKd+3.52))-273.15;
                if app.GrtPh_KR78_CheckBox.Value == 1
                    plot(app.GrtPh_UIAxes,GrtPh_T_KR78_plot,GrtPh_P_plot,...
                        'Color',app.GrtPh_KR78color_EditField.Value,...
                        "LineWidth",app.GrtPh_LineWidth_Spinner.Value);
                end
                
                % Green & Hellman (1982)
                GrtPh_T_GH82a = ((5560+0.036*GrtPh_P*1000)/(GrtPh_lnKd+4.65))-273.15;
                app.GrtPh_GH82a_EditField.Value = GrtPh_T_GH82a;
                GrtPh_T_GH82a_plot = ((5560+0.036*GrtPh_P_plot*1000)/(GrtPh_lnKd+4.65))-273.15;
                if app.GrtPh_GH82a_CheckBox.Value == 1
                    plot(app.GrtPh_UIAxes,GrtPh_T_GH82a_plot,GrtPh_P_plot,...
                        'Color',app.GrtPh_GH82acolor_EditField.Value,...
                        "LineWidth",app.GrtPh_LineWidth_Spinner.Value);
                end
                GrtPh_T_GH82b = ((5680+0.036*GrtPh_P*1000)/(GrtPh_lnKd+4.48))-273.15;
                app.GrtPh_GH82b_EditField.Value = GrtPh_T_GH82b;
                GrtPh_T_GH82b_plot = ((5680+0.036*GrtPh_P_plot*1000)/(GrtPh_lnKd+4.48))-273.15;
                if app.GrtPh_GH82b_CheckBox.Value == 1
                    plot(app.GrtPh_UIAxes,GrtPh_T_GH82b_plot,GrtPh_P_plot,...
                        'Color',app.GrtPh_GH82bcolor_EditField.Value,...
                        "LineWidth",app.GrtPh_LineWidth_Spinner.Value);
                end
                GrtPh_T_GH82c = ((5170+0.036*GrtPh_P*1000)/(GrtPh_lnKd+4.17))-273.15;
                app.GrtPh_GH82c_EditField.Value = GrtPh_T_GH82c;
                GrtPh_T_GH82c_plot = ((5170+0.036*GrtPh_P_plot*1000)/(GrtPh_lnKd+4.17))-273.15;
                if app.GrtPh_GH82c_CheckBox.Value == 1
                    plot(app.GrtPh_UIAxes,GrtPh_T_GH82c_plot,GrtPh_P_plot,...
                        'Color',app.GrtPh_GH82ccolor_EditField.Value,...
                        "LineWidth",app.GrtPh_LineWidth_Spinner.Value);
                end
                                
                % Hynes & Forest (1988)
                GrtPh_T_HF88 = ((4726+17*GrtPh_P)/(GrtPh_lnKd+4.16))-273.15;
                app.GrtPh_HF88_EditField.Value = GrtPh_T_HF88;
                GrtPh_T_HF88_plot = ((4726+17*GrtPh_P_plot)/(GrtPh_lnKd+4.16))-273.15;
                if app.GrtPh_HF88_CheckBox.Value == 1
                    plot(app.GrtPh_UIAxes,GrtPh_T_HF88_plot,GrtPh_P_plot,...
                        'Color',app.GrtPh_HF88color_EditField.Value,...
                        "LineWidth",app.GrtPh_LineWidth_Spinner.Value);
                end
                
                % Wu et al. (2002)
                GrtPh_Ga_W02 = 12.4*GrtPh_grt_XFe^2+22.09*GrtPh_grt_XMg^2-12.02*GrtPh_grt_XCa^2+23.01*...
                    GrtPh_grt_XMn^2-68.98*GrtPh_grt_XFe*GrtPh_grt_XMg+37.33*GrtPh_grt_XFe*...
                    GrtPh_grt_XCa+18.165*GrtPh_grt_XFe*GrtPh_grt_XMn+35.33*GrtPh_grt_XMg*GrtPh_grt_XCa+...
                    27.855*GrtPh_grt_XMg*GrtPh_grt_XMn+35.165*GrtPh_grt_XCa*GrtPh_grt_XMn;
                GrtPh_Gb_W02 = -0.05*GrtPh_grt_XFe^2-0.034*GrtPh_grt_XMg^2-0.005*GrtPh_grt_XCa^2-0.014*...
                    GrtPh_grt_XMn^2+0.168*GrtPh_grt_XFe*GrtPh_grt_XMg+0.1565*GrtPh_grt_XFe*...
                    GrtPh_grt_XCa-0.022*GrtPh_grt_XFe*GrtPh_grt_XMn-0.2125*GrtPh_grt_XMg*GrtPh_grt_XCa-...
                    0.006*GrtPh_grt_XMg*GrtPh_grt_XMn-0.0305*GrtPh_grt_XCa*GrtPh_grt_XMn;
                GrtPh_Gc_W02 = -22265*GrtPh_grt_XFe^2-24166*GrtPh_grt_XMg^2+3220*GrtPh_grt_XCa^2-39632*...
                    GrtPh_grt_XMn^2+92862*GrtPh_grt_XFe*GrtPh_grt_XMg-67328*GrtPh_grt_XFe*...
                    GrtPh_grt_XCa-38681.5*GrtPh_grt_XFe*GrtPh_grt_XMn-99262*GrtPh_grt_XMg*GrtPh_grt_XCa-...
                    40582.5*GrtPh_grt_XMg*GrtPh_grt_XMn-79669.5*GrtPh_grt_XCa*GrtPh_grt_XMn;
                GrtPh_T_W02a = ((969.9+GrtPh_P*(1.3-9.1*GrtPh_Gb_W02)-0.0091*GrtPh_Gc_W02-4393.8*...
                    (GrtPh_ph_XFe-GrtPh_ph_XMg)+200.4*GrtPh_ph_XAl)/...
                    (1+0.0091*(3*8.3144*GrtPh_lnKd+GrtPh_Ga_W02)))-273.15;
                app.GrtPh_W02a_EditField.Value = GrtPh_T_W02a;
                GrtPh_T_W02a_plot = ((969.9+GrtPh_P_plot*(1.3-9.1*GrtPh_Gb_W02)-0.0091*GrtPh_Gc_W02-4393.8*...
                    (GrtPh_ph_XFe-GrtPh_ph_XMg)+200.4*GrtPh_ph_XAl)/...
                    (1+0.0091*(3*8.3144*GrtPh_lnKd+GrtPh_Ga_W02)))-273.15;
                if app.GrtPh_W02a_CheckBox.Value == 1
                    plot(app.GrtPh_UIAxes,GrtPh_T_W02a_plot,GrtPh_P_plot,...
                        'Color',app.GrtPh_W02acolor_EditField.Value,...
                        "LineWidth",app.GrtPh_LineWidth_Spinner.Value);
                end
                GrtPh_T_W02b = ((-1167.3-GrtPh_P*(0.2+8.8*GrtPh_Gb_W02)-0.0088*GrtPh_Gc_W02-6878.1*...
                    (GrtPh_ph_XFe-GrtPh_ph_XMg)+2469*GrtPh_ph_XAl)/...
                    (1+0.0088*(3*8.3144*GrtPh_lnKd+GrtPh_Ga_W02)))-273.15;
                app.GrtPh_W02b_EditField.Value = GrtPh_T_W02b;
                GrtPh_T_W02b_plot = ((-1167.3-GrtPh_P_plot*(0.2+8.8*GrtPh_Gb_W02)-0.0088*GrtPh_Gc_W02-6878.1*...
                    (GrtPh_ph_XFe-GrtPh_ph_XMg)+2469*GrtPh_ph_XAl)/...
                    (1+0.0088*(3*8.3144*GrtPh_lnKd+GrtPh_Ga_W02)))-273.15;
                if app.GrtPh_W02b_CheckBox.Value == 1
                    plot(app.GrtPh_UIAxes,GrtPh_T_W02b_plot,GrtPh_P_plot,...
                        'Color',app.GrtPh_W02bcolor_EditField.Value,...
                        "LineWidth",app.GrtPh_LineWidth_Spinner.Value);
                end
            
            end
            
            hold(app.GrtPh_UIAxes,'off');
            
        end
        
        function GrtOl_calculate(app)
            
            % Grt-Ol Input  
            
            GrtOl_grt_Fe = app.GrtOl_grt_Fe_EditField.Value;
            GrtOl_grt_Mg = app.GrtOl_grt_Mg_EditField.Value;
            GrtOl_grt_Ca = app.GrtOl_grt_Ca_EditField.Value;
            GrtOl_grt_Mn = app.GrtOl_grt_Mn_EditField.Value; 
            GrtOl_ol_Fe = app.GrtOl_ol_Fe_EditField.Value;
            GrtOl_ol_Mg = app.GrtOl_ol_Mg_EditField.Value;
            GrtOl_P = app.GrtOl_P_EditField.Value;
            
            if GrtOl_grt_Fe || GrtOl_grt_Mg || GrtOl_grt_Ca || GrtOl_grt_Mn > 0                  
                GrtOl_grt_XFe = GrtOl_grt_Fe / (GrtOl_grt_Fe + GrtOl_grt_Mg + GrtOl_grt_Ca + GrtOl_grt_Mn);
                GrtOl_grt_XMg = GrtOl_grt_Mg / (GrtOl_grt_Fe + GrtOl_grt_Mg + GrtOl_grt_Ca + GrtOl_grt_Mn);
                GrtOl_grt_XCa = GrtOl_grt_Ca / (GrtOl_grt_Fe + GrtOl_grt_Mg + GrtOl_grt_Ca + GrtOl_grt_Mn);
                GrtOl_grt_XMn = GrtOl_grt_Mn / (GrtOl_grt_Fe + GrtOl_grt_Mg + GrtOl_grt_Ca + GrtOl_grt_Mn);
                app.GrtOl_grt_XFe_EditField.Value = GrtOl_grt_XFe;
                app.GrtOl_grt_XMg_EditField.Value = GrtOl_grt_XMg;
                app.GrtOl_grt_XCa_EditField.Value = GrtOl_grt_XCa;
                app.GrtOl_grt_XMn_EditField.Value = GrtOl_grt_XMn;
            end
                
            if GrtOl_ol_Fe || GrtOl_ol_Mg > 0
                GrtOl_ol_XFe = GrtOl_ol_Fe / (GrtOl_ol_Fe + GrtOl_ol_Mg);
                GrtOl_ol_XMg = GrtOl_ol_Mg / (GrtOl_ol_Fe + GrtOl_ol_Mg);
                app.GrtOl_ol_XFe_EditField.Value = GrtOl_ol_XFe;
                app.GrtOl_ol_XMg_EditField.Value = GrtOl_ol_XMg;
            end
            
            % Grt-Ol Plot
                       
            plot(app.GrtOl_UIAxes,0,0);
            hold(app.GrtOl_UIAxes,'on');
            app.GrtOl_UIAxes.XLim = [app.GrtOl_T_min.Value app.GrtOl_T_max.Value];
            app.GrtOl_UIAxes.YLim = [app.GrtOl_P_min.Value app.GrtOl_P_max.Value];
            app.GrtOl_UIAxes.XGrid = app.GrtOl_GridCheckBox.Value;
            app.GrtOl_UIAxes.YGrid = app.GrtOl_GridCheckBox.Value;
            GrtOl_P_plot = [app.GrtOl_P_min.Value app.GrtOl_P_max.Value];
            
            % Grt-Ol Thermometers 
            
            if GrtOl_grt_Fe && GrtOl_grt_Mg && GrtOl_ol_Fe && GrtOl_ol_Mg > 0
                
                GrtOl_lnKd = log((GrtOl_grt_Fe/GrtOl_grt_Mg)/(GrtOl_ol_Fe/GrtOl_ol_Mg)); 
                app.GrtOl_lnKd_EditField.Value = GrtOl_lnKd;                
                
                % Kawasaki & Matsui (1977)
                
                % Mori & Green (1978)
                GrtOl_T_MG78 = (900/(GrtOl_lnKd-0.09))-273.15;
                
                % O'Neill & Wood (1979)
                
                % Smith & Wilson (1985)
                
                % Brey & Kohler (1990)                                                           
     
            end
            
            hold(app.GrtOl_UIAxes,'off');
            
        end
        
        function GrtOpx_calculate(app)
            
            % Grt-Opx Input  
            
            GrtOpx_grt_Fe = app.GrtOpx_grt_Fe_EditField.Value;
            GrtOpx_grt_Mg = app.GrtOpx_grt_Mg_EditField.Value;
            GrtOpx_grt_Ca = app.GrtOpx_grt_Ca_EditField.Value;
            GrtOpx_grt_Mn = app.GrtOpx_grt_Mn_EditField.Value;
            GrtOpx_opx_Al = app.GrtOpx_opx_Al_EditField.Value;
            GrtOpx_opx_Fe = app.GrtOpx_opx_Fe_EditField.Value;
            GrtOpx_opx_Mg = app.GrtOpx_opx_Mg_EditField.Value;            
            GrtOpx_P = app.GrtOpx_P_EditField.Value;
            
            if GrtOpx_grt_Fe || GrtOpx_grt_Mg || GrtOpx_grt_Ca || GrtOpx_grt_Mn > 0                  
                GrtOpx_grt_XFe = GrtOpx_grt_Fe / (GrtOpx_grt_Fe + GrtOpx_grt_Mg + GrtOpx_grt_Ca + GrtOpx_grt_Mn);
                GrtOpx_grt_XMg = GrtOpx_grt_Mg / (GrtOpx_grt_Fe + GrtOpx_grt_Mg + GrtOpx_grt_Ca + GrtOpx_grt_Mn);
                GrtOpx_grt_XCa = GrtOpx_grt_Ca / (GrtOpx_grt_Fe + GrtOpx_grt_Mg + GrtOpx_grt_Ca + GrtOpx_grt_Mn);
                GrtOpx_grt_XMn = GrtOpx_grt_Mn / (GrtOpx_grt_Fe + GrtOpx_grt_Mg + GrtOpx_grt_Ca + GrtOpx_grt_Mn);
                app.GrtOpx_grt_XFe_EditField.Value = GrtOpx_grt_XFe;
                app.GrtOpx_grt_XMg_EditField.Value = GrtOpx_grt_XMg;
                app.GrtOpx_grt_XCa_EditField.Value = GrtOpx_grt_XCa;
                app.GrtOpx_grt_XMn_EditField.Value = GrtOpx_grt_XMn;
            end
                
            if GrtOpx_opx_Al || GrtOpx_opx_Fe || GrtOpx_opx_Mg  > 0
                GrtOpx_opx_XAl = (GrtOpx_opx_Al / 2) / (GrtOpx_opx_Fe + GrtOpx_opx_Mg + GrtOpx_opx_Al / 2);
                GrtOpx_opx_XFe = GrtOpx_opx_Fe / (GrtOpx_opx_Fe + GrtOpx_opx_Mg + GrtOpx_opx_Al / 2);
                GrtOpx_opx_XMg = GrtOpx_opx_Mg / (GrtOpx_opx_Fe + GrtOpx_opx_Mg + GrtOpx_opx_Al / 2);                
                app.GrtOpx_opx_XAl_EditField.Value = GrtOpx_opx_XAl;
                app.GrtOpx_opx_XFe_EditField.Value = GrtOpx_opx_XFe;
                app.GrtOpx_opx_XMg_EditField.Value = GrtOpx_opx_XMg;
            end
            
            % Grt-Opx Plot
                       
            plot(app.GrtOpx_UIAxes,0,0);
            hold(app.GrtOpx_UIAxes,'on');
            app.GrtOpx_UIAxes.XLim = [app.GrtOpx_T_min.Value app.GrtOpx_T_max.Value];
            app.GrtOpx_UIAxes.YLim = [app.GrtOpx_P_min.Value app.GrtOpx_P_max.Value];
            app.GrtOpx_UIAxes.XGrid = app.GrtOpx_GridCheckBox.Value;
            app.GrtOpx_UIAxes.YGrid = app.GrtOpx_GridCheckBox.Value;
            GrtOpx_P_plot = [app.GrtOpx_P_min.Value app.GrtOpx_P_max.Value];
            
            % Grt-Opx Thermometers 
            
            if GrtOpx_grt_Fe && GrtOpx_grt_Mg && GrtOpx_opx_Fe && GrtOpx_opx_Mg > 0
                
                GrtOpx_lnKd = log((GrtOpx_grt_Fe / GrtOpx_grt_Mg) / (GrtOpx_opx_Fe / GrtOpx_opx_Mg)); 
                app.GrtOpx_lnKd_EditField.Value = GrtOpx_lnKd;
                                                         
                % Mori & Green (1978)
                GrtOpx_T_MG78 = 1300 / (GrtOpx_lnKd + 0.12) - 273.15;
                app.GrtOpx_MG78_EditField.Value = GrtOpx_T_MG78;
                if app.GrtOpx_MG78_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,[GrtOpx_T_MG78,GrtOpx_T_MG78],GrtOpx_P_plot,'Color',app.GrtOpx_MG78color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Dahl (1980)
                GrtOpx_T_D80 = (1391 + 1509 * (GrtOpx_grt_XFe - GrtOpx_grt_XMg) + 2810 * GrtOpx_grt_XCa + 2855 * GrtOpx_grt_XMn) / (1.987 * GrtOpx_lnKd) - 273.15;
                app.GrtOpx_D80_EditField.Value = GrtOpx_T_D80;
                if app.GrtOpx_D80_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,[GrtOpx_T_D80,GrtOpx_T_D80],GrtOpx_P_plot,'Color',app.GrtOpx_D80color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end 
                
                % Raith et al. (1983)
                GrtOpx_T_R83 = 1684/(GrtOpx_lnKd+0.334)-273.15;
                app.GrtOpx_R83_EditField.Value = GrtOpx_T_R83;
                if app.GrtOpx_R83_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,[GrtOpx_T_R83,GrtOpx_T_R83],GrtOpx_P_plot,'Color',app.GrtOpx_R83color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Harley (1984)
                GrtOpx_T_H84 = (3740+1400*GrtOpx_grt_XCa+22.86*GrtOpx_P)/(1.987*GrtOpx_lnKd+1.96)-273.15;
                app.GrtOpx_H84_EditField.Value = GrtOpx_T_H84;
                GrtOpx_T_H84_plot = (3740+1400*GrtOpx_grt_XCa+22.86*GrtOpx_P_plot)/(1.987*GrtOpx_lnKd+1.96)-273.15;
                if app.GrtOpx_H84_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_H84_plot,GrtOpx_P_plot,'Color',app.GrtOpx_H84color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Sen & Bhattacharya (1984)
                GrtOpx_T_SB84 = (2713+0.022*GrtOpx_P*1000+3300*GrtOpx_grt_XCa+195*(GrtOpx_grt_XFe-GrtOpx_grt_XMg))/(-1.9872*-GrtOpx_lnKd+0.787+1.5*GrtOpx_grt_XCa)-273.15;
                app.GrtOpx_SB84_EditField.Value = GrtOpx_T_SB84;
                GrtOpx_T_SB84_plot = (2713+0.022*GrtOpx_P_plot*1000+3300*GrtOpx_grt_XCa+195*(GrtOpx_grt_XFe-GrtOpx_grt_XMg))/(-1.9872*-GrtOpx_lnKd+0.787+1.5*GrtOpx_grt_XCa)-273.15;
                if app.GrtOpx_SB84_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_SB84_plot,GrtOpx_P_plot,'Color',app.GrtOpx_SB84color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Lee & Ganguly (1984)
                GrtOpx_T_LG84 = (2187+1510*(GrtOpx_grt_XCa-GrtOpx_grt_XMn)+8.6*GrtOpx_P)/(GrtOpx_lnKd+1.071)-273.15;
                app.GrtOpx_LG84_EditField.Value = GrtOpx_T_LG84;
                GrtOpx_T_LG84_plot = (2187+1510*(GrtOpx_grt_XCa-GrtOpx_grt_XMn)+8.6*GrtOpx_P_plot)/(GrtOpx_lnKd+1.071)-273.15;
                if app.GrtOpx_LG84_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_LG84_plot,GrtOpx_P_plot,'Color',app.GrtOpx_LG84color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Perchuk et al. (1985)
                GrtOpx_T_P85 = (4766+2533*(GrtOpx_opx_XFe-GrtOpx_opx_XMg)-5214*GrtOpx_opx_XAl+5704*GrtOpx_grt_XCa+0.023*GrtOpx_P*1000)/(1.987*GrtOpx_lnKd+2.65+1.86*(GrtOpx_opx_XFe-GrtOpx_opx_XMg)+1.242*GrtOpx_grt_XCa)-273.15;
                app.GrtOpx_P85_EditField.Value = GrtOpx_T_P85;
                GrtOpx_T_P85_plot = (4766+2533*(GrtOpx_opx_XFe-GrtOpx_opx_XMg)-5214*GrtOpx_opx_XAl+5704*GrtOpx_grt_XCa+0.023*GrtOpx_P_plot*1000)/(1.987*GrtOpx_lnKd+2.65+1.86*(GrtOpx_opx_XFe-GrtOpx_opx_XMg)+1.242*GrtOpx_grt_XCa)-273.15;
                if app.GrtOpx_P85_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_P85_plot,GrtOpx_P_plot,'Color',app.GrtOpx_P85color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Lee & Ganguly (1988)
                GrtOpx_T_LG88 = (1981+1509.66*(GrtOpx_grt_XCa-GrtOpx_grt_XMn)+11.91*GrtOpx_P)/(GrtOpx_lnKd+0.97)-273.15;
                app.GrtOpx_LG88_EditField.Value = GrtOpx_T_LG88;
                GrtOpx_T_LG88_plot = (1981+1509.66*(GrtOpx_grt_XCa-GrtOpx_grt_XMn)+11.91*GrtOpx_P_plot)/(GrtOpx_lnKd+0.97)-273.15;
                if app.GrtOpx_LG88_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_LG88_plot,GrtOpx_P_plot,'Color',app.GrtOpx_LG88color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Aranovich & Podlesskii (1989)
                GrtOpx_A_AP89 = -626*GrtOpx_grt_XCa^2-6642*GrtOpx_grt_XFe*GrtOpx_grt_XCa-8100*GrtOpx_grt_XMg*GrtOpx_grt_XCa+GrtOpx_grt_XCa*(GrtOpx_grt_XMg-GrtOpx_grt_XFe)*1051.5;
                GrtOpx_B_AP89 = 1.266*GrtOpx_grt_XCa^2+2.836*GrtOpx_grt_XFe*GrtOpx_grt_XCa+3*GrtOpx_grt_XMg*GrtOpx_grt_XCa+GrtOpx_grt_XCa*(GrtOpx_grt_XMg-GrtOpx_grt_XFe)*(-0.908);
                GrtOpx_T_AP89 = ((GrtOpx_P*1000-1)*0.02342+4766-GrtOpx_A_AP89+(GrtOpx_opx_XFe-GrtOpx_opx_XMg)*2372-5204*GrtOpx_opx_XAl)/(1.987*GrtOpx_lnKd+2.654+GrtOpx_B_AP89+1.69*(GrtOpx_opx_XFe-GrtOpx_opx_XMg))-273.15;
                app.GrtOpx_AP89_EditField.Value = GrtOpx_T_AP89;
                GrtOpx_T_AP89_plot = ((GrtOpx_P_plot*1000-1)*0.02342+4766-GrtOpx_A_AP89+(GrtOpx_opx_XFe-GrtOpx_opx_XMg)*2372-5204*GrtOpx_opx_XAl)/(1.987*GrtOpx_lnKd+2.654+GrtOpx_B_AP89+1.69*(GrtOpx_opx_XFe-GrtOpx_opx_XMg))-273.15;
                if app.GrtOpx_AP89_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_AP89_plot,GrtOpx_P_plot,'Color',app.GrtOpx_AP89color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Perchuk & Lavrenteva (1990)
                GrtOpx_T_PL90 = (4066-347*(GrtOpx_opx_XMg-GrtOpx_opx_XFe)-17484*GrtOpx_opx_XAl+5769*GrtOpx_grt_XCa+23.42*GrtOpx_P)/(1.987*GrtOpx_lnKd+2.143+0.0929*(GrtOpx_opx_XMg-GrtOpx_opx_XFe)-12.8994*GrtOpx_opx_XAl+3.846*GrtOpx_grt_XCa)-273.15;
                app.GrtOpx_PL90_EditField.Value = GrtOpx_T_PL90;
                GrtOpx_T_PL90_plot = (4066-347*(GrtOpx_opx_XMg-GrtOpx_opx_XFe)-17484*GrtOpx_opx_XAl+5769*GrtOpx_grt_XCa+23.42*GrtOpx_P_plot)/(1.987*GrtOpx_lnKd+2.143+0.0929*(GrtOpx_opx_XMg-GrtOpx_opx_XFe)-12.8994*GrtOpx_opx_XAl+3.846*GrtOpx_grt_XCa)-273.15;
                if app.GrtOpx_PL90_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_PL90_plot,GrtOpx_P_plot,'Color',app.GrtOpx_PL90color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
                % Bhattacharya et al. (1991)
                GrtOpx_A_B91 = -1220*GrtOpx_grt_XFe*GrtOpx_grt_XMg-441*GrtOpx_grt_XCa*(GrtOpx_grt_XMg-GrtOpx_grt_XFe)-136*GrtOpx_grt_XMg^2+746*GrtOpx_grt_XFe^2;
                GrtOpx_T_B91 = (1611+0.021*GrtOpx_P*1000+906*GrtOpx_grt_XCa+GrtOpx_A_B91+477*(2*GrtOpx_opx_XMg-1))/(GrtOpx_lnKd+0.796)-273.15;
                app.GrtOpx_B91_EditField.Value = GrtOpx_T_B91;
                GrtOpx_T_B91_plot = (1611+0.021*GrtOpx_P_plot*1000+906*GrtOpx_grt_XCa+GrtOpx_A_B91+477*(2*GrtOpx_opx_XMg-1))/(GrtOpx_lnKd+0.796)-273.15;
                if app.GrtOpx_B91_CheckBox.Value == 1
                    plot(app.GrtOpx_UIAxes,GrtOpx_T_B91_plot,GrtOpx_P_plot,'Color',app.GrtOpx_B91color_EditField.Value,...
                        "LineWidth",app.GrtOpx_LineWidth_Spinner.Value);
                end
                
            end
            
            hold(app.GrtOpx_UIAxes,'off');
                        
        end
        
        function TiBi_calculate(app)
            
            % Ti-in-Bi Input  

            TiBi_Ti = app.TiBi_Ti_EditField.Value;
            TiBi_Al = app.TiBi_Al_EditField.Value;
            TiBi_Fe = app.TiBi_Fe_EditField.Value;
            TiBi_Mg = app.TiBi_Mg_EditField.Value;
            TiBi_P = app.TiBi_P_EditField.Value;           
                
            if TiBi_Ti || TiBi_Al || TiBi_Fe || TiBi_Mg > 0
                TiBi_XTi = TiBi_Ti / (TiBi_Ti + TiBi_Al + TiBi_Fe + TiBi_Mg);
                TiBi_XAl = TiBi_Al / (TiBi_Ti + TiBi_Al + TiBi_Fe + TiBi_Mg);
                TiBi_XFe = TiBi_Fe / (TiBi_Ti + TiBi_Al + TiBi_Fe + TiBi_Mg);
                TiBi_XMg = TiBi_Mg / (TiBi_Ti + TiBi_Al + TiBi_Fe + TiBi_Mg);
                TiBi_MgN = TiBi_Mg / (TiBi_Fe + TiBi_Mg);
                app.TiBi_XTi_EditField.Value = TiBi_XTi;
                app.TiBi_XAl_EditField.Value = TiBi_XAl;
                app.TiBi_XFe_EditField.Value = TiBi_XFe;
                app.TiBi_XMg_EditField.Value = TiBi_XMg;
                app.TiBi_MgN_EditField.Value = TiBi_MgN;
            end
            
            % Ti-in-Bi Plot
                       
            plot(app.TiBi_UIAxes,0,0);
            hold(app.TiBi_UIAxes,'on');
            app.TiBi_UIAxes.XLim = [app.TiBi_T_min.Value app.TiBi_T_max.Value];
            app.TiBi_UIAxes.YLim = [app.TiBi_P_min.Value app.TiBi_P_max.Value];
            app.TiBi_UIAxes.XGrid = app.TiBi_GridCheckBox.Value;
            app.TiBi_UIAxes.YGrid = app.TiBi_GridCheckBox.Value;
            TiBi_P_plot = [app.TiBi_P_min.Value app.TiBi_P_max.Value];
            
            % Ti-in-Bi Thermometers 
            
            if TiBi_Ti && TiBi_Fe && TiBi_Mg > 0
                
                % Henry & Guidotti (2002)
                TiBi_T_HG02 = ((log10(TiBi_Ti)+2.3353+1.6718*TiBi_MgN^3)/(4.343*10^-9))^0.333;
                app.TiBi_HG02_EditField.Value = TiBi_T_HG02;
                if app.TiBi_HG02_CheckBox.Value == 1
                    plot(app.TiBi_UIAxes,[TiBi_T_HG02,TiBi_T_HG02],TiBi_P_plot,...
                        'Color',app.TiBi_HG02color_EditField.Value,...
                        "LineWidth",app.TiBi_LineWidth_Spinner.Value);
                end
                
                % Henry et al. (2005)
                TiBi_T_H05 = ((log10(TiBi_Ti)+2.3594+1.7283*TiBi_MgN^3)/(4.6482*10^-9))^0.333;
                app.TiBi_H05_EditField.Value = TiBi_T_H05;
                if app.TiBi_H05_CheckBox.Value == 1
                    plot(app.TiBi_UIAxes,[TiBi_T_H05,TiBi_T_H05],TiBi_P_plot,...
                        'Color',app.TiBi_H05color_EditField.Value,...
                        "LineWidth",app.TiBi_LineWidth_Spinner.Value);
                end
                
                % Wu & Chen (2015)
                TiBi_T_WC15 = exp(6.313+0.224*log(TiBi_XTi)-0.288*log(TiBi_XFe)-...
                    0.449*log(TiBi_XMg)+0.15*TiBi_P*0.1);
                app.TiBi_WC15_EditField.Value = TiBi_T_WC15;
                TiBi_T_WC15_plot = exp(6.313+0.224*log(TiBi_XTi)-0.288*log(TiBi_XFe)-...
                    0.449*log(TiBi_XMg)+0.15*TiBi_P_plot*0.1);
                if app.TiBi_WC15_CheckBox.Value == 1
                    plot(app.TiBi_UIAxes,TiBi_T_WC15_plot,TiBi_P_plot,...
                        'Color',app.TiBi_WC15color_EditField.Value,...
                        "LineWidth",app.TiBi_LineWidth_Spinner.Value);
                end
                                                                                 
            end
            
            hold(app.TiBi_UIAxes,'off');
            
        end

        function TiQz_calculate(app)
            
            % Ti-in-Qz Input  

            TiQz_Ti = app.TiQz_Ti_EditField.Value;
            TiQz_aTiO2 = app.TiQz_aTiO2_EditField.Value;
            TiQz_P = app.TiQz_P_EditField.Value;
            TiQz_Ti_cpfu = (TiQz_Ti/(10000*0.599*79.87))/((TiQz_Ti/(10000*0.599*79.87))+...
                ((100-TiQz_Ti/(10000*0.599*79.87))*1/60.09));
   
            % Ti-in-Qz Plot
                       
            plot(app.TiQz_UIAxes,0,0);
            hold(app.TiQz_UIAxes,'on');
            app.TiQz_UIAxes.XLim = [app.TiQz_T_min.Value app.TiQz_T_max.Value];
            app.TiQz_UIAxes.YLim = [app.TiQz_P_min.Value app.TiQz_P_max.Value];
            app.TiQz_UIAxes.XGrid = app.TiQz_GridCheckBox.Value;
            app.TiQz_UIAxes.YGrid = app.TiQz_GridCheckBox.Value;
            TiQz_P_plot = [app.TiQz_P_min.Value app.TiQz_P_max.Value];
            
            % Ti-in-Qz Thermometers 
            
            if TiQz_Ti > 0
                
                % Wark & Watson (2006)
                TiQz_T_WW06 = -3765/(log10(TiQz_Ti)-5.69)-273.15;
                app.TiQz_WW06_EditField.Value = TiQz_T_WW06;
                if app.TiQz_WW06_CheckBox.Value == 1
                    plot(app.TiQz_UIAxes,[TiQz_T_WW06,TiQz_T_WW06],TiQz_P_plot,...
                        'Color',app.TiQz_WW06color_EditField.Value,...
                        "LineWidth",app.TiQz_LineWidth_Spinner.Value);
                end
                
                % Kawasaki & Osanai (2008)
                TiQz_T_KO08 = -5895/(log(TiQz_Ti_cpfu)+1.729)-273.15;
                app.TiQz_KO08_EditField.Value = TiQz_T_KO08;
                if app.TiQz_KO08_CheckBox.Value == 1
                    plot(app.TiQz_UIAxes,[TiQz_T_KO08,TiQz_T_KO08],TiQz_P_plot,...
                        'Color',app.TiQz_KO08color_EditField.Value,...
                        "LineWidth",app.TiQz_LineWidth_Spinner.Value);
                end
                
                % Thomas et al. (2010)
                TiQz_T_T10 = (60952+1741*TiQz_P)/(1.52-8.3145*log(TiQz_Ti_cpfu)+8.3145*log(TiQz_aTiO2))-273.15;
                app.TiQz_T10_EditField.Value = TiQz_T_T10;
                TiQz_T_T10_plot = (60952+1741*TiQz_P_plot)/(1.52-8.3145*log(TiQz_Ti_cpfu)+8.3145*log(TiQz_aTiO2))-273.15;
                if app.TiQz_T10_CheckBox.Value == 1
                    plot(app.TiQz_UIAxes,TiQz_T_T10_plot,TiQz_P_plot,...
                        'Color',app.TiQz_T10color_EditField.Value,...
                        "LineWidth",app.TiQz_LineWidth_Spinner.Value);
                end
                
                % Huang & Audetat (2012)
                TiQz_T_HA12 = (2794.3+660*TiQz_P^0.35)/(5.6459-log10(TiQz_Ti))-273.15;
                app.TiQz_HA12_EditField.Value = TiQz_T_HA12;
                TiQz_T_HA12_plot = (2794.3+660*TiQz_P_plot.^0.35)/(5.6459-log10(TiQz_Ti))-273.15;
                if app.TiQz_HA12_CheckBox.Value == 1
                    plot(app.TiQz_UIAxes,TiQz_T_HA12_plot,TiQz_P_plot,...
                        'Color',app.TiQz_HA12color_EditField.Value,...
                        "LineWidth",app.TiQz_LineWidth_Spinner.Value);
                end
                                                                                                                 
            end
            
            hold(app.TiQz_UIAxes,'off');
            
        end
        
        function TiZrn_calculate(app)
            
            % Ti-in-Zrn Input  

            TiZrn_Ti = app.TiZrn_Ti_EditField.Value;
            TiZrn_aSiO2 = app.TiZrn_aSiO2_EditField.Value;
            TiZrn_aTiO2 = app.TiZrn_aTiO2_EditField.Value;                          
            
            % Ti-in-Zrn Plot
                       
            plot(app.TiZrn_UIAxes,0,0);
            hold(app.TiZrn_UIAxes,'on');
            app.TiZrn_UIAxes.XLim = [app.TiZrn_T_min.Value app.TiZrn_T_max.Value];
            app.TiZrn_UIAxes.YLim = [app.TiZrn_P_min.Value app.TiZrn_P_max.Value];
            app.TiZrn_UIAxes.XGrid = app.TiZrn_GridCheckBox.Value;
            app.TiZrn_UIAxes.YGrid = app.TiZrn_GridCheckBox.Value;
            TiZrn_P_plot = [app.TiZrn_P_min.Value app.TiZrn_P_max.Value];
            
            % Ti-in-Zrn Thermometers 
            
            if TiZrn_Ti > 0
                
                % Watson et al. (2006)
                TiZrn_T_W06 = 5080/(6.01-log10(TiZrn_Ti))-273.15;
                app.TiZrn_W06_EditField.Value = TiZrn_T_W06;
                if app.TiZrn_W06_CheckBox.Value == 1
                    plot(app.TiZrn_UIAxes,[TiZrn_T_W06,TiZrn_T_W06],TiZrn_P_plot,...
                        'Color',app.TiZrn_W06color_EditField.Value,...
                        "LineWidth",app.TiZrn_LineWidth_Spinner.Value);
                end
                
                % Ferry & Watson (2007)
                TiZrn_T_FW07 = 4800/(5.711-log10(TiZrn_Ti)-log10(TiZrn_aSiO2)+log10(TiZrn_aTiO2))-273.15;
                app.TiZrn_FW07_EditField.Value = TiZrn_T_FW07;
                if app.TiZrn_FW07_CheckBox.Value == 1
                    plot(app.TiZrn_UIAxes,[TiZrn_T_FW07,TiZrn_T_FW07],TiZrn_P_plot,...
                        'Color',app.TiZrn_FW07color_EditField.Value,...
                        "LineWidth",app.TiZrn_LineWidth_Spinner.Value);
                end
                                                                                                                
            end
            
            hold(app.TiZrn_UIAxes,'off');
            
        end
 
        function ZrRu_calculate(app)
            
            % Zr-in-Ru Input  

            ZrRu_Zr = app.ZrRu_Zr_EditField.Value;
            ZrRu_aSiO2 = app.ZrRu_aSiO2_EditField.Value;
            ZrRu_P = app.ZrRu_P_EditField.Value;
   
            % Zr-in-Ru Plot
                       
            plot(app.ZrRu_UIAxes,0,0);
            hold(app.ZrRu_UIAxes,'on');
            app.ZrRu_UIAxes.XLim = [app.ZrRu_T_min.Value app.ZrRu_T_max.Value];
            app.ZrRu_UIAxes.YLim = [app.ZrRu_P_min.Value app.ZrRu_P_max.Value];
            app.ZrRu_UIAxes.XGrid = app.ZrRu_GridCheckBox.Value;
            app.ZrRu_UIAxes.YGrid = app.ZrRu_GridCheckBox.Value;
            ZrRu_P_plot = [app.ZrRu_P_min.Value app.ZrRu_P_max.Value];
            
            % Zr-in-Ru Thermometers 
            
            if ZrRu_Zr > 0
                
                % Zack et al. (2004)
                ZrRu_T_Z04 = 127.8*log(ZrRu_Zr)-10;
                app.ZrRu_Z04_EditField.Value = ZrRu_T_Z04;
                if app.ZrRu_Z04_CheckBox.Value == 1
                    plot(app.ZrRu_UIAxes,[ZrRu_T_Z04,ZrRu_T_Z04],ZrRu_P_plot,...
                        'Color',app.ZrRu_Z04color_EditField.Value,...
                        "LineWidth",app.ZrRu_LineWidth_Spinner.Value);
                end
                
                % Watson et al. (2006)
                ZrRu_T_W06 = 4470/(7.36-log10(ZrRu_Zr))-273.15;
                app.ZrRu_W06_EditField.Value = ZrRu_T_W06;
                if app.ZrRu_W06_CheckBox.Value == 1
                    plot(app.ZrRu_UIAxes,[ZrRu_T_W06,ZrRu_T_W06],ZrRu_P_plot,...
                        'Color',app.ZrRu_W06color_EditField.Value,...
                        "LineWidth",app.ZrRu_LineWidth_Spinner.Value);
                end
                
                % Ferry & Watson (2007)
                ZrRu_T_FW07 = 4530/(7.42-log10(ZrRu_Zr))-log10(ZrRu_aSiO2)-273.15;
                app.ZrRu_FW07_EditField.Value = ZrRu_T_FW07;
                if app.ZrRu_FW07_CheckBox.Value == 1
                    plot(app.ZrRu_UIAxes,[ZrRu_T_FW07,ZrRu_T_FW07],ZrRu_P_plot,...
                        'Color',app.ZrRu_FW07color_EditField.Value,...
                        "LineWidth",app.ZrRu_LineWidth_Spinner.Value);
                end
                
                % Tomkins et al. (2007)
                ZrRu_T_T07a = (83.9+0.41*ZrRu_P)/(0.1428-0.0083144*log(ZrRu_Zr))-273.15;
                app.ZrRu_T07a_EditField.Value = ZrRu_T_T07a;
                ZrRu_T_T07a_plot = (83.9+0.41*ZrRu_P_plot)/(0.1428-0.0083144*log(ZrRu_Zr))-273.15;
                if app.ZrRu_T07a_CheckBox.Value == 1
                    plot(app.ZrRu_UIAxes,ZrRu_T_T07a_plot,ZrRu_P_plot,...
                        'Color',app.ZrRu_T07acolor_EditField.Value,...
                        "LineWidth",app.ZrRu_LineWidth_Spinner.Value);
                end
                ZrRu_T_T07b = (85.7+0.473*ZrRu_P)/(0.1453-0.0083144*log(ZrRu_Zr))-273.15;
                app.ZrRu_T07b_EditField.Value = ZrRu_T_T07b;
                ZrRu_T_T07b_plot = (85.7+0.473*ZrRu_P_plot)/(0.1453-0.0083144*log(ZrRu_Zr))-273.15;
                if app.ZrRu_T07b_CheckBox.Value == 1
                    plot(app.ZrRu_UIAxes,ZrRu_T_T07b_plot,ZrRu_P_plot,...
                        'Color',app.ZrRu_T07bcolor_EditField.Value,...
                        "LineWidth",app.ZrRu_LineWidth_Spinner.Value);
                end
                ZrRu_T_T07c = (88.1+0.206*ZrRu_P)/(0.1412-0.0083144*log(ZrRu_Zr))-273.15;
                app.ZrRu_T07c_EditField.Value = ZrRu_T_T07c;
                ZrRu_T_T07c_plot = (88.1+0.206*ZrRu_P_plot)/(0.1412-0.0083144*log(ZrRu_Zr))-273.15;
                if app.ZrRu_T07c_CheckBox.Value == 1
                    plot(app.ZrRu_UIAxes,ZrRu_T_T07c_plot,ZrRu_P_plot,...
                        'Color',app.ZrRu_T07ccolor_EditField.Value,...
                        "LineWidth",app.ZrRu_LineWidth_Spinner.Value);
                end
                                                                                             
            end
            
            hold(app.ZrRu_UIAxes,'off');
            
        end
        
        function ZrTtn_calculate(app)
            
            % Zr-in-Ttn Input  

            ZrTtn_Zr = app.ZrTtn_Zr_EditField.Value;
            ZrTtn_aSiO2 = app.ZrTtn_aSiO2_EditField.Value;
            ZrTtn_aTiO2 = app.ZrTtn_aTiO2_EditField.Value;
            ZrTtn_P = app.ZrTtn_P_EditField.Value;
   
            % Zr-in-Ttn Plot
                       
            plot(app.ZrTtn_UIAxes,0,0);
            hold(app.ZrTtn_UIAxes,'on');
            app.ZrTtn_UIAxes.XLim = [app.ZrTtn_T_min.Value app.ZrTtn_T_max.Value];
            app.ZrTtn_UIAxes.YLim = [app.ZrTtn_P_min.Value app.ZrTtn_P_max.Value];
            app.ZrTtn_UIAxes.XGrid = app.ZrTtn_GridCheckBox.Value;
            app.ZrTtn_UIAxes.YGrid = app.ZrTtn_GridCheckBox.Value;
            ZrTtn_P_plot = [app.ZrTtn_P_min.Value app.ZrTtn_P_max.Value];
            
            % Zr-in-Ttn Thermometers 
            
            if ZrTtn_Zr > 0
                
                % Hayden et al. (2008)
                ZrTtn_T_H08 = (7708+960*ZrTtn_P*0.1)/(10.52-log10(ZrTtn_aTiO2)-log10(ZrTtn_aSiO2)-log10(ZrTtn_Zr))-273.15;
                app.ZrTtn_H08_EditField.Value = ZrTtn_T_H08;
                ZrTtn_T_H08_plot = (7708+960*ZrTtn_P_plot*0.1)/(10.52-log10(ZrTtn_aTiO2)-log10(ZrTtn_aSiO2)-log10(ZrTtn_Zr))-273.15;
                if app.ZrTtn_H08_CheckBox.Value == 1
                    plot(app.ZrTtn_UIAxes,ZrTtn_T_H08_plot,ZrTtn_P_plot,...
                        'Color',app.ZrTtn_H08color_EditField.Value,...
                        "LineWidth",app.ZrTtn_LineWidth_Spinner.Value);
                end
                                                                                             
            end
            
            hold(app.ZrTtn_UIAxes,'off');
            
        end
        
        function EX_calculate(app)
                                         
            app.EX_lnKd_EditField.Value = log((app.EX_grt_XFe_Slider.Value/app.EX_grt_XMg_Slider.Value)/...
                (app.EX_bi_XFe_Slider.Value/app.EX_bi_XMg_Slider.Value));
            
            EX_P = app.EX_P_Slider.Value;
            EX_P_plot = [0 20];
            
            % Ferry & Spear (1978)
            EX_T = (-12454-0.057*EX_P*1000)/((5.961*-app.EX_lnKd_EditField.Value)-4.662)-273.15;
            app.EX_T_EditField.Value = EX_T;
            EX_T_plot = (-12454-0.057*EX_P_plot*1000)/((5.961*-app.EX_lnKd_EditField.Value)-4.662)-273.15;
            plot(app.EX_UIAxes,EX_T_plot,EX_P_plot);
            if EX_T > 1000
                app.EX_UIAxes.XLim = [0 EX_T+100];
            else
                app.EX_UIAxes.XLim = [0 1000];
            end            
            hold(app.EX_UIAxes,'on');
            scatter(app.EX_UIAxes,EX_T,EX_P);
            hold(app.EX_UIAxes,'off'); 
            
        end               
       
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            EX_calculate(app) 
        end

        % Button pushed function: TestInput_Button
        function TestInput_ButtonPushed(app, event)
            if app.TabGroup.SelectedTab == app.GrtOpx_Tab
                app.GrtOpx_grt_Fe_EditField.Value = 2.010;
                app.GrtOpx_grt_Mg_EditField.Value = 0.800;
                app.GrtOpx_grt_Ca_EditField.Value = 0.149;
                app.GrtOpx_grt_Mn_EditField.Value = 0.048;
                app.GrtOpx_opx_Al_EditField.Value = 0.176;
                app.GrtOpx_opx_Fe_EditField.Value = 0.891;
                app.GrtOpx_opx_Mg_EditField.Value = 0.974;
                app.GrtOpx_P_EditField.Value = 7;
                GrtOpx_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtCpx_Tab
                app.GrtCpx_grt_Fe_EditField.Value = 2.057;
                app.GrtCpx_grt_Mg_EditField.Value = 0.268;
                app.GrtCpx_grt_Ca_EditField.Value = 0.685;
                app.GrtCpx_grt_Mn_EditField.Value = 0.069;
                app.GrtCpx_cpx_Al_EditField.Value = 0.080;                
                app.GrtCpx_cpx_Fe_EditField.Value = 0.560;
                app.GrtCpx_cpx_Mg_EditField.Value = 0.497;
                app.GrtCpx_cpx_Ca_EditField.Value = 0.879;
                app.GrtCpx_cpx_Na_EditField.Value = 0.032;
                app.GrtCpx_P_EditField.Value = 8;
                GrtCpx_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtBi_Tab
                app.GrtBi_grt_Fe_EditField.Value = 2.358;
                app.GrtBi_grt_Mg_EditField.Value = 0.525;
                app.GrtBi_grt_Ca_EditField.Value = 0.039;
                app.GrtBi_grt_Mn_EditField.Value = 0.016;
                app.GrtBi_bi_Ti_EditField.Value = 0.046;
                app.GrtBi_bi_Al_EditField.Value = 0.459;                
                app.GrtBi_bi_Fe_EditField.Value = 1.191;
                app.GrtBi_bi_Mg_EditField.Value = 1.219;
                app.GrtBi_bi_Mn_EditField.Value = 0.001;                
                app.GrtBi_P_EditField.Value = 5;
                GrtBi_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtCrd_Tab
                app.GrtCrd_grt_Fe_EditField.Value = 2.238;
                app.GrtCrd_grt_Mg_EditField.Value = 0.730;
                app.GrtCrd_grt_Ca_EditField.Value = 0.117;
                app.GrtCrd_grt_Mn_EditField.Value = 0.047;                
                app.GrtCrd_crd_Fe_EditField.Value = 0.609;
                app.GrtCrd_crd_Mg_EditField.Value = 1.471;                
                app.GrtCrd_P_EditField.Value = 6;
                GrtCrd_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtHbl_Tab
                app.GrtHbl_grt_Fe_EditField.Value = 2.074;
                app.GrtHbl_grt_Mg_EditField.Value = 0.370;
                app.GrtHbl_grt_Ca_EditField.Value = 0.617;
                app.GrtHbl_grt_Mn_EditField.Value = 0.078;                
                app.GrtHbl_hbl_Fe_EditField.Value = 2.788;
                app.GrtHbl_hbl_Mg_EditField.Value = 1.479;                
                GrtHbl_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtChl_Tab
                app.GrtChl_grt_Fe_EditField.Value = 2.358;
                app.GrtChl_grt_Mg_EditField.Value = 0.525;
                app.GrtChl_grt_Ca_EditField.Value = 0.039;
                app.GrtChl_grt_Mn_EditField.Value = 0.016;                
                app.GrtChl_chl_Fe_EditField.Value = 1.191;
                app.GrtChl_chl_Mg_EditField.Value = 1.219;
                app.GrtChl_P_EditField.Value = 5;
                GrtChl_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtPh_Tab
                app.GrtPh_grt_Fe_EditField.Value = 2.224;
                app.GrtPh_grt_Mg_EditField.Value = 0.270;
                app.GrtPh_grt_Ca_EditField.Value = 0.074;
                app.GrtPh_grt_Mn_EditField.Value = 0.324; 
                app.GrtPh_ph_Al_EditField.Value = 3.560;
                app.GrtPh_ph_Fe_EditField.Value = 0.210;
                app.GrtPh_ph_Mg_EditField.Value = 0.160;
                app.GrtPh_P_EditField.Value = 4;
                GrtPh_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtIlm_Tab
                app.GrtIlm_grt_Fe_EditField.Value = 2.224;
                app.GrtIlm_grt_Mg_EditField.Value = 0.270;
                app.GrtIlm_grt_Ca_EditField.Value = 0.074;
                app.GrtIlm_grt_Mn_EditField.Value = 0.324; 
                app.GrtIlm_ilm_Fe_EditField.Value = 1.000;
                app.GrtIlm_ilm_Mn_EditField.Value = 0.020;
                GrtIlm_calculate(app)
            elseif app.TabGroup.SelectedTab == app.TiBi_Tab
                app.TiBi_Ti_EditField.Value = 0.046;
                app.TiBi_Al_EditField.Value = 0.459;                
                app.TiBi_Fe_EditField.Value = 1.191;
                app.TiBi_Mg_EditField.Value = 1.219;                
                app.TiBi_P_EditField.Value = 5;
                TiBi_calculate(app)
            elseif app.TabGroup.SelectedTab == app.TiZrn_Tab
                app.TiZrn_Ti_EditField.Value = 21.8;
                TiZrn_calculate(app)
            elseif app.TabGroup.SelectedTab == app.TiQz_Tab
                app.TiQz_Ti_EditField.Value = 119.902;
                app.TiQz_P_EditField.Value = 10;
                TiQz_calculate(app)
            elseif app.TabGroup.SelectedTab == app.ZrRu_Tab
                app.ZrRu_Zr_EditField.Value = 148;
                app.ZrRu_P_EditField.Value = 10;
                ZrRu_calculate(app)
            elseif app.TabGroup.SelectedTab == app.ZrTtn_Tab
                app.ZrTtn_Zr_EditField.Value = 148;
                app.ZrTtn_P_EditField.Value = 10;
                ZrTtn_calculate(app)
            end
        end

        % Button pushed function: Clear_Button
        function Clear_ButtonPushed(app, event)
            if app.TabGroup.SelectedTab == app.GrtOpx_Tab 
                app.GrtOpx_grt_Fe_EditField.Value = 0;
                app.GrtOpx_grt_Mg_EditField.Value = 0;
                app.GrtOpx_grt_Ca_EditField.Value = 0;
                app.GrtOpx_grt_Mn_EditField.Value = 0;            
                app.GrtOpx_opx_Fe_EditField.Value = 0;
                app.GrtOpx_opx_Mg_EditField.Value = 0;
                app.GrtOpx_opx_Al_EditField.Value = 0;           
                app.GrtOpx_P_EditField.Value = 0;
                app.GrtOpx_grt_XFe_EditField.Value = 0;
                app.GrtOpx_grt_XMg_EditField.Value = 0;
                app.GrtOpx_grt_XCa_EditField.Value = 0;
                app.GrtOpx_grt_XMn_EditField.Value = 0;            
                app.GrtOpx_opx_XFe_EditField.Value = 0;
                app.GrtOpx_opx_XMg_EditField.Value = 0;
                app.GrtOpx_opx_XAl_EditField.Value = 0;           
                app.GrtOpx_lnKd_EditField.Value = 0;
                app.GrtOpx_MG78_EditField.Value = 0;
                app.GrtOpx_D80_EditField.Value = 0;
                app.GrtOpx_R83_EditField.Value = 0;
                app.GrtOpx_H84_EditField.Value = 0;
                app.GrtOpx_SB84_EditField.Value = 0;
                app.GrtOpx_LG84_EditField.Value = 0;
                app.GrtOpx_P85_EditField.Value = 0;
                app.GrtOpx_LG88_EditField.Value = 0;
                app.GrtOpx_AP89_EditField.Value = 0;
                app.GrtOpx_PL90_EditField.Value = 0;
                app.GrtOpx_B91_EditField.Value = 0;
                GrtOpx_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtCpx_Tab
                app.GrtCpx_grt_Fe_EditField.Value = 0;
                app.GrtCpx_grt_Mg_EditField.Value = 0;
                app.GrtCpx_grt_Ca_EditField.Value = 0;
                app.GrtCpx_grt_Mn_EditField.Value = 0;
                app.GrtCpx_cpx_Al_EditField.Value = 0;                
                app.GrtCpx_cpx_Fe_EditField.Value = 0;
                app.GrtCpx_cpx_Mg_EditField.Value = 0;
                app.GrtCpx_cpx_Ca_EditField.Value = 0;
                app.GrtCpx_cpx_Na_EditField.Value = 0;
                app.GrtCpx_P_EditField.Value = 0;
                app.GrtCpx_grt_XFe_EditField.Value = 0;
                app.GrtCpx_grt_XMg_EditField.Value = 0;
                app.GrtCpx_grt_XCa_EditField.Value = 0;
                app.GrtCpx_grt_XMn_EditField.Value = 0;
                app.GrtCpx_cpx_XFe_EditField.Value = 0;
                app.GrtCpx_cpx_XMg_EditField.Value = 0;
                app.GrtCpx_cpx_XCa_EditField.Value = 0;
                app.GrtCpx_lnKd_EditField.Value = 0;
                app.GrtCpx_MH72_EditField.Value = 0;
                app.GrtCpx_RG74_EditField.Value = 0;
                app.GrtCpx_MG78_EditField.Value = 0;
                app.GrtCpx_EG79_EditField.Value = 0;
                app.GrtCpx_S79_EditField.Value = 0;
                app.GrtCpx_D80_EditField.Value = 0;
                app.GrtCpx_P85_EditField.Value = 0;
                app.GrtCpx_K88_EditField.Value = 0;
                app.GrtCpx_A94_EditField.Value = 0;
                app.GrtCpx_R00_EditField.Value = 0;
                app.GrtCpx_N09_EditField.Value = 0;                
                GrtCpx_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtBi_Tab 
                app.GrtBi_grt_Fe_EditField.Value = 0;
                app.GrtBi_grt_Mg_EditField.Value = 0;
                app.GrtBi_grt_Ca_EditField.Value = 0;
                app.GrtBi_grt_Mn_EditField.Value = 0;
                app.GrtBi_bi_Ti_EditField.Value = 0;
                app.GrtBi_bi_Al_EditField.Value = 0;
                app.GrtBi_bi_Fe_EditField.Value = 0;
                app.GrtBi_bi_Mg_EditField.Value = 0;
                app.GrtBi_bi_Mn_EditField.Value = 0;
                app.GrtBi_P_EditField.Value = 0;
                app.GrtBi_grt_XFe_EditField.Value = 0;
                app.GrtBi_grt_XMg_EditField.Value = 0;
                app.GrtBi_grt_XCa_EditField.Value = 0;
                app.GrtBi_grt_XMn_EditField.Value = 0;
                app.GrtBi_bi_XTi_EditField.Value = 0;
                app.GrtBi_bi_XAl_EditField.Value = 0;
                app.GrtBi_bi_XFe_EditField.Value = 0;
                app.GrtBi_bi_XMg_EditField.Value = 0;
                app.GrtBi_bi_XMn_EditField.Value = 0;
                app.GrtBi_lnKd_EditField.Value = 0;
                app.GrtBi_T76_EditField.Value = 0;
                app.GrtBi_GA77_EditField.Value = 0;
                app.GrtBi_HL77_EditField.Value = 0;
                app.GrtBi_FS78_EditField.Value = 0;
                app.GrtBi_HS82_EditField.Value = 0;
                app.GrtBi_PL83a_EditField.Value = 0;
                app.GrtBi_PL83b_EditField.Value = 0;
                app.GrtBi_GS84_EditField.Value = 0;
                app.GrtBi_GS84_EditField.Value = 0;
                app.GrtBi_IM85a_EditField.Value = 0;
                app.GrtBi_IM85b_EditField.Value = 0;
                app.GrtBi_P85_EditField.Value = 0;
                app.GrtBi_D91_EditField.Value = 0;
                app.GrtBi_B92a_EditField.Value = 0;
                app.GrtBi_B92b_EditField.Value = 0;
                GrtBi_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtCrd_Tab
                app.GrtCrd_grt_Fe_EditField.Value = 0;
                app.GrtCrd_grt_Mg_EditField.Value = 0;
                app.GrtCrd_grt_Ca_EditField.Value = 0;
                app.GrtCrd_grt_Mn_EditField.Value = 0;                
                app.GrtCrd_crd_Fe_EditField.Value = 0;
                app.GrtCrd_crd_Mg_EditField.Value = 0;                
                app.GrtCrd_P_EditField.Value = 0;
                app.GrtCrd_grt_XFe_EditField.Value = 0;
                app.GrtCrd_grt_XMg_EditField.Value = 0;
                app.GrtCrd_grt_XCa_EditField.Value = 0;
                app.GrtCrd_grt_XMn_EditField.Value = 0;                
                app.GrtCrd_crd_XFe_EditField.Value = 0;
                app.GrtCrd_crd_XMg_EditField.Value = 0;
                app.GrtCrd_lnKd_EditField.Value = 0;
                app.GrtCrd_C71_EditField.Value = 0; 
                app.GrtCrd_T76_EditField.Value = 0;
                app.GrtCrd_HL77_EditField.Value = 0;
                app.GrtCrd_P85_EditField.Value = 0;
                app.GrtCrd_B88_EditField.Value = 0;
                app.GrtCrd_B93_EditField.Value = 0;
                GrtCrd_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtHbl_Tab
                app.GrtHbl_grt_Fe_EditField.Value = 0;
                app.GrtHbl_grt_Mg_EditField.Value = 0;
                app.GrtHbl_grt_Ca_EditField.Value = 0;
                app.GrtHbl_grt_Mn_EditField.Value = 0;                
                app.GrtHbl_hbl_Fe_EditField.Value = 0;
                app.GrtHbl_hbl_Mg_EditField.Value = 0;                
                app.GrtHbl_grt_XFe_EditField.Value = 0;
                app.GrtHbl_grt_XMg_EditField.Value = 0;
                app.GrtHbl_grt_XCa_EditField.Value = 0;
                app.GrtHbl_grt_XMn_EditField.Value = 0;                
                app.GrtHbl_hbl_XFe_EditField.Value = 0;
                app.GrtHbl_hbl_XMg_EditField.Value = 0;
                app.GrtHbl_lnKd_EditField.Value = 0;
                app.GrtHbl_GP84_EditField.Value = 0; 
                app.GrtHbl_Per85_EditField.Value = 0;
                app.GrtHbl_Pow85_EditField.Value = 0;
                app.GrtHbl_R00_EditField.Value = 0;
                GrtHbl_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtChl_Tab
                app.GrtChl_grt_Fe_EditField.Value = 0;
                app.GrtChl_grt_Mg_EditField.Value = 0;
                app.GrtChl_grt_Ca_EditField.Value = 0;
                app.GrtChl_grt_Mn_EditField.Value = 0;                
                app.GrtChl_chl_Fe_EditField.Value = 0;
                app.GrtChl_chl_Mg_EditField.Value = 0;                
                app.GrtChl_grt_XFe_EditField.Value = 0;
                app.GrtChl_grt_XMg_EditField.Value = 0;
                app.GrtChl_grt_XCa_EditField.Value = 0;
                app.GrtChl_grt_XMn_EditField.Value = 0;                
                app.GrtChl_chl_XFe_EditField.Value = 0;
                app.GrtChl_chl_XMg_EditField.Value = 0;
                app.GrtChl_P_EditField.Value = 0;
                app.GrtChl_lnKd_EditField.Value = 0;
                app.GrtChl_DH86_EditField.Value = 0; 
                app.GrtChl_G90_EditField.Value = 0;
                app.GrtChl_P91_EditField.Value = 0;
                GrtChl_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtPh_Tab
                app.GrtPh_grt_Fe_EditField.Value = 0;
                app.GrtPh_grt_Mg_EditField.Value = 0;
                app.GrtPh_grt_Ca_EditField.Value = 0;
                app.GrtPh_grt_Mn_EditField.Value = 0;
                app.GrtPh_ph_Al_EditField.Value = 0;
                app.GrtPh_ph_Fe_EditField.Value = 0;
                app.GrtPh_ph_Mg_EditField.Value = 0;                
                app.GrtPh_grt_XFe_EditField.Value = 0;
                app.GrtPh_grt_XMg_EditField.Value = 0;
                app.GrtPh_grt_XCa_EditField.Value = 0;
                app.GrtPh_grt_XMn_EditField.Value = 0;
                app.GrtPh_ph_XAl_EditField.Value = 0;
                app.GrtPh_ph_XFe_EditField.Value = 0;
                app.GrtPh_ph_XMg_EditField.Value = 0;
                app.GrtPh_P_EditField.Value = 0;
                app.GrtPh_lnKd_EditField.Value = 0;
                app.GrtPh_KR78_EditField.Value = 0; 
                app.GrtPh_GH82a_EditField.Value = 0;
                app.GrtPh_GH82b_EditField.Value = 0;
                app.GrtPh_GH82c_EditField.Value = 0;
                app.GrtPh_HF88_EditField.Value = 0;
                app.GrtPh_W02a_EditField.Value = 0;
                app.GrtPh_W02b_EditField.Value = 0;
                GrtPh_calculate(app)
            elseif app.TabGroup.SelectedTab == app.GrtIlm_Tab
                app.GrtIlm_grt_Fe_EditField.Value = 0;
                app.GrtIlm_grt_Mg_EditField.Value = 0;
                app.GrtIlm_grt_Ca_EditField.Value = 0;
                app.GrtIlm_grt_Mn_EditField.Value = 0;
                app.GrtIlm_ilm_Fe_EditField.Value = 0;
                app.GrtIlm_ilm_Mn_EditField.Value = 0;                
                app.GrtIlm_grt_XFe_EditField.Value = 0;
                app.GrtIlm_grt_XMg_EditField.Value = 0;
                app.GrtIlm_grt_XCa_EditField.Value = 0;
                app.GrtIlm_grt_XMn_EditField.Value = 0;
                app.GrtIlm_ilm_XFe_EditField.Value = 0;
                app.GrtIlm_ilm_XMn_EditField.Value = 0;
                app.GrtIlm_lnKd_EditField.Value = 0;
                app.GrtIlm_P87_EditField.Value = 0; 
                app.GrtIlm_P91_EditField.Value = 0;
                app.GrtIlm_M10_EditField.Value = 0;
                GrtIlm_calculate(app)
            elseif app.TabGroup.SelectedTab == app.TiBi_Tab 
                app.TiBi_Ti_EditField.Value = 0;
                app.TiBi_Al_EditField.Value = 0;
                app.TiBi_Fe_EditField.Value = 0;
                app.TiBi_Mg_EditField.Value = 0;
                app.TiBi_P_EditField.Value = 0;
                app.TiBi_XTi_EditField.Value = 0;
                app.TiBi_XAl_EditField.Value = 0;
                app.TiBi_XFe_EditField.Value = 0;
                app.TiBi_XMg_EditField.Value = 0;
                app.TiBi_MgN_EditField.Value = 0;
                app.TiBi_HG02_EditField.Value = 0;
                app.TiBi_H05_EditField.Value = 0;
                app.TiBi_WC15_EditField.Value = 0;
                TiBi_calculate(app)
            elseif app.TabGroup.SelectedTab == app.TiZrn_Tab 
                app.TiZrn_Ti_EditField.Value = 0;
                app.TiZrn_aSiO2_EditField.Value = 1;
                app.TiZrn_aTiO2_EditField.Value = 1;
                app.TiZrn_W06_EditField.Value = 0;
                app.TiZrn_FW07_EditField.Value = 0;
                TiZrn_calculate(app)
            elseif app.TabGroup.SelectedTab == app.TiQz_Tab 
                app.TiQz_Ti_EditField.Value = 0;
                app.TiQz_aTiO2_EditField.Value = 1;
                app.TiQz_P_EditField.Value = 0;
                app.TiQz_WW06_EditField.Value = 0;
                app.TiQz_KO08_EditField.Value = 0;
                app.TiQz_T10_EditField.Value = 0;
                app.TiQz_HA12_EditField.Value = 0;
                TiQz_calculate(app)
            elseif app.TabGroup.SelectedTab == app.ZrRu_Tab 
                app.ZrRu_Zr_EditField.Value = 0;
                app.ZrRu_aSiO2_EditField.Value = 1;
                app.ZrRu_P_EditField.Value = 0;
                app.ZrRu_Z04_EditField.Value = 0;
                app.ZrRu_W06_EditField.Value = 0;
                app.ZrRu_FW07_EditField.Value = 0;
                app.ZrRu_T07a_EditField.Value = 0;
                app.ZrRu_T07b_EditField.Value = 0;
                app.ZrRu_T07c_EditField.Value = 0;
                ZrRu_calculate(app)
            elseif app.TabGroup.SelectedTab == app.ZrTtn_Tab 
                app.ZrTtn_Zr_EditField.Value = 0;
                app.ZrTtn_aSiO2_EditField.Value = 1;
                app.ZrTtn_aTiO2_EditField.Value = 1;
                app.ZrTtn_P_EditField.Value = 0;
                app.ZrTtn_H08_EditField.Value = 0;
                ZrTtn_calculate(app)
            end
        end

        % Button pushed function: Export_Button
        function Export_ButtonPushed(app, event)
            if app.TabGroup.SelectedTab == app.GrtOpx_Tab
                GrtOpxDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","opx_Al","opx_Fe","opx_Mg",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","opx_XAl","opx_XFe","opx_XMg","lnKd","P","MG78","D80","R83",...
                    "H84","SB84","LG84","P85","LG88","AP89","PL90","B91"];
                GrtOpxData = [app.GrtOpx_grt_Fe_EditField.Value,app.GrtOpx_grt_Mg_EditField.Value,...
                    app.GrtOpx_grt_Ca_EditField.Value,app.GrtOpx_grt_Mn_EditField.Value,app.GrtOpx_opx_Al_EditField.Value,...
                    app.GrtOpx_opx_Fe_EditField.Value,app.GrtOpx_opx_Mg_EditField.Value,app.GrtOpx_grt_XFe_EditField.Value,...
                    app.GrtOpx_grt_XMg_EditField.Value,app.GrtOpx_grt_XCa_EditField.Value,app.GrtOpx_grt_XMn_EditField.Value,...
                    app.GrtOpx_opx_XAl_EditField.Value,app.GrtOpx_opx_XFe_EditField.Value,app.GrtOpx_opx_XMg_EditField.Value,...
                    app.GrtOpx_lnKd_EditField.Value,app.GrtOpx_P_EditField.Value,app.GrtOpx_MG78_EditField.Value,...
                    app.GrtOpx_D80_EditField.Value,app.GrtOpx_R83_EditField.Value,app.GrtOpx_H84_EditField.Value,...
                    app.GrtOpx_SB84_EditField.Value,app.GrtOpx_LG84_EditField.Value,app.GrtOpx_P85_EditField.Value,...
                    app.GrtOpx_LG88_EditField.Value,app.GrtOpx_AP89_EditField.Value,app.GrtOpx_PL90_EditField.Value,app.GrtOpx_B91_EditField.Value];
                GrtOpxDataExport = [GrtOpxDataTitle;GrtOpxData];
                GrtOpx_file = fopen('export_GrtOpx.txt','a');
                fprintf(GrtOpx_file,'%s %.3f\n',GrtOpxDataExport);
                fprintf(GrtOpx_file,'%s\n','');
                fclose(GrtOpx_file);
            elseif app.TabGroup.SelectedTab == app.GrtCpx_Tab
                GrtCpxDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","cpx_Al","cpx_Fe","cpx_Mg","cpx_Ca","cpx_Na",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","cpx_XFe","cpx_XMg","cpx_XCa","lnKd","P","MH72","RG74","MG78",...
                    "EG79","S79","D80","P85","K88","A94","R00","N09"];
                GrtCpxData = [app.GrtCpx_grt_Fe_EditField.Value,app.GrtCpx_grt_Mg_EditField.Value,app.GrtCpx_grt_Ca_EditField.Value,...
                    app.GrtCpx_grt_Mn_EditField.Value,app.GrtCpx_cpx_Al_EditField.Value,app.GrtCpx_cpx_Fe_EditField.Value,app.GrtCpx_cpx_Mg_EditField.Value,...
                    app.GrtCpx_cpx_Ca_EditField.Value,app.GrtCpx_cpx_Na_EditField.Value,app.GrtCpx_grt_XFe_EditField.Value,app.GrtCpx_grt_XMg_EditField.Value,...
                    app.GrtCpx_grt_XCa_EditField.Value,app.GrtCpx_grt_XMn_EditField.Value,app.GrtCpx_cpx_XFe_EditField.Value,...
                    app.GrtCpx_cpx_XMg_EditField.Value,app.GrtCpx_cpx_XCa_EditField.Value,app.GrtCpx_lnKd_EditField.Value,...
                    app.GrtCpx_P_EditField.Value,app.GrtCpx_MH72_EditField.Value,app.GrtCpx_RG74_EditField.Value,...
                    app.GrtCpx_MG78_EditField.Value,app.GrtCpx_EG79_EditField.Value,app.GrtCpx_S79_EditField.Value,...
                    app.GrtCpx_D80_EditField.Value,app.GrtCpx_P85_EditField.Value,app.GrtCpx_K88_EditField.Value,...
                    app.GrtCpx_A94_EditField.Value,app.GrtCpx_R00_EditField.Value,app.GrtCpx_N09_EditField.Value];
                GrtCpxDataExport = [GrtCpxDataTitle;GrtCpxData];
                GrtCpx_file = fopen('export_GrtCpx.txt','a');
                fprintf(GrtCpx_file,'%s %.3f\n',GrtCpxDataExport);
                fprintf(GrtCpx_file,'%s\n','');
                fclose(GrtCpx_file);
            elseif app.TabGroup.SelectedTab == app.GrtBi_Tab
                GrtBiDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","bi_Ti","bi_Al","bi_Fe","bi_Mg","bi_Mn",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","bi_XTi","bi_XAl","bi_XFe","bi_XMg","bi_XMn","lnKd","P","T76","GA77","HL77",...
                    "FS78","HS82","PL83a","PL83b","GS84","P85","IM85a","IM85b","D91","B92a","B92b"];
                GrtBiData = [app.GrtBi_grt_Fe_EditField.Value,app.GrtBi_grt_Mg_EditField.Value,app.GrtBi_grt_Ca_EditField.Value,...
                    app.GrtBi_grt_Mn_EditField.Value,app.GrtBi_bi_Ti_EditField.Value,app.GrtBi_bi_Al_EditField.Value,...
                    app.GrtBi_bi_Fe_EditField.Value,app.GrtBi_bi_Mg_EditField.Value,app.GrtBi_bi_Mn_EditField.Value,...
                    app.GrtBi_grt_XFe_EditField.Value,app.GrtBi_grt_XMg_EditField.Value,...
                    app.GrtBi_grt_XCa_EditField.Value,app.GrtBi_grt_XMn_EditField.Value,app.GrtBi_bi_XTi_EditField.Value,...
                    app.GrtBi_bi_XAl_EditField.Value,app.GrtBi_bi_XFe_EditField.Value,...
                    app.GrtBi_bi_XMg_EditField.Value,app.GrtBi_bi_XMn_EditField.Value,app.GrtBi_lnKd_EditField.Value,...
                    app.GrtBi_P_EditField.Value,app.GrtBi_T76_EditField.Value,app.GrtBi_GA77_EditField.Value,...
                    app.GrtBi_HL77_EditField.Value,app.GrtBi_FS78_EditField.Value,app.GrtBi_HS82_EditField.Value,...
                    app.GrtBi_PL83a_EditField.Value,app.GrtBi_PL83b_EditField.Value,app.GrtBi_GS84_EditField.Value,...
                    app.GrtBi_IM85a_EditField.Value,app.GrtBi_IM85b_EditField.Value,app.GrtBi_P85_EditField.Value,...
                    app.GrtBi_D91_EditField.Value,app.GrtBi_B92a_EditField.Value,app.GrtBi_B92b_EditField.Value];
                GrtBiDataExport = [GrtBiDataTitle;GrtBiData];
                GrtBi_file = fopen('export_GrtBi.txt','a');
                fprintf(GrtBi_file,'%s %.3f\n',GrtBiDataExport);
                fprintf(GrtBi_file,'%s\n','');
                fclose(GrtBi_file);
            elseif app.TabGroup.SelectedTab == app.GrtCrd_Tab
                GrtCrdDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","crd_Fe","crd_Mg",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","crd_XFe","crd_XMg","lnKd","P","C71","T76","HL77",...
                    "P85","B88","B93"];
                GrtCrdData = [app.GrtCrd_grt_Fe_EditField.Value,app.GrtCrd_grt_Mg_EditField.Value,app.GrtCrd_grt_Ca_EditField.Value,...
                    app.GrtCrd_grt_Mn_EditField.Value,app.GrtCrd_crd_Fe_EditField.Value,app.GrtCrd_crd_Mg_EditField.Value,...
                    app.GrtCrd_grt_XFe_EditField.Value,app.GrtCrd_grt_XMg_EditField.Value,app.GrtCrd_grt_XCa_EditField.Value,...
                    app.GrtCrd_grt_XMn_EditField.Value,app.GrtCrd_crd_XFe_EditField.Value,app.GrtCrd_crd_XMg_EditField.Value,...
                    app.GrtCrd_lnKd_EditField.Value,app.GrtCrd_P_EditField.Value,app.GrtCrd_C71_EditField.Value,...
                    app.GrtCrd_T76_EditField.Value,app.GrtCrd_HL77_EditField.Value,app.GrtCrd_P85_EditField.Value,...
                    app.GrtCrd_B88_EditField.Value,app.GrtCrd_B93_EditField.Value];
                GrtCrdDataExport = [GrtCrdDataTitle;GrtCrdData];
                GrtCrd_file = fopen('export_GrtCrd.txt','a');
                fprintf(GrtCrd_file,'%s %.3f\n',GrtCrdDataExport);
                fprintf(GrtCrd_file,'%s\n','');
                fclose(GrtCrd_file);
            elseif app.TabGroup.SelectedTab == app.GrtHbl_Tab
                GrtHblDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","hbl_Fe","hbl_Mg",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","hbl_XFe","hbl_XMg","lnKd","GP84","Per85","Pow85","R00"];
                GrtHblData = [app.GrtHbl_grt_Fe_EditField.Value,app.GrtHbl_grt_Mg_EditField.Value,app.GrtHbl_grt_Ca_EditField.Value,...
                    app.GrtHbl_grt_Mn_EditField.Value,app.GrtHbl_hbl_Fe_EditField.Value,app.GrtHbl_hbl_Mg_EditField.Value,...
                    app.GrtHbl_grt_XFe_EditField.Value,app.GrtHbl_grt_XMg_EditField.Value,app.GrtHbl_grt_XCa_EditField.Value,...
                    app.GrtHbl_grt_XMn_EditField.Value,app.GrtHbl_hbl_XFe_EditField.Value,app.GrtHbl_hbl_XMg_EditField.Value,...
                    app.GrtHbl_lnKd_EditField.Value,app.GrtHbl_GP84_EditField.Value,app.GrtHbl_Per85_EditField.Value,...
                    app.GrtHbl_Pow85_EditField.Value,app.GrtHbl_R00_EditField.Value];
                GrtHblDataExport = [GrtHblDataTitle;GrtHblData];
                GrtHbl_file = fopen('export_GrtHbl.txt','a');
                fprintf(GrtHbl_file,'%s %.3f\n',GrtHblDataExport);
                fprintf(GrtHbl_file,'%s\n','');
                fclose(GrtHbl_file);
            elseif app.TabGroup.SelectedTab == app.GrtChl_Tab
                GrtChlDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","chl_Fe","chl_Mg",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","chl_XFe","chl_XMg","lnKd","P","DH86","G90","P91"];
                GrtChlData = [app.GrtChl_grt_Fe_EditField.Value,app.GrtChl_grt_Mg_EditField.Value,app.GrtChl_grt_Ca_EditField.Value,...
                    app.GrtChl_grt_Mn_EditField.Value,app.GrtChl_chl_Fe_EditField.Value,app.GrtChl_chl_Mg_EditField.Value,...
                    app.GrtChl_grt_XFe_EditField.Value,app.GrtChl_grt_XMg_EditField.Value,app.GrtChl_grt_XCa_EditField.Value,...
                    app.GrtChl_grt_XMn_EditField.Value,app.GrtChl_chl_XFe_EditField.Value,app.GrtChl_chl_XMg_EditField.Value,...
                    app.GrtChl_lnKd_EditField.Value,app.GrtChl_P_EditField.Value,app.GrtChl_DH86_EditField.Value,app.GrtChl_G90_EditField.Value,...
                    app.GrtChl_P91_EditField.Value];
                GrtChlDataExport = [GrtChlDataTitle;GrtChlData];
                GrtChl_file = fopen('export_GrtChl.txt','a');
                fprintf(GrtChl_file,'%s %.3f\n',GrtChlDataExport);
                fprintf(GrtChl_file,'%s\n','');
                fclose(GrtChl_file);
            elseif app.TabGroup.SelectedTab == app.GrtPh_Tab
                GrtPhDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","ph_Al","ph_Fe","ph_Mg",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","ph_XAl","ph_XFe","ph_XMg","lnKd",...
                    "P","KR78","GH82a","GH82b","GH82c","HF88","W02a","W02b"];
                GrtPhData = [app.GrtPh_grt_Fe_EditField.Value,app.GrtPh_grt_Mg_EditField.Value,app.GrtPh_grt_Ca_EditField.Value,...
                    app.GrtPh_grt_Mn_EditField.Value,app.GrtPh_ph_Al_EditField.Value,app.GrtPh_ph_Fe_EditField.Value,app.GrtPh_ph_Mg_EditField.Value,...
                    app.GrtPh_grt_XFe_EditField.Value,app.GrtPh_grt_XMg_EditField.Value,app.GrtPh_grt_XCa_EditField.Value,...
                    app.GrtPh_grt_XMn_EditField.Value,app.GrtPh_ph_XAl_EditField.Value,app.GrtPh_ph_XFe_EditField.Value,app.GrtPh_ph_XMg_EditField.Value,...
                    app.GrtPh_lnKd_EditField.Value,app.GrtPh_P_EditField.Value,app.GrtPh_KR78_EditField.Value,app.GrtPh_GH82a_EditField.Value,...
                    app.GrtPh_GH82b_EditField.Value,app.GrtPh_GH82c_EditField.Value,app.GrtPh_HF88_EditField.Value,...
                    app.GrtPh_W02a_EditField.Value,app.GrtPh_W02b_EditField.Value];
                GrtPhDataExport = [GrtPhDataTitle;GrtPhData];
                GrtPh_file = fopen('export_GrtPh.txt','a');
                fprintf(GrtPh_file,'%s %.3f\n',GrtPhDataExport);
                fprintf(GrtPh_file,'%s\n','');
                fclose(GrtPh_file);
            elseif app.TabGroup.SelectedTab == app.GrtIlm_Tab
                GrtIlmDataTitle = ["grt_Fe","grt_Mg","grt_Ca","grt_Mn","ilm_Fe","ilm_Mn",...
                    "grt_XFe","grt_XMg","grt_XCa","grt_XMn","ilm_XFe","ilm_XMn","lnKd",...
                    "P87","P91","M10"];
                GrtIlmData = [app.GrtIlm_grt_Fe_EditField.Value,app.GrtIlm_grt_Mg_EditField.Value,app.GrtIlm_grt_Ca_EditField.Value,...
                    app.GrtIlm_grt_Mn_EditField.Value,app.GrtIlm_ilm_Fe_EditField.Value,app.GrtIlm_ilm_Mn_EditField.Value,...
                    app.GrtIlm_grt_XFe_EditField.Value,app.GrtIlm_grt_XMg_EditField.Value,app.GrtIlm_grt_XCa_EditField.Value,...
                    app.GrtIlm_grt_XMn_EditField.Value,app.GrtIlm_ilm_XFe_EditField.Value,app.GrtIlm_ilm_XMn_EditField.Value,...
                    app.GrtIlm_lnKd_EditField.Value,app.GrtIlm_P87_EditField.Value,app.GrtIlm_P91_EditField.Value,...
                    app.GrtIlm_M10_EditField.Value];
                GrtIlmDataExport = [GrtIlmDataTitle;GrtIlmData];
                GrtIlm_file = fopen('export_GrtIlm.txt','a');
                fprintf(GrtIlm_file,'%s %.3f\n',GrtIlmDataExport);
                fprintf(GrtIlm_file,'%s\n','');
                fclose(GrtIlm_file);
            elseif app.TabGroup.SelectedTab == app.TiBi_Tab
                TiBiDataTitle = ["Ti","Al","Fe","Mg","XTi","XAl","XFe","XMg",...
                    "Mg#","P","HG02","H05","WC15"];
                TiBiData = [app.TiBi_Ti_EditField.Value,app.TiBi_Al_EditField.Value,...
                    app.TiBi_Fe_EditField.Value,app.TiBi_Mg_EditField.Value,app.TiBi_XTi_EditField.Value,...
                    app.TiBi_XAl_EditField.Value,app.TiBi_XFe_EditField.Value,app.TiBi_XMg_EditField.Value,...
                    app.TiBi_MgN_EditField.Value,app.TiBi_P_EditField.Value,app.TiBi_HG02_EditField.Value,...
                    app.TiBi_H05_EditField.Value,app.TiBi_WC15_EditField.Value];
                TiBiDataExport = [TiBiDataTitle;TiBiData];
                TiBi_file = fopen('export_TiBi.txt','a');
                fprintf(TiBi_file,'%s %.3f\n',TiBiDataExport);
                fprintf(TiBi_file,'%s\n','');
                fclose(TiBi_file);
            elseif app.TabGroup.SelectedTab == app.TiZrn_Tab
                TiZrnDataTitle = ["Ti","aSiO2","aTiO2","W06","FW07"];
                TiZrnData = [app.TiZrn_Ti_EditField.Value,app.TiZrn_aSiO2_EditField.Value,...
                    app.TiZrn_aTiO2_EditField.Value,app.TiZrn_W06_EditField.Value,app.TiZrn_FW07_EditField.Value];
                TiZrnDataExport = [TiZrnDataTitle;TiZrnData];
                TiZrn_file = fopen('export_TiZrn.txt','a');
                fprintf(TiZrn_file,'%s %.3f\n',TiZrnDataExport);
                fprintf(TiZrn_file,'%s\n','');
                fclose(TiZrn_file);
            elseif app.TabGroup.SelectedTab == app.TiQz_Tab
                TiQzDataTitle = ["Ti","aTiO2","P","WW06","KO08","T10","HA12"];
                TiQzData = [app.TiQz_Ti_EditField.Value,app.TiQz_aTiO2_EditField.Value,app.TiQz_P_EditField.Value,...
                    app.TiQz_WW06_EditField.Value,app.TiQz_KO08_EditField.Value,...
                    app.TiQz_T10_EditField.Value,app.TiQz_HA12_EditField.Value];
                TiQzDataExport = [TiQzDataTitle;TiQzData];
                TiQz_file = fopen('export_TiQz.txt','a');
                fprintf(TiQz_file,'%s %.3f\n',TiQzDataExport);
                fprintf(TiQz_file,'%s\n','');
                fclose(TiQz_file);
            elseif app.TabGroup.SelectedTab == app.ZrRu_Tab
                ZrRuDataTitle = ["Zr","aSiO2","P","Z04","W06","FW07","T07a","T07b","T07c"];
                ZrRuData = [app.ZrRu_Zr_EditField.Value,app.ZrRu_aSiO2_EditField.Value,app.ZrRu_P_EditField.Value,...
                    app.ZrRu_Z04_EditField.Value,app.ZrRu_W06_EditField.Value,app.ZrRu_FW07_EditField.Value,...
                    app.ZrRu_T07a_EditField.Value,app.ZrRu_T07b_EditField.Value,app.ZrRu_T07c_EditField.Value];
                ZrRuDataExport = [ZrRuDataTitle;ZrRuData];
                ZrRu_file = fopen('export_ZrRu.txt','a');
                fprintf(ZrRu_file,'%s %.3f\n',ZrRuDataExport);
                fprintf(ZrRu_file,'%s\n','');
                fclose(ZrRu_file);
            elseif app.TabGroup.SelectedTab == app.ZrTtn_Tab
                ZrTtnDataTitle = ["Zr","aSiO2","aTiO2","P","H08"];
                ZrTtnData = [app.ZrTtn_Zr_EditField.Value,app.ZrTtn_aSiO2_EditField.Value,app.ZrTtn_aTiO2_EditField.Value,...
                    app.ZrTtn_P_EditField.Value,app.ZrTtn_H08_EditField.Value];
                ZrTtnDataExport = [ZrTtnDataTitle;ZrTtnData];
                ZrTtn_file = fopen('export_ZrTtn.txt','a');
                fprintf(ZrTtn_file,'%s %.3f\n',ZrTtnDataExport);
                fprintf(ZrTtn_file,'%s\n','');
                fclose(ZrTtn_file);
            end
        end

        % Value changed function: GrtBi_B92a_CheckBox, 
        % GrtBi_B92acolor_EditField, GrtBi_B92b_CheckBox, 
        % GrtBi_B92bcolor_EditField, GrtBi_D91_CheckBox, 
        % GrtBi_D91color_EditField, GrtBi_FS78_CheckBox, 
        % GrtBi_FS78color_EditField, GrtBi_GA77_CheckBox, 
        % GrtBi_GA77color_EditField, GrtBi_GS84_CheckBox, 
        % GrtBi_GS84color_EditField, GrtBi_GridCheckBox, 
        % GrtBi_HL77_CheckBox, GrtBi_HL77color_EditField, 
        % GrtBi_HS82_CheckBox, GrtBi_HS82color_EditField, 
        % GrtBi_IM85a_CheckBox, GrtBi_IM85acolor_EditField, 
        % GrtBi_IM85b_CheckBox, GrtBi_IM85bcolor_EditField, 
        % GrtBi_LineWidth_Spinner, GrtBi_P85_CheckBox, 
        % GrtBi_P85color_EditField, GrtBi_PL83a_CheckBox, 
        % GrtBi_PL83acolor_EditField, GrtBi_PL83b_CheckBox, 
        % GrtBi_PL83bcolor_EditField, GrtBi_P_EditField, 
        % GrtBi_P_max, GrtBi_P_min, GrtBi_T76_CheckBox, 
        % GrtBi_T76color_EditField, GrtBi_T_max, GrtBi_T_min, 
        % GrtBi_bi_Al_EditField, GrtBi_bi_Fe_EditField, 
        % GrtBi_bi_Mg_EditField, GrtBi_bi_Mn_EditField, 
        % GrtBi_bi_Ti_EditField, GrtBi_grt_Ca_EditField, 
        % GrtBi_grt_Fe_EditField, GrtBi_grt_Mg_EditField, 
        % GrtBi_grt_Mn_EditField
        function GrtBi(app, event)
            GrtBi_calculate(app)            
        end

        % Value changed function: GrtBi_ColorAll_EditField
        function GrtBi_ColorAll_EditFieldValueChanged(app, event)
            app.GrtBi_T76color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_GA77color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_HL77color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_FS78color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_HS82color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_PL83acolor_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_PL83bcolor_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_GS84color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_P85color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_IM85acolor_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_IM85bcolor_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_D91color_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_B92acolor_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            app.GrtBi_B92bcolor_EditField.Value = app.GrtBi_ColorAll_EditField.Value;
            GrtBi_calculate(app)            
        end

        % Value changed function: GrtBi_DisplayAll_CheckBox
        function GrtBi_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtBi_DisplayAll_CheckBox.Value == 1
                app.GrtBi_T76_CheckBox.Value = 1;
                app.GrtBi_GA77_CheckBox.Value = 1;
                app.GrtBi_HL77_CheckBox.Value = 1;
                app.GrtBi_FS78_CheckBox.Value = 1;
                app.GrtBi_HS82_CheckBox.Value = 1;
                app.GrtBi_PL83a_CheckBox.Value = 1;
                app.GrtBi_PL83b_CheckBox.Value = 1;
                app.GrtBi_GS84_CheckBox.Value = 1;
                app.GrtBi_P85_CheckBox.Value = 1;
                app.GrtBi_IM85a_CheckBox.Value = 1;
                app.GrtBi_IM85b_CheckBox.Value = 1;
                app.GrtBi_D91_CheckBox.Value = 1;
                app.GrtBi_B92a_CheckBox.Value = 1;
                app.GrtBi_B92b_CheckBox.Value = 1;
            else
                app.GrtBi_T76_CheckBox.Value = 0;
                app.GrtBi_GA77_CheckBox.Value = 0;
                app.GrtBi_HL77_CheckBox.Value = 0;
                app.GrtBi_FS78_CheckBox.Value = 0;
                app.GrtBi_HS82_CheckBox.Value = 0;
                app.GrtBi_PL83a_CheckBox.Value = 0;
                app.GrtBi_PL83b_CheckBox.Value = 0;
                app.GrtBi_GS84_CheckBox.Value = 0;
                app.GrtBi_P85_CheckBox.Value = 0;
                app.GrtBi_IM85a_CheckBox.Value = 0;
                app.GrtBi_IM85b_CheckBox.Value = 0;
                app.GrtBi_D91_CheckBox.Value = 0;
                app.GrtBi_B92a_CheckBox.Value = 0;
                app.GrtBi_B92b_CheckBox.Value = 0;
            end
            GrtBi_calculate(app)            
        end

        % Value changed function: GrtChl_DH86_CheckBox, 
        % GrtChl_DH86color_EditField, GrtChl_G90_CheckBox, 
        % GrtChl_G90color_EditField, GrtChl_GridCheckBox, 
        % GrtChl_LineWidth_Spinner, GrtChl_P91_CheckBox, 
        % GrtChl_P91color_EditField, GrtChl_P_max, GrtChl_P_min, 
        % GrtChl_T_max, GrtChl_T_min, GrtChl_chl_Fe_EditField, 
        % GrtChl_chl_Mg_EditField, GrtChl_grt_Ca_EditField, 
        % GrtChl_grt_Fe_EditField, GrtChl_grt_Mg_EditField, 
        % GrtChl_grt_Mn_EditField
        function GrtChl(app, event)
            GrtChl_calculate(app)           
        end

        % Value changed function: GrtChl_ColorAll_EditField
        function GrtChl_ColorAll_EditFieldValueChanged(app, event)
            app.GrtChl_DH86color_EditField.Value = app.GrtChl_ColorAll_EditField.Value;
            app.GrtChl_G90color_EditField.Value = app.GrtChl_ColorAll_EditField.Value;
            app.GrtChl_P91color_EditField.Value = app.GrtChl_ColorAll_EditField.Value;
            GrtChl_calculate(app)
        end

        % Value changed function: GrtChl_DisplayAll_CheckBox
        function GrtChl_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtChl_DisplayAll_CheckBox.Value == 1
                app.GrtChl_DH86_CheckBox.Value = 1;
                app.GrtChl_G90_CheckBox.Value = 1;
                app.GrtChl_P91_CheckBox.Value = 1;
            else
                app.GrtChl_DH86_CheckBox.Value = 0;
                app.GrtChl_G90_CheckBox.Value = 0;
                app.GrtChl_P91_CheckBox.Value = 0;
            end
            GrtChl_calculate(app)
        end

        % Value changed function: GrtCpx_A94_CheckBox, 
        % GrtCpx_A94color_EditField, GrtCpx_D80_CheckBox, 
        % GrtCpx_D80color_EditField, GrtCpx_EG79_CheckBox, 
        % GrtCpx_EG79color_EditField, GrtCpx_GridCheckBox, 
        % GrtCpx_K88_CheckBox, GrtCpx_K88color_EditField, 
        % GrtCpx_LineWidth_Spinner, GrtCpx_MG78_CheckBox, 
        % GrtCpx_MG78color_EditField, GrtCpx_MH72_CheckBox, 
        % GrtCpx_MH72color_EditField, GrtCpx_N09_CheckBox, 
        % GrtCpx_N09color_EditField, GrtCpx_P85_CheckBox, 
        % GrtCpx_P85color_EditField, GrtCpx_P_EditField, 
        % GrtCpx_P_max, GrtCpx_P_min, GrtCpx_R00_CheckBox, 
        % GrtCpx_R00color_EditField, GrtCpx_RG74_CheckBox, 
        % GrtCpx_RG74color_EditField, GrtCpx_S79_CheckBox, 
        % GrtCpx_S79color_EditField, GrtCpx_T_max, GrtCpx_T_min, 
        % GrtCpx_cpx_Al_EditField, GrtCpx_cpx_Ca_EditField, 
        % GrtCpx_cpx_Fe_EditField, GrtCpx_cpx_Mg_EditField, 
        % GrtCpx_cpx_Na_EditField, GrtCpx_grt_Ca_EditField, 
        % GrtCpx_grt_Fe_EditField, GrtCpx_grt_Mg_EditField, 
        % GrtCpx_grt_Mn_EditField
        function GrtCpx(app, event)
            GrtCpx_calculate(app)            
        end

        % Value changed function: GrtCpx_ColorAll_EditField
        function GrtCpx_ColorAll_EditFieldValueChanged(app, event)
            app.GrtCpx_MH72color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_RG74color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_MG78color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_EG79color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_S79color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_D80color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_P85color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_K88color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_A94color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_R00color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            app.GrtCpx_N09color_EditField.Value = app.GrtCpx_ColorAll_EditField.Value;
            GrtCpx_calculate(app)            
        end

        % Value changed function: GrtCpx_DisplayAll_CheckBox
        function GrtCpx_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtCpx_DisplayAll_CheckBox.Value == 1
                app.GrtCpx_MH72_CheckBox.Value = 1;
                app.GrtCpx_RG74_CheckBox.Value = 1;
                app.GrtCpx_MG78_CheckBox.Value = 1;
                app.GrtCpx_EG79_CheckBox.Value = 1;
                app.GrtCpx_S79_CheckBox.Value = 1;
                app.GrtCpx_D80_CheckBox.Value = 1;
                app.GrtCpx_P85_CheckBox.Value = 1;
                app.GrtCpx_K88_CheckBox.Value = 1;
                app.GrtCpx_A94_CheckBox.Value = 1;
                app.GrtCpx_R00_CheckBox.Value = 1;
                app.GrtCpx_N09_CheckBox.Value = 1;
            else
                app.GrtCpx_MH72_CheckBox.Value = 0;
                app.GrtCpx_RG74_CheckBox.Value = 0;
                app.GrtCpx_MG78_CheckBox.Value = 0;
                app.GrtCpx_EG79_CheckBox.Value = 0;
                app.GrtCpx_S79_CheckBox.Value = 0;
                app.GrtCpx_D80_CheckBox.Value = 0;
                app.GrtCpx_P85_CheckBox.Value = 0;
                app.GrtCpx_K88_CheckBox.Value = 0;
                app.GrtCpx_A94_CheckBox.Value = 0;
                app.GrtCpx_R00_CheckBox.Value = 0;
                app.GrtCpx_N09_CheckBox.Value = 0;
            end
            GrtCpx_calculate(app)           
        end

        % Value changed function: GrtCrd_B88_CheckBox, 
        % GrtCrd_B88color_EditField, GrtCrd_B93_CheckBox, 
        % GrtCrd_B93color_EditField, GrtCrd_C71_CheckBox, 
        % GrtCrd_C71color_EditField, GrtCrd_GridCheckBox, 
        % GrtCrd_HL77_CheckBox, GrtCrd_HL77color_EditField, 
        % GrtCrd_LineWidth_Spinner, GrtCrd_P85_CheckBox, 
        % GrtCrd_P85color_EditField, GrtCrd_P_EditField, 
        % GrtCrd_P_max, GrtCrd_P_min, GrtCrd_T76_CheckBox, 
        % GrtCrd_T76color_EditField, GrtCrd_T_max, GrtCrd_T_min, 
        % GrtCrd_crd_Fe_EditField, GrtCrd_crd_Mg_EditField, 
        % GrtCrd_grt_Ca_EditField, GrtCrd_grt_Fe_EditField, 
        % GrtCrd_grt_Mg_EditField, GrtCrd_grt_Mn_EditField
        function GrtCrd(app, event)
            GrtCrd_calculate(app)            
        end

        % Value changed function: GrtCrd_ColorAll_EditField
        function GrtCrd_ColorAll_EditFieldValueChanged(app, event)
            app.GrtCrd_C71color_EditField.Value = app.GrtCrd_ColorAll_EditField.Value;
            app.GrtCrd_T76color_EditField.Value = app.GrtCrd_ColorAll_EditField.Value;
            app.GrtCrd_HL77color_EditField.Value = app.GrtCrd_ColorAll_EditField.Value;
            app.GrtCrd_P85color_EditField.Value = app.GrtCrd_ColorAll_EditField.Value;
            app.GrtCrd_B88color_EditField.Value = app.GrtCrd_ColorAll_EditField.Value;
            app.GrtCrd_B93color_EditField.Value = app.GrtCrd_ColorAll_EditField.Value;
            GrtCrd_calculate(app)            
        end

        % Value changed function: GrtCrd_DisplayAll_CheckBox
        function GrtCrd_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtCrd_DisplayAll_CheckBox.Value == 1
                app.GrtCrd_C71_CheckBox.Value = 1;
                app.GrtCrd_T76_CheckBox.Value = 1;
                app.GrtCrd_HL77_CheckBox.Value = 1;
                app.GrtCrd_P85_CheckBox.Value = 1;
                app.GrtCrd_B88_CheckBox.Value = 1;
                app.GrtCrd_B93_CheckBox.Value = 1;
            else
                app.GrtCrd_C71_CheckBox.Value = 0;
                app.GrtCrd_T76_CheckBox.Value = 0;
                app.GrtCrd_HL77_CheckBox.Value = 0;
                app.GrtCrd_P85_CheckBox.Value = 0;
                app.GrtCrd_B88_CheckBox.Value = 0;
                app.GrtCrd_B93_CheckBox.Value = 0;
            end
            GrtCrd_calculate(app)            
        end

        % Value changed function: GrtHbl_GP84_CheckBox, 
        % GrtHbl_GP84color_EditField, GrtHbl_GridCheckBox, 
        % GrtHbl_LineWidth_Spinner, GrtHbl_P_max, GrtHbl_P_min, 
        % GrtHbl_Per85_CheckBox, GrtHbl_Per85color_EditField, 
        % GrtHbl_Pow85_CheckBox, GrtHbl_Pow85color_EditField, 
        % GrtHbl_R00_CheckBox, GrtHbl_R00color_EditField, 
        % GrtHbl_T_max, GrtHbl_T_min, GrtHbl_grt_Ca_EditField, 
        % GrtHbl_grt_Fe_EditField, GrtHbl_grt_Mg_EditField, 
        % GrtHbl_grt_Mn_EditField, GrtHbl_hbl_Fe_EditField, 
        % GrtHbl_hbl_Mg_EditField
        function GrtHbl(app, event)
            GrtHbl_calculate(app)            
        end

        % Value changed function: GrtHbl_ColorAll_EditField
        function GrtHbl_ColorAll_EditFieldValueChanged(app, event)
            app.GrtHbl_GP84color_EditField.Value = app.GrtHbl_ColorAll_EditField.Value;
            app.GrtHbl_Per85color_EditField.Value = app.GrtHbl_ColorAll_EditField.Value;
            app.GrtHbl_Pow85color_EditField.Value = app.GrtHbl_ColorAll_EditField.Value;
            app.GrtHbl_R00color_EditField.Value = app.GrtHbl_ColorAll_EditField.Value;
            GrtHbl_calculate(app)
        end

        % Value changed function: GrtHbl_DisplayAll_CheckBox
        function GrtHbl_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtHbl_DisplayAll_CheckBox.Value == 1
                app.GrtHbl_GP84_CheckBox.Value = 1;
                app.GrtHbl_Per85_CheckBox.Value = 1;
                app.GrtHbl_Pow85_CheckBox.Value = 1;
                app.GrtHbl_R00_CheckBox.Value = 1;
            else
                app.GrtHbl_GP84_CheckBox.Value = 0;
                app.GrtHbl_Per85_CheckBox.Value = 0;
                app.GrtHbl_Pow85_CheckBox.Value = 0;
                app.GrtHbl_R00_CheckBox.Value = 0;
            end
            GrtHbl_calculate(app)            
        end

        % Value changed function: GrtIlm_GridCheckBox, 
        % GrtIlm_LineWidth_Spinner, GrtIlm_M10_CheckBox, 
        % GrtIlm_M10color_EditField, GrtIlm_P87_CheckBox, 
        % GrtIlm_P87color_EditField, GrtIlm_P91_CheckBox, 
        % GrtIlm_P91color_EditField, GrtIlm_P_max, GrtIlm_P_min, 
        % GrtIlm_T_max, GrtIlm_T_min, GrtIlm_grt_Ca_EditField, 
        % GrtIlm_grt_Fe_EditField, GrtIlm_grt_Mg_EditField, 
        % GrtIlm_grt_Mn_EditField, GrtIlm_ilm_Fe_EditField, 
        % GrtIlm_ilm_Mn_EditField
        function GrtIlm(app, event)
            GrtIlm_calculate(app)
        end

        % Value changed function: GrtIlm_ColorAll_EditField
        function GrtIlm_ColorAll_EditFieldValueChanged(app, event)
            app.GrtIlm_P87color_EditField.Value = app.GrtIlm_ColorAll_EditField.Value;
            app.GrtIlm_P91color_EditField.Value = app.GrtIlm_ColorAll_EditField.Value;
            app.GrtIlm_M10color_EditField.Value = app.GrtIlm_ColorAll_EditField.Value;
            GrtIlm_calculate(app)
        end

        % Value changed function: GrtIlm_DisplayAll_CheckBox
        function GrtIlm_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtIlm_DisplayAll_CheckBox.Value == 1
                app.GrtIlm_P87_CheckBox.Value = 1;
                app.GrtIlm_P91_CheckBox.Value = 1;
                app.GrtIlm_M10_CheckBox.Value = 1;
            else
                app.GrtIlm_P87_CheckBox.Value = 0;
                app.GrtIlm_P91_CheckBox.Value = 0;
                app.GrtIlm_M10_CheckBox.Value = 0;
            end
            GrtIlm_calculate(app)
        end

        % Value changed function: GrtPh_GH82a_CheckBox, 
        % GrtPh_GH82acolor_EditField, GrtPh_GH82b_CheckBox, 
        % GrtPh_GH82bcolor_EditField, GrtPh_GH82c_CheckBox, 
        % GrtPh_GH82ccolor_EditField, GrtPh_GridCheckBox, 
        % GrtPh_HF88_CheckBox, GrtPh_HF88color_EditField, 
        % GrtPh_KR78_CheckBox, GrtPh_KR78color_EditField, 
        % GrtPh_LineWidth_Spinner, GrtPh_P_EditField, GrtPh_P_max, 
        % GrtPh_P_min, GrtPh_T_max, GrtPh_T_min, 
        % GrtPh_W02a_CheckBox, GrtPh_W02acolor_EditField, 
        % GrtPh_W02b_CheckBox, GrtPh_W02bcolor_EditField, 
        % GrtPh_grt_Ca_EditField, GrtPh_grt_Fe_EditField, 
        % GrtPh_grt_Mg_EditField, GrtPh_grt_Mn_EditField, 
        % GrtPh_ph_Al_EditField, GrtPh_ph_Fe_EditField, 
        % GrtPh_ph_Mg_EditField
        function GrtPh(app, event)
            GrtPh_calculate(app)
        end

        % Value changed function: GrtPh_ColorAll_EditField
        function GrtPh_ColorAll_EditFieldValueChanged(app, event)
            app.GrtPh_KR78color_EditField.Value = app.GrtPh_ColorAll_EditField.Value;
            app.GrtPh_GH82acolor_EditField.Value = app.GrtPh_ColorAll_EditField.Value;
            app.GrtPh_GH82bcolor_EditField.Value = app.GrtPh_ColorAll_EditField.Value;
            app.GrtPh_GH82ccolor_EditField.Value = app.GrtPh_ColorAll_EditField.Value;
            app.GrtPh_HF88color_EditField.Value = app.GrtPh_ColorAll_EditField.Value;
            app.GrtPh_W02acolor_EditField.Value = app.GrtPh_ColorAll_EditField.Value;
            app.GrtPh_W02bcolor_EditField.Value = app.GrtPh_ColorAll_EditField.Value;
            GrtPh_calculate(app)
        end

        % Value changed function: GrtPh_DisplayAll_CheckBox
        function GrtPh_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtPh_DisplayAll_CheckBox.Value == 1
                app.GrtPh_KR78_CheckBox.Value = 1;
                app.GrtPh_GH82a_CheckBox.Value = 1;
                app.GrtPh_GH82b_CheckBox.Value = 1;
                app.GrtPh_GH82c_CheckBox.Value = 1;
                app.GrtPh_HF88_CheckBox.Value = 1;
                app.GrtPh_W02a_CheckBox.Value = 1;
                app.GrtPh_W02b_CheckBox.Value = 1;
            else
                app.GrtPh_KR78_CheckBox.Value = 0;
                app.GrtPh_GH82a_CheckBox.Value = 0;
                app.GrtPh_GH82b_CheckBox.Value = 0;
                app.GrtPh_GH82c_CheckBox.Value = 0;
                app.GrtPh_HF88_CheckBox.Value = 0;
                app.GrtPh_W02a_CheckBox.Value = 0;
                app.GrtPh_W02b_CheckBox.Value = 0;
            end
            GrtPh_calculate(app)  
        end

        % Value changed function: GrtOpx_AP89_CheckBox, 
        % GrtOpx_AP89color_EditField, GrtOpx_B91_CheckBox, 
        % GrtOpx_B91color_EditField, GrtOpx_D80_CheckBox, 
        % GrtOpx_D80color_EditField, GrtOpx_GridCheckBox, 
        % GrtOpx_H84_CheckBox, GrtOpx_H84color_EditField, 
        % GrtOpx_LG84_CheckBox, GrtOpx_LG84color_EditField, 
        % GrtOpx_LG88_CheckBox, GrtOpx_LG88color_EditField, 
        % GrtOpx_LineWidth_Spinner, GrtOpx_MG78_CheckBox, 
        % GrtOpx_MG78color_EditField, GrtOpx_P85_CheckBox, 
        % GrtOpx_P85color_EditField, GrtOpx_PL90_CheckBox, 
        % GrtOpx_PL90color_EditField, GrtOpx_P_EditField, 
        % GrtOpx_P_max, GrtOpx_P_min, GrtOpx_R83_CheckBox, 
        % GrtOpx_R83color_EditField, GrtOpx_SB84_CheckBox, 
        % GrtOpx_SB84color_EditField, GrtOpx_T_max, GrtOpx_T_min, 
        % GrtOpx_grt_Ca_EditField, GrtOpx_grt_Fe_EditField, 
        % GrtOpx_grt_Mg_EditField, GrtOpx_grt_Mn_EditField, 
        % GrtOpx_opx_Al_EditField, GrtOpx_opx_Fe_EditField, 
        % GrtOpx_opx_Mg_EditField
        function GrtOpx(app, event)
            GrtOpx_calculate(app)
        end

        % Value changed function: GrtOpx_ColorAll_EditField
        function GrtOpx_ColorAll_EditFieldValueChanged(app, event)
            app.GrtOpx_MG78color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_D80color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_R83color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_H84color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_SB84color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_LG84color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_P85color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_LG88color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_AP89color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_PL90color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            app.GrtOpx_B91color_EditField.Value = app.GrtOpx_ColorAll_EditField.Value;
            GrtOpx_calculate(app)
        end

        % Value changed function: GrtOpx_DisplayAll_CheckBox
        function GrtOpx_DisplayAll_CheckBoxValueChanged(app, event)
            if app.GrtOpx_DisplayAll_CheckBox.Value == 1
                app.GrtOpx_MG78_CheckBox.Value = 1;
                app.GrtOpx_D80_CheckBox.Value = 1;
                app.GrtOpx_R83_CheckBox.Value = 1;
                app.GrtOpx_H84_CheckBox.Value = 1;
                app.GrtOpx_SB84_CheckBox.Value = 1;
                app.GrtOpx_LG84_CheckBox.Value = 1;
                app.GrtOpx_P85_CheckBox.Value = 1;
                app.GrtOpx_LG88_CheckBox.Value = 1;
                app.GrtOpx_AP89_CheckBox.Value = 1;
                app.GrtOpx_PL90_CheckBox.Value = 1;
                app.GrtOpx_B91_CheckBox.Value = 1;
            else
                app.GrtOpx_MG78_CheckBox.Value = 0;
                app.GrtOpx_D80_CheckBox.Value = 0;
                app.GrtOpx_R83_CheckBox.Value = 0;
                app.GrtOpx_H84_CheckBox.Value = 0;
                app.GrtOpx_SB84_CheckBox.Value = 0;
                app.GrtOpx_LG84_CheckBox.Value = 0;
                app.GrtOpx_P85_CheckBox.Value = 0;
                app.GrtOpx_LG88_CheckBox.Value = 0;
                app.GrtOpx_AP89_CheckBox.Value = 0;
                app.GrtOpx_PL90_CheckBox.Value = 0;
                app.GrtOpx_B91_CheckBox.Value = 0;
            end
            GrtOpx_calculate(app)
        end

        % Value changed function: TiBi_Al_EditField, 
        % TiBi_Fe_EditField, TiBi_GridCheckBox, TiBi_H05_CheckBox, 
        % TiBi_H05color_EditField, TiBi_HG02_CheckBox, 
        % TiBi_HG02color_EditField, TiBi_LineWidth_Spinner, 
        % TiBi_Mg_EditField, TiBi_P_EditField, TiBi_P_max, 
        % TiBi_P_min, TiBi_T_max, TiBi_T_min, TiBi_Ti_EditField, 
        % TiBi_WC15_CheckBox, TiBi_WC15color_EditField
        function TiBi(app, event)
            TiBi_calculate(app)
        end

        % Value changed function: TiBi_ColorAll_EditField
        function TiBi_ColorAll_EditFieldValueChanged(app, event)
            app.TiBi_HG02color_EditField.Value = app.TiBi_ColorAll_EditField.Value;
            app.TiBi_H05color_EditField.Value = app.TiBi_ColorAll_EditField.Value;
            app.TiBi_WC15color_EditField.Value = app.TiBi_ColorAll_EditField.Value;
            TiBi_calculate(app) 
        end

        % Value changed function: TiBi_DisplayAll_CheckBox
        function TiBi_DisplayAll_CheckBoxValueChanged(app, event)
            if app.TiBi_DisplayAll_CheckBox.Value == 1
                app.TiBi_HG02_CheckBox.Value = 1;
                app.TiBi_H05_CheckBox.Value = 1;
                app.TiBi_WC15_CheckBox.Value = 1;
            else
                app.TiBi_HG02_CheckBox.Value = 0;
                app.TiBi_H05_CheckBox.Value = 0;
                app.TiBi_WC15_CheckBox.Value = 0;
            end
            TiBi_calculate(app)   
        end

        % Value changed function: TiQz_GridCheckBox, 
        % TiQz_HA12_CheckBox, TiQz_HA12color_EditField, 
        % TiQz_KO08_CheckBox, TiQz_KO08color_EditField, 
        % TiQz_LineWidth_Spinner, TiQz_P_EditField, TiQz_P_max, 
        % TiQz_P_min, TiQz_T10_CheckBox, TiQz_T10color_EditField, 
        % TiQz_T_max, TiQz_T_min, TiQz_Ti_EditField, 
        % TiQz_WW06_CheckBox, TiQz_WW06color_EditField, 
        % TiQz_aTiO2_EditField
        function TiQz(app, event)
            TiQz_calculate(app)
        end

        % Value changed function: TiQz_ColorAll_EditField
        function TiQz_ColorAll_EditFieldValueChanged(app, event)
            app.TiQz_WW06color_EditField.Value = app.TiQz_ColorAll_EditField.Value;
            app.TiQz_KO08color_EditField.Value = app.TiQz_ColorAll_EditField.Value;
            app.TiQz_T10color_EditField.Value = app.TiQz_ColorAll_EditField.Value;
            app.TiQz_HA12color_EditField.Value = app.TiQz_ColorAll_EditField.Value;
            TiQz_calculate(app)
        end

        % Value changed function: TiQz_DisplayAll_CheckBox
        function TiQz_DisplayAll_CheckBoxValueChanged(app, event)
            if app.TiQz_DisplayAll_CheckBox.Value == 1
                app.TiQz_WW06_CheckBox.Value = 1;
                app.TiQz_KO08_CheckBox.Value = 1;
                app.TiQz_T10_CheckBox.Value = 1;
                app.TiQz_HA12_CheckBox.Value = 1;
            else
                app.TiQz_WW06_CheckBox.Value = 0;
                app.TiQz_KO08_CheckBox.Value = 0;
                app.TiQz_T10_CheckBox.Value = 0;
                app.TiQz_HA12_CheckBox.Value = 0;
            end
            TiQz_calculate(app)
        end

        % Value changed function: TiZrn_FW07_CheckBox, 
        % TiZrn_FW07color_EditField, TiZrn_GridCheckBox, 
        % TiZrn_LineWidth_Spinner, TiZrn_P_max, TiZrn_P_min, 
        % TiZrn_T_max, TiZrn_T_min, TiZrn_Ti_EditField, 
        % TiZrn_W06_CheckBox, TiZrn_W06color_EditField, 
        % TiZrn_aSiO2_EditField, TiZrn_aTiO2_EditField
        function TiZrn(app, event)
            TiZrn_calculate(app)
        end

        % Value changed function: TiZrn_ColorAll_EditField
        function TiZrn_ColorAll_EditFieldValueChanged(app, event)
            app.TiZrn_W06color_EditField.Value = app.TiZrn_ColorAll_EditField.Value;
            app.TiZrn_FW07color_EditField.Value = app.TiZrn_ColorAll_EditField.Value;
            TiZrn_calculate(app)
        end

        % Value changed function: TiZrn_DisplayAll_CheckBox
        function TiZrn_DisplayAll_CheckBoxValueChanged(app, event)
            if app.TiZrn_DisplayAll_CheckBox.Value == 1
                app.TiZrn_W06_CheckBox.Value = 1;
                app.TiZrn_FW07_CheckBox.Value = 1;
            else
                app.TiZrn_W06_CheckBox.Value = 0;
                app.TiZrn_FW07_CheckBox.Value = 0;
            end
            TiZrn_calculate(app) 
        end

        % Value changed function: ZrRu_FW07_CheckBox, 
        % ZrRu_FW07color_EditField, ZrRu_GridCheckBox, 
        % ZrRu_LineWidth_Spinner, ZrRu_P_EditField, ZrRu_P_max, 
        % ZrRu_P_min, ZrRu_T07a_CheckBox, ZrRu_T07acolor_EditField, 
        % ZrRu_T07b_CheckBox, ZrRu_T07bcolor_EditField, 
        % ZrRu_T07c_CheckBox, ZrRu_T07ccolor_EditField, ZrRu_T_max, 
        % ZrRu_T_min, ZrRu_W06_CheckBox, ZrRu_W06color_EditField, 
        % ZrRu_Z04_CheckBox, ZrRu_Z04color_EditField, 
        % ZrRu_Zr_EditField, ZrRu_aSiO2_EditField
        function ZrRu(app, event)
            ZrRu_calculate(app)
        end

        % Value changed function: ZrRu_ColorAll_EditField
        function ZrRu_ColorAll_EditFieldValueChanged(app, event)
            app.ZrRu_Z04color_EditField.Value = app.ZrRu_ColorAll_EditField.Value;
            app.ZrRu_W06color_EditField.Value = app.ZrRu_ColorAll_EditField.Value;
            app.ZrRu_FW07color_EditField.Value = app.ZrRu_ColorAll_EditField.Value;
            app.ZrRu_T07acolor_EditField.Value = app.ZrRu_ColorAll_EditField.Value;
            app.ZrRu_T07bcolor_EditField.Value = app.ZrRu_ColorAll_EditField.Value;
            app.ZrRu_T07ccolor_EditField.Value = app.ZrRu_ColorAll_EditField.Value;
            ZrRu_calculate(app)
        end

        % Value changed function: ZrRu_DisplayAll_CheckBox
        function ZrRu_DisplayAll_CheckBoxValueChanged(app, event)
            if app.ZrRu_DisplayAll_CheckBox.Value == 1
                app.ZrRu_Z04_CheckBox.Value = 1;
                app.ZrRu_W06_CheckBox.Value = 1;
                app.ZrRu_FW07_CheckBox.Value = 1;
                app.ZrRu_T07a_CheckBox.Value = 1;
                app.ZrRu_T07b_CheckBox.Value = 1;
                app.ZrRu_T07c_CheckBox.Value = 1;
            else
                app.ZrRu_Z04_CheckBox.Value = 0;
                app.ZrRu_W06_CheckBox.Value = 0;
                app.ZrRu_FW07_CheckBox.Value = 0;
                app.ZrRu_T07a_CheckBox.Value = 0;
                app.ZrRu_T07b_CheckBox.Value = 0;
                app.ZrRu_T07c_CheckBox.Value = 0;
            end
            ZrRu_calculate(app)
        end

        % Value changed function: ZrTtn_GridCheckBox, 
        % ZrTtn_H08_CheckBox, ZrTtn_H08color_EditField, 
        % ZrTtn_LineWidth_Spinner, ZrTtn_P_EditField, ZrTtn_P_max, 
        % ZrTtn_P_min, ZrTtn_T_max, ZrTtn_T_min, 
        % ZrTtn_Zr_EditField, ZrTtn_aSiO2_EditField, 
        % ZrTtn_aTiO2_EditField
        function ZrTtn(app, event)
            ZrTtn_calculate(app)
        end

        % Value changed function: ZrTtn_ColorAll_EditField
        function ZrTtn_ColorAll_EditFieldValueChanged(app, event)
            app.ZrTtn_H08color_EditField.Value = app.ZrTtn_ColorAll_EditField.Value;
            ZrTtn_calculate(app)
        end

        % Value changed function: ZrTtn_DisplayAll_CheckBox
        function ZrTtn_DisplayAll_CheckBoxValueChanged(app, event)
            if app.ZrTtn_DisplayAll_CheckBox.Value == 1
                app.ZrTtn_H08_CheckBox.Value = 1;
            else
                app.ZrTtn_H08_CheckBox.Value = 0;
            end
            ZrTtn_calculate(app)
        end

        % Value changing function: EX_P_Slider
        function EX(app, event)
            EX_calculate(app)           
        end

        % Value changed function: EX_bi_XFe_Slider
        function EX_bi_XFe_SliderValueChanged(app, event)
            app.EX_bi_XMg_Slider.Value = 1 - app.EX_bi_XFe_Slider.Value;
            EX_calculate(app)
        end

        % Value changed function: EX_bi_XMg_Slider
        function EX_bi_XMg_SliderValueChanged(app, event)
            app.EX_bi_XFe_Slider.Value = 1 - app.EX_bi_XMg_Slider.Value;
            EX_calculate(app)
        end

        % Value changing function: EX_grt_XFe_Slider
        function EX_grt_XFe_SliderValueChanging(app, event)
            app.EX_grt_XMg_Slider.Value = 1 - app.EX_grt_XFe_Slider.Value;
            EX_calculate(app) 
        end

        % Value changing function: EX_grt_XMg_Slider
        function EX_grt_XMg_SliderValueChanging(app, event)
            app.EX_grt_XFe_Slider.Value = 1 - app.EX_grt_XMg_Slider.Value;
            EX_calculate(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.PTtoolbox = uifigure('Visible', 'off');
            app.PTtoolbox.Name = 'PTtoolbox';
            app.PTtoolbox_Logo = uiimage(app.PTtoolbox);
            app.PTtoolbox_Logo.Tooltip = {''};
            app.PTtoolbox_Logo.ImageSource = 'logo.png';
            app.PTtoolbox_Label = uilabel(app.PTtoolbox);
            app.PTtoolbox_Label.Tooltip = {'PTtools v1.0.0'};
            app.PTtoolbox_Label.Text = 'PTtoolbox';
            app.TabGroup = uitabgroup(app.PTtoolbox);
            app.GrtBi_Tab = uitab(app.TabGroup);
            app.GrtBi_Tab.Title = 'Grt-Bi';
            app.GrtBi_Input_Tab = uipanel(app.GrtBi_Tab);
            app.GrtBi_Input_TabGrid = uigridlayout(app.GrtBi_Input_Tab);
            app.GrtBi_Garnet_Label = uilabel(app.GrtBi_Input_TabGrid);
            app.GrtBi_Garnet_Label.Text = 'Garnet';
            app.GrtBi_Biotite_Label = uilabel(app.GrtBi_Input_TabGrid);
            app.GrtBi_Biotite_Label.Text = 'Biotite';
            app.FeEditFieldLabel_5 = uilabel(app.GrtBi_Input_TabGrid);
            app.FeEditFieldLabel_5.Text = 'Fe';
            app.GrtBi_grt_Fe_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel_5 = uilabel(app.GrtBi_Input_TabGrid);
            app.MgLabel_5.Text = 'Mg';
            app.GrtBi_grt_Mg_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel_4 = uilabel(app.GrtBi_Input_TabGrid);
            app.CaLabel_4.Text = 'Ca';
            app.GrtBi_grt_Ca_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel_3 = uilabel(app.GrtBi_Input_TabGrid);
            app.MnLabel_3.Text = 'Mn';
            app.GrtBi_grt_Mn_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_grt_Mn_EditField.Tooltip = {''};
            app.AlLabel_3 = uilabel(app.GrtBi_Input_TabGrid);
            app.AlLabel_3.Text = 'Al';
            app.GrtBi_bi_Al_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_bi_Al_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_bi_Al_EditField.Tooltip = {''};
            app.FeLabel = uilabel(app.GrtBi_Input_TabGrid);
            app.FeLabel.Text = 'Fe';
            app.GrtBi_bi_Fe_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_bi_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_bi_Fe_EditField.Tooltip = {''};
            app.TiLabel = uilabel(app.GrtBi_Input_TabGrid);
            app.TiLabel.Text = 'Ti';
            app.GrtBi_bi_Ti_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_bi_Ti_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_bi_Ti_EditField.Tooltip = {''};
            app.PkbarLabel_3 = uilabel(app.GrtBi_Input_TabGrid);
            app.PkbarLabel_3.Text = 'P (kbar)';
            app.GrtBi_P_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_P_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_P_EditField.Tooltip = {''};
            app.MgLabel_6 = uilabel(app.GrtBi_Input_TabGrid);
            app.MgLabel_6.Text = 'Mg';
            app.GrtBi_bi_Mg_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_bi_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_bi_Mg_EditField.Tooltip = {''};
            app.MnLabel_4 = uilabel(app.GrtBi_Input_TabGrid);
            app.MnLabel_4.Text = 'Mn';
            app.GrtBi_bi_Mn_EditField = uieditfield(app.GrtBi_Input_TabGrid, 'numeric');
            app.GrtBi_bi_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_bi_Mn_EditField.Tooltip = {''};
            app.GrtBi_Results_Tab = uipanel(app.GrtBi_Tab);
            app.GrtBi_Results_TabGrid = uigridlayout(app.GrtBi_Results_Tab);
            app.GrtBi_T76color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_T76color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_T76color_EditField.Value = 'red';
            app.GrtBi_T76_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_T76_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_T76_CheckBox.Text = '';
            app.GrtBi_T76_CheckBox.Value = true;
            app.T76Label = uilabel(app.GrtBi_Results_TabGrid);
            app.T76Label.Text = 'T76';
            app.GrtBi_T76_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_T76_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_T76_EditField.Editable = 'off';
            app.GrtBi_T76_EditField.Tooltip = {''};
            app.XMgLabel_5 = uilabel(app.GrtBi_Results_TabGrid);
            app.XMgLabel_5.Text = 'XMg';
            app.GrtBi_bi_XMg_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_bi_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_bi_XMg_EditField.Editable = 'off';
            app.GrtBi_bi_XMg_EditField.Tooltip = {''};
            app.XFeLabel_5 = uilabel(app.GrtBi_Results_TabGrid);
            app.XFeLabel_5.Text = 'XFe';
            app.GrtBi_bi_XFe_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_bi_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_bi_XFe_EditField.Editable = 'off';
            app.GrtBi_bi_XFe_EditField.Tooltip = {''};
            app.XMgLabel_6 = uilabel(app.GrtBi_Results_TabGrid);
            app.XMgLabel_6.Text = 'XMg';
            app.GrtBi_grt_XMg_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_grt_XMg_EditField.Editable = 'off';
            app.GrtBi_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel_6 = uilabel(app.GrtBi_Results_TabGrid);
            app.XFeLabel_6.Text = 'XFe';
            app.GrtBi_grt_XFe_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_grt_XFe_EditField.Editable = 'off';
            app.GrtBi_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel_4 = uilabel(app.GrtBi_Results_TabGrid);
            app.XCaLabel_4.Text = 'XCa';
            app.GrtBi_grt_XCa_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_grt_XCa_EditField.Editable = 'off';
            app.GrtBi_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel_3 = uilabel(app.GrtBi_Results_TabGrid);
            app.XMnLabel_3.Text = 'XMn';
            app.GrtBi_grt_XMn_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_grt_XMn_EditField.Editable = 'off';
            app.GrtBi_grt_XMn_EditField.Tooltip = {''};
            app.XMnLabel_4 = uilabel(app.GrtBi_Results_TabGrid);
            app.XMnLabel_4.Text = 'XMn';
            app.GrtBi_bi_XMn_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_bi_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_bi_XMn_EditField.Editable = 'off';
            app.GrtBi_bi_XMn_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel_3 = uilabel(app.GrtBi_Results_TabGrid);
            app.lnKdEditFieldLabel_3.Text = 'lnKd';
            app.GrtBi_lnKd_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtBi_lnKd_EditField.Editable = 'off';
            app.GrtBi_lnKd_EditField.Tooltip = {''};
            app.GrtBi_GarnetResult_Label = uilabel(app.GrtBi_Results_TabGrid);
            app.GrtBi_GarnetResult_Label.Text = 'Garnet';
            app.GrtBi_BiotiteResult_Label = uilabel(app.GrtBi_Results_TabGrid);
            app.GrtBi_BiotiteResult_Label.Text = 'Biotite';
            app.GA77Label = uilabel(app.GrtBi_Results_TabGrid);
            app.GA77Label.Text = 'GA77';
            app.GrtBi_GA77_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_GA77_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_GA77_EditField.Editable = 'off';
            app.GrtBi_GA77_EditField.Tooltip = {''};
            app.HL77Label = uilabel(app.GrtBi_Results_TabGrid);
            app.HL77Label.Text = 'HL77';
            app.GrtBi_HL77_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_HL77_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_HL77_EditField.Editable = 'off';
            app.GrtBi_HL77_EditField.Tooltip = {''};
            app.FS78Label = uilabel(app.GrtBi_Results_TabGrid);
            app.FS78Label.Text = 'FS78';
            app.GrtBi_FS78_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_FS78_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_FS78_EditField.Editable = 'off';
            app.GrtBi_FS78_EditField.Tooltip = {''};
            app.HS82Label = uilabel(app.GrtBi_Results_TabGrid);
            app.HS82Label.Text = 'HS82';
            app.GrtBi_HS82_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_HS82_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_HS82_EditField.Editable = 'off';
            app.GrtBi_HS82_EditField.Tooltip = {''};
            app.PL83aLabel = uilabel(app.GrtBi_Results_TabGrid);
            app.PL83aLabel.Text = 'PL83a';
            app.GrtBi_PL83a_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_PL83a_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_PL83a_EditField.Editable = 'off';
            app.GrtBi_PL83a_EditField.Tooltip = {''};
            app.GS84Label = uilabel(app.GrtBi_Results_TabGrid);
            app.GS84Label.Text = 'GS84';
            app.GrtBi_GS84_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_GS84_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_GS84_EditField.Editable = 'off';
            app.GrtBi_GS84_EditField.Tooltip = {''};
            app.D91Label = uilabel(app.GrtBi_Results_TabGrid);
            app.D91Label.Text = 'D91';
            app.GrtBi_D91_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_D91_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_D91_EditField.Editable = 'off';
            app.GrtBi_D91_EditField.Tooltip = {''};
            app.B92aLabel = uilabel(app.GrtBi_Results_TabGrid);
            app.B92aLabel.Text = 'B92a';
            app.GrtBi_B92a_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_B92a_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_B92a_EditField.Editable = 'off';
            app.GrtBi_B92a_EditField.Tooltip = {''};
            app.B92bLabel = uilabel(app.GrtBi_Results_TabGrid);
            app.B92bLabel.Text = 'B92b';
            app.GrtBi_B92b_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_B92b_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_B92b_EditField.Editable = 'off';
            app.GrtBi_B92b_EditField.Tooltip = {''};
            app.XTiLabel = uilabel(app.GrtBi_Results_TabGrid);
            app.XTiLabel.Text = 'XTi';
            app.GrtBi_bi_XTi_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_bi_XTi_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_bi_XTi_EditField.Editable = 'off';
            app.GrtBi_bi_XTi_EditField.Tooltip = {''};
            app.XAlLabel_2 = uilabel(app.GrtBi_Results_TabGrid);
            app.XAlLabel_2.Text = 'XAl';
            app.GrtBi_bi_XAl_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_bi_XAl_EditField.ValueDisplayFormat = '%.2f';
            app.GrtBi_bi_XAl_EditField.Editable = 'off';
            app.GrtBi_bi_XAl_EditField.Tooltip = {''};
            app.GrtBi_GA77_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_GA77_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_GA77_CheckBox.Text = '';
            app.GrtBi_GA77_CheckBox.Value = true;
            app.GrtBi_HL77_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_HL77_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_HL77_CheckBox.Text = '';
            app.GrtBi_HL77_CheckBox.Value = true;
            app.GrtBi_FS78_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_FS78_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_FS78_CheckBox.Text = '';
            app.GrtBi_FS78_CheckBox.Value = true;
            app.GrtBi_HS82_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_HS82_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_HS82_CheckBox.Text = '';
            app.GrtBi_HS82_CheckBox.Value = true;
            app.GrtBi_PL83a_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_PL83a_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_PL83a_CheckBox.Text = '';
            app.GrtBi_PL83a_CheckBox.Value = true;
            app.GrtBi_GS84_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_GS84_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_GS84_CheckBox.Text = '';
            app.GrtBi_GS84_CheckBox.Value = true;
            app.GrtBi_D91_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_D91_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_D91_CheckBox.Text = '';
            app.GrtBi_D91_CheckBox.Value = true;
            app.GrtBi_B92a_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_B92a_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_B92a_CheckBox.Text = '';
            app.GrtBi_B92a_CheckBox.Value = true;
            app.GrtBi_B92b_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_B92b_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_B92b_CheckBox.Text = '';
            app.GrtBi_B92b_CheckBox.Value = true;
            app.GrtBi_GA77color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_GA77color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_GA77color_EditField.Value = 'red';
            app.GrtBi_HL77color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_HL77color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_HL77color_EditField.Value = 'red';
            app.GrtBi_FS78color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_FS78color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_FS78color_EditField.Value = 'red';
            app.GrtBi_HS82color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_HS82color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_HS82color_EditField.Value = 'red';
            app.GrtBi_PL83acolor_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_PL83acolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_PL83acolor_EditField.Value = 'red';
            app.GrtBi_GS84color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_GS84color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_GS84color_EditField.Value = 'red';
            app.GrtBi_D91color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_D91color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_D91color_EditField.Value = 'red';
            app.GrtBi_B92acolor_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_B92acolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_B92acolor_EditField.Value = 'red';
            app.GrtBi_B92bcolor_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_B92bcolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_B92bcolor_EditField.Value = 'red';
            app.IM85aLabel = uilabel(app.GrtBi_Results_TabGrid);
            app.IM85aLabel.Text = 'IM85a';
            app.GrtBi_IM85a_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_IM85a_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_IM85a_EditField.Editable = 'off';
            app.GrtBi_IM85a_EditField.Tooltip = {''};
            app.IM85bLabel = uilabel(app.GrtBi_Results_TabGrid);
            app.IM85bLabel.Text = 'IM85b';
            app.GrtBi_IM85b_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_IM85b_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_IM85b_EditField.Editable = 'off';
            app.GrtBi_IM85b_EditField.Tooltip = {''};
            app.GrtBi_IM85a_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_IM85a_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_IM85a_CheckBox.Text = '';
            app.GrtBi_IM85a_CheckBox.Value = true;
            app.GrtBi_IM85b_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_IM85b_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_IM85b_CheckBox.Text = '';
            app.GrtBi_IM85b_CheckBox.Value = true;
            app.GrtBi_P85_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_P85_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_P85_CheckBox.Text = '';
            app.GrtBi_P85_CheckBox.Value = true;
            app.GrtBi_IM85acolor_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_IM85acolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_IM85acolor_EditField.Value = 'red';
            app.GrtBi_IM85bcolor_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_IM85bcolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_IM85bcolor_EditField.Value = 'red';
            app.GrtBi_P85color_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_P85color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_P85color_EditField.Value = 'red';
            app.P85Label_2 = uilabel(app.GrtBi_Results_TabGrid);
            app.P85Label_2.Text = 'P85';
            app.GrtBi_P85_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_P85_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_P85_EditField.Editable = 'off';
            app.GrtBi_P85_EditField.Tooltip = {''};
            app.GrtBi_Thermometers_Label = uilabel(app.GrtBi_Results_TabGrid);
            app.GrtBi_Thermometers_Label.Text = 'Thermometers';
            app.PL83bLabel = uilabel(app.GrtBi_Results_TabGrid);
            app.PL83bLabel.Text = 'PL83b';
            app.GrtBi_PL83b_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'numeric');
            app.GrtBi_PL83b_EditField.ValueDisplayFormat = '%.0f';
            app.GrtBi_PL83b_EditField.Editable = 'off';
            app.GrtBi_PL83b_EditField.Tooltip = {''};
            app.GrtBi_PL83b_CheckBox = uicheckbox(app.GrtBi_Results_TabGrid);
            app.GrtBi_PL83b_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_PL83b_CheckBox.Text = '';
            app.GrtBi_PL83b_CheckBox.Value = true;
            app.GrtBi_PL83bcolor_EditField = uieditfield(app.GrtBi_Results_TabGrid, 'text');
            app.GrtBi_PL83bcolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_PL83bcolor_EditField.Value = 'red';
            app.GrtBi_Plot_Tab = uipanel(app.GrtBi_Tab);
            app.GrtBi_Plot_TabGrid = uigridlayout(app.GrtBi_Plot_Tab);
            app.GrtBi_P_max_Label = uilabel(app.GrtBi_Plot_TabGrid);
            app.GrtBi_P_max_Label.Text = 'P max';
            app.GrtBi_P_max = uispinner(app.GrtBi_Plot_TabGrid);
            app.GrtBi_P_max.Limits = [1 Inf];
            app.GrtBi_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_P_max.Tooltip = {'P (kbar) max'};
            app.GrtBi_P_max.Value = 15;
            app.GrtBi_P_min_Label = uilabel(app.GrtBi_Plot_TabGrid);
            app.GrtBi_P_min_Label.Text = 'P min';
            app.GrtBi_T_max_Label = uilabel(app.GrtBi_Plot_TabGrid);
            app.GrtBi_T_max_Label.Text = 'T max';
            app.GrtBi_P_min = uispinner(app.GrtBi_Plot_TabGrid);
            app.GrtBi_P_min.Limits = [0 Inf];
            app.GrtBi_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_P_min.Tooltip = {'P (kbar) min'};
            app.GrtBi_T_max = uispinner(app.GrtBi_Plot_TabGrid);
            app.GrtBi_T_max.Step = 50;
            app.GrtBi_T_max.Limits = [50 Inf];
            app.GrtBi_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_T_max.Tooltip = {'T (C) max'};
            app.GrtBi_T_max.Value = 1000;
            app.GrtBi_GridCheckBox = uicheckbox(app.GrtBi_Plot_TabGrid);
            app.GrtBi_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_GridCheckBox.Text = 'Grid';
            app.GrtBi_LineWidth_Label = uilabel(app.GrtBi_Plot_TabGrid);
            app.GrtBi_LineWidth_Label.Text = 'Width';
            app.GrtBi_LineWidth_Spinner = uispinner(app.GrtBi_Plot_TabGrid);
            app.GrtBi_LineWidth_Spinner.Step = 0.5;
            app.GrtBi_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtBi_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtBi_LineWidth_Spinner.Value = 1;
            app.GrtBi_T_min_Label = uilabel(app.GrtBi_Plot_TabGrid);
            app.GrtBi_T_min_Label.Text = 'T min';
            app.GrtBi_T_min = uispinner(app.GrtBi_Plot_TabGrid);
            app.GrtBi_T_min.Step = 50;
            app.GrtBi_T_min.Limits = [0 Inf];
            app.GrtBi_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtBi, true);
            app.GrtBi_T_min.Tooltip = {'T (C) min'};
            app.GrtBi_UIAxes = uiaxes(app.GrtBi_Plot_TabGrid);
            title(app.GrtBi_UIAxes, '')
            xlabel(app.GrtBi_UIAxes, 'T (C)')
            ylabel(app.GrtBi_UIAxes, 'P (kbar)')
            app.GrtBi_UIAxes.XLim = [0 1000];
            app.GrtBi_UIAxes.YLim = [0 15];
            app.GrtBi_UIAxes.LineStyleOrder = {'-'};
            app.GrtBi_DisplayAll_CheckBox = uicheckbox(app.GrtBi_Plot_TabGrid);
            app.GrtBi_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtBi_DisplayAll_CheckBoxValueChanged, true);
            app.GrtBi_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtBi_DisplayAll_CheckBox.Value = true;
            app.GrtBi_ColorAll_EditField = uieditfield(app.GrtBi_Plot_TabGrid, 'text');
            app.GrtBi_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtBi_ColorAll_EditFieldValueChanged, true);
            app.GrtBi_ColorAll_EditField.Value = 'red';
            app.GrtBi_Colour_Label = uilabel(app.GrtBi_Plot_TabGrid);
            app.GrtBi_Colour_Label.Text = 'Colour';
            app.GrtChl_Tab = uitab(app.TabGroup);
            app.GrtChl_Tab.Title = 'Grt-Chl';
            app.GrtChl_Input_Tab = uipanel(app.GrtChl_Tab);
            app.GrtChl_Input_TabGrid = uigridlayout(app.GrtChl_Input_Tab);
            app.GrtChl_Garnet_Label = uilabel(app.GrtChl_Input_TabGrid);
            app.GrtChl_Garnet_Label.Text = 'Garnet';
            app.GrtChl_Chlorite_Label = uilabel(app.GrtChl_Input_TabGrid);
            app.GrtChl_Chlorite_Label.Text = 'Chlorite';
            app.FeEditFieldLabel_8 = uilabel(app.GrtChl_Input_TabGrid);
            app.FeEditFieldLabel_8.Text = 'Fe';
            app.GrtChl_grt_Fe_EditField = uieditfield(app.GrtChl_Input_TabGrid, 'numeric');
            app.GrtChl_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel_11 = uilabel(app.GrtChl_Input_TabGrid);
            app.MgLabel_11.Text = 'Mg';
            app.GrtChl_grt_Mg_EditField = uieditfield(app.GrtChl_Input_TabGrid, 'numeric');
            app.GrtChl_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel_7 = uilabel(app.GrtChl_Input_TabGrid);
            app.CaLabel_7.Text = 'Ca';
            app.GrtChl_grt_Ca_EditField = uieditfield(app.GrtChl_Input_TabGrid, 'numeric');
            app.GrtChl_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel_7 = uilabel(app.GrtChl_Input_TabGrid);
            app.MnLabel_7.Text = 'Mn';
            app.GrtChl_grt_Mn_EditField = uieditfield(app.GrtChl_Input_TabGrid, 'numeric');
            app.GrtChl_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_grt_Mn_EditField.Tooltip = {''};
            app.FeLabel_4 = uilabel(app.GrtChl_Input_TabGrid);
            app.FeLabel_4.Text = 'Fe';
            app.GrtChl_chl_Fe_EditField = uieditfield(app.GrtChl_Input_TabGrid, 'numeric');
            app.GrtChl_chl_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_chl_Fe_EditField.Tooltip = {''};
            app.MgLabel_12 = uilabel(app.GrtChl_Input_TabGrid);
            app.MgLabel_12.Text = 'Mg';
            app.GrtChl_chl_Mg_EditField = uieditfield(app.GrtChl_Input_TabGrid, 'numeric');
            app.GrtChl_chl_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_chl_Mg_EditField.Tooltip = {''};
            app.PkbarLabel_5 = uilabel(app.GrtChl_Input_TabGrid);
            app.PkbarLabel_5.Text = 'P (kbar)';
            app.GrtChl_P_EditField = uieditfield(app.GrtChl_Input_TabGrid, 'numeric');
            app.GrtChl_P_EditField.Tooltip = {''};
            app.GrtChl_Results_Tab = uipanel(app.GrtChl_Tab);
            app.GrtChl_Results_TabGrid = uigridlayout(app.GrtChl_Results_Tab);
            app.GrtChl_DH86color_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'text');
            app.GrtChl_DH86color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_DH86color_EditField.Value = 'red';
            app.GrtChl_DH86_CheckBox = uicheckbox(app.GrtChl_Results_TabGrid);
            app.GrtChl_DH86_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_DH86_CheckBox.Text = '';
            app.GrtChl_DH86_CheckBox.Value = true;
            app.DH86Label = uilabel(app.GrtChl_Results_TabGrid);
            app.DH86Label.Text = 'DH86';
            app.GrtChl_DH86_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_DH86_EditField.ValueDisplayFormat = '%.0f';
            app.GrtChl_DH86_EditField.Editable = 'off';
            app.GrtChl_DH86_EditField.Tooltip = {''};
            app.XMgLabel_11 = uilabel(app.GrtChl_Results_TabGrid);
            app.XMgLabel_11.Text = 'XMg';
            app.GrtChl_chl_XMg_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_chl_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtChl_chl_XMg_EditField.Editable = 'off';
            app.GrtChl_chl_XMg_EditField.Tooltip = {''};
            app.XFeLabel_11 = uilabel(app.GrtChl_Results_TabGrid);
            app.XFeLabel_11.Text = 'XFe';
            app.GrtChl_chl_XFe_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_chl_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtChl_chl_XFe_EditField.Editable = 'off';
            app.GrtChl_chl_XFe_EditField.Tooltip = {''};
            app.XMgLabel_12 = uilabel(app.GrtChl_Results_TabGrid);
            app.XMgLabel_12.Text = 'XMg';
            app.GrtChl_grt_XMg_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtChl_grt_XMg_EditField.Editable = 'off';
            app.GrtChl_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel_12 = uilabel(app.GrtChl_Results_TabGrid);
            app.XFeLabel_12.Text = 'XFe';
            app.GrtChl_grt_XFe_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtChl_grt_XFe_EditField.Editable = 'off';
            app.GrtChl_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel_7 = uilabel(app.GrtChl_Results_TabGrid);
            app.XCaLabel_7.Text = 'XCa';
            app.GrtChl_grt_XCa_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtChl_grt_XCa_EditField.Editable = 'off';
            app.GrtChl_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel_7 = uilabel(app.GrtChl_Results_TabGrid);
            app.XMnLabel_7.Text = 'XMn';
            app.GrtChl_grt_XMn_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtChl_grt_XMn_EditField.Editable = 'off';
            app.GrtChl_grt_XMn_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel_6 = uilabel(app.GrtChl_Results_TabGrid);
            app.lnKdEditFieldLabel_6.Text = 'lnKd';
            app.GrtChl_lnKd_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtChl_lnKd_EditField.Editable = 'off';
            app.GrtChl_lnKd_EditField.Tooltip = {''};
            app.GrtChl_GarnetResult_Label = uilabel(app.GrtChl_Results_TabGrid);
            app.GrtChl_GarnetResult_Label.Text = 'Garnet';
            app.GrtChl_ChloriteResult_Label = uilabel(app.GrtChl_Results_TabGrid);
            app.GrtChl_ChloriteResult_Label.Text = 'Chlorite';
            app.G90Label = uilabel(app.GrtChl_Results_TabGrid);
            app.G90Label.Text = 'G90';
            app.GrtChl_G90_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_G90_EditField.ValueDisplayFormat = '%.0f';
            app.GrtChl_G90_EditField.Editable = 'off';
            app.GrtChl_G90_EditField.Tooltip = {''};
            app.P91Label = uilabel(app.GrtChl_Results_TabGrid);
            app.P91Label.Text = 'P91';
            app.GrtChl_P91_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'numeric');
            app.GrtChl_P91_EditField.ValueDisplayFormat = '%.0f';
            app.GrtChl_P91_EditField.Editable = 'off';
            app.GrtChl_P91_EditField.Tooltip = {''};
            app.GrtChl_G90_CheckBox = uicheckbox(app.GrtChl_Results_TabGrid);
            app.GrtChl_G90_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_G90_CheckBox.Text = '';
            app.GrtChl_G90_CheckBox.Value = true;
            app.GrtChl_P91_CheckBox = uicheckbox(app.GrtChl_Results_TabGrid);
            app.GrtChl_P91_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_P91_CheckBox.Text = '';
            app.GrtChl_P91_CheckBox.Value = true;
            app.GrtChl_G90color_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'text');
            app.GrtChl_G90color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_G90color_EditField.Value = 'red';
            app.GrtChl_P91color_EditField = uieditfield(app.GrtChl_Results_TabGrid, 'text');
            app.GrtChl_P91color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_P91color_EditField.Value = 'red';
            app.GrtChl_Thermometers_Label = uilabel(app.GrtChl_Results_TabGrid);
            app.GrtChl_Thermometers_Label.Text = 'Thermometers';
            app.GrtChl_Plot_Tab = uipanel(app.GrtChl_Tab);
            app.GrtChl_Plot_TabGrid = uigridlayout(app.GrtChl_Plot_Tab);
            app.GrtChl_P_max_Label = uilabel(app.GrtChl_Plot_TabGrid);
            app.GrtChl_P_max_Label.Text = 'P max';
            app.GrtChl_P_max = uispinner(app.GrtChl_Plot_TabGrid);
            app.GrtChl_P_max.Limits = [1 Inf];
            app.GrtChl_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_P_max.Tooltip = {'P (kbar) max'};
            app.GrtChl_P_max.Value = 15;
            app.GrtChl_P_min_Label = uilabel(app.GrtChl_Plot_TabGrid);
            app.GrtChl_P_min_Label.Text = 'P min';
            app.GrtChl_T_max_Label = uilabel(app.GrtChl_Plot_TabGrid);
            app.GrtChl_T_max_Label.Text = 'T max';
            app.GrtChl_P_min = uispinner(app.GrtChl_Plot_TabGrid);
            app.GrtChl_P_min.Limits = [0 Inf];
            app.GrtChl_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_P_min.Tooltip = {'P (kbar) min'};
            app.GrtChl_T_max = uispinner(app.GrtChl_Plot_TabGrid);
            app.GrtChl_T_max.Step = 50;
            app.GrtChl_T_max.Limits = [50 Inf];
            app.GrtChl_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_T_max.Tooltip = {'T (C) max'};
            app.GrtChl_T_max.Value = 1000;
            app.GrtChl_GridCheckBox = uicheckbox(app.GrtChl_Plot_TabGrid);
            app.GrtChl_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_GridCheckBox.Text = 'Grid';
            app.GrtChl_LineWidth_Label = uilabel(app.GrtChl_Plot_TabGrid);
            app.GrtChl_LineWidth_Label.Text = 'Width';
            app.GrtChl_LineWidth_Spinner = uispinner(app.GrtChl_Plot_TabGrid);
            app.GrtChl_LineWidth_Spinner.Step = 0.5;
            app.GrtChl_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtChl_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtChl_LineWidth_Spinner.Value = 1;
            app.GrtChl_T_min_Label = uilabel(app.GrtChl_Plot_TabGrid);
            app.GrtChl_T_min_Label.Text = 'T min';
            app.GrtChl_T_min = uispinner(app.GrtChl_Plot_TabGrid);
            app.GrtChl_T_min.Step = 50;
            app.GrtChl_T_min.Limits = [0 Inf];
            app.GrtChl_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtChl, true);
            app.GrtChl_T_min.Tooltip = {'T (C) min'};
            app.GrtChl_UIAxes = uiaxes(app.GrtChl_Plot_TabGrid);
            title(app.GrtChl_UIAxes, '')
            xlabel(app.GrtChl_UIAxes, 'T (C)')
            ylabel(app.GrtChl_UIAxes, 'P (kbar)')
            app.GrtChl_UIAxes.XLim = [0 1000];
            app.GrtChl_UIAxes.YLim = [0 15];
            app.GrtChl_UIAxes.LineStyleOrder = {'-'};
            app.GrtChl_DisplayAll_CheckBox = uicheckbox(app.GrtChl_Plot_TabGrid);
            app.GrtChl_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtChl_DisplayAll_CheckBoxValueChanged, true);
            app.GrtChl_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtChl_DisplayAll_CheckBox.Value = true;
            app.GrtChl_ColorAll_EditField = uieditfield(app.GrtChl_Plot_TabGrid, 'text');
            app.GrtChl_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtChl_ColorAll_EditFieldValueChanged, true);
            app.GrtChl_ColorAll_EditField.Value = 'red';
            app.GrtChl_Colour_Label = uilabel(app.GrtChl_Plot_TabGrid);
            app.GrtChl_Colour_Label.Text = 'Colour';
            app.GrtCpx_Tab = uitab(app.TabGroup);
            app.GrtCpx_Tab.Title = 'Grt-Cpx';
            app.GrtCpx_Input_Tab = uipanel(app.GrtCpx_Tab);
            app.GrtCpx_Input_TabGrid = uigridlayout(app.GrtCpx_Input_Tab);
            app.GrtCpx_Garnet_Label = uilabel(app.GrtCpx_Input_TabGrid);
            app.GrtCpx_Garnet_Label.Text = 'Garnet';
            app.GrtCpx_Cpx_Label = uilabel(app.GrtCpx_Input_TabGrid);
            app.GrtCpx_Cpx_Label.Text = 'Cpx';
            app.FeEditFieldLabel_3 = uilabel(app.GrtCpx_Input_TabGrid);
            app.FeEditFieldLabel_3.Text = 'Fe';
            app.GrtCpx_grt_Fe_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel_3 = uilabel(app.GrtCpx_Input_TabGrid);
            app.MgLabel_3.Text = 'Mg';
            app.GrtCpx_grt_Mg_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel_2 = uilabel(app.GrtCpx_Input_TabGrid);
            app.CaLabel_2.Text = 'Ca';
            app.GrtCpx_grt_Ca_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel_2 = uilabel(app.GrtCpx_Input_TabGrid);
            app.MnLabel_2.Text = 'Mn';
            app.GrtCpx_grt_Mn_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_grt_Mn_EditField.Tooltip = {''};
            app.FeEditFieldLabel_4 = uilabel(app.GrtCpx_Input_TabGrid);
            app.FeEditFieldLabel_4.Text = 'Fe';
            app.GrtCpx_cpx_Fe_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_cpx_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_cpx_Fe_EditField.Tooltip = {''};
            app.MgLabel_4 = uilabel(app.GrtCpx_Input_TabGrid);
            app.MgLabel_4.Text = 'Mg';
            app.GrtCpx_cpx_Mg_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_cpx_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_cpx_Mg_EditField.Tooltip = {''};
            app.AlLabel_2 = uilabel(app.GrtCpx_Input_TabGrid);
            app.AlLabel_2.Text = 'Al';
            app.GrtCpx_cpx_Al_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_cpx_Al_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_cpx_Al_EditField.Tooltip = {''};
            app.PkbarLabel_2 = uilabel(app.GrtCpx_Input_TabGrid);
            app.PkbarLabel_2.Text = 'P (kbar)';
            app.GrtCpx_P_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_P_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_P_EditField.Tooltip = {''};
            app.CaLabel_3 = uilabel(app.GrtCpx_Input_TabGrid);
            app.CaLabel_3.Text = 'Ca';
            app.GrtCpx_cpx_Ca_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_cpx_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_cpx_Ca_EditField.Tooltip = {''};
            app.NaLabel = uilabel(app.GrtCpx_Input_TabGrid);
            app.NaLabel.Text = 'Na';
            app.GrtCpx_cpx_Na_EditField = uieditfield(app.GrtCpx_Input_TabGrid, 'numeric');
            app.GrtCpx_cpx_Na_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_cpx_Na_EditField.Tooltip = {''};
            app.GrtCpx_Results_Tab = uipanel(app.GrtCpx_Tab);
            app.GrtCpx_Results_TabGrid = uigridlayout(app.GrtCpx_Results_Tab);
            app.GrtCpx_MH72color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_MH72color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_MH72color_EditField.Value = 'red';
            app.GrtCpx_MH72_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_MH72_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_MH72_CheckBox.Text = '';
            app.GrtCpx_MH72_CheckBox.Value = true;
            app.MH72Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.MH72Label.Text = 'MH72';
            app.GrtCpx_MH72_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_MH72_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_MH72_EditField.Editable = 'off';
            app.GrtCpx_MH72_EditField.Tooltip = {''};
            app.XMgLabel_3 = uilabel(app.GrtCpx_Results_TabGrid);
            app.XMgLabel_3.Text = 'XMg';
            app.GrtCpx_cpx_XMg_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_cpx_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCpx_cpx_XMg_EditField.Editable = 'off';
            app.GrtCpx_cpx_XMg_EditField.Tooltip = {''};
            app.XFeLabel_3 = uilabel(app.GrtCpx_Results_TabGrid);
            app.XFeLabel_3.Text = 'XFe';
            app.GrtCpx_cpx_XFe_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_cpx_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCpx_cpx_XFe_EditField.Editable = 'off';
            app.GrtCpx_cpx_XFe_EditField.Tooltip = {''};
            app.XMgLabel_4 = uilabel(app.GrtCpx_Results_TabGrid);
            app.XMgLabel_4.Text = 'XMg';
            app.GrtCpx_grt_XMg_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCpx_grt_XMg_EditField.Editable = 'off';
            app.GrtCpx_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel_4 = uilabel(app.GrtCpx_Results_TabGrid);
            app.XFeLabel_4.Text = 'XFe';
            app.GrtCpx_grt_XFe_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCpx_grt_XFe_EditField.Editable = 'off';
            app.GrtCpx_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel_2 = uilabel(app.GrtCpx_Results_TabGrid);
            app.XCaLabel_2.Text = 'XCa';
            app.GrtCpx_grt_XCa_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCpx_grt_XCa_EditField.Editable = 'off';
            app.GrtCpx_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel_2 = uilabel(app.GrtCpx_Results_TabGrid);
            app.XMnLabel_2.Text = 'XMn';
            app.GrtCpx_grt_XMn_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCpx_grt_XMn_EditField.Editable = 'off';
            app.GrtCpx_grt_XMn_EditField.Tooltip = {''};
            app.XCaLabel_3 = uilabel(app.GrtCpx_Results_TabGrid);
            app.XCaLabel_3.Text = 'XCa';
            app.GrtCpx_cpx_XCa_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_cpx_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCpx_cpx_XCa_EditField.Editable = 'off';
            app.GrtCpx_cpx_XCa_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel_2 = uilabel(app.GrtCpx_Results_TabGrid);
            app.lnKdEditFieldLabel_2.Text = 'lnKd';
            app.GrtCpx_lnKd_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtCpx_lnKd_EditField.Editable = 'off';
            app.GrtCpx_lnKd_EditField.Tooltip = {''};
            app.GrtCpx_GarnetResult_Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_GarnetResult_Label.Text = 'Garnet';
            app.GrtCpx_CpxResult_Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_CpxResult_Label.Text = 'Cpx';
            app.RG74Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.RG74Label.Text = 'RG74';
            app.GrtCpx_RG74_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_RG74_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_RG74_EditField.Editable = 'off';
            app.GrtCpx_RG74_EditField.Tooltip = {''};
            app.MG78EditFieldLabel_4 = uilabel(app.GrtCpx_Results_TabGrid);
            app.MG78EditFieldLabel_4.Text = 'MG78';
            app.GrtCpx_MG78_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_MG78_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_MG78_EditField.Editable = 'off';
            app.GrtCpx_MG78_EditField.Tooltip = {''};
            app.EG79Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.EG79Label.Text = 'EG79';
            app.GrtCpx_EG79_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_EG79_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_EG79_EditField.Editable = 'off';
            app.GrtCpx_EG79_EditField.Tooltip = {''};
            app.S79Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.S79Label.Text = 'S79';
            app.GrtCpx_S79_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_S79_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_S79_EditField.Editable = 'off';
            app.GrtCpx_S79_EditField.Tooltip = {''};
            app.D80Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.D80Label.Text = 'D80';
            app.GrtCpx_D80_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_D80_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_D80_EditField.Editable = 'off';
            app.GrtCpx_D80_EditField.Tooltip = {''};
            app.P85Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.P85Label.Text = 'P85';
            app.GrtCpx_P85_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_P85_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_P85_EditField.Editable = 'off';
            app.GrtCpx_P85_EditField.Tooltip = {''};
            app.K88Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.K88Label.Text = 'K88';
            app.GrtCpx_K88_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_K88_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_K88_EditField.Editable = 'off';
            app.GrtCpx_K88_EditField.Tooltip = {''};
            app.A94Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.A94Label.Text = 'A94';
            app.GrtCpx_A94_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_A94_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_A94_EditField.Editable = 'off';
            app.GrtCpx_A94_EditField.Tooltip = {''};
            app.R00Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.R00Label.Text = 'R00';
            app.GrtCpx_R00_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_R00_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_R00_EditField.Editable = 'off';
            app.GrtCpx_R00_EditField.Tooltip = {''};
            app.N09Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.N09Label.Text = 'N09';
            app.GrtCpx_N09_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'numeric');
            app.GrtCpx_N09_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCpx_N09_EditField.Editable = 'off';
            app.GrtCpx_N09_EditField.Tooltip = {''};
            app.GrtCpx_RG74_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_RG74_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_RG74_CheckBox.Text = '';
            app.GrtCpx_RG74_CheckBox.Value = true;
            app.GrtCpx_MG78_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_MG78_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_MG78_CheckBox.Text = '';
            app.GrtCpx_MG78_CheckBox.Value = true;
            app.GrtCpx_EG79_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_EG79_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_EG79_CheckBox.Text = '';
            app.GrtCpx_EG79_CheckBox.Value = true;
            app.GrtCpx_S79_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_S79_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_S79_CheckBox.Text = '';
            app.GrtCpx_S79_CheckBox.Value = true;
            app.GrtCpx_D80_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_D80_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_D80_CheckBox.Text = '';
            app.GrtCpx_D80_CheckBox.Value = true;
            app.GrtCpx_P85_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_P85_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_P85_CheckBox.Text = '';
            app.GrtCpx_P85_CheckBox.Value = true;
            app.GrtCpx_K88_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_K88_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_K88_CheckBox.Text = '';
            app.GrtCpx_K88_CheckBox.Value = true;
            app.GrtCpx_A94_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_A94_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_A94_CheckBox.Text = '';
            app.GrtCpx_A94_CheckBox.Value = true;
            app.GrtCpx_R00_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_R00_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_R00_CheckBox.Text = '';
            app.GrtCpx_R00_CheckBox.Value = true;
            app.GrtCpx_N09_CheckBox = uicheckbox(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_N09_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_N09_CheckBox.Text = '';
            app.GrtCpx_N09_CheckBox.Value = true;
            app.GrtCpx_RG74color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_RG74color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_RG74color_EditField.Value = 'red';
            app.GrtCpx_MG78color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_MG78color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_MG78color_EditField.Value = 'red';
            app.GrtCpx_EG79color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_EG79color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_EG79color_EditField.Value = 'red';
            app.GrtCpx_S79color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_S79color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_S79color_EditField.Value = 'red';
            app.GrtCpx_D80color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_D80color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_D80color_EditField.Value = 'red';
            app.GrtCpx_P85color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_P85color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_P85color_EditField.Value = 'red';
            app.GrtCpx_K88color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_K88color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_K88color_EditField.Value = 'red';
            app.GrtCpx_A94color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_A94color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_A94color_EditField.Value = 'red';
            app.GrtCpx_R00color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_R00color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_R00color_EditField.Value = 'red';
            app.GrtCpx_N09color_EditField = uieditfield(app.GrtCpx_Results_TabGrid, 'text');
            app.GrtCpx_N09color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_N09color_EditField.Value = 'red';
            app.GrtCpx_Thermometers_Label = uilabel(app.GrtCpx_Results_TabGrid);
            app.GrtCpx_Thermometers_Label.Text = 'Thermometers';
            app.GrtCpx_Plot_Tab = uipanel(app.GrtCpx_Tab);
            app.GrtCpx_Plot_TabGrid = uigridlayout(app.GrtCpx_Plot_Tab);
            app.GrtCpx_P_max_Label = uilabel(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_P_max_Label.Text = 'P max';
            app.GrtCpx_P_max = uispinner(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_P_max.Limits = [1 Inf];
            app.GrtCpx_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_P_max.Tooltip = {'P (kbar) max'};
            app.GrtCpx_P_max.Value = 15;
            app.GrtCpx_P_min_Label = uilabel(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_P_min_Label.Text = 'P min';
            app.GrtCpx_T_max_Label = uilabel(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_T_max_Label.Text = 'T max';
            app.GrtCpx_P_min = uispinner(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_P_min.Limits = [0 Inf];
            app.GrtCpx_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_P_min.Tooltip = {'P (kbar) min'};
            app.GrtCpx_T_max = uispinner(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_T_max.Step = 50;
            app.GrtCpx_T_max.Limits = [50 Inf];
            app.GrtCpx_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_T_max.Tooltip = {'T (C) max'};
            app.GrtCpx_T_max.Value = 1000;
            app.GrtCpx_GridCheckBox = uicheckbox(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_GridCheckBox.Text = 'Grid';
            app.GrtCpx_LineWidth_Label = uilabel(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_LineWidth_Label.Text = 'Width';
            app.GrtCpx_LineWidth_Spinner = uispinner(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_LineWidth_Spinner.Step = 0.5;
            app.GrtCpx_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtCpx_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtCpx_LineWidth_Spinner.Value = 1;
            app.GrtCpx_T_min_Label = uilabel(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_T_min_Label.Text = 'T min';
            app.GrtCpx_T_min = uispinner(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_T_min.Step = 50;
            app.GrtCpx_T_min.Limits = [0 Inf];
            app.GrtCpx_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtCpx, true);
            app.GrtCpx_T_min.Tooltip = {'T (C) min'};
            app.GrtCpx_UIAxes = uiaxes(app.GrtCpx_Plot_TabGrid);
            title(app.GrtCpx_UIAxes, '')
            xlabel(app.GrtCpx_UIAxes, 'T (C)')
            ylabel(app.GrtCpx_UIAxes, 'P (kbar)')
            app.GrtCpx_UIAxes.XLim = [0 1000];
            app.GrtCpx_UIAxes.YLim = [0 15];
            app.GrtCpx_UIAxes.LineStyleOrder = {'-'};
            app.GrtCpx_DisplayAll_CheckBox = uicheckbox(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCpx_DisplayAll_CheckBoxValueChanged, true);
            app.GrtCpx_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtCpx_DisplayAll_CheckBox.Value = true;
            app.GrtCpx_ColorAll_EditField = uieditfield(app.GrtCpx_Plot_TabGrid, 'text');
            app.GrtCpx_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCpx_ColorAll_EditFieldValueChanged, true);
            app.GrtCpx_ColorAll_EditField.Value = 'red';
            app.GrtCpx_Colour_Label = uilabel(app.GrtCpx_Plot_TabGrid);
            app.GrtCpx_Colour_Label.Text = 'Colour';
            app.GrtCrd_Tab = uitab(app.TabGroup);
            app.GrtCrd_Tab.Title = 'Grt-Crd';
            app.GrtCrd_Input_Tab = uipanel(app.GrtCrd_Tab);
            app.GrtCrd_Input_TabGrid = uigridlayout(app.GrtCrd_Input_Tab);
            app.GrtCrd_Garnet_Label = uilabel(app.GrtCrd_Input_TabGrid);
            app.GrtCrd_Garnet_Label.Text = 'Garnet';
            app.GrtCrd_Cordierite_Label = uilabel(app.GrtCrd_Input_TabGrid);
            app.GrtCrd_Cordierite_Label.Text = 'Cordierite';
            app.FeEditFieldLabel_6 = uilabel(app.GrtCrd_Input_TabGrid);
            app.FeEditFieldLabel_6.Text = 'Fe';
            app.GrtCrd_grt_Fe_EditField = uieditfield(app.GrtCrd_Input_TabGrid, 'numeric');
            app.GrtCrd_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel_7 = uilabel(app.GrtCrd_Input_TabGrid);
            app.MgLabel_7.Text = 'Mg';
            app.GrtCrd_grt_Mg_EditField = uieditfield(app.GrtCrd_Input_TabGrid, 'numeric');
            app.GrtCrd_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel_5 = uilabel(app.GrtCrd_Input_TabGrid);
            app.CaLabel_5.Text = 'Ca';
            app.GrtCrd_grt_Ca_EditField = uieditfield(app.GrtCrd_Input_TabGrid, 'numeric');
            app.GrtCrd_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel_5 = uilabel(app.GrtCrd_Input_TabGrid);
            app.MnLabel_5.Text = 'Mn';
            app.GrtCrd_grt_Mn_EditField = uieditfield(app.GrtCrd_Input_TabGrid, 'numeric');
            app.GrtCrd_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_grt_Mn_EditField.Tooltip = {''};
            app.FeLabel_2 = uilabel(app.GrtCrd_Input_TabGrid);
            app.FeLabel_2.Text = 'Fe';
            app.GrtCrd_crd_Fe_EditField = uieditfield(app.GrtCrd_Input_TabGrid, 'numeric');
            app.GrtCrd_crd_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_crd_Fe_EditField.Tooltip = {''};
            app.PkbarLabel_4 = uilabel(app.GrtCrd_Input_TabGrid);
            app.PkbarLabel_4.Text = 'P (kbar)';
            app.GrtCrd_P_EditField = uieditfield(app.GrtCrd_Input_TabGrid, 'numeric');
            app.GrtCrd_P_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_P_EditField.Tooltip = {''};
            app.MgLabel_8 = uilabel(app.GrtCrd_Input_TabGrid);
            app.MgLabel_8.Text = 'Mg';
            app.GrtCrd_crd_Mg_EditField = uieditfield(app.GrtCrd_Input_TabGrid, 'numeric');
            app.GrtCrd_crd_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_crd_Mg_EditField.Tooltip = {''};
            app.GrtCrd_Results_Tab = uipanel(app.GrtCrd_Tab);
            app.GrtCrd_Results_TabGrid = uigridlayout(app.GrtCrd_Results_Tab);
            app.GrtCrd_C71color_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'text');
            app.GrtCrd_C71color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_C71color_EditField.Value = 'red';
            app.GrtCrd_C71_CheckBox = uicheckbox(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_C71_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_C71_CheckBox.Text = '';
            app.GrtCrd_C71_CheckBox.Value = true;
            app.C71Label = uilabel(app.GrtCrd_Results_TabGrid);
            app.C71Label.Text = 'C71';
            app.GrtCrd_C71_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_C71_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCrd_C71_EditField.Editable = 'off';
            app.GrtCrd_C71_EditField.Tooltip = {''};
            app.XMgLabel_7 = uilabel(app.GrtCrd_Results_TabGrid);
            app.XMgLabel_7.Text = 'XMg';
            app.GrtCrd_crd_XMg_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_crd_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCrd_crd_XMg_EditField.Editable = 'off';
            app.GrtCrd_crd_XMg_EditField.Tooltip = {''};
            app.XFeLabel_7 = uilabel(app.GrtCrd_Results_TabGrid);
            app.XFeLabel_7.Text = 'XFe';
            app.GrtCrd_crd_XFe_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_crd_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCrd_crd_XFe_EditField.Editable = 'off';
            app.GrtCrd_crd_XFe_EditField.Tooltip = {''};
            app.XMgLabel_8 = uilabel(app.GrtCrd_Results_TabGrid);
            app.XMgLabel_8.Text = 'XMg';
            app.GrtCrd_grt_XMg_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCrd_grt_XMg_EditField.Editable = 'off';
            app.GrtCrd_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel_8 = uilabel(app.GrtCrd_Results_TabGrid);
            app.XFeLabel_8.Text = 'XFe';
            app.GrtCrd_grt_XFe_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCrd_grt_XFe_EditField.Editable = 'off';
            app.GrtCrd_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel_5 = uilabel(app.GrtCrd_Results_TabGrid);
            app.XCaLabel_5.Text = 'XCa';
            app.GrtCrd_grt_XCa_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCrd_grt_XCa_EditField.Editable = 'off';
            app.GrtCrd_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel_5 = uilabel(app.GrtCrd_Results_TabGrid);
            app.XMnLabel_5.Text = 'XMn';
            app.GrtCrd_grt_XMn_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtCrd_grt_XMn_EditField.Editable = 'off';
            app.GrtCrd_grt_XMn_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel_4 = uilabel(app.GrtCrd_Results_TabGrid);
            app.lnKdEditFieldLabel_4.Text = 'lnKd';
            app.GrtCrd_lnKd_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtCrd_lnKd_EditField.Editable = 'off';
            app.GrtCrd_lnKd_EditField.Tooltip = {''};
            app.GrtCrd_GarnetResult_Label = uilabel(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_GarnetResult_Label.Text = 'Garnet';
            app.GrtCrd_CordieriteResult_Label = uilabel(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_CordieriteResult_Label.Text = 'Cordierite';
            app.T76Label_2 = uilabel(app.GrtCrd_Results_TabGrid);
            app.T76Label_2.Text = 'T76';
            app.GrtCrd_T76_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_T76_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCrd_T76_EditField.Editable = 'off';
            app.GrtCrd_T76_EditField.Tooltip = {''};
            app.HL77Label_2 = uilabel(app.GrtCrd_Results_TabGrid);
            app.HL77Label_2.Text = 'HL77';
            app.GrtCrd_HL77_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_HL77_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCrd_HL77_EditField.Editable = 'off';
            app.GrtCrd_HL77_EditField.Tooltip = {''};
            app.P85Label_3 = uilabel(app.GrtCrd_Results_TabGrid);
            app.P85Label_3.Text = 'P85';
            app.GrtCrd_P85_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_P85_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCrd_P85_EditField.Editable = 'off';
            app.GrtCrd_P85_EditField.Tooltip = {''};
            app.B88Label = uilabel(app.GrtCrd_Results_TabGrid);
            app.B88Label.Text = 'B88';
            app.GrtCrd_B88_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_B88_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCrd_B88_EditField.Editable = 'off';
            app.GrtCrd_B88_EditField.Tooltip = {''};
            app.B93Label = uilabel(app.GrtCrd_Results_TabGrid);
            app.B93Label.Text = 'B93';
            app.GrtCrd_B93_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'numeric');
            app.GrtCrd_B93_EditField.ValueDisplayFormat = '%.0f';
            app.GrtCrd_B93_EditField.Editable = 'off';
            app.GrtCrd_B93_EditField.Tooltip = {''};
            app.GrtCrd_T76_CheckBox = uicheckbox(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_T76_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_T76_CheckBox.Text = '';
            app.GrtCrd_T76_CheckBox.Value = true;
            app.GrtCrd_HL77_CheckBox = uicheckbox(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_HL77_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_HL77_CheckBox.Text = '';
            app.GrtCrd_HL77_CheckBox.Value = true;
            app.GrtCrd_P85_CheckBox = uicheckbox(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_P85_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_P85_CheckBox.Text = '';
            app.GrtCrd_P85_CheckBox.Value = true;
            app.GrtCrd_B88_CheckBox = uicheckbox(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_B88_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_B88_CheckBox.Text = '';
            app.GrtCrd_B88_CheckBox.Value = true;
            app.GrtCrd_B93_CheckBox = uicheckbox(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_B93_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_B93_CheckBox.Text = '';
            app.GrtCrd_B93_CheckBox.Value = true;
            app.GrtCrd_T76color_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'text');
            app.GrtCrd_T76color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_T76color_EditField.Value = 'red';
            app.GrtCrd_HL77color_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'text');
            app.GrtCrd_HL77color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_HL77color_EditField.Value = 'red';
            app.GrtCrd_P85color_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'text');
            app.GrtCrd_P85color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_P85color_EditField.Value = 'red';
            app.GrtCrd_B88color_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'text');
            app.GrtCrd_B88color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_B88color_EditField.Value = 'red';
            app.GrtCrd_B93color_EditField = uieditfield(app.GrtCrd_Results_TabGrid, 'text');
            app.GrtCrd_B93color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_B93color_EditField.Value = 'red';
            app.GrtCrd_Thermometers_Label = uilabel(app.GrtCrd_Results_TabGrid);
            app.GrtCrd_Thermometers_Label.Text = 'Thermometers';
            app.GrtCrd_Plot_Tab = uipanel(app.GrtCrd_Tab);
            app.GrtCrd_Plot_TabGrid = uigridlayout(app.GrtCrd_Plot_Tab);
            app.GrtCrd_P_max_Label = uilabel(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_P_max_Label.Text = 'P max';
            app.GrtCrd_P_max = uispinner(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_P_max.Limits = [1 Inf];
            app.GrtCrd_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_P_max.Tooltip = {'P (kbar) max'};
            app.GrtCrd_P_max.Value = 15;
            app.GrtCrd_P_min_Label = uilabel(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_P_min_Label.Text = 'P min';
            app.GrtCrd_T_max_Label = uilabel(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_T_max_Label.Text = 'T max';
            app.GrtCrd_P_min = uispinner(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_P_min.Limits = [0 Inf];
            app.GrtCrd_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_P_min.Tooltip = {'P (kbar) min'};
            app.GrtCrd_T_max = uispinner(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_T_max.Step = 50;
            app.GrtCrd_T_max.Limits = [50 Inf];
            app.GrtCrd_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_T_max.Tooltip = {'T (C) max'};
            app.GrtCrd_T_max.Value = 1000;
            app.GrtCrd_GridCheckBox = uicheckbox(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_GridCheckBox.Text = 'Grid';
            app.GrtCrd_LineWidth_Label = uilabel(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_LineWidth_Label.Text = 'Width';
            app.GrtCrd_LineWidth_Spinner = uispinner(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_LineWidth_Spinner.Step = 0.5;
            app.GrtCrd_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtCrd_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtCrd_LineWidth_Spinner.Value = 1;
            app.GrtCrd_T_min_Label = uilabel(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_T_min_Label.Text = 'T min';
            app.GrtCrd_T_min = uispinner(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_T_min.Step = 50;
            app.GrtCrd_T_min.Limits = [0 Inf];
            app.GrtCrd_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtCrd, true);
            app.GrtCrd_T_min.Tooltip = {'T (C) min'};
            app.GrtCrd_UIAxes = uiaxes(app.GrtCrd_Plot_TabGrid);
            title(app.GrtCrd_UIAxes, '')
            xlabel(app.GrtCrd_UIAxes, 'T (C)')
            ylabel(app.GrtCrd_UIAxes, 'P (kbar)')
            app.GrtCrd_UIAxes.XLim = [0 1000];
            app.GrtCrd_UIAxes.YLim = [0 15];
            app.GrtCrd_UIAxes.LineStyleOrder = {'-'};
            app.GrtCrd_DisplayAll_CheckBox = uicheckbox(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtCrd_DisplayAll_CheckBoxValueChanged, true);
            app.GrtCrd_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtCrd_DisplayAll_CheckBox.Value = true;
            app.GrtCrd_ColorAll_EditField = uieditfield(app.GrtCrd_Plot_TabGrid, 'text');
            app.GrtCrd_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtCrd_ColorAll_EditFieldValueChanged, true);
            app.GrtCrd_ColorAll_EditField.Value = 'red';
            app.GrtCrd_Colour_Label = uilabel(app.GrtCrd_Plot_TabGrid);
            app.GrtCrd_Colour_Label.Text = 'Colour';
            app.GrtHbl_Tab = uitab(app.TabGroup);
            app.GrtHbl_Tab.Title = 'Grt-Hbl';
            app.GrtHbl_Input_Tab = uipanel(app.GrtHbl_Tab);
            app.GrtHbl_Input_TabGrid = uigridlayout(app.GrtHbl_Input_Tab);
            app.GrtHbl_Garnet_Label = uilabel(app.GrtHbl_Input_TabGrid);
            app.GrtHbl_Garnet_Label.Text = 'Garnet';
            app.GrtHbl_Hornblende_Label = uilabel(app.GrtHbl_Input_TabGrid);
            app.GrtHbl_Hornblende_Label.Text = 'Hornblende';
            app.FeEditFieldLabel_7 = uilabel(app.GrtHbl_Input_TabGrid);
            app.FeEditFieldLabel_7.Text = 'Fe';
            app.GrtHbl_grt_Fe_EditField = uieditfield(app.GrtHbl_Input_TabGrid, 'numeric');
            app.GrtHbl_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel_9 = uilabel(app.GrtHbl_Input_TabGrid);
            app.MgLabel_9.Text = 'Mg';
            app.GrtHbl_grt_Mg_EditField = uieditfield(app.GrtHbl_Input_TabGrid, 'numeric');
            app.GrtHbl_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel_6 = uilabel(app.GrtHbl_Input_TabGrid);
            app.CaLabel_6.Text = 'Ca';
            app.GrtHbl_grt_Ca_EditField = uieditfield(app.GrtHbl_Input_TabGrid, 'numeric');
            app.GrtHbl_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel_6 = uilabel(app.GrtHbl_Input_TabGrid);
            app.MnLabel_6.Text = 'Mn';
            app.GrtHbl_grt_Mn_EditField = uieditfield(app.GrtHbl_Input_TabGrid, 'numeric');
            app.GrtHbl_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_grt_Mn_EditField.Tooltip = {''};
            app.FeLabel_3 = uilabel(app.GrtHbl_Input_TabGrid);
            app.FeLabel_3.Text = 'Fe';
            app.GrtHbl_hbl_Fe_EditField = uieditfield(app.GrtHbl_Input_TabGrid, 'numeric');
            app.GrtHbl_hbl_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_hbl_Fe_EditField.Tooltip = {''};
            app.MgLabel_10 = uilabel(app.GrtHbl_Input_TabGrid);
            app.MgLabel_10.Text = 'Mg';
            app.GrtHbl_hbl_Mg_EditField = uieditfield(app.GrtHbl_Input_TabGrid, 'numeric');
            app.GrtHbl_hbl_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_hbl_Mg_EditField.Tooltip = {''};
            app.GrtHbl_Results_Tab = uipanel(app.GrtHbl_Tab);
            app.GrtHbl_Results_TabGrid = uigridlayout(app.GrtHbl_Results_Tab);
            app.GrtHbl_GP84color_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'text');
            app.GrtHbl_GP84color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_GP84color_EditField.Value = 'red';
            app.GrtHbl_GP84_CheckBox = uicheckbox(app.GrtHbl_Results_TabGrid);
            app.GrtHbl_GP84_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_GP84_CheckBox.Text = '';
            app.GrtHbl_GP84_CheckBox.Value = true;
            app.GP84Label = uilabel(app.GrtHbl_Results_TabGrid);
            app.GP84Label.Text = 'GP84';
            app.GrtHbl_GP84_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_GP84_EditField.ValueDisplayFormat = '%.0f';
            app.GrtHbl_GP84_EditField.Editable = 'off';
            app.GrtHbl_GP84_EditField.Tooltip = {''};
            app.XMgLabel_9 = uilabel(app.GrtHbl_Results_TabGrid);
            app.XMgLabel_9.Text = 'XMg';
            app.GrtHbl_hbl_XMg_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_hbl_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtHbl_hbl_XMg_EditField.Editable = 'off';
            app.GrtHbl_hbl_XMg_EditField.Tooltip = {''};

            app.XFeLabel_9 = uilabel(app.GrtHbl_Results_TabGrid);
            app.XFeLabel_9.Text = 'XFe';
            app.GrtHbl_hbl_XFe_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_hbl_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtHbl_hbl_XFe_EditField.Editable = 'off';
            app.GrtHbl_hbl_XFe_EditField.Tooltip = {''};
            app.XMgLabel_10 = uilabel(app.GrtHbl_Results_TabGrid);
            app.XMgLabel_10.Text = 'XMg';
            app.GrtHbl_grt_XMg_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtHbl_grt_XMg_EditField.Editable = 'off';
            app.GrtHbl_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel_10 = uilabel(app.GrtHbl_Results_TabGrid);
            app.XFeLabel_10.Text = 'XFe';
            app.GrtHbl_grt_XFe_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtHbl_grt_XFe_EditField.Editable = 'off';
            app.GrtHbl_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel_6 = uilabel(app.GrtHbl_Results_TabGrid);
            app.XCaLabel_6.Text = 'XCa';
            app.GrtHbl_grt_XCa_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtHbl_grt_XCa_EditField.Editable = 'off';
            app.GrtHbl_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel_6 = uilabel(app.GrtHbl_Results_TabGrid);
            app.XMnLabel_6.Text = 'XMn';
            app.GrtHbl_grt_XMn_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtHbl_grt_XMn_EditField.Editable = 'off';
            app.GrtHbl_grt_XMn_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel_5 = uilabel(app.GrtHbl_Results_TabGrid);
            app.lnKdEditFieldLabel_5.Text = 'lnKd';
            app.GrtHbl_lnKd_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtHbl_lnKd_EditField.Editable = 'off';
            app.GrtHbl_lnKd_EditField.Tooltip = {''};
            app.GrtHbl_GarnetResult_Label = uilabel(app.GrtHbl_Results_TabGrid);
            app.GrtHbl_GarnetResult_Label.Text = 'Garnet';
            app.GrtHbl_HornblendeResult_Label = uilabel(app.GrtHbl_Results_TabGrid);
            app.GrtHbl_HornblendeResult_Label.Text = 'Hornblende';
            app.Per85Label = uilabel(app.GrtHbl_Results_TabGrid);
            app.Per85Label.Text = 'Per85';
            app.GrtHbl_Per85_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_Per85_EditField.ValueDisplayFormat = '%.0f';
            app.GrtHbl_Per85_EditField.Editable = 'off';
            app.GrtHbl_Per85_EditField.Tooltip = {''};
            app.Pow85Label = uilabel(app.GrtHbl_Results_TabGrid);
            app.Pow85Label.Text = 'Pow85';
            app.GrtHbl_Pow85_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_Pow85_EditField.ValueDisplayFormat = '%.0f';
            app.GrtHbl_Pow85_EditField.Editable = 'off';
            app.GrtHbl_Pow85_EditField.Tooltip = {''};
            app.R00Label_2 = uilabel(app.GrtHbl_Results_TabGrid);
            app.R00Label_2.Text = 'R00';
            app.GrtHbl_R00_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'numeric');
            app.GrtHbl_R00_EditField.ValueDisplayFormat = '%.0f';
            app.GrtHbl_R00_EditField.Editable = 'off';
            app.GrtHbl_R00_EditField.Tooltip = {''};
            app.GrtHbl_Per85_CheckBox = uicheckbox(app.GrtHbl_Results_TabGrid);
            app.GrtHbl_Per85_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_Per85_CheckBox.Text = '';
            app.GrtHbl_Per85_CheckBox.Value = true;
            app.GrtHbl_Pow85_CheckBox = uicheckbox(app.GrtHbl_Results_TabGrid);
            app.GrtHbl_Pow85_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_Pow85_CheckBox.Text = '';
            app.GrtHbl_Pow85_CheckBox.Value = true;
            app.GrtHbl_R00_CheckBox = uicheckbox(app.GrtHbl_Results_TabGrid);
            app.GrtHbl_R00_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_R00_CheckBox.Text = '';
            app.GrtHbl_R00_CheckBox.Value = true;
            app.GrtHbl_Per85color_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'text');
            app.GrtHbl_Per85color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_Per85color_EditField.Value = 'red';
            app.GrtHbl_Pow85color_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'text');
            app.GrtHbl_Pow85color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_Pow85color_EditField.Value = 'red';
            app.GrtHbl_R00color_EditField = uieditfield(app.GrtHbl_Results_TabGrid, 'text');
            app.GrtHbl_R00color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_R00color_EditField.Value = 'red';
            app.GrtHbl_Thermometers_Label = uilabel(app.GrtHbl_Results_TabGrid);
            app.GrtHbl_Thermometers_Label.Text = 'Thermometers';
            app.GrtHbl_Plot_Tab = uipanel(app.GrtHbl_Tab);
            app.GrtHbl_Plot_TabGrid = uigridlayout(app.GrtHbl_Plot_Tab);
            app.GrtHbl_P_max_Label = uilabel(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_P_max_Label.Text = 'P max';
            app.GrtHbl_P_max = uispinner(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_P_max.Limits = [1 Inf];
            app.GrtHbl_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_P_max.Tooltip = {'P (kbar) max'};
            app.GrtHbl_P_max.Value = 15;
            app.GrtHbl_P_min_Label = uilabel(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_P_min_Label.Text = 'P min';
            app.GrtHbl_T_max_Label = uilabel(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_T_max_Label.Text = 'T max';
            app.GrtHbl_P_min = uispinner(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_P_min.Limits = [0 Inf];
            app.GrtHbl_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_P_min.Tooltip = {'P (kbar) min'};
            app.GrtHbl_T_max = uispinner(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_T_max.Step = 50;
            app.GrtHbl_T_max.Limits = [50 Inf];
            app.GrtHbl_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_T_max.Tooltip = {'T (C) max'};
            app.GrtHbl_T_max.Value = 1000;
            app.GrtHbl_GridCheckBox = uicheckbox(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_GridCheckBox.Text = 'Grid';
            app.GrtHbl_LineWidth_Label = uilabel(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_LineWidth_Label.Text = 'Width';
            app.GrtHbl_LineWidth_Spinner = uispinner(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_LineWidth_Spinner.Step = 0.5;
            app.GrtHbl_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtHbl_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtHbl_LineWidth_Spinner.Value = 1;
            app.GrtHbl_T_min_Label = uilabel(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_T_min_Label.Text = 'T min';
            app.GrtHbl_T_min = uispinner(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_T_min.Step = 50;
            app.GrtHbl_T_min.Limits = [0 Inf];
            app.GrtHbl_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtHbl, true);
            app.GrtHbl_T_min.Tooltip = {'T (C) min'};
            app.GrtHbl_UIAxes = uiaxes(app.GrtHbl_Plot_TabGrid);
            title(app.GrtHbl_UIAxes, '')
            xlabel(app.GrtHbl_UIAxes, 'T (C)')
            ylabel(app.GrtHbl_UIAxes, 'P (kbar)')
            app.GrtHbl_UIAxes.XLim = [0 1000];
            app.GrtHbl_UIAxes.YLim = [0 15];
            app.GrtHbl_UIAxes.LineStyleOrder = {'-'};
            app.GrtHbl_DisplayAll_CheckBox = uicheckbox(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtHbl_DisplayAll_CheckBoxValueChanged, true);
            app.GrtHbl_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtHbl_DisplayAll_CheckBox.Value = true;
            app.GrtHbl_ColorAll_EditField = uieditfield(app.GrtHbl_Plot_TabGrid, 'text');
            app.GrtHbl_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtHbl_ColorAll_EditFieldValueChanged, true);
            app.GrtHbl_ColorAll_EditField.Value = 'red';
            app.GrtHbl_Colour_Label = uilabel(app.GrtHbl_Plot_TabGrid);
            app.GrtHbl_Colour_Label.Text = 'Colour';
            app.GrtIlm_Tab = uitab(app.TabGroup);
            app.GrtIlm_Tab.Title = 'Grt-Ilm';
            app.GrtIlm_Input_Tab = uipanel(app.GrtIlm_Tab);
            app.GrtIlm_Input_TabGrid = uigridlayout(app.GrtIlm_Input_Tab);
            app.GrtIlm_Garnet_Label = uilabel(app.GrtIlm_Input_TabGrid);
            app.GrtIlm_Garnet_Label.Text = 'Garnet';
            app.GrtIlm_Ilmenite_Label = uilabel(app.GrtIlm_Input_TabGrid);
            app.GrtIlm_Ilmenite_Label.Text = 'Ilmenite';
            app.FeEditFieldLabel_11 = uilabel(app.GrtIlm_Input_TabGrid);
            app.FeEditFieldLabel_11.Text = 'Fe';
            app.GrtIlm_grt_Fe_EditField = uieditfield(app.GrtIlm_Input_TabGrid, 'numeric');
            app.GrtIlm_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel_15 = uilabel(app.GrtIlm_Input_TabGrid);
            app.MgLabel_15.Text = 'Mg';
            app.GrtIlm_grt_Mg_EditField = uieditfield(app.GrtIlm_Input_TabGrid, 'numeric');
            app.GrtIlm_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel_9 = uilabel(app.GrtIlm_Input_TabGrid);
            app.CaLabel_9.Text = 'Ca';
            app.GrtIlm_grt_Ca_EditField = uieditfield(app.GrtIlm_Input_TabGrid, 'numeric');
            app.GrtIlm_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel_9 = uilabel(app.GrtIlm_Input_TabGrid);
            app.MnLabel_9.Text = 'Mn';
            app.GrtIlm_grt_Mn_EditField = uieditfield(app.GrtIlm_Input_TabGrid, 'numeric');
            app.GrtIlm_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_grt_Mn_EditField.Tooltip = {''};
            app.FeLabel_5 = uilabel(app.GrtIlm_Input_TabGrid);
            app.FeLabel_5.Text = 'Fe';
            app.GrtIlm_ilm_Fe_EditField = uieditfield(app.GrtIlm_Input_TabGrid, 'numeric');
            app.GrtIlm_ilm_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_ilm_Fe_EditField.Tooltip = {''};
            app.MnLabel_10 = uilabel(app.GrtIlm_Input_TabGrid);
            app.MnLabel_10.Text = 'Mn';
            app.GrtIlm_ilm_Mn_EditField = uieditfield(app.GrtIlm_Input_TabGrid, 'numeric');
            app.GrtIlm_ilm_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_ilm_Mn_EditField.Tooltip = {''};
            app.GrtIlm_Results_Tab = uipanel(app.GrtIlm_Tab);
            app.GrtIlm_Results_TabGrid = uigridlayout(app.GrtIlm_Results_Tab);
            app.GrtIlm_P87color_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'text');
            app.GrtIlm_P87color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_P87color_EditField.Value = 'red';
            app.GrtIlm_P87_CheckBox = uicheckbox(app.GrtIlm_Results_TabGrid);
            app.GrtIlm_P87_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_P87_CheckBox.Text = '';
            app.GrtIlm_P87_CheckBox.Value = true;
            app.P87Label = uilabel(app.GrtIlm_Results_TabGrid);
            app.P87Label.Text = 'P87';
            app.GrtIlm_P87_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_P87_EditField.ValueDisplayFormat = '%.0f';
            app.GrtIlm_P87_EditField.Editable = 'off';
            app.GrtIlm_P87_EditField.Tooltip = {''};
            app.XMnLabel_10 = uilabel(app.GrtIlm_Results_TabGrid);
            app.XMnLabel_10.Text = 'XMn';
            app.GrtIlm_ilm_XMn_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_ilm_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtIlm_ilm_XMn_EditField.Editable = 'off';
            app.GrtIlm_ilm_XMn_EditField.Tooltip = {''};
            app.XFeLabel_15 = uilabel(app.GrtIlm_Results_TabGrid);
            app.XFeLabel_15.Text = 'XFe';
            app.GrtIlm_ilm_XFe_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_ilm_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtIlm_ilm_XFe_EditField.Editable = 'off';
            app.GrtIlm_ilm_XFe_EditField.Tooltip = {''};
            app.XMgLabel_16 = uilabel(app.GrtIlm_Results_TabGrid);
            app.XMgLabel_16.Text = 'XMg';
            app.GrtIlm_grt_XMg_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtIlm_grt_XMg_EditField.Editable = 'off';
            app.GrtIlm_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel_16 = uilabel(app.GrtIlm_Results_TabGrid);
            app.XFeLabel_16.Text = 'XFe';
            app.GrtIlm_grt_XFe_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtIlm_grt_XFe_EditField.Editable = 'off';
            app.GrtIlm_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel_9 = uilabel(app.GrtIlm_Results_TabGrid);
            app.XCaLabel_9.Text = 'XCa';
            app.GrtIlm_grt_XCa_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtIlm_grt_XCa_EditField.Editable = 'off';
            app.GrtIlm_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel_9 = uilabel(app.GrtIlm_Results_TabGrid);
            app.XMnLabel_9.Text = 'XMn';
            app.GrtIlm_grt_XMn_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtIlm_grt_XMn_EditField.Editable = 'off';
            app.GrtIlm_grt_XMn_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel_8 = uilabel(app.GrtIlm_Results_TabGrid);
            app.lnKdEditFieldLabel_8.Text = 'lnKd';
            app.GrtIlm_lnKd_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtIlm_lnKd_EditField.Editable = 'off';
            app.GrtIlm_lnKd_EditField.Tooltip = {''};
            app.GrtIlm_GarnetResult_Label = uilabel(app.GrtIlm_Results_TabGrid);
            app.GrtIlm_GarnetResult_Label.Text = 'Garnet';
            app.GrtIlm_IlmeniteResult_Label = uilabel(app.GrtIlm_Results_TabGrid);
            app.GrtIlm_IlmeniteResult_Label.Text = 'Ilmenite';
            app.P91Label_2 = uilabel(app.GrtIlm_Results_TabGrid);
            app.P91Label_2.Text = 'P91';
            app.GrtIlm_P91_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_P91_EditField.ValueDisplayFormat = '%.0f';
            app.GrtIlm_P91_EditField.Editable = 'off';
            app.GrtIlm_P91_EditField.Tooltip = {''};
            app.M10Label = uilabel(app.GrtIlm_Results_TabGrid);
            app.M10Label.Text = 'M10';
            app.GrtIlm_M10_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'numeric');
            app.GrtIlm_M10_EditField.ValueDisplayFormat = '%.0f';
            app.GrtIlm_M10_EditField.Editable = 'off';
            app.GrtIlm_M10_EditField.Tooltip = {''};
            app.GrtIlm_P91_CheckBox = uicheckbox(app.GrtIlm_Results_TabGrid);
            app.GrtIlm_P91_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_P91_CheckBox.Text = '';
            app.GrtIlm_P91_CheckBox.Value = true;
            app.GrtIlm_M10_CheckBox = uicheckbox(app.GrtIlm_Results_TabGrid);
            app.GrtIlm_M10_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_M10_CheckBox.Text = '';
            app.GrtIlm_M10_CheckBox.Value = true;
            app.GrtIlm_P91color_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'text');
            app.GrtIlm_P91color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_P91color_EditField.Value = 'red';
            app.GrtIlm_M10color_EditField = uieditfield(app.GrtIlm_Results_TabGrid, 'text');
            app.GrtIlm_M10color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_M10color_EditField.Value = 'red';
            app.GrtIlm_Thermometers_Label = uilabel(app.GrtIlm_Results_TabGrid);
            app.GrtIlm_Thermometers_Label.Text = 'Thermometers';
            app.GrtIlm_Plot_Tab = uipanel(app.GrtIlm_Tab);
            app.GrtIlm_Plot_TabGrid = uigridlayout(app.GrtIlm_Plot_Tab);
            app.GrtIlm_P_max_Label = uilabel(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_P_max_Label.Text = 'P max';
            app.GrtIlm_P_max = uispinner(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_P_max.Limits = [1 Inf];
            app.GrtIlm_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_P_max.Tooltip = {'P (kbar) max'};
            app.GrtIlm_P_max.Value = 15;
            app.GrtIlm_P_min_Label = uilabel(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_P_min_Label.Text = 'P min';
            app.GrtIlm_T_max_Label = uilabel(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_T_max_Label.Text = 'T max';
            app.GrtIlm_P_min = uispinner(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_P_min.Limits = [0 Inf];
            app.GrtIlm_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_P_min.Tooltip = {'P (kbar) min'};
            app.GrtIlm_T_max = uispinner(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_T_max.Step = 50;
            app.GrtIlm_T_max.Limits = [50 Inf];
            app.GrtIlm_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_T_max.Tooltip = {'T (C) max'};
            app.GrtIlm_T_max.Value = 1000;
            app.GrtIlm_GridCheckBox = uicheckbox(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_GridCheckBox.Text = 'Grid';
            app.GrtIlm_LineWidth_Label = uilabel(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_LineWidth_Label.Text = 'Width';
            app.GrtIlm_LineWidth_Spinner = uispinner(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_LineWidth_Spinner.Step = 0.5;
            app.GrtIlm_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtIlm_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtIlm_LineWidth_Spinner.Value = 1;
            app.GrtIlm_T_min_Label = uilabel(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_T_min_Label.Text = 'T min';
            app.GrtIlm_T_min = uispinner(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_T_min.Step = 50;
            app.GrtIlm_T_min.Limits = [0 Inf];
            app.GrtIlm_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtIlm, true);
            app.GrtIlm_T_min.Tooltip = {'T (C) min'};
            app.GrtIlm_UIAxes = uiaxes(app.GrtIlm_Plot_TabGrid);
            title(app.GrtIlm_UIAxes, '')
            xlabel(app.GrtIlm_UIAxes, 'T (C)')
            ylabel(app.GrtIlm_UIAxes, 'P (kbar)')
            app.GrtIlm_UIAxes.XLim = [0 1000];
            app.GrtIlm_UIAxes.YLim = [0 15];
            app.GrtIlm_UIAxes.LineStyleOrder = {'-'};
            app.GrtIlm_DisplayAll_CheckBox = uicheckbox(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtIlm_DisplayAll_CheckBoxValueChanged, true);
            app.GrtIlm_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtIlm_DisplayAll_CheckBox.Value = true;
            app.GrtIlm_ColorAll_EditField = uieditfield(app.GrtIlm_Plot_TabGrid, 'text');
            app.GrtIlm_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtIlm_ColorAll_EditFieldValueChanged, true);
            app.GrtIlm_ColorAll_EditField.Value = 'red';
            app.GrtIlm_Colour_Label = uilabel(app.GrtIlm_Plot_TabGrid);
            app.GrtIlm_Colour_Label.Text = 'Colour';
            app.GrtPh_Tab = uitab(app.TabGroup);
            app.GrtPh_Tab.Title = 'Grt-Ph';
            app.GrtPh_Input_Tab = uipanel(app.GrtPh_Tab);
            app.GrtPh_Input_TabGrid = uigridlayout(app.GrtPh_Input_Tab);
            app.GrtPh_Garnet_Label = uilabel(app.GrtPh_Input_TabGrid);
            app.GrtPh_Garnet_Label.Text = 'Garnet';
            app.GrtPh_Phengite_Label = uilabel(app.GrtPh_Input_TabGrid);
            app.GrtPh_Phengite_Label.Text = 'Phengite';
            app.FeEditFieldLabel_9 = uilabel(app.GrtPh_Input_TabGrid);
            app.FeEditFieldLabel_9.Text = 'Fe';
            app.GrtPh_grt_Fe_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel_13 = uilabel(app.GrtPh_Input_TabGrid);
            app.MgLabel_13.Text = 'Mg';
            app.GrtPh_grt_Mg_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel_8 = uilabel(app.GrtPh_Input_TabGrid);
            app.CaLabel_8.Text = 'Ca';
            app.GrtPh_grt_Ca_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel_8 = uilabel(app.GrtPh_Input_TabGrid);
            app.MnLabel_8.Text = 'Mn';
            app.GrtPh_grt_Mn_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_grt_Mn_EditField.Tooltip = {''};
            app.FeEditFieldLabel_10 = uilabel(app.GrtPh_Input_TabGrid);
            app.FeEditFieldLabel_10.Text = 'Fe';
            app.GrtPh_ph_Fe_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_ph_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_ph_Fe_EditField.Tooltip = {''};
            app.MgLabel_14 = uilabel(app.GrtPh_Input_TabGrid);
            app.MgLabel_14.Text = 'Mg';
            app.GrtPh_ph_Mg_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_ph_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_ph_Mg_EditField.Tooltip = {''};
            app.AlLabel_4 = uilabel(app.GrtPh_Input_TabGrid);
            app.AlLabel_4.Text = 'Al';
            app.GrtPh_ph_Al_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_ph_Al_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_ph_Al_EditField.Tooltip = {''};
            app.PkbarLabel_6 = uilabel(app.GrtPh_Input_TabGrid);
            app.PkbarLabel_6.Text = 'P (kbar)';
            app.GrtPh_P_EditField = uieditfield(app.GrtPh_Input_TabGrid, 'numeric');
            app.GrtPh_P_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_P_EditField.Tooltip = {''};
            app.GrtPh_Results_Tab = uipanel(app.GrtPh_Tab);
            app.GrtPh_Results_TabGrid = uigridlayout(app.GrtPh_Results_Tab);
            app.GrtPh_W02acolor_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'text');
            app.GrtPh_W02acolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_W02acolor_EditField.Value = 'red';
            app.GrtPh_HF88color_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'text');
            app.GrtPh_HF88color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_HF88color_EditField.Value = 'red';
            app.GrtPh_W02a_CheckBox = uicheckbox(app.GrtPh_Results_TabGrid);
            app.GrtPh_W02a_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_W02a_CheckBox.Text = '';
            app.GrtPh_W02a_CheckBox.Value = true;
            app.GrtPh_HF88_CheckBox = uicheckbox(app.GrtPh_Results_TabGrid);
            app.GrtPh_HF88_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_HF88_CheckBox.Text = '';
            app.GrtPh_HF88_CheckBox.Value = true;
            app.GrtPh_GH82acolor_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'text');
            app.GrtPh_GH82acolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_GH82acolor_EditField.Value = 'red';
            app.GrtPh_KR78color_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'text');
            app.GrtPh_KR78color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_KR78color_EditField.Value = 'red';
            app.GrtPh_GH82a_CheckBox = uicheckbox(app.GrtPh_Results_TabGrid);
            app.GrtPh_GH82a_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_GH82a_CheckBox.Text = '';
            app.GrtPh_GH82a_CheckBox.Value = true;
            app.GrtPh_KR78_CheckBox = uicheckbox(app.GrtPh_Results_TabGrid);
            app.GrtPh_KR78_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_KR78_CheckBox.Text = '';
            app.GrtPh_KR78_CheckBox.Value = true;
            app.W02aLabel = uilabel(app.GrtPh_Results_TabGrid);
            app.W02aLabel.Text = 'W02a';
            app.GrtPh_W02a_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_W02a_EditField.ValueDisplayFormat = '%.0f';
            app.GrtPh_W02a_EditField.Editable = 'off';
            app.GrtPh_W02a_EditField.Tooltip = {''};
            app.HF88Label = uilabel(app.GrtPh_Results_TabGrid);
            app.HF88Label.Text = 'HF88';
            app.GrtPh_HF88_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_HF88_EditField.ValueDisplayFormat = '%.0f';
            app.GrtPh_HF88_EditField.Editable = 'off';
            app.GrtPh_HF88_EditField.Tooltip = {''};
            app.GH82aLabel = uilabel(app.GrtPh_Results_TabGrid);
            app.GH82aLabel.Text = 'GH82a';
            app.GrtPh_GH82a_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_GH82a_EditField.ValueDisplayFormat = '%.0f';
            app.GrtPh_GH82a_EditField.Editable = 'off';
            app.GrtPh_GH82a_EditField.Tooltip = {''};
            app.KR78Label = uilabel(app.GrtPh_Results_TabGrid);
            app.KR78Label.Text = 'KR78';
            app.GrtPh_KR78_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_KR78_EditField.ValueDisplayFormat = '%.0f';
            app.GrtPh_KR78_EditField.Editable = 'off';
            app.GrtPh_KR78_EditField.Tooltip = {''};
            app.XMgLabel_13 = uilabel(app.GrtPh_Results_TabGrid);
            app.XMgLabel_13.Text = 'XMg';
            app.GrtPh_ph_XMg_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_ph_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtPh_ph_XMg_EditField.Editable = 'off';
            app.GrtPh_ph_XMg_EditField.Tooltip = {''};
            app.XFeLabel_13 = uilabel(app.GrtPh_Results_TabGrid);
            app.XFeLabel_13.Text = 'XFe';
            app.GrtPh_ph_XFe_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_ph_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtPh_ph_XFe_EditField.Editable = 'off';
            app.GrtPh_ph_XFe_EditField.Tooltip = {''};
            app.XMgLabel_14 = uilabel(app.GrtPh_Results_TabGrid);
            app.XMgLabel_14.Text = 'XMg';
            app.GrtPh_grt_XMg_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtPh_grt_XMg_EditField.Editable = 'off';
            app.GrtPh_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel_14 = uilabel(app.GrtPh_Results_TabGrid);
            app.XFeLabel_14.Text = 'XFe';
            app.GrtPh_grt_XFe_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtPh_grt_XFe_EditField.Editable = 'off';
            app.GrtPh_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel_8 = uilabel(app.GrtPh_Results_TabGrid);
            app.XCaLabel_8.Text = 'XCa';
            app.GrtPh_grt_XCa_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtPh_grt_XCa_EditField.Editable = 'off';
            app.GrtPh_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel_8 = uilabel(app.GrtPh_Results_TabGrid);
            app.XMnLabel_8.Text = 'XMn';
            app.GrtPh_grt_XMn_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtPh_grt_XMn_EditField.Editable = 'off';
            app.GrtPh_grt_XMn_EditField.Tooltip = {''};
            app.XAlLabel_3 = uilabel(app.GrtPh_Results_TabGrid);
            app.XAlLabel_3.Text = 'XAl';
            app.GrtPh_ph_XAl_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_ph_XAl_EditField.ValueDisplayFormat = '%.2f';
            app.GrtPh_ph_XAl_EditField.Editable = 'off';
            app.GrtPh_ph_XAl_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel_7 = uilabel(app.GrtPh_Results_TabGrid);
            app.lnKdEditFieldLabel_7.Text = 'lnKd';
            app.GrtPh_lnKd_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtPh_lnKd_EditField.Editable = 'off';
            app.GrtPh_lnKd_EditField.Tooltip = {''};
            app.GH82bLabel = uilabel(app.GrtPh_Results_TabGrid);
            app.GH82bLabel.Text = 'GH82b';
            app.GrtPh_GH82b_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_GH82b_EditField.ValueDisplayFormat = '%.0f';
            app.GrtPh_GH82b_EditField.Editable = 'off';
            app.GrtPh_GH82b_EditField.Tooltip = {''};
            app.GH82cLabel = uilabel(app.GrtPh_Results_TabGrid);
            app.GH82cLabel.Text = 'GH82c';
            app.GrtPh_GH82c_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_GH82c_EditField.ValueDisplayFormat = '%.0f';
            app.GrtPh_GH82c_EditField.Editable = 'off';
            app.GrtPh_GH82c_EditField.Tooltip = {''};
            app.W02bLabel = uilabel(app.GrtPh_Results_TabGrid);
            app.W02bLabel.Text = 'W02b';
            app.GrtPh_W02b_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'numeric');
            app.GrtPh_W02b_EditField.ValueDisplayFormat = '%.0f';
            app.GrtPh_W02b_EditField.Editable = 'off';
            app.GrtPh_W02b_EditField.Tooltip = {''};
            app.GrtPh_GH82b_CheckBox = uicheckbox(app.GrtPh_Results_TabGrid);
            app.GrtPh_GH82b_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_GH82b_CheckBox.Text = '';
            app.GrtPh_GH82b_CheckBox.Value = true;
            app.GrtPh_GH82c_CheckBox = uicheckbox(app.GrtPh_Results_TabGrid);
            app.GrtPh_GH82c_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_GH82c_CheckBox.Text = '';
            app.GrtPh_GH82c_CheckBox.Value = true;
            app.GrtPh_W02b_CheckBox = uicheckbox(app.GrtPh_Results_TabGrid);
            app.GrtPh_W02b_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_W02b_CheckBox.Text = '';
            app.GrtPh_W02b_CheckBox.Value = true;
            app.GrtPh_GH82bcolor_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'text');
            app.GrtPh_GH82bcolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_GH82bcolor_EditField.Value = 'red';
            app.GrtPh_GH82ccolor_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'text');
            app.GrtPh_GH82ccolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_GH82ccolor_EditField.Value = 'red';
            app.GrtPh_W02bcolor_EditField = uieditfield(app.GrtPh_Results_TabGrid, 'text');
            app.GrtPh_W02bcolor_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_W02bcolor_EditField.Value = 'red';
            app.GrtPh_GarnetResult_Label = uilabel(app.GrtPh_Results_TabGrid);
            app.GrtPh_GarnetResult_Label.Text = 'Garnet';
            app.GrtPh_PhengiteResult_Label = uilabel(app.GrtPh_Results_TabGrid);
            app.GrtPh_PhengiteResult_Label.Text = 'Phengite';
            app.GrtPh_Thermometers_Label = uilabel(app.GrtPh_Results_TabGrid);
            app.GrtPh_Thermometers_Label.Text = 'Thermometers';
            app.GrtPh_Plot_Tab = uipanel(app.GrtPh_Tab);
            app.GrtPh_Plot_TabGrid = uigridlayout(app.GrtPh_Plot_Tab);
            app.GrtPh_P_max_Label = uilabel(app.GrtPh_Plot_TabGrid);
            app.GrtPh_P_max_Label.Text = 'P max';
            app.GrtPh_P_max = uispinner(app.GrtPh_Plot_TabGrid);
            app.GrtPh_P_max.Limits = [1 Inf];
            app.GrtPh_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_P_max.Tooltip = {'P (kbar) max'};
            app.GrtPh_P_max.Value = 15;
            app.GrtPh_P_min_Label = uilabel(app.GrtPh_Plot_TabGrid);
            app.GrtPh_P_min_Label.Text = 'P min';
            app.GrtPh_T_max_Label = uilabel(app.GrtPh_Plot_TabGrid);
            app.GrtPh_T_max_Label.Text = 'T max';
            app.GrtPh_P_min = uispinner(app.GrtPh_Plot_TabGrid);
            app.GrtPh_P_min.Limits = [0 Inf];
            app.GrtPh_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_P_min.Tooltip = {'P (kbar) min'};
            app.GrtPh_T_max = uispinner(app.GrtPh_Plot_TabGrid);
            app.GrtPh_T_max.Step = 50;
            app.GrtPh_T_max.Limits = [50 Inf];
            app.GrtPh_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_T_max.Tooltip = {'T (C) max'};
            app.GrtPh_T_max.Value = 1000;
            app.GrtPh_GridCheckBox = uicheckbox(app.GrtPh_Plot_TabGrid);
            app.GrtPh_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_GridCheckBox.Text = 'Grid';
            app.GrtPh_LineWidth_Label = uilabel(app.GrtPh_Plot_TabGrid);
            app.GrtPh_LineWidth_Label.Text = 'Width';
            app.GrtPh_LineWidth_Spinner = uispinner(app.GrtPh_Plot_TabGrid);
            app.GrtPh_LineWidth_Spinner.Step = 0.5;
            app.GrtPh_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtPh_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtPh_LineWidth_Spinner.Value = 1;
            app.GrtPh_T_min_Label = uilabel(app.GrtPh_Plot_TabGrid);
            app.GrtPh_T_min_Label.Text = 'T min';
            app.GrtPh_T_min = uispinner(app.GrtPh_Plot_TabGrid);
            app.GrtPh_T_min.Step = 50;
            app.GrtPh_T_min.Limits = [0 Inf];
            app.GrtPh_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtPh, true);
            app.GrtPh_T_min.Tooltip = {'T (C) min'};
            app.GrtPh_UIAxes = uiaxes(app.GrtPh_Plot_TabGrid);
            title(app.GrtPh_UIAxes, '')
            xlabel(app.GrtPh_UIAxes, 'T (C)')
            ylabel(app.GrtPh_UIAxes, 'P (kbar)')
            app.GrtPh_UIAxes.XLim = [0 1000];
            app.GrtPh_UIAxes.YLim = [0 15];
            app.GrtPh_UIAxes.LineStyleOrder = {'-'};
            app.GrtPh_DisplayAll_CheckBox = uicheckbox(app.GrtPh_Plot_TabGrid);
            app.GrtPh_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtPh_DisplayAll_CheckBoxValueChanged, true);
            app.GrtPh_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtPh_DisplayAll_CheckBox.Value = true;
            app.GrtPh_ColorAll_EditField = uieditfield(app.GrtPh_Plot_TabGrid, 'text');
            app.GrtPh_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtPh_ColorAll_EditFieldValueChanged, true);
            app.GrtPh_ColorAll_EditField.Value = 'red';
            app.GrtPh_Colour_Label = uilabel(app.GrtPh_Plot_TabGrid);
            app.GrtPh_Colour_Label.Text = 'Colour';
            app.GrtOl_Tab = uitab(app.TabGroup);
            app.GrtOl_Tab.Title = 'Grt-Ol';
            app.GrtOpx_Tab = uitab(app.TabGroup);
            app.GrtOpx_Tab.Title = 'Grt-Opx';
            app.GrtOpx_Input_Tab = uipanel(app.GrtOpx_Tab);
            app.GrtOpx_Input_TabGrid = uigridlayout(app.GrtOpx_Input_Tab);
            app.GrtOpx_Garnet_Label = uilabel(app.GrtOpx_Input_TabGrid);
            app.GrtOpx_Garnet_Label.Text = 'Garnet';
            app.GrtOpx_Opx_Label = uilabel(app.GrtOpx_Input_TabGrid);
            app.GrtOpx_Opx_Label.Text = 'Opx';
            app.FeEditFieldLabel = uilabel(app.GrtOpx_Input_TabGrid);
            app.FeEditFieldLabel.Text = 'Fe';
            app.GrtOpx_grt_Fe_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_grt_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_grt_Fe_EditField.Tooltip = {''};
            app.MgLabel = uilabel(app.GrtOpx_Input_TabGrid);
            app.MgLabel.Text = 'Mg';
            app.GrtOpx_grt_Mg_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_grt_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_grt_Mg_EditField.Tooltip = {''};
            app.CaLabel = uilabel(app.GrtOpx_Input_TabGrid);
            app.CaLabel.Text = 'Ca';
            app.GrtOpx_grt_Ca_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_grt_Ca_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_grt_Ca_EditField.Tooltip = {''};
            app.MnLabel = uilabel(app.GrtOpx_Input_TabGrid);
            app.MnLabel.Text = 'Mn';
            app.GrtOpx_grt_Mn_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_grt_Mn_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_grt_Mn_EditField.Tooltip = {''};
            app.FeEditFieldLabel_2 = uilabel(app.GrtOpx_Input_TabGrid);
            app.FeEditFieldLabel_2.Text = 'Fe';
            app.GrtOpx_opx_Fe_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_opx_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_opx_Fe_EditField.Tooltip = {''};
            app.MgLabel_2 = uilabel(app.GrtOpx_Input_TabGrid);
            app.MgLabel_2.Text = 'Mg';
            app.GrtOpx_opx_Mg_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_opx_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_opx_Mg_EditField.Tooltip = {''};
            app.AlLabel = uilabel(app.GrtOpx_Input_TabGrid);
            app.AlLabel.Text = 'Al';
            app.GrtOpx_opx_Al_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_opx_Al_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_opx_Al_EditField.Tooltip = {''};
            app.PkbarLabel = uilabel(app.GrtOpx_Input_TabGrid);
            app.PkbarLabel.Text = 'P (kbar)';
            app.GrtOpx_P_EditField = uieditfield(app.GrtOpx_Input_TabGrid, 'numeric');
            app.GrtOpx_P_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_P_EditField.Tooltip = {''};
            app.GrtOpx_Plot_Tab = uipanel(app.GrtOpx_Tab);
            app.GrtOpx_Plot_TabGrid = uigridlayout(app.GrtOpx_Plot_Tab);
            app.GrtOpx_P_max_Label = uilabel(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_P_max_Label.Text = 'P max';
            app.GrtOpx_P_max = uispinner(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_P_max.Limits = [1 Inf];
            app.GrtOpx_P_max.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_P_max.Tooltip = {'P (kbar) max'};
            app.GrtOpx_P_max.Value = 15;
            app.GrtOpx_P_min_Label = uilabel(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_P_min_Label.Text = 'P min';
            app.GrtOpx_T_max_Label = uilabel(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_T_max_Label.Text = 'T max';
            app.GrtOpx_P_min = uispinner(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_P_min.Limits = [0 Inf];
            app.GrtOpx_P_min.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_P_min.Tooltip = {'P (kbar) min'};
            app.GrtOpx_T_max = uispinner(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_T_max.Step = 50;
            app.GrtOpx_T_max.Limits = [50 Inf];
            app.GrtOpx_T_max.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_T_max.Tooltip = {'T (C) max'};
            app.GrtOpx_T_max.Value = 1000;
            app.GrtOpx_GridCheckBox = uicheckbox(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_GridCheckBox.Text = 'Grid';
            app.GrtOpx_LineWidth_Label = uilabel(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_LineWidth_Label.Text = 'Width';
            app.GrtOpx_LineWidth_Spinner = uispinner(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_LineWidth_Spinner.Step = 0.5;
            app.GrtOpx_LineWidth_Spinner.Limits = [0.1 10];
            app.GrtOpx_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.GrtOpx_LineWidth_Spinner.Value = 1;
            app.GrtOpx_T_min_Label = uilabel(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_T_min_Label.Text = 'T min';
            app.GrtOpx_T_min = uispinner(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_T_min.Step = 50;
            app.GrtOpx_T_min.Limits = [0 Inf];
            app.GrtOpx_T_min.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_T_min.Tooltip = {'T (C) min'};
            app.GrtOpx_UIAxes = uiaxes(app.GrtOpx_Plot_TabGrid);
            title(app.GrtOpx_UIAxes, '')
            xlabel(app.GrtOpx_UIAxes, 'T (C)')
            ylabel(app.GrtOpx_UIAxes, 'P (kbar)')
            app.GrtOpx_UIAxes.XLim = [0 1000];
            app.GrtOpx_UIAxes.YLim = [0 15];
            app.GrtOpx_UIAxes.LineStyleOrder = {'-'};
            app.GrtOpx_DisplayAll_CheckBox = uicheckbox(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx_DisplayAll_CheckBoxValueChanged, true);
            app.GrtOpx_DisplayAll_CheckBox.Text = 'Display all lines';
            app.GrtOpx_DisplayAll_CheckBox.Value = true;
            app.GrtOpx_ColorAll_EditField = uieditfield(app.GrtOpx_Plot_TabGrid, 'text');
            app.GrtOpx_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx_ColorAll_EditFieldValueChanged, true);
            app.GrtOpx_ColorAll_EditField.Value = 'red';
            app.GrtOpx_Colour_Label = uilabel(app.GrtOpx_Plot_TabGrid);
            app.GrtOpx_Colour_Label.Text = 'Colour';
            app.GrtOpx_Results_Tab = uipanel(app.GrtOpx_Tab);
            app.GrtOpx_Results_TabGrid = uigridlayout(app.GrtOpx_Results_Tab);
            app.GrtOpx_PL90color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_PL90color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_PL90color_EditField.Value = 'red';
            app.GrtOpx_PL90_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_PL90_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_PL90_CheckBox.Text = '';
            app.GrtOpx_PL90_CheckBox.Value = true;
            app.GrtOpx_AP89color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_AP89color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_AP89color_EditField.Value = 'red';
            app.GrtOpx_AP89_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_AP89_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_AP89_CheckBox.Text = '';
            app.GrtOpx_AP89_CheckBox.Value = true;
            app.GrtOpx_LG84color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_LG84color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_LG84color_EditField.Value = 'red';
            app.GrtOpx_SB84color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_SB84color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_SB84color_EditField.Value = 'red';
            app.GrtOpx_LG84_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_LG84_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_LG84_CheckBox.Text = '';
            app.GrtOpx_LG84_CheckBox.Value = true;
            app.GrtOpx_SB84_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_SB84_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_SB84_CheckBox.Text = '';
            app.GrtOpx_SB84_CheckBox.Value = true;
            app.GrtOpx_D80color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_D80color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_D80color_EditField.Value = 'red';
            app.GrtOpx_MG78color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_MG78color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_MG78color_EditField.Value = 'red';
            app.GrtOpx_D80_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_D80_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_D80_CheckBox.Text = '';
            app.GrtOpx_D80_CheckBox.Value = true;
            app.GrtOpx_MG78_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_MG78_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_MG78_CheckBox.Text = '';
            app.GrtOpx_MG78_CheckBox.Value = true;
            app.PL90EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.PL90EditFieldLabel.Text = 'PL90';
            app.GrtOpx_PL90_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_PL90_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_PL90_EditField.Editable = 'off';
            app.GrtOpx_PL90_EditField.Tooltip = {''};
            app.AP89EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.AP89EditFieldLabel.Text = 'AP89';
            app.GrtOpx_AP89_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_AP89_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_AP89_EditField.Editable = 'off';
            app.GrtOpx_AP89_EditField.Tooltip = {''};
            app.LG84EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.LG84EditFieldLabel.Text = 'LG84';
            app.GrtOpx_LG84_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_LG84_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_LG84_EditField.Editable = 'off';
            app.GrtOpx_LG84_EditField.Tooltip = {''};
            app.SB84EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.SB84EditFieldLabel.Text = 'SB84';
            app.GrtOpx_SB84_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_SB84_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_SB84_EditField.Editable = 'off';
            app.GrtOpx_SB84_EditField.Tooltip = {''};
            app.D80EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.D80EditFieldLabel.Text = 'D80';
            app.GrtOpx_D80_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_D80_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_D80_EditField.Editable = 'off';
            app.GrtOpx_D80_EditField.Tooltip = {''};
            app.MG78EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.MG78EditFieldLabel.Text = 'MG78';
            app.GrtOpx_MG78_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_MG78_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_MG78_EditField.Editable = 'off';
            app.GrtOpx_MG78_EditField.Tooltip = {''};
            app.XMgLabel_2 = uilabel(app.GrtOpx_Results_TabGrid);
            app.XMgLabel_2.Text = 'XMg';
            app.GrtOpx_opx_XMg_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_opx_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtOpx_opx_XMg_EditField.Editable = 'off';
            app.GrtOpx_opx_XMg_EditField.Tooltip = {''};
            app.XFeLabel_2 = uilabel(app.GrtOpx_Results_TabGrid);
            app.XFeLabel_2.Text = 'XFe';
            app.GrtOpx_opx_XFe_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_opx_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtOpx_opx_XFe_EditField.Editable = 'off';
            app.GrtOpx_opx_XFe_EditField.Tooltip = {''};
            app.XMgLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.XMgLabel.Text = 'XMg';
            app.GrtOpx_grt_XMg_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_grt_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.GrtOpx_grt_XMg_EditField.Editable = 'off';
            app.GrtOpx_grt_XMg_EditField.Tooltip = {''};
            app.XFeLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.XFeLabel.Text = 'XFe';
            app.GrtOpx_grt_XFe_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_grt_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.GrtOpx_grt_XFe_EditField.Editable = 'off';
            app.GrtOpx_grt_XFe_EditField.Tooltip = {''};
            app.XCaLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.XCaLabel.Text = 'XCa';
            app.GrtOpx_grt_XCa_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_grt_XCa_EditField.ValueDisplayFormat = '%.2f';
            app.GrtOpx_grt_XCa_EditField.Editable = 'off';
            app.GrtOpx_grt_XCa_EditField.Tooltip = {''};
            app.XMnLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.XMnLabel.Text = 'XMn';
            app.GrtOpx_grt_XMn_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_grt_XMn_EditField.ValueDisplayFormat = '%.2f';
            app.GrtOpx_grt_XMn_EditField.Editable = 'off';
            app.GrtOpx_grt_XMn_EditField.Tooltip = {''};
            app.XAlLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.XAlLabel.Text = 'XAl';
            app.GrtOpx_opx_XAl_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_opx_XAl_EditField.ValueDisplayFormat = '%.2f';
            app.GrtOpx_opx_XAl_EditField.Editable = 'off';
            app.GrtOpx_opx_XAl_EditField.Tooltip = {''};
            app.lnKdEditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.lnKdEditFieldLabel.Text = 'lnKd';
            app.GrtOpx_lnKd_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.GrtOpx_lnKd_EditField.Editable = 'off';
            app.GrtOpx_lnKd_EditField.Tooltip = {''};
            app.R83EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.R83EditFieldLabel.Text = 'R83';
            app.GrtOpx_R83_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_R83_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_R83_EditField.Editable = 'off';
            app.GrtOpx_R83_EditField.Tooltip = {''};
            app.H84EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.H84EditFieldLabel.Text = 'H84';
            app.GrtOpx_H84_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_H84_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_H84_EditField.Editable = 'off';
            app.GrtOpx_H84_EditField.Tooltip = {''};
            app.P85EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.P85EditFieldLabel.Text = 'P85';
            app.GrtOpx_P85_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_P85_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_P85_EditField.Editable = 'off';
            app.GrtOpx_P85_EditField.Tooltip = {''};
            app.LG88EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.LG88EditFieldLabel.Text = 'LG88';
            app.GrtOpx_LG88_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_LG88_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_LG88_EditField.Editable = 'off';
            app.GrtOpx_LG88_EditField.Tooltip = {''};
            app.B91EditFieldLabel = uilabel(app.GrtOpx_Results_TabGrid);
            app.B91EditFieldLabel.Text = 'B91';
            app.GrtOpx_B91_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'numeric');
            app.GrtOpx_B91_EditField.ValueDisplayFormat = '%.0f';
            app.GrtOpx_B91_EditField.Editable = 'off';
            app.GrtOpx_B91_EditField.Tooltip = {''};
            app.GrtOpx_R83_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_R83_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_R83_CheckBox.Text = '';
            app.GrtOpx_R83_CheckBox.Value = true;
            app.GrtOpx_H84_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_H84_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_H84_CheckBox.Text = '';
            app.GrtOpx_H84_CheckBox.Value = true;
            app.GrtOpx_B91_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_B91_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_B91_CheckBox.Text = '';
            app.GrtOpx_B91_CheckBox.Value = true;
            app.GrtOpx_P85_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_P85_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_P85_CheckBox.Text = '';
            app.GrtOpx_P85_CheckBox.Value = true;
            app.GrtOpx_LG88_CheckBox = uicheckbox(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_LG88_CheckBox.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_LG88_CheckBox.Text = '';
            app.GrtOpx_LG88_CheckBox.Value = true;
            app.GrtOpx_R83color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_R83color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_R83color_EditField.Value = 'red';
            app.GrtOpx_H84color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_H84color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_H84color_EditField.Value = 'red';
            app.GrtOpx_P85color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_P85color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_P85color_EditField.Value = 'red';
            app.GrtOpx_LG88color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_LG88color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_LG88color_EditField.Value = 'red';
            app.GrtOpx_B91color_EditField = uieditfield(app.GrtOpx_Results_TabGrid, 'text');
            app.GrtOpx_B91color_EditField.ValueChangedFcn = createCallbackFcn(app, @GrtOpx, true);
            app.GrtOpx_B91color_EditField.Value = 'red';
            app.GrtOpx_GarnetResult_Label = uilabel(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_GarnetResult_Label.Text = 'Garnet';
            app.GrtOpx_OpxResult_Label = uilabel(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_OpxResult_Label.Text = 'Opx';
            app.GrtOpx_Thermometers_Label = uilabel(app.GrtOpx_Results_TabGrid);
            app.GrtOpx_Thermometers_Label.Text = 'Thermometers';
            app.TiBi_Tab = uitab(app.TabGroup);
            app.TiBi_Tab.Title = 'Ti-in-Bi';
            app.TiBi_Input_Tab = uipanel(app.TiBi_Tab);
            app.TiBi_Input_TabGrid = uigridlayout(app.TiBi_Input_Tab);
            app.TiBi_Biotite_Label = uilabel(app.TiBi_Input_TabGrid);
            app.TiBi_Biotite_Label.Text = 'Biotite';
            app.AlLabel_5 = uilabel(app.TiBi_Input_TabGrid);
            app.AlLabel_5.Text = 'Al';
            app.TiBi_Al_EditField = uieditfield(app.TiBi_Input_TabGrid, 'numeric');
            app.TiBi_Al_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_Al_EditField.Tooltip = {''};
            app.FeLabel_6 = uilabel(app.TiBi_Input_TabGrid);
            app.FeLabel_6.Text = 'Fe';
            app.TiBi_Fe_EditField = uieditfield(app.TiBi_Input_TabGrid, 'numeric');
            app.TiBi_Fe_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_Fe_EditField.Tooltip = {''};
            app.TiLabel_2 = uilabel(app.TiBi_Input_TabGrid);
            app.TiLabel_2.Text = 'Ti';
            app.TiBi_Ti_EditField = uieditfield(app.TiBi_Input_TabGrid, 'numeric');
            app.TiBi_Ti_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_Ti_EditField.Tooltip = {''};
            app.PkbarLabel_7 = uilabel(app.TiBi_Input_TabGrid);
            app.PkbarLabel_7.Text = 'P (kbar)';
            app.TiBi_P_EditField = uieditfield(app.TiBi_Input_TabGrid, 'numeric');
            app.TiBi_P_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_P_EditField.Tooltip = {''};
            app.MgLabel_17 = uilabel(app.TiBi_Input_TabGrid);
            app.MgLabel_17.Text = 'Mg';
            app.TiBi_Mg_EditField = uieditfield(app.TiBi_Input_TabGrid, 'numeric');
            app.TiBi_Mg_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_Mg_EditField.Tooltip = {''};
            app.TiBi_Results_Tab = uipanel(app.TiBi_Tab);
            app.TiBi_Results_TabGrid = uigridlayout(app.TiBi_Results_Tab);
            app.TiBi_HG02color_EditField = uieditfield(app.TiBi_Results_TabGrid, 'text');
            app.TiBi_HG02color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_HG02color_EditField.Value = 'red';
            app.TiBi_HG02_CheckBox = uicheckbox(app.TiBi_Results_TabGrid);
            app.TiBi_HG02_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_HG02_CheckBox.Text = '';
            app.TiBi_HG02_CheckBox.Value = true;
            app.HG02Label = uilabel(app.TiBi_Results_TabGrid);
            app.HG02Label.Text = 'HG02';
            app.TiBi_HG02_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_HG02_EditField.ValueDisplayFormat = '%.0f';
            app.TiBi_HG02_EditField.Editable = 'off';
            app.TiBi_HG02_EditField.Tooltip = {''};
            app.XMgLabel_17 = uilabel(app.TiBi_Results_TabGrid);
            app.XMgLabel_17.Text = 'XMg';
            app.TiBi_XMg_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_XMg_EditField.ValueDisplayFormat = '%.2f';
            app.TiBi_XMg_EditField.Editable = 'off';
            app.TiBi_XMg_EditField.Tooltip = {''};
            app.XFeLabel_17 = uilabel(app.TiBi_Results_TabGrid);
            app.XFeLabel_17.Text = 'XFe';
            app.TiBi_XFe_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_XFe_EditField.ValueDisplayFormat = '%.2f';
            app.TiBi_XFe_EditField.Editable = 'off';
            app.TiBi_XFe_EditField.Tooltip = {''};
            app.MgLabel_18 = uilabel(app.TiBi_Results_TabGrid);
            app.MgLabel_18.Text = 'Mg#';
            app.TiBi_MgN_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_MgN_EditField.ValueDisplayFormat = '%.2f';
            app.TiBi_MgN_EditField.Editable = 'off';
            app.TiBi_MgN_EditField.Tooltip = {''};
            app.TiBi_BiotiteResult_Label = uilabel(app.TiBi_Results_TabGrid);
            app.TiBi_BiotiteResult_Label.Text = 'Biotite';
            app.H05Label = uilabel(app.TiBi_Results_TabGrid);
            app.H05Label.Text = 'H05';
            app.TiBi_H05_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_H05_EditField.ValueDisplayFormat = '%.0f';
            app.TiBi_H05_EditField.Editable = 'off';
            app.TiBi_H05_EditField.Tooltip = {''};
            app.WC15Label = uilabel(app.TiBi_Results_TabGrid);
            app.WC15Label.Text = 'WC15';
            app.TiBi_WC15_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_WC15_EditField.ValueDisplayFormat = '%.0f';
            app.TiBi_WC15_EditField.Editable = 'off';
            app.TiBi_WC15_EditField.Tooltip = {''};
            app.XTiLabel_2 = uilabel(app.TiBi_Results_TabGrid);
            app.XTiLabel_2.Text = 'XTi';
            app.TiBi_XTi_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_XTi_EditField.ValueDisplayFormat = '%.2f';
            app.TiBi_XTi_EditField.Editable = 'off';
            app.TiBi_XTi_EditField.Tooltip = {''};
            app.XAlLabel_4 = uilabel(app.TiBi_Results_TabGrid);
            app.XAlLabel_4.Text = 'XAl';
            app.TiBi_XAl_EditField = uieditfield(app.TiBi_Results_TabGrid, 'numeric');
            app.TiBi_XAl_EditField.ValueDisplayFormat = '%.2f';
            app.TiBi_XAl_EditField.Editable = 'off';
            app.TiBi_XAl_EditField.Tooltip = {''};
            app.TiBi_H05_CheckBox = uicheckbox(app.TiBi_Results_TabGrid);
            app.TiBi_H05_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_H05_CheckBox.Text = '';
            app.TiBi_H05_CheckBox.Value = true;
            app.TiBi_WC15_CheckBox = uicheckbox(app.TiBi_Results_TabGrid);
            app.TiBi_WC15_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_WC15_CheckBox.Text = '';
            app.TiBi_WC15_CheckBox.Value = true;
            app.TiBi_H05color_EditField = uieditfield(app.TiBi_Results_TabGrid, 'text');
            app.TiBi_H05color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_H05color_EditField.Value = 'red';
            app.TiBi_WC15color_EditField = uieditfield(app.TiBi_Results_TabGrid, 'text');
            app.TiBi_WC15color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_WC15color_EditField.Value = 'red';
            app.TiBi_Thermometers_Label = uilabel(app.TiBi_Results_TabGrid);
            app.TiBi_Thermometers_Label.Text = 'Thermometers';
            app.TiBi_Plot_Tab = uipanel(app.TiBi_Tab);
            app.TiBi_Plot_TabGrid = uigridlayout(app.TiBi_Plot_Tab);
            app.TiBi_P_max_Label = uilabel(app.TiBi_Plot_TabGrid);
            app.TiBi_P_max_Label.Text = 'P max';
            app.TiBi_P_max = uispinner(app.TiBi_Plot_TabGrid);
            app.TiBi_P_max.Limits = [1 Inf];
            app.TiBi_P_max.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_P_max.Tooltip = {'P (kbar) max'};
            app.TiBi_P_max.Value = 15;
            app.TiBi_P_min_Label = uilabel(app.TiBi_Plot_TabGrid);
            app.TiBi_P_min_Label.Text = 'P min';
            app.TiBi_T_max_Label = uilabel(app.TiBi_Plot_TabGrid);
            app.TiBi_T_max_Label.Text = 'T max';
            app.TiBi_P_min = uispinner(app.TiBi_Plot_TabGrid);
            app.TiBi_P_min.Limits = [0 Inf];
            app.TiBi_P_min.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_P_min.Tooltip = {'P (kbar) min'};
            app.TiBi_T_max = uispinner(app.TiBi_Plot_TabGrid);
            app.TiBi_T_max.Step = 50;
            app.TiBi_T_max.Limits = [50 Inf];
            app.TiBi_T_max.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_T_max.Tooltip = {'T (C) max'};
            app.TiBi_T_max.Value = 1000;
            app.TiBi_GridCheckBox = uicheckbox(app.TiBi_Plot_TabGrid);
            app.TiBi_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_GridCheckBox.Text = 'Grid';
            app.TiBi_LineWidth_Label = uilabel(app.TiBi_Plot_TabGrid);
            app.TiBi_LineWidth_Label.Text = 'Width';
            app.TiBi_LineWidth_Spinner = uispinner(app.TiBi_Plot_TabGrid);
            app.TiBi_LineWidth_Spinner.Step = 0.5;
            app.TiBi_LineWidth_Spinner.Limits = [0.1 10];
            app.TiBi_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.TiBi_LineWidth_Spinner.Value = 1;
            app.TiBi_T_min_Label = uilabel(app.TiBi_Plot_TabGrid);
            app.TiBi_T_min_Label.Text = 'T min';
            app.TiBi_T_min = uispinner(app.TiBi_Plot_TabGrid);
            app.TiBi_T_min.Step = 50;
            app.TiBi_T_min.Limits = [0 Inf];
            app.TiBi_T_min.ValueChangedFcn = createCallbackFcn(app, @TiBi, true);
            app.TiBi_T_min.Tooltip = {'T (C) min'};
            app.TiBi_UIAxes = uiaxes(app.TiBi_Plot_TabGrid);
            title(app.TiBi_UIAxes, '')
            xlabel(app.TiBi_UIAxes, 'T (C)')
            ylabel(app.TiBi_UIAxes, 'P (kbar)')
            app.TiBi_UIAxes.XLim = [0 1000];
            app.TiBi_UIAxes.YLim = [0 15];
            app.TiBi_UIAxes.LineStyleOrder = {'-'};
            app.TiBi_DisplayAll_CheckBox = uicheckbox(app.TiBi_Plot_TabGrid);
            app.TiBi_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiBi_DisplayAll_CheckBoxValueChanged, true);
            app.TiBi_DisplayAll_CheckBox.Text = 'Display all lines';
            app.TiBi_DisplayAll_CheckBox.Value = true;
            app.TiBi_ColorAll_EditField = uieditfield(app.TiBi_Plot_TabGrid, 'text');
            app.TiBi_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @TiBi_ColorAll_EditFieldValueChanged, true);
            app.TiBi_ColorAll_EditField.Value = 'red';
            app.TiBi_Colour_Label = uilabel(app.TiBi_Plot_TabGrid);
            app.TiBi_Colour_Label.Text = 'Colour';
            app.TiQz_Tab = uitab(app.TabGroup);
            app.TiQz_Tab.Title = 'Ti-in-Qz';
            app.TiQz_Input_Tab = uipanel(app.TiQz_Tab);
            app.TiQz_Input_TabGrid = uigridlayout(app.TiQz_Input_Tab);
            app.TiQz_Quartz_Label = uilabel(app.TiQz_Input_TabGrid);
            app.TiQz_Quartz_Label.Text = 'Quartz';
            app.aTiO2Label_2 = uilabel(app.TiQz_Input_TabGrid);
            app.aTiO2Label_2.Text = 'aTiO2';
            app.TiQz_aTiO2_EditField = uieditfield(app.TiQz_Input_TabGrid, 'numeric');
            app.TiQz_aTiO2_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_aTiO2_EditField.Tooltip = {''};
            app.TiQz_aTiO2_EditField.Value = 1;
            app.TippmLabel_2 = uilabel(app.TiQz_Input_TabGrid);
            app.TippmLabel_2.Text = 'Ti (ppm)';
            app.TiQz_Ti_EditField = uieditfield(app.TiQz_Input_TabGrid, 'numeric');
            app.TiQz_Ti_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_Ti_EditField.Tooltip = {''};
            app.PkbarLabel_8 = uilabel(app.TiQz_Input_TabGrid);
            app.PkbarLabel_8.Text = 'P (kbar)';
            app.TiQz_P_EditField = uieditfield(app.TiQz_Input_TabGrid, 'numeric');
            app.TiQz_P_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_P_EditField.Tooltip = {''};
            app.TiQz_Results_Tab = uipanel(app.TiQz_Tab);
            app.TiQz_Results_TabGrid = uigridlayout(app.TiQz_Results_Tab);
            app.TiQz_WW06color_EditField = uieditfield(app.TiQz_Results_TabGrid, 'text');
            app.TiQz_WW06color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_WW06color_EditField.Value = 'red';
            app.TiQz_WW06_CheckBox = uicheckbox(app.TiQz_Results_TabGrid);
            app.TiQz_WW06_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_WW06_CheckBox.Text = '';
            app.TiQz_WW06_CheckBox.Value = true;
            app.WW06Label = uilabel(app.TiQz_Results_TabGrid);
            app.WW06Label.Text = 'WW06';
            app.TiQz_WW06_EditField = uieditfield(app.TiQz_Results_TabGrid, 'numeric');
            app.TiQz_WW06_EditField.ValueDisplayFormat = '%.0f';
            app.TiQz_WW06_EditField.Editable = 'off';
            app.TiQz_WW06_EditField.Tooltip = {''};
            app.TiQz_QuartzResult_Label = uilabel(app.TiQz_Results_TabGrid);
            app.TiQz_QuartzResult_Label.Text = 'Quartz';
            app.KO08Label = uilabel(app.TiQz_Results_TabGrid);
            app.KO08Label.Text = 'KO08';
            app.TiQz_KO08_EditField = uieditfield(app.TiQz_Results_TabGrid, 'numeric');
            app.TiQz_KO08_EditField.ValueDisplayFormat = '%.0f';
            app.TiQz_KO08_EditField.Editable = 'off';
            app.TiQz_KO08_EditField.Tooltip = {''};
            app.TiQz_KO08_CheckBox = uicheckbox(app.TiQz_Results_TabGrid);
            app.TiQz_KO08_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_KO08_CheckBox.Text = '';
            app.TiQz_KO08_CheckBox.Value = true;
            app.TiQz_KO08color_EditField = uieditfield(app.TiQz_Results_TabGrid, 'text');
            app.TiQz_KO08color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_KO08color_EditField.Value = 'red';
            app.TiQz_Thermometers_Label = uilabel(app.TiQz_Results_TabGrid);
            app.TiQz_Thermometers_Label.Text = 'Thermometers';
            app.TiQz_T10_CheckBox = uicheckbox(app.TiQz_Results_TabGrid);
            app.TiQz_T10_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_T10_CheckBox.Text = '';
            app.TiQz_T10_CheckBox.Value = true;
            app.TiQz_T10color_EditField = uieditfield(app.TiQz_Results_TabGrid, 'text');
            app.TiQz_T10color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_T10color_EditField.Value = 'red';
            app.T10Label = uilabel(app.TiQz_Results_TabGrid);
            app.T10Label.Text = 'T10';
            app.TiQz_T10_EditField = uieditfield(app.TiQz_Results_TabGrid, 'numeric');
            app.TiQz_T10_EditField.ValueDisplayFormat = '%.0f';
            app.TiQz_T10_EditField.Editable = 'off';
            app.TiQz_T10_EditField.Tooltip = {''};
            app.TiQz_HA12_CheckBox = uicheckbox(app.TiQz_Results_TabGrid);
            app.TiQz_HA12_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_HA12_CheckBox.Text = '';
            app.TiQz_HA12_CheckBox.Value = true;
            app.TiQz_HA12color_EditField = uieditfield(app.TiQz_Results_TabGrid, 'text');
            app.TiQz_HA12color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_HA12color_EditField.Value = 'red';
            app.HA12Label = uilabel(app.TiQz_Results_TabGrid);
            app.HA12Label.Text = 'HA12';
            app.TiQz_HA12_EditField = uieditfield(app.TiQz_Results_TabGrid, 'numeric');
            app.TiQz_HA12_EditField.ValueDisplayFormat = '%.0f';
            app.TiQz_HA12_EditField.Editable = 'off';
            app.TiQz_HA12_EditField.Tooltip = {''};
            app.TiQz_Plot_Tab = uipanel(app.TiQz_Tab);
            app.TiQz_Plot_TabGrid = uigridlayout(app.TiQz_Plot_Tab);
            app.TiQz_P_max_Label = uilabel(app.TiQz_Plot_TabGrid);
            app.TiQz_P_max_Label.Text = 'P max';
            app.TiQz_P_max = uispinner(app.TiQz_Plot_TabGrid);
            app.TiQz_P_max.Limits = [1 Inf];
            app.TiQz_P_max.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_P_max.Tooltip = {'P (kbar) max'};
            app.TiQz_P_max.Value = 15;
            app.TiQz_P_min_Label = uilabel(app.TiQz_Plot_TabGrid);
            app.TiQz_P_min_Label.Text = 'P min';
            app.TiQz_T_max_Label = uilabel(app.TiQz_Plot_TabGrid);
            app.TiQz_T_max_Label.Text = 'T max';
            app.TiQz_P_min = uispinner(app.TiQz_Plot_TabGrid);
            app.TiQz_P_min.Limits = [0 Inf];
            app.TiQz_P_min.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_P_min.Tooltip = {'P (kbar) min'};
            app.TiQz_T_max = uispinner(app.TiQz_Plot_TabGrid);
            app.TiQz_T_max.Step = 50;
            app.TiQz_T_max.Limits = [50 Inf];
            app.TiQz_T_max.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_T_max.Tooltip = {'T (C) max'};
            app.TiQz_T_max.Value = 1000;
            app.TiQz_GridCheckBox = uicheckbox(app.TiQz_Plot_TabGrid);
            app.TiQz_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_GridCheckBox.Text = 'Grid';
            app.TiQz_LineWidth_Label = uilabel(app.TiQz_Plot_TabGrid);
            app.TiQz_LineWidth_Label.Text = 'Width';
            app.TiQz_LineWidth_Spinner = uispinner(app.TiQz_Plot_TabGrid);
            app.TiQz_LineWidth_Spinner.Step = 0.5;
            app.TiQz_LineWidth_Spinner.Limits = [0.1 10];
            app.TiQz_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.TiQz_LineWidth_Spinner.Value = 1;
            app.TiQz_T_min_Label = uilabel(app.TiQz_Plot_TabGrid);
            app.TiQz_T_min_Label.Text = 'T min';
            app.TiQz_T_min = uispinner(app.TiQz_Plot_TabGrid);
            app.TiQz_T_min.Step = 50;
            app.TiQz_T_min.Limits = [0 Inf];
            app.TiQz_T_min.ValueChangedFcn = createCallbackFcn(app, @TiQz, true);
            app.TiQz_T_min.Tooltip = {'T (C) min'};
            app.TiQz_UIAxes = uiaxes(app.TiQz_Plot_TabGrid);
            title(app.TiQz_UIAxes, '')
            xlabel(app.TiQz_UIAxes, 'T (C)')
            ylabel(app.TiQz_UIAxes, 'P (kbar)')
            app.TiQz_UIAxes.XLim = [0 1000];
            app.TiQz_UIAxes.YLim = [0 15];
            app.TiQz_UIAxes.LineStyleOrder = {'-'};
            app.TiQz_DisplayAll_CheckBox = uicheckbox(app.TiQz_Plot_TabGrid);
            app.TiQz_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiQz_DisplayAll_CheckBoxValueChanged, true);
            app.TiQz_DisplayAll_CheckBox.Text = 'Display all lines';
            app.TiQz_DisplayAll_CheckBox.Value = true;
            app.TiQz_ColorAll_EditField = uieditfield(app.TiQz_Plot_TabGrid, 'text');
            app.TiQz_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @TiQz_ColorAll_EditFieldValueChanged, true);
            app.TiQz_ColorAll_EditField.Value = 'red';
            app.TiQz_Colour_Label = uilabel(app.TiQz_Plot_TabGrid);
            app.TiQz_Colour_Label.Text = 'Colour';
            app.TiZrn_Tab = uitab(app.TabGroup);
            app.TiZrn_Tab.Title = 'Ti-in-Zrn';
            app.TiZrn_Input_Tab = uipanel(app.TiZrn_Tab);
            app.TiZrn_Input_TabGrid = uigridlayout(app.TiZrn_Input_Tab);
            app.TiZrn_Zircon_Label = uilabel(app.TiZrn_Input_TabGrid);
            app.TiZrn_Zircon_Label.Text = 'Zircon';
            app.aSiO2Label = uilabel(app.TiZrn_Input_TabGrid);
            app.aSiO2Label.Text = 'aSiO2';
            app.TiZrn_aSiO2_EditField = uieditfield(app.TiZrn_Input_TabGrid, 'numeric');
            app.TiZrn_aSiO2_EditField.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_aSiO2_EditField.Tooltip = {''};
            app.TiZrn_aSiO2_EditField.Value = 1;
            app.aTiO2Label = uilabel(app.TiZrn_Input_TabGrid);
            app.aTiO2Label.Text = 'aTiO2';
            app.TiZrn_aTiO2_EditField = uieditfield(app.TiZrn_Input_TabGrid, 'numeric');
            app.TiZrn_aTiO2_EditField.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_aTiO2_EditField.Tooltip = {''};
            app.TiZrn_aTiO2_EditField.Value = 1;
            app.TippmLabel = uilabel(app.TiZrn_Input_TabGrid);
            app.TippmLabel.Text = 'Ti (ppm)';
            app.TiZrn_Ti_EditField = uieditfield(app.TiZrn_Input_TabGrid, 'numeric');
            app.TiZrn_Ti_EditField.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_Ti_EditField.Tooltip = {''};
            app.TiZrn_Results_Tab = uipanel(app.TiZrn_Tab);
            app.TiZrn_Results_TabGrid = uigridlayout(app.TiZrn_Results_Tab);
            app.TiZrn_W06color_EditField = uieditfield(app.TiZrn_Results_TabGrid, 'text');
            app.TiZrn_W06color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_W06color_EditField.Value = 'red';
            app.TiZrn_W06_CheckBox = uicheckbox(app.TiZrn_Results_TabGrid);
            app.TiZrn_W06_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_W06_CheckBox.Text = '';
            app.TiZrn_W06_CheckBox.Value = true;
            app.W06Label = uilabel(app.TiZrn_Results_TabGrid);
            app.W06Label.Text = 'W06';
            app.TiZrn_W06_EditField = uieditfield(app.TiZrn_Results_TabGrid, 'numeric');
            app.TiZrn_W06_EditField.ValueDisplayFormat = '%.0f';
            app.TiZrn_W06_EditField.Editable = 'off';
            app.TiZrn_W06_EditField.Tooltip = {''};
            app.TiZrn_ZirconResult_Label = uilabel(app.TiZrn_Results_TabGrid);
            app.TiZrn_ZirconResult_Label.Text = 'Zircon';
            app.FW07Label = uilabel(app.TiZrn_Results_TabGrid);
            app.FW07Label.Text = 'FW07';
            app.TiZrn_FW07_EditField = uieditfield(app.TiZrn_Results_TabGrid, 'numeric');
            app.TiZrn_FW07_EditField.ValueDisplayFormat = '%.0f';
            app.TiZrn_FW07_EditField.Editable = 'off';
            app.TiZrn_FW07_EditField.Tooltip = {''};
            app.TiZrn_FW07_CheckBox = uicheckbox(app.TiZrn_Results_TabGrid);
            app.TiZrn_FW07_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_FW07_CheckBox.Text = '';
            app.TiZrn_FW07_CheckBox.Value = true;
            app.TiZrn_FW07color_EditField = uieditfield(app.TiZrn_Results_TabGrid, 'text');
            app.TiZrn_FW07color_EditField.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_FW07color_EditField.Value = 'red';
            app.TiZrn_Thermometers_Label = uilabel(app.TiZrn_Results_TabGrid);
            app.TiZrn_Thermometers_Label.Text = 'Thermometers';
            app.TiZrn_Plot_Tab = uipanel(app.TiZrn_Tab);
            app.TiZrn_Plot_TabGrid = uigridlayout(app.TiZrn_Plot_Tab);
            app.TiZrn_P_max_Label = uilabel(app.TiZrn_Plot_TabGrid);
            app.TiZrn_P_max_Label.Text = 'P max';
            app.TiZrn_P_max = uispinner(app.TiZrn_Plot_TabGrid);
            app.TiZrn_P_max.Limits = [1 Inf];
            app.TiZrn_P_max.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_P_max.Tooltip = {'P (kbar) max'};
            app.TiZrn_P_max.Value = 15;
            app.TiZrn_P_min_Label = uilabel(app.TiZrn_Plot_TabGrid);
            app.TiZrn_P_min_Label.Text = 'P min';
            app.TiZrn_T_max_Label = uilabel(app.TiZrn_Plot_TabGrid);
            app.TiZrn_T_max_Label.Text = 'T max';
            app.TiZrn_P_min = uispinner(app.TiZrn_Plot_TabGrid);
            app.TiZrn_P_min.Limits = [0 Inf];
            app.TiZrn_P_min.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_P_min.Tooltip = {'P (kbar) min'};
            app.TiZrn_T_max = uispinner(app.TiZrn_Plot_TabGrid);
            app.TiZrn_T_max.Step = 50;
            app.TiZrn_T_max.Limits = [50 Inf];
            app.TiZrn_T_max.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_T_max.Tooltip = {'T (C) max'};
            app.TiZrn_T_max.Value = 1000;
            app.TiZrn_GridCheckBox = uicheckbox(app.TiZrn_Plot_TabGrid);
            app.TiZrn_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_GridCheckBox.Text = 'Grid';
            app.TiZrn_LineWidth_Label = uilabel(app.TiZrn_Plot_TabGrid);
            app.TiZrn_LineWidth_Label.Text = 'Width';
            app.TiZrn_LineWidth_Spinner = uispinner(app.TiZrn_Plot_TabGrid);
            app.TiZrn_LineWidth_Spinner.Step = 0.5;
            app.TiZrn_LineWidth_Spinner.Limits = [0.1 10];
            app.TiZrn_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.TiZrn_LineWidth_Spinner.Value = 1;
            app.TiZrn_T_min_Label = uilabel(app.TiZrn_Plot_TabGrid);
            app.TiZrn_T_min_Label.Text = 'T min';
            app.TiZrn_T_min = uispinner(app.TiZrn_Plot_TabGrid);
            app.TiZrn_T_min.Step = 50;
            app.TiZrn_T_min.Limits = [0 Inf];
            app.TiZrn_T_min.ValueChangedFcn = createCallbackFcn(app, @TiZrn, true);
            app.TiZrn_T_min.Tooltip = {'T (C) min'};
            app.TiZrn_UIAxes = uiaxes(app.TiZrn_Plot_TabGrid);
            title(app.TiZrn_UIAxes, '')
            xlabel(app.TiZrn_UIAxes, 'T (C)')
            ylabel(app.TiZrn_UIAxes, 'P (kbar)')
            app.TiZrn_UIAxes.XLim = [0 1000];
            app.TiZrn_UIAxes.YLim = [0 15];
            app.TiZrn_UIAxes.LineStyleOrder = {'-'};
            app.TiZrn_DisplayAll_CheckBox = uicheckbox(app.TiZrn_Plot_TabGrid);
            app.TiZrn_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @TiZrn_DisplayAll_CheckBoxValueChanged, true);
            app.TiZrn_DisplayAll_CheckBox.Text = 'Display all lines';
            app.TiZrn_DisplayAll_CheckBox.Value = true;
            app.TiZrn_ColorAll_EditField = uieditfield(app.TiZrn_Plot_TabGrid, 'text');
            app.TiZrn_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @TiZrn_ColorAll_EditFieldValueChanged, true);
            app.TiZrn_ColorAll_EditField.Value = 'red';
            app.TiZrn_Colour_Label = uilabel(app.TiZrn_Plot_TabGrid);
            app.TiZrn_Colour_Label.Text = 'Colour';
            app.ZrRu_Tab = uitab(app.TabGroup);
            app.ZrRu_Tab.Title = 'Zr-in-Ru';
            app.ZrRu_Input_Tab = uipanel(app.ZrRu_Tab);
            app.ZrRu_Input_TabGrid = uigridlayout(app.ZrRu_Input_Tab);
            app.ZrRu_Rutile_Label = uilabel(app.ZrRu_Input_TabGrid);
            app.ZrRu_Rutile_Label.Text = 'Rutile';
            app.aSiO2Label_2 = uilabel(app.ZrRu_Input_TabGrid);
            app.aSiO2Label_2.Text = 'aSiO2';
            app.ZrRu_aSiO2_EditField = uieditfield(app.ZrRu_Input_TabGrid, 'numeric');
            app.ZrRu_aSiO2_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_aSiO2_EditField.Tooltip = {''};
            app.ZrRu_aSiO2_EditField.Value = 1;
            app.ZrppmLabel = uilabel(app.ZrRu_Input_TabGrid);
            app.ZrppmLabel.Text = 'Zr (ppm)';
            app.ZrRu_Zr_EditField = uieditfield(app.ZrRu_Input_TabGrid, 'numeric');
            app.ZrRu_Zr_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_Zr_EditField.Tooltip = {''};
            app.PkbarLabel_9 = uilabel(app.ZrRu_Input_TabGrid);
            app.PkbarLabel_9.Text = 'P (kbar)';
            app.ZrRu_P_EditField = uieditfield(app.ZrRu_Input_TabGrid, 'numeric');
            app.ZrRu_P_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_P_EditField.Tooltip = {''};
            app.ZrRu_Results_Tab = uipanel(app.ZrRu_Tab);
            app.ZrRu_Results_TabGrid = uigridlayout(app.ZrRu_Results_Tab);
            app.ZrRu_Z04color_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'text');
            app.ZrRu_Z04color_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_Z04color_EditField.Value = 'red';
            app.ZrRu_Z04_CheckBox = uicheckbox(app.ZrRu_Results_TabGrid);
            app.ZrRu_Z04_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_Z04_CheckBox.Text = '';
            app.ZrRu_Z04_CheckBox.Value = true;
            app.Z04Label = uilabel(app.ZrRu_Results_TabGrid);
            app.Z04Label.Text = 'Z04';
            app.ZrRu_Z04_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'numeric');
            app.ZrRu_Z04_EditField.ValueDisplayFormat = '%.0f';
            app.ZrRu_Z04_EditField.Editable = 'off';
            app.ZrRu_Z04_EditField.Tooltip = {''};
            app.ZrRu_RutileResult_Label = uilabel(app.ZrRu_Results_TabGrid);
            app.ZrRu_RutileResult_Label.Text = 'Rutile';
            app.FW07Label_2 = uilabel(app.ZrRu_Results_TabGrid);
            app.FW07Label_2.Text = 'FW07';
            app.ZrRu_FW07_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'numeric');
            app.ZrRu_FW07_EditField.ValueDisplayFormat = '%.0f';
            app.ZrRu_FW07_EditField.Editable = 'off';
            app.ZrRu_FW07_EditField.Tooltip = {''};
            app.ZrRu_FW07_CheckBox = uicheckbox(app.ZrRu_Results_TabGrid);
            app.ZrRu_FW07_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_FW07_CheckBox.Text = '';
            app.ZrRu_FW07_CheckBox.Value = true;
            app.ZrRu_FW07color_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'text');
            app.ZrRu_FW07color_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_FW07color_EditField.Value = 'red';
            app.ZrRu_Thermometers_Label = uilabel(app.ZrRu_Results_TabGrid);
            app.ZrRu_Thermometers_Label.Text = 'Thermometers';
            app.ZrRu_T07a_CheckBox = uicheckbox(app.ZrRu_Results_TabGrid);
            app.ZrRu_T07a_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T07a_CheckBox.Text = '';
            app.ZrRu_T07a_CheckBox.Value = true;
            app.ZrRu_T07acolor_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'text');
            app.ZrRu_T07acolor_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T07acolor_EditField.Value = 'red';
            app.T07aLabel = uilabel(app.ZrRu_Results_TabGrid);
            app.T07aLabel.Text = 'T07a';
            app.ZrRu_T07a_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'numeric');
            app.ZrRu_T07a_EditField.ValueDisplayFormat = '%.0f';
            app.ZrRu_T07a_EditField.Editable = 'off';
            app.ZrRu_T07a_EditField.Tooltip = {''};
            app.ZrRu_T07b_CheckBox = uicheckbox(app.ZrRu_Results_TabGrid);
            app.ZrRu_T07b_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T07b_CheckBox.Text = '';
            app.ZrRu_T07b_CheckBox.Value = true;
            app.ZrRu_T07bcolor_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'text');
            app.ZrRu_T07bcolor_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T07bcolor_EditField.Value = 'red';
            app.T07bLabel = uilabel(app.ZrRu_Results_TabGrid);
            app.T07bLabel.Text = 'T07b';
            app.ZrRu_T07b_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'numeric');
            app.ZrRu_T07b_EditField.ValueDisplayFormat = '%.0f';
            app.ZrRu_T07b_EditField.Editable = 'off';
            app.ZrRu_T07b_EditField.Tooltip = {''};
            app.ZrRu_T07c_CheckBox = uicheckbox(app.ZrRu_Results_TabGrid);
            app.ZrRu_T07c_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T07c_CheckBox.Text = '';
            app.ZrRu_T07c_CheckBox.Value = true;
            app.ZrRu_T07ccolor_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'text');
            app.ZrRu_T07ccolor_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T07ccolor_EditField.Value = 'red';
            app.T07cLabel = uilabel(app.ZrRu_Results_TabGrid);
            app.T07cLabel.Text = 'T07c';
            app.ZrRu_T07c_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'numeric');
            app.ZrRu_T07c_EditField.ValueDisplayFormat = '%.0f';
            app.ZrRu_T07c_EditField.Editable = 'off';
            app.ZrRu_T07c_EditField.Tooltip = {''};
            app.ZrRu_W06_CheckBox = uicheckbox(app.ZrRu_Results_TabGrid);
            app.ZrRu_W06_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_W06_CheckBox.Text = '';
            app.ZrRu_W06_CheckBox.Value = true;
            app.ZrRu_W06color_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'text');
            app.ZrRu_W06color_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_W06color_EditField.Value = 'red';
            app.W06Label_2 = uilabel(app.ZrRu_Results_TabGrid);
            app.W06Label_2.Text = 'W06';
            app.ZrRu_W06_EditField = uieditfield(app.ZrRu_Results_TabGrid, 'numeric');
            app.ZrRu_W06_EditField.ValueDisplayFormat = '%.0f';
            app.ZrRu_W06_EditField.Editable = 'off';
            app.ZrRu_W06_EditField.Tooltip = {''};
            app.ZrRu_Plot_Tab = uipanel(app.ZrRu_Tab);
            app.ZrRu_Plot_TabGrid = uigridlayout(app.ZrRu_Plot_Tab);
            app.ZrRu_P_max_Label = uilabel(app.ZrRu_Plot_TabGrid);
            app.ZrRu_P_max_Label.Text = 'P max';
            app.ZrRu_P_max = uispinner(app.ZrRu_Plot_TabGrid);
            app.ZrRu_P_max.Limits = [1 Inf];
            app.ZrRu_P_max.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_P_max.Tooltip = {'P (kbar) max'};
            app.ZrRu_P_max.Value = 15;
            app.ZrRu_P_min_Label = uilabel(app.ZrRu_Plot_TabGrid);
            app.ZrRu_P_min_Label.Text = 'P min';
            app.ZrRu_T_max_Label = uilabel(app.ZrRu_Plot_TabGrid);
            app.ZrRu_T_max_Label.Text = 'T max';
            app.ZrRu_P_min = uispinner(app.ZrRu_Plot_TabGrid);
            app.ZrRu_P_min.Limits = [0 Inf];
            app.ZrRu_P_min.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_P_min.Tooltip = {'P (kbar) min'};
            app.ZrRu_T_max = uispinner(app.ZrRu_Plot_TabGrid);
            app.ZrRu_T_max.Step = 50;
            app.ZrRu_T_max.Limits = [50 Inf];
            app.ZrRu_T_max.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T_max.Tooltip = {'T (C) max'};
            app.ZrRu_T_max.Value = 1000;
            app.ZrRu_GridCheckBox = uicheckbox(app.ZrRu_Plot_TabGrid);
            app.ZrRu_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_GridCheckBox.Text = 'Grid';
            app.ZrRu_LineWidth_Label = uilabel(app.ZrRu_Plot_TabGrid);
            app.ZrRu_LineWidth_Label.Text = 'Width';
            app.ZrRu_LineWidth_Spinner = uispinner(app.ZrRu_Plot_TabGrid);
            app.ZrRu_LineWidth_Spinner.Step = 0.5;
            app.ZrRu_LineWidth_Spinner.Limits = [0.1 10];
            app.ZrRu_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.ZrRu_LineWidth_Spinner.Value = 1;
            app.ZrRu_T_min_Label = uilabel(app.ZrRu_Plot_TabGrid);
            app.ZrRu_T_min_Label.Text = 'T min';
            app.ZrRu_T_min = uispinner(app.ZrRu_Plot_TabGrid);
            app.ZrRu_T_min.Step = 50;
            app.ZrRu_T_min.Limits = [0 Inf];
            app.ZrRu_T_min.ValueChangedFcn = createCallbackFcn(app, @ZrRu, true);
            app.ZrRu_T_min.Tooltip = {'T (C) min'};
            app.ZrRu_UIAxes = uiaxes(app.ZrRu_Plot_TabGrid);
            title(app.ZrRu_UIAxes, '')
            xlabel(app.ZrRu_UIAxes, 'T (C)')
            ylabel(app.ZrRu_UIAxes, 'P (kbar)')
            app.ZrRu_UIAxes.XLim = [0 1000];
            app.ZrRu_UIAxes.YLim = [0 15];
            app.ZrRu_UIAxes.LineStyleOrder = {'-'};
            app.ZrRu_DisplayAll_CheckBox = uicheckbox(app.ZrRu_Plot_TabGrid);
            app.ZrRu_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrRu_DisplayAll_CheckBoxValueChanged, true);
            app.ZrRu_DisplayAll_CheckBox.Text = 'Display all lines';
            app.ZrRu_DisplayAll_CheckBox.Value = true;
            app.ZrRu_ColorAll_EditField = uieditfield(app.ZrRu_Plot_TabGrid, 'text');
            app.ZrRu_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrRu_ColorAll_EditFieldValueChanged, true);
            app.ZrRu_ColorAll_EditField.Value = 'red';
            app.ZrRu_Colour_Label = uilabel(app.ZrRu_Plot_TabGrid);
            app.ZrRu_Colour_Label.Text = 'Colour';
            app.ZrTtn_Tab = uitab(app.TabGroup);
            app.ZrTtn_Tab.Title = 'Zr-in-Ttn';
            app.ZrTtn_Input_Tab = uipanel(app.ZrTtn_Tab);
            app.ZrTtn_Input_TabGrid = uigridlayout(app.ZrTtn_Input_Tab);
            app.ZrTtn_Titanite_Label = uilabel(app.ZrTtn_Input_TabGrid);
            app.ZrTtn_Titanite_Label.Text = 'Titanite';
            app.aSiO2Label_3 = uilabel(app.ZrTtn_Input_TabGrid);
            app.aSiO2Label_3.Text = 'aSiO2';
            app.ZrTtn_aSiO2_EditField = uieditfield(app.ZrTtn_Input_TabGrid, 'numeric');
            app.ZrTtn_aSiO2_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_aSiO2_EditField.Tooltip = {''};
            app.ZrTtn_aSiO2_EditField.Value = 1;
            app.ZrppmLabel_2 = uilabel(app.ZrTtn_Input_TabGrid);
            app.ZrppmLabel_2.Text = 'Zr (ppm)';
            app.ZrTtn_Zr_EditField = uieditfield(app.ZrTtn_Input_TabGrid, 'numeric');
            app.ZrTtn_Zr_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_Zr_EditField.Tooltip = {''};
            app.PkbarLabel_10 = uilabel(app.ZrTtn_Input_TabGrid);
            app.PkbarLabel_10.Text = 'P (kbar)';
            app.ZrTtn_P_EditField = uieditfield(app.ZrTtn_Input_TabGrid, 'numeric');
            app.ZrTtn_P_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_P_EditField.Tooltip = {''};
            app.aTiO2Label_3 = uilabel(app.ZrTtn_Input_TabGrid);
            app.aTiO2Label_3.Text = 'aTiO2';
            app.ZrTtn_aTiO2_EditField = uieditfield(app.ZrTtn_Input_TabGrid, 'numeric');
            app.ZrTtn_aTiO2_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_aTiO2_EditField.Tooltip = {''};
            app.ZrTtn_aTiO2_EditField.Value = 1;
            app.ZrTtn_Results_Tab = uipanel(app.ZrTtn_Tab);
            app.ZrTtn_Results_TabGrid = uigridlayout(app.ZrTtn_Results_Tab);
            app.ZrTtn_H08color_EditField = uieditfield(app.ZrTtn_Results_TabGrid, 'text');
            app.ZrTtn_H08color_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_H08color_EditField.Value = 'red';
            app.ZrTtn_H08_CheckBox = uicheckbox(app.ZrTtn_Results_TabGrid);
            app.ZrTtn_H08_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_H08_CheckBox.Text = '';
            app.ZrTtn_H08_CheckBox.Value = true;
            app.H08Label = uilabel(app.ZrTtn_Results_TabGrid);
            app.H08Label.Text = 'H08';
            app.ZrTtn_H08_EditField = uieditfield(app.ZrTtn_Results_TabGrid, 'numeric');
            app.ZrTtn_H08_EditField.ValueDisplayFormat = '%.0f';
            app.ZrTtn_H08_EditField.Editable = 'off';
            app.ZrTtn_H08_EditField.Tooltip = {''};
            app.ZrTtn_TitaniteResult_Label = uilabel(app.ZrTtn_Results_TabGrid);
            app.ZrTtn_TitaniteResult_Label.Text = 'Rutile';
            app.ZrTtn_Thermometers_Label = uilabel(app.ZrTtn_Results_TabGrid);
            app.ZrTtn_Thermometers_Label.Text = 'Thermometers';
            app.ZrTtn_Plot_Tab = uipanel(app.ZrTtn_Tab);
            app.ZrTtn_Plot_TabGrid = uigridlayout(app.ZrTtn_Plot_Tab);
            app.ZrTtn_P_max_Label = uilabel(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_P_max_Label.Text = 'P max';
            app.ZrTtn_P_max = uispinner(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_P_max.Limits = [1 Inf];
            app.ZrTtn_P_max.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_P_max.Tooltip = {'P (kbar) max'};
            app.ZrTtn_P_max.Value = 15;
            app.ZrTtn_P_min_Label = uilabel(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_P_min_Label.Text = 'P min';
            app.ZrTtn_T_max_Label = uilabel(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_T_max_Label.Text = 'T max';
            app.ZrTtn_P_min = uispinner(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_P_min.Limits = [0 Inf];
            app.ZrTtn_P_min.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_P_min.Tooltip = {'P (kbar) min'};
            app.ZrTtn_T_max = uispinner(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_T_max.Step = 50;
            app.ZrTtn_T_max.Limits = [50 Inf];
            app.ZrTtn_T_max.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_T_max.Tooltip = {'T (C) max'};
            app.ZrTtn_T_max.Value = 1000;
            app.ZrTtn_GridCheckBox = uicheckbox(app.ZrTtn_Plot_TabGrid);

            app.ZrTtn_GridCheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_GridCheckBox.Text = 'Grid';
            app.ZrTtn_LineWidth_Label = uilabel(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_LineWidth_Label.Text = 'Width';
            app.ZrTtn_LineWidth_Spinner = uispinner(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_LineWidth_Spinner.Step = 0.5;
            app.ZrTtn_LineWidth_Spinner.Limits = [0.1 10];
            app.ZrTtn_LineWidth_Spinner.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_LineWidth_Spinner.Tooltip = {'Plot line width'};
            app.ZrTtn_LineWidth_Spinner.Value = 1;
            app.ZrTtn_T_min_Label = uilabel(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_T_min_Label.Text = 'T min';
            app.ZrTtn_T_min = uispinner(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_T_min.Step = 50;
            app.ZrTtn_T_min.Limits = [0 Inf];
            app.ZrTtn_T_min.ValueChangedFcn = createCallbackFcn(app, @ZrTtn, true);
            app.ZrTtn_T_min.Tooltip = {'T (C) min'};
            app.ZrTtn_UIAxes = uiaxes(app.ZrTtn_Plot_TabGrid);
            title(app.ZrTtn_UIAxes, '')
            xlabel(app.ZrTtn_UIAxes, 'T (C)')
            ylabel(app.ZrTtn_UIAxes, 'P (kbar)')
            app.ZrTtn_UIAxes.XLim = [0 1000];
            app.ZrTtn_UIAxes.YLim = [0 15];
            app.ZrTtn_UIAxes.LineStyleOrder = {'-'};
            app.ZrTtn_DisplayAll_CheckBox = uicheckbox(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_DisplayAll_CheckBox.ValueChangedFcn = createCallbackFcn(app, @ZrTtn_DisplayAll_CheckBoxValueChanged, true);
            app.ZrTtn_DisplayAll_CheckBox.Text = 'Display all lines';
            app.ZrTtn_DisplayAll_CheckBox.Value = true;
            app.ZrTtn_ColorAll_EditField = uieditfield(app.ZrTtn_Plot_TabGrid, 'text');
            app.ZrTtn_ColorAll_EditField.ValueChangedFcn = createCallbackFcn(app, @ZrTtn_ColorAll_EditFieldValueChanged, true);
            app.ZrTtn_ColorAll_EditField.Value = 'red';
            app.ZrTtn_Colour_Label = uilabel(app.ZrTtn_Plot_TabGrid);
            app.ZrTtn_Colour_Label.Text = 'Colour';
            app.EX_Tab = uitab(app.TabGroup);
            app.EX_Tab.Title = 'Example';
            app.EX_TabGrid = uigridlayout(app.EX_Tab);
            app.EX_Garnet_Label = uilabel(app.EX_TabGrid);
            app.EX_Garnet_Label.Text = 'Garnet';
            app.EX_Biotite_Label = uilabel(app.EX_TabGrid);
            app.EX_Biotite_Label.Text = 'Biotite';
            app.EX_UIAxes = uiaxes(app.EX_TabGrid);
            title(app.EX_UIAxes, '')
            xlabel(app.EX_UIAxes, 'T (C)')
            ylabel(app.EX_UIAxes, 'P (kbar)')
            app.EX_UIAxes.XLim = [0 1000];
            app.EX_UIAxes.YLim = [0 20];
            app.EX_UIAxes.LineStyleOrder = {'-'};
            app.XFeSliderLabel = uilabel(app.EX_TabGrid);
            app.XFeSliderLabel.Text = 'XFe';
            app.EX_grt_XFe_Slider = uislider(app.EX_TabGrid);
            app.EX_grt_XFe_Slider.Limits = [0 1];
            app.EX_grt_XFe_Slider.ValueChangingFcn = createCallbackFcn(app, @EX_grt_XFe_SliderValueChanging, true);
            app.EX_grt_XFe_Slider.Value = 0.8;
            app.XMgSliderLabel = uilabel(app.EX_TabGrid);
            app.XMgSliderLabel.Text = 'XMg';
            app.EX_grt_XMg_Slider = uislider(app.EX_TabGrid);
            app.EX_grt_XMg_Slider.Limits = [0 1];
            app.EX_grt_XMg_Slider.ValueChangingFcn = createCallbackFcn(app, @EX_grt_XMg_SliderValueChanging, true);
            app.EX_grt_XMg_Slider.Value = 0.2;
            app.XFeSlider_2Label = uilabel(app.EX_TabGrid);
            app.XFeSlider_2Label.Text = 'XFe';
            app.EX_bi_XFe_Slider = uislider(app.EX_TabGrid);
            app.EX_bi_XFe_Slider.Limits = [0 1];
            app.EX_bi_XFe_Slider.ValueChangedFcn = createCallbackFcn(app, @EX_bi_XFe_SliderValueChanged, true);
            app.EX_bi_XFe_Slider.Value = 0.4;
            app.XMgSlider_2Label = uilabel(app.EX_TabGrid);
            app.XMgSlider_2Label.Text = 'XMg';
            app.EX_bi_XMg_Slider = uislider(app.EX_TabGrid);
            app.EX_bi_XMg_Slider.Limits = [0 1];
            app.EX_bi_XMg_Slider.ValueChangedFcn = createCallbackFcn(app, @EX_bi_XMg_SliderValueChanged, true);
            app.EX_bi_XMg_Slider.Value = 0.6;
            app.TCLabel = uilabel(app.EX_TabGrid);
            app.TCLabel.Text = 'T (C)';
            app.EX_T_EditField = uieditfield(app.EX_TabGrid, 'numeric');
            app.EX_T_EditField.ValueDisplayFormat = '%.0f';
            app.EX_T_EditField.Editable = 'off';
            app.PkbarSliderLabel = uilabel(app.EX_TabGrid);
            app.PkbarSliderLabel.Text = 'P (kbar)';
            app.EX_P_Slider = uislider(app.EX_TabGrid);
            app.EX_P_Slider.Limits = [0 20];
            app.EX_P_Slider.ValueChangingFcn = createCallbackFcn(app, @EX, true);
            app.EX_P_Slider.Value = 8;
            app.lnKdEditField_2Label = uilabel(app.EX_TabGrid);
            app.lnKdEditField_2Label.Text = 'lnKd';
            app.EX_lnKd_EditField = uieditfield(app.EX_TabGrid, 'numeric');
            app.EX_lnKd_EditField.ValueDisplayFormat = '%.3f';
            app.EX_lnKd_EditField.Editable = 'off';
            app.TestInput_Button = uibutton(app.PTtoolbox, 'push');
            app.TestInput_Button.ButtonPushedFcn = createCallbackFcn(app, @TestInput_ButtonPushed, true);
            app.TestInput_Button.Text = 'Test Input';
            app.Clear_Button = uibutton(app.PTtoolbox, 'push');
            app.Clear_Button.ButtonPushedFcn = createCallbackFcn(app, @Clear_ButtonPushed, true);
            app.Clear_Button.Icon = 'icon_clear.png';
            app.Clear_Button.Text = 'Clear';
            app.Export_Button = uibutton(app.PTtoolbox, 'push');
            app.Export_Button.ButtonPushedFcn = createCallbackFcn(app, @Export_ButtonPushed, true);
            app.Export_Button.Icon = 'icon_export.png';
            app.Export_Button.Text = 'Export';
            app.PTtoolbox_Version_Label = uilabel(app.PTtoolbox);
            app.PTtoolbox_Version_Label.Text = 'Developer Build';
            % Show the figure after all components are created
            app.PTtoolbox.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PTtoolbox_DEVELOP

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.PTtoolbox)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.PTtoolbox)
        end
    end
end